classdef AddElement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddElementUIFigure             matlab.ui.Figure
        SelectaElementtypeButtonGroup  matlab.ui.container.ButtonGroup
        StandardButton                 matlab.ui.control.RadioButton
        UnknownButton                  matlab.ui.control.RadioButton
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        selApp; % Select Chamber App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, selApp)
            app.selApp = selApp;
            
            tmpPosX = selApp.figure1.Position(1) + selApp.figure1.Position(3)/2 - app.AddElementUIFigure.Position(3)/2;
            tmpPosY = selApp.figure1.Position(2) + selApp.figure1.Position(4)/2 - app.AddElementUIFigure.Position(4)/2;
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.AddElementUIFigure.Position(1) = tmpPosX;
                app.AddElementUIFigure.Position(2) = tmpPosY;
                
            else
                
                app.AddElementUIFigure.Position(1) = 10;
                app.AddElementUIFigure.Position(2) = 50;
                
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            selectedButton = app.SelectaElementtypeButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Standard')
                
                AddStandard(app.selApp)
                
            elseif strcmp(selectedButton.Text, 'Unknown')
                
                AddUnknown(app.selApp)
                
            end
            
            delete(app.AddElementUIFigure)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.AddElementUIFigure)
        end

        % Key release function: AddElementUIFigure
        function AddElementUIFigureKeyRelease(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddElementUIFigure = uifigure('Visible', 'off');
            app.AddElementUIFigure.Name = 'Add Element';
            app.AddElementUIFigure.Icon = 'Max_icon.png';
            app.AddElementUIFigure.KeyReleaseFcn = createCallbackFcn(app, @AddElementUIFigureKeyRelease, true);
            app.SelectaElementtypeButtonGroup = uibuttongroup(app.AddElementUIFigure);
            app.SelectaElementtypeButtonGroup.Title = 'Select a Element type';
            app.StandardButton = uiradiobutton(app.SelectaElementtypeButtonGroup);
            app.StandardButton.Tag = 'Standard';
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.UnknownButton = uiradiobutton(app.SelectaElementtypeButtonGroup);
            app.UnknownButton.Tag = 'Unknown';
            app.UnknownButton.Text = 'Unknown';
            app.OKButton = uibutton(app.AddElementUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.AddElementUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.AddElementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddElement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddElementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddElementUIFigure)
        end
    end
end