classdef AddStandard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddStandardUIFigure      matlab.ui.Figure
        ButtonPreview            matlab.ui.control.Button
        ButtonOK                 matlab.ui.control.Button
        ButtonCancel             matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        SelectaColorLabel        matlab.ui.control.Label
        StartConcentrationLabel  matlab.ui.control.Label
        DilutionFactorLabel      matlab.ui.control.Label
        NumbersLabel             matlab.ui.control.Label
        ReplicationsLabel        matlab.ui.control.Label
        ListOrderLabel           matlab.ui.control.Label
        pushbutton4              matlab.ui.control.Button
        edit1                    matlab.ui.control.NumericEditField
        popupmenu2               matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        edit2                    matlab.ui.control.NumericEditField
        edit3                    matlab.ui.control.NumericEditField
        checkbox1                matlab.ui.control.CheckBox
        edit4                    matlab.ui.control.NumericEditField
        popupmenu3               matlab.ui.control.DropDown
        PreviewListBoxLabel      matlab.ui.control.Label
        listbox1                 matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        selApp; % Select Chamber App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, selApp)
            app.selApp = selApp;
            
            tmpPosX = selApp.figure1.Position(1) + selApp.figure1.Position(3)/2 - app.AddStandardUIFigure.Position(3)/2;
            tmpPosY = selApp.figure1.Position(2) + selApp.figure1.Position(4)/2 - app.AddStandardUIFigure.Position(4)/2;
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.AddStandardUIFigure.Position(1) = tmpPosX;
                app.AddStandardUIFigure.Position(2) = tmpPosY;
                
            else
                
                app.AddStandardUIFigure.Position(1) = 10;
                app.AddStandardUIFigure.Position(2) = 50;
                
            end
        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            DataStruct         = getappdata(0, 'DataStruct');
            
            grpVal    = app.selApp.listboxGroup.Value;
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);      

            stdNum = DataStruct.(grpStr).StdNum;
            
            [stdList, listColor, listType, listRepNo, listCon, listPos, stdNum, unkNum, listUnit, listInt]...
                = MakeStdList(app, stdNum);
            
            DataStruct.(grpStr).Element       = [DataStruct.(grpStr).Element;       stdList];
            DataStruct.(grpStr).Color         = [DataStruct.(grpStr).Color;         listColor];
            DataStruct.(grpStr).Type          = [DataStruct.(grpStr).Type;          listType];
            DataStruct.(grpStr).RepNo         = [DataStruct.(grpStr).RepNo;         listRepNo];
            DataStruct.(grpStr).Concentration = [DataStruct.(grpStr).Concentration; listCon];
            DataStruct.(grpStr).Position      = [DataStruct.(grpStr).Position;      listPos];
            DataStruct.(grpStr).StdNum        = [DataStruct.(grpStr).StdNum;        stdNum];
            DataStruct.(grpStr).UnkNum        = [DataStruct.(grpStr).UnkNum;        unkNum];
            DataStruct.(grpStr).Unit          = [DataStruct.(grpStr).Unit;          listUnit];
            DataStruct.(grpStr).Intensity     = [DataStruct.(grpStr).Intensity;     listInt];
            
            if isempty(DataStruct.(grpStr).Element)
                
                val = 0;
                
            else

                val = size(get(app.selApp.listboxElement, 'Items'), 1)+1;
                
            end
            
            setappdata(0, 'DataStruct', DataStruct)
            
            set(app.selApp.listboxElement, 'Items', DataStruct.(grpStr).Element)
            set(app.selApp.listboxElement, 'Value', DataStruct.(grpStr).Element{val})
           
            RenewElemStatus(app.selApp, grpStr, DataStruct.(grpStr).Element{val}, DataStruct)
            
            delete(app.AddStandardUIFigure)
        end

        % Button pushed function: ButtonPreview
        function ButtonPreviewPushed(app, event)
%             MainGrpListHandle  = getappdata(0, 'MainGrpListHandle');
            DataStruct         = getappdata(0, 'DataStruct');
            grpVal             = get(app.selApp.listboxGroup, 'Value');
            grpNumSel          = str2double(grpVal{end}(7:end));
            grpStr             = sprintf('Grp%d', grpNumSel);
            stdNum             = DataStruct.(grpStr).StdNum;
            
            [stdList, ~, ~, ~, listCon] = MakeStdList(app, stdNum);
            
            listPrev = strcat(stdList, {' - '}, listCon);
            
            set(app.listbox1, 'Items', listPrev)
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            prevColor = get(event.Source, 'BackgroundColor');

            selColor = uisetcolor(prevColor);
            app.AddStandardUIFigure.WindowStyle = 'normal';
            app.AddStandardUIFigure.WindowStyle = 'modal';
            figure(app.AddStandardUIFigure)
            
            if length(selColor) == 1
                
                return
                
            end
            
            set(event.Source, 'BackgroundColor', selColor)
            
%             figure(app.AddStandardUIFigure)
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app.AddStandardUIFigure)
        end

        % Key press function: AddStandardUIFigure
        function AddStandardUIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                ButtonOKPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddStandardUIFigure = uifigure('Visible', 'off');
            app.AddStandardUIFigure.Name = 'Add Standard';
            app.AddStandardUIFigure.Icon = 'Max_icon.png';
            app.AddStandardUIFigure.KeyPressFcn = createCallbackFcn(app, @AddStandardUIFigureKeyPress, true);
            app.ButtonPreview = uibutton(app.AddStandardUIFigure, 'push');
            app.ButtonPreview.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewPushed, true);
            app.ButtonPreview.Text = 'Preview';
            app.ButtonOK = uibutton(app.AddStandardUIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = 'OK';
            app.ButtonCancel = uibutton(app.AddStandardUIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = 'Cancel';
            app.Panel = uipanel(app.AddStandardUIFigure);
            app.SelectaColorLabel = uilabel(app.Panel);
            app.SelectaColorLabel.Text = 'Select a Color :';
            app.StartConcentrationLabel = uilabel(app.Panel);
            app.StartConcentrationLabel.Text = 'Start Concentration :';
            app.DilutionFactorLabel = uilabel(app.Panel);
            app.DilutionFactorLabel.Text = 'Dilution Factor :';
            app.NumbersLabel = uilabel(app.Panel);
            app.NumbersLabel.Text = 'Numbers :';
            app.ReplicationsLabel = uilabel(app.Panel);
            app.ReplicationsLabel.Text = 'Replications :';
            app.ListOrderLabel = uilabel(app.Panel);
            app.ListOrderLabel.Text = 'List Order :';
            app.pushbutton4 = uibutton(app.Panel, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Text = '';
            app.edit1 = uieditfield(app.Panel, 'numeric');
            app.edit1.ValueDisplayFormat = '%.2f';
            app.edit1.Value = 1000;
            app.popupmenu2 = uidropdown(app.Panel);
            app.popupmenu2.Items = {'ug/ml', 'ng/ml', 'pg/ml'};
            app.popupmenu2.Enable = 'off';
            app.popupmenu2.Value = 'pg/ml';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '1 /';
            app.edit2 = uieditfield(app.Panel, 'numeric');
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.Value = 2;
            app.edit3 = uieditfield(app.Panel, 'numeric');
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 5;
            app.checkbox1 = uicheckbox(app.Panel);
            app.checkbox1.Text = 'Add a Zero Standard';
            app.edit4 = uieditfield(app.Panel, 'numeric');
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Value = 5;
            app.popupmenu3 = uidropdown(app.Panel);
            app.popupmenu3.Items = {'Num → Rep', 'Rep → Num'};
            app.popupmenu3.Value = 'Num → Rep';
            app.PreviewListBoxLabel = uilabel(app.Panel);
            app.PreviewListBoxLabel.Text = 'Preview';
            app.listbox1 = uilistbox(app.Panel);
            app.listbox1.Items = {};
            app.listbox1.Value = {};
            % Show the figure after all components are created
            app.AddStandardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddStandard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddStandardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddStandardUIFigure)
        end
    end
end