classdef CoorCorrWell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        DefaultButton              matlab.ui.control.Button
        CameraROIPanel             matlab.ui.container.Panel
        HeightEditField            matlab.ui.control.NumericEditField
        HeightEditFieldLabel       matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        WidthEditFieldLabel        matlab.ui.control.Label
        YoffsetEditField           matlab.ui.control.NumericEditField
        YoffsetEditFieldLabel      matlab.ui.control.Label
        XoffsetEditField           matlab.ui.control.NumericEditField
        XoffsetEditFieldLabel      matlab.ui.control.Label
        ScanningOptionPanel        matlab.ui.container.Panel
        OverlabRateEditField2      matlab.ui.control.NumericEditField
        OverlabRateEditField1      matlab.ui.control.NumericEditField
        OverlabRateEditFieldLabel  matlab.ui.control.Label
        ObserveRateEditField       matlab.ui.control.NumericEditField
        ObserveRateEditFieldLabel  matlab.ui.control.Label
        HorizontalLabel            matlab.ui.control.Label
        VerticalLabel              matlab.ui.control.Label
        PositionLeftRightTopBottomLabel  matlab.ui.control.Label
        Panel3                     matlab.ui.container.Panel
        GetButton12                matlab.ui.control.Button
        edit24                     matlab.ui.control.NumericEditField
        edit23                     matlab.ui.control.NumericEditField
        PositionLabel3_4           matlab.ui.control.Label
        GetButton11                matlab.ui.control.Button
        edit22                     matlab.ui.control.NumericEditField
        edit21                     matlab.ui.control.NumericEditField
        PositionLabel3_3           matlab.ui.control.Label
        GetButton10                matlab.ui.control.Button
        edit20                     matlab.ui.control.NumericEditField
        edit19                     matlab.ui.control.NumericEditField
        PositionLabel3_2           matlab.ui.control.Label
        GetButton9                 matlab.ui.control.Button
        edit18                     matlab.ui.control.NumericEditField
        edit17                     matlab.ui.control.NumericEditField
        PositionLabel3_1           matlab.ui.control.Label
        MoveButton_7               matlab.ui.control.Button
        YLabel_4                   matlab.ui.control.Label
        XLabel_4                   matlab.ui.control.Label
        Panel4                     matlab.ui.container.Panel
        GetButton16                matlab.ui.control.Button
        edit32                     matlab.ui.control.NumericEditField
        edit31                     matlab.ui.control.NumericEditField
        PositionLabel4_4           matlab.ui.control.Label
        GetButton15                matlab.ui.control.Button
        edit30                     matlab.ui.control.NumericEditField
        edit29                     matlab.ui.control.NumericEditField
        PositionLabel4_3           matlab.ui.control.Label
        GetButton14                matlab.ui.control.Button
        edit28                     matlab.ui.control.NumericEditField
        edit27                     matlab.ui.control.NumericEditField
        PositionLabel4_2           matlab.ui.control.Label
        GetButton13                matlab.ui.control.Button
        edit26                     matlab.ui.control.NumericEditField
        edit25                     matlab.ui.control.NumericEditField
        PositionLabel4_1           matlab.ui.control.Label
        MoveButton_8               matlab.ui.control.Button
        YLabel_3                   matlab.ui.control.Label
        XLabel_3                   matlab.ui.control.Label
        Panel2                     matlab.ui.container.Panel
        GetButton8                 matlab.ui.control.Button
        edit16                     matlab.ui.control.NumericEditField
        edit15                     matlab.ui.control.NumericEditField
        PositionLabel2_4           matlab.ui.control.Label
        GetButton7                 matlab.ui.control.Button
        edit14                     matlab.ui.control.NumericEditField
        edit13                     matlab.ui.control.NumericEditField
        PositionLabel2_3           matlab.ui.control.Label
        GetButton6                 matlab.ui.control.Button
        edit12                     matlab.ui.control.NumericEditField
        edit11                     matlab.ui.control.NumericEditField
        PositionLabel2_2           matlab.ui.control.Label
        GetButton5                 matlab.ui.control.Button
        edit10                     matlab.ui.control.NumericEditField
        edit9                      matlab.ui.control.NumericEditField
        PositionLabel2_1           matlab.ui.control.Label
        MoveButton_6               matlab.ui.control.Button
        YLabel_2                   matlab.ui.control.Label
        XLabel_2                   matlab.ui.control.Label
        Panel1                     matlab.ui.container.Panel
        GetButton4                 matlab.ui.control.Button
        edit8                      matlab.ui.control.NumericEditField
        edit7                      matlab.ui.control.NumericEditField
        PositionLabel1_4           matlab.ui.control.Label
        GetButton3                 matlab.ui.control.Button
        edit6                      matlab.ui.control.NumericEditField
        edit5                      matlab.ui.control.NumericEditField
        PositionLabel1_3           matlab.ui.control.Label
        GetButton2                 matlab.ui.control.Button
        edit4                      matlab.ui.control.NumericEditField
        edit3                      matlab.ui.control.NumericEditField
        PositionLabel1_2           matlab.ui.control.Label
        GetButton1                 matlab.ui.control.Button
        edit2                      matlab.ui.control.NumericEditField
        edit1                      matlab.ui.control.NumericEditField
        PositionLabel1_1           matlab.ui.control.Label
        MoveButton_5               matlab.ui.control.Button
        YLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp; % Main window
        CurChipNo = 0;        
    end
    
    properties (Access = private)
        progDlg; % Description
    end
    
    methods (Access = private)
        
        function editCallback(~, event)
            
            global step_per_um_X step_per_um_Y max_step_X max_step_Y

            tmpTag = get(event.Source, 'Tag');
            tmpVal = get(event.Source, 'Value');
            tmpNum = str2double(tmpTag(5:end));
            
            if mod(tmpNum, 2) == 1
                
                if tmpVal < 0
                    
                    set(event.Source, 'Value', 0);
                    
              elseif tmpVal > (max_step_X/step_per_um_X)
                    
                    set(event.Source, 'Value', floor(max_step_X/step_per_um_X))
                    
                end
                
            else
                
                if tmpVal < 0
                        
                    set(event.Source, 'Value', 0)
            
                elseif tmpVal > (max_step_Y/step_per_um_Y)
            
                    set(event.Source, 'Value', floor(max_step_Y/step_per_um_Y))
            
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
%             global CoorCorrFile coorBackup 
            global CoorCorrFile ChipInform CurrentChip
            
            app.MainApp = MainApp;
            
            set(MainApp.popupmenu_chip, 'Enable', 'Off')
            
            popupVal = get(app.MainApp.popupmenu_chip, 'Value');
            popupItems = get(app.MainApp.popupmenu_chip, 'Items');
            app.CurChipNo = find(strcmp(popupVal, popupItems));   
            
            chamberNum = ChipInform(app.CurChipNo).ChamberNum(1) *...
                ChipInform(app.CurChipNo).ChamberNum(2);
            
            if chamberNum < 4 % Total number of nodes : 4
                
                for chamberNoi = chamberNum+1 : 4
                
                    panelStr = sprintf('Panel%d', chamberNoi);
                    set(app.(panelStr), 'Visible', 'Off')
                    
                end
            
            end

            if exist(CoorCorrFile, 'file') == 2
                
                load(CoorCorrFile)                
%                 coorVal = ChipInform(app.CurChipNo).DefaultCoordinate;
                coorVal = ChipInform(app.CurChipNo).MeasuredCoordinate;
                
                j = 1;
                
                for i = 1:32
            
                    editStr = sprintf('edit%d', i);
            
                    if mod(i, 2) == 1
            
                        set(app.(editStr), 'Value', coorVal(j, 1))
            
                    else
            
                        set(app.(editStr), 'Value', coorVal(j, 2));
                        j = j+1;
            
                    end
            
                end
                
                set(app.ObserveRateEditField, 'Value', ChipInform(app.CurChipNo).ObserveRate)
                set(app.OverlabRateEditField1, 'Value', ChipInform(app.CurChipNo).OverlabRate(1))
                set(app.OverlabRateEditField2, 'Value', ChipInform(app.CurChipNo).OverlabRate(2))
                
                set(app.XoffsetEditField, 'Value', ChipInform(app.CurChipNo).ROI(1));
                set(app.YoffsetEditField, 'Value', ChipInform(app.CurChipNo).ROI(2));
                set(app.WidthEditField, 'Value', ChipInform(app.CurChipNo).ROI(3));
                set(app.HeightEditField, 'Value', ChipInform(app.CurChipNo).ROI(4));
                
                
                try
                    
%                     numVal = noFrame;
%                     set(app.popupmenu1, 'Value', app.popupmenu1.Items{numVal});
                    
                catch
                    
                    DefaultButtonPushed(app)
                    
                end
                
            else
                
                DefaultButtonPushed(app)
            
            end
            
            
%             for i = 1:32
%             
%                 editStr = sprintf('edit%d', i);
%                 coorBackup(i) = get(app.(editStr), 'Value');
%                 
%             end
            
            scrPos = get(0, 'ScreenSize');
            
            tmpPosX = MainApp.figure1.Position(1) - app.UIFigure.Position(3) - 10;
            tmpPosY = MainApp.figure1.Position(2) - app.UIFigure.Position(4) + MainApp.figure1.Position(4);
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.UIFigure.Position(1) = tmpPosX;
                app.UIFigure.Position(2) = tmpPosY;
                
            else
                
                app.UIFigure.Position(1) = 0;
                app.UIFigure.Position(2) = scrPos(4) - app.UIFigure.Position(4) - 50;
                
            end
            
            CurrentChip = size(ChipInform, 2) + 1;
            set(app.MainApp.popupmenu_chip, 'Value', app.MainApp.popupmenu_chip.Items{CurrentChip}); % Manual
            PopupChipCallback(app.MainApp)
            
            if app.CurChipNo == 1 % WellPlate
                
                for i = 1:4
                    
                    labelStr1 = sprintf('PositionLabel%d_1', i);
                    labelStr2 = sprintf('PositionLabel%d_2', i);
                    labelStr3 = sprintf('PositionLabel%d_3', i);
                    labelStr4 = sprintf('PositionLabel%d_4', i);
                    app.(labelStr1).Text = 'L';
                    app.(labelStr2).Text = 'R';
                    app.(labelStr3).Text = 'T';
                    app.(labelStr4).Text = 'B';
                        
                end                                    
                
            else % DropletChip
                
                for i = 1:4
                    
                    labelStr1 = sprintf('PositionLabel%d_1', i);
                    labelStr2 = sprintf('PositionLabel%d_2', i);
                    labelStr3 = sprintf('PositionLabel%d_3', i);
                    labelStr4 = sprintf('PositionLabel%d_4', i);
                    app.(labelStr1).Text = 'L-T';
                    app.(labelStr2).Text = 'R-T';
                    app.(labelStr3).Text = 'L-B';
                    app.(labelStr4).Text = 'R-B';
                        
                end                
                
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global CoorCorrFile LensMagDefault FluorModeFile...
                 ChipInform Pixel2umDefault CurrentChip
            
            currentChipInform = ChipInform(app.CurChipNo);
            
            app.progDlg = uiprogressdlg(app.UIFigure,...
                'Message', 'Applying current settings...',...
                'Indeterminate', 'on');
            
            coorVal = zeros(16, 2);
            
            j = 1;
            for i = 1 : size(coorVal, 1)*size(coorVal, 2)
                
                editStr = sprintf('edit%d', i);
                
                if mod(i, 2) == 1
                    
                    coorVal(j, 1) = get(app.(editStr), 'Value');    
                    
                else
                    
                    coorVal(j, 2) = get(app.(editStr), 'Value');    
                    j = j+1;
                    
                end
            
            end
            
            
            if exist(FluorModeFile, 'file') == 2
                
                load(FluorModeFile)
                
            else
                
                lensMag = LensMagDefault;
                
            end
            
            x = get(app.XoffsetEditField, 'Value');
            y = get(app.YoffsetEditField, 'Value');
            w = get(app.WidthEditField, 'Value');
            h = get(app.HeightEditField, 'Value');
            ROI = [x, y, w, h];
                       
            pixel2um = Pixel2umDefault * LensMagDefault/lensMag;
            observeRate = get(app.ObserveRateEditField, 'Value');
            overlabRate = [get(app.OverlabRateEditField1, 'Value'), get(app.OverlabRateEditField2, 'Value')];
            chipInformStruct = struct;
%             chipInformStruct = CalculateChamberCoordinate(...
%                 currentChipInform.Type, currentChipInform.Name, [], ...
%                 coorVal, currentChipInform.ROI, currentChipInform.ChamberNum,...
%                 pixel2um, observeRate, overlabRate);
            chipInformStruct = CalculateChamberCoordinate(...
                currentChipInform.Type, currentChipInform.Name, [], ...
                coorVal, ROI, currentChipInform.ChamberNum,...
                pixel2um, observeRate, overlabRate);
            
            structField = fieldnames(chipInformStruct);
            
            for fieldNum = 1:size(structField, 1)
                currentChipInform.(structField{fieldNum, 1}) = chipInformStruct.(structField{fieldNum, 1});
            end            
            
            ChipInform(app.CurChipNo) = currentChipInform;
            save(CoorCorrFile, 'ChipInform')

            PopupChipCallback(app.MainApp)
            isConnection = 0;
            SettingCameraROI(app.MainApp, ChipInform(app.CurChipNo).ROI, isConnection);
            DefineGlobalMovingStep(ChipInform(app.CurChipNo).ROI);
            
            tag = sprintf('togglebutton_C%d_Chamb', app.CurChipNo);
            CurrentChip = app.CurChipNo;
            togglebutton_Chamb_Act(app.MainApp, tag, 1, currentChipInform);
            
            delete(app.progDlg)
            
            horNo = ChipInform(CurrentChip).FrameNum(1);
            verNo = ChipInform(CurrentChip).FrameNum(2);                
            helpMsg = sprintf(...
                'The number of Scanning Image is %d\n(Horizontal : %d x Vertical : %d in each chamber.)',...
                horNo*verNo, horNo, verNo);
            helpDlg = helpdlg(helpMsg, 'Scanning Information');
            waitfor(helpDlg)
            
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
%             global defaultCoor
            global ChipInform ObserveRateDefault OverlabRateDefault ROIDefault
            
            defaultCoor = ChipInform(app.CurChipNo).DefaultCoordinate;
            
            j = 1;
            for i = 1:32
            
                editStr = sprintf('edit%d', i);
        
                if mod(i, 2) == 1
        
                    set(app.(editStr), 'Value', defaultCoor(j, 1))
        
                else
        
                    set(app.(editStr), 'Value', defaultCoor(j, 2));
                    j = j+1;
        
                end
        
            end
            
            set(app.ObserveRateEditField, 'Value', ObserveRateDefault)
            set(app.OverlabRateEditField1, 'Value', OverlabRateDefault(1))
            set(app.OverlabRateEditField2, 'Value', OverlabRateDefault(2))
            
            set(app.XoffsetEditField, 'Value', ROIDefault(1));
            set(app.YoffsetEditField, 'Value', ROIDefault(2));
            set(app.WidthEditField, 'Value', ROIDefault(3));
            set(app.HeightEditField, 'Value', ROIDefault(4));
            
%             set(app.popupmenu1, 'Value', app.popupmenu1.Items{3});
        end

        % Value changed function: edit1, edit10, edit11, edit12, 
        % edit13, edit14, edit15, edit16, edit17, edit18, edit19, 
        % edit2, edit20, edit21, edit22, edit23, edit24, edit25, 
        % edit26, edit27, edit28, edit29, edit3, edit30, edit31, 
        % edit32, edit4, edit5, edit6, edit7, edit8, edit9
        function edit1ValueChanged(app, event)
            app.editCallback(event)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end

        % Button pushed function: GetButton1, GetButton10, 
        % GetButton11, GetButton12, GetButton13, GetButton14, 
        % GetButton15, GetButton16, GetButton2, GetButton3, 
        % GetButton4, GetButton5, GetButton6, GetButton7, 
        % GetButton8, GetButton9
        function GetButton1Pushed(app, event)
            btnTagNo = str2double(event.Source.Tag);
            editStrX = sprintf('edit%d', btnTagNo * 2 - 1);
            editStrY = sprintf('edit%d', btnTagNo * 2 );
            
            app.(editStrX).Value = app.MainApp.edit_X.Value;
            app.(editStrY).Value = app.MainApp.edit_Y.Value;
        end

        % Button pushed function: MoveButton_5, MoveButton_6, 
        % MoveButton_7, MoveButton_8
        function MoveButtonPushed(app, event)
            global X_abs_um Y_abs_um Reset_flag
            
            progDlg = uiprogressdlg(app.UIFigure,...
                'Message', 'Moving...', 'Indeterminate', 'on');
            
            btnTagNo = str2double(event.Source.Tag);
            editNo = (btnTagNo-1) * 8 + 1;
            
            tgtX = 0;
            tgtY = 0;
            
            for i = 0:3
            
                editStrX = sprintf('edit%d', editNo + i*2);
                editStrY = sprintf('edit%d', editNo + i*2 + 1);
                tgtX = tgtX + app.(editStrX).Value;
                tgtY = tgtY + app.(editStrY).Value;
                
            end
            
            tgtX = tgtX/4;
            tgtY = tgtY/4;
            
            distCoorX = abs(X_abs_um - tgtX);
            distCoorY = abs(Y_abs_um - tgtY);
            
            if X_abs_um > tgtX

                Stage_Control(app.MainApp, 'X', 'F', distCoorX);
    
            elseif X_abs_um < tgtX
    
                Stage_Control(app.MainApp, 'X', 'B', distCoorX);
    
            end
            
            if Y_abs_um > tgtY

                Stage_Control(app.MainApp, 'Y', 'F', distCoorY);
    
            elseif Y_abs_um < tgtY
    
                Stage_Control(app.MainApp, 'Y', 'B', distCoorY);
    
            end
            
            app.Panel1.BackgroundColor = [0.94,0.94,0.94];
            app.Panel2.BackgroundColor = [0.94,0.94,0.94];
            app.Panel3.BackgroundColor = [0.94,0.94,0.94];
            app.Panel4.BackgroundColor = [0.94,0.94,0.94];
            
            panStr = sprintf('Panel%d', btnTagNo);
            app.(panStr).BackgroundColor = [0.65,0.65,0.65];
            
            % Reset After Move
            progDlgReset = uiprogressdlg(app.MainApp.figure1,...
                'Message', 'Reset X, Y Stage', 'Indeterminate', 'on');
            
            Reset_flag = 1;
            prevX = X_abs_um;
            prevY = Y_abs_um;
            
            ResetStage(app.MainApp, [1 1 0])            
            Stage_Control(app.MainApp, 'X', 'B', prevX)
            Stage_Control(app.MainApp, 'Y', 'B', prevY)
            Reset_flag = 0;
            delete(progDlgReset)
            
            delete(progDlg)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global CurrentChip
            
            CurrentChip = app.CurChipNo;
            set(app.MainApp.popupmenu_chip, 'Enable', 'On')
            set(app.MainApp.popupmenu_chip, 'Value',...
                app.MainApp.popupmenu_chip.Items{app.CurChipNo});
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Carlibration';
            app.UIFigure.Icon = 'Max_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'A-12 (node 1)';
            app.XLabel = uilabel(app.Panel1);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Panel1);
            app.YLabel.Text = 'Y';
            app.MoveButton_5 = uibutton(app.Panel1, 'push');
            app.MoveButton_5.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_5.Tag = '1';
            app.MoveButton_5.Text = 'Move';
            app.PositionLabel1_1 = uilabel(app.Panel1);
            app.PositionLabel1_1.Text = 'L-T';
            app.edit1 = uieditfield(app.Panel1, 'numeric');
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.Panel1, 'numeric');
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit2.Tag = 'edit2';
            app.GetButton1 = uibutton(app.Panel1, 'push');
            app.GetButton1.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton1.Tag = '1';
            app.GetButton1.Text = 'Get';
            app.PositionLabel1_2 = uilabel(app.Panel1);
            app.PositionLabel1_2.Text = 'Right';
            app.edit3 = uieditfield(app.Panel1, 'numeric');
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.Panel1, 'numeric');
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit4.Tag = 'edit4';
            app.GetButton2 = uibutton(app.Panel1, 'push');
            app.GetButton2.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton2.Tag = '2';
            app.GetButton2.Text = 'Get';
            app.PositionLabel1_3 = uilabel(app.Panel1);
            app.PositionLabel1_3.Text = 'Up';
            app.edit5 = uieditfield(app.Panel1, 'numeric');
            app.edit5.ValueDisplayFormat = '%.0f';
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit5.Tag = 'edit5';
            app.edit6 = uieditfield(app.Panel1, 'numeric');
            app.edit6.ValueDisplayFormat = '%.0f';
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit6.Tag = 'edit6';
            app.GetButton3 = uibutton(app.Panel1, 'push');
            app.GetButton3.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton3.Tag = '3';
            app.GetButton3.Text = 'Get';
            app.PositionLabel1_4 = uilabel(app.Panel1);
            app.PositionLabel1_4.Text = 'R-B';
            app.edit7 = uieditfield(app.Panel1, 'numeric');
            app.edit7.ValueDisplayFormat = '%.0f';
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.Panel1, 'numeric');
            app.edit8.ValueDisplayFormat = '%.0f';
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit8.Tag = 'edit8';
            app.GetButton4 = uibutton(app.Panel1, 'push');
            app.GetButton4.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton4.Tag = '4';
            app.GetButton4.Text = 'Get';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'H-12 (node 2)';
            app.XLabel_2 = uilabel(app.Panel2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.Panel2);
            app.YLabel_2.Text = 'Y';
            app.MoveButton_6 = uibutton(app.Panel2, 'push');
            app.MoveButton_6.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_6.Tag = '2';
            app.MoveButton_6.Text = 'Move';
            app.PositionLabel2_1 = uilabel(app.Panel2);
            app.PositionLabel2_1.Text = 'Left';
            app.edit9 = uieditfield(app.Panel2, 'numeric');
            app.edit9.ValueDisplayFormat = '%.0f';
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit9.Tag = 'edit9';
            app.edit10 = uieditfield(app.Panel2, 'numeric');
            app.edit10.ValueDisplayFormat = '%.0f';
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit10.Tag = 'edit10';
            app.GetButton5 = uibutton(app.Panel2, 'push');
            app.GetButton5.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton5.Tag = '5';
            app.GetButton5.Text = 'Get';
            app.PositionLabel2_2 = uilabel(app.Panel2);
            app.PositionLabel2_2.Text = 'Right';
            app.edit11 = uieditfield(app.Panel2, 'numeric');
            app.edit11.ValueDisplayFormat = '%.0f';
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit11.Tag = 'edit11';
            app.edit12 = uieditfield(app.Panel2, 'numeric');
            app.edit12.ValueDisplayFormat = '%.0f';
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit12.Tag = 'edit12';
            app.GetButton6 = uibutton(app.Panel2, 'push');
            app.GetButton6.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton6.Tag = '6';
            app.GetButton6.Text = 'Get';
            app.PositionLabel2_3 = uilabel(app.Panel2);
            app.PositionLabel2_3.Text = 'Up';
            app.edit13 = uieditfield(app.Panel2, 'numeric');
            app.edit13.ValueDisplayFormat = '%.0f';
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit13.Tag = 'edit13';
            app.edit14 = uieditfield(app.Panel2, 'numeric');
            app.edit14.ValueDisplayFormat = '%.0f';
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit14.Tag = 'edit14';
            app.GetButton7 = uibutton(app.Panel2, 'push');
            app.GetButton7.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton7.Tag = '7';
            app.GetButton7.Text = 'Get';
            app.PositionLabel2_4 = uilabel(app.Panel2);
            app.PositionLabel2_4.Text = 'Down';
            app.edit15 = uieditfield(app.Panel2, 'numeric');
            app.edit15.ValueDisplayFormat = '%.0f';
            app.edit15.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit15.Tag = 'edit15';
            app.edit16 = uieditfield(app.Panel2, 'numeric');
            app.edit16.ValueDisplayFormat = '%.0f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit16.Tag = 'edit16';
            app.GetButton8 = uibutton(app.Panel2, 'push');
            app.GetButton8.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton8.Tag = '8';
            app.GetButton8.Text = 'Get';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'H-1 (node 4)';
            app.XLabel_3 = uilabel(app.Panel4);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.Panel4);
            app.YLabel_3.Text = 'Y';
            app.MoveButton_8 = uibutton(app.Panel4, 'push');
            app.MoveButton_8.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_8.Tag = '4';
            app.MoveButton_8.Text = 'Move';
            app.PositionLabel4_1 = uilabel(app.Panel4);
            app.PositionLabel4_1.Text = 'Left';
            app.edit25 = uieditfield(app.Panel4, 'numeric');
            app.edit25.ValueDisplayFormat = '%.0f';
            app.edit25.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit25.Tag = 'edit1';
            app.edit26 = uieditfield(app.Panel4, 'numeric');
            app.edit26.ValueDisplayFormat = '%.0f';
            app.edit26.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit26.Tag = 'edit2';
            app.GetButton13 = uibutton(app.Panel4, 'push');
            app.GetButton13.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton13.Tag = '13';
            app.GetButton13.Text = 'Get';
            app.PositionLabel4_2 = uilabel(app.Panel4);
            app.PositionLabel4_2.Text = 'Right';
            app.edit27 = uieditfield(app.Panel4, 'numeric');
            app.edit27.ValueDisplayFormat = '%.0f';
            app.edit27.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit27.Tag = 'edit3';
            app.edit28 = uieditfield(app.Panel4, 'numeric');
            app.edit28.ValueDisplayFormat = '%.0f';
            app.edit28.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit28.Tag = 'edit4';
            app.GetButton14 = uibutton(app.Panel4, 'push');
            app.GetButton14.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton14.Tag = '14';
            app.GetButton14.Text = 'Get';
            app.PositionLabel4_3 = uilabel(app.Panel4);
            app.PositionLabel4_3.Text = 'Up';
            app.edit29 = uieditfield(app.Panel4, 'numeric');
            app.edit29.ValueDisplayFormat = '%.0f';
            app.edit29.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit29.Tag = 'edit5';
            app.edit30 = uieditfield(app.Panel4, 'numeric');
            app.edit30.ValueDisplayFormat = '%.0f';
            app.edit30.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit30.Tag = 'edit6';
            app.GetButton15 = uibutton(app.Panel4, 'push');
            app.GetButton15.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton15.Tag = '15';
            app.GetButton15.Text = 'Get';
            app.PositionLabel4_4 = uilabel(app.Panel4);
            app.PositionLabel4_4.Text = 'Down';
            app.edit31 = uieditfield(app.Panel4, 'numeric');
            app.edit31.ValueDisplayFormat = '%.0f';
            app.edit31.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit31.Tag = 'edit7';
            app.edit32 = uieditfield(app.Panel4, 'numeric');
            app.edit32.ValueDisplayFormat = '%.0f';
            app.edit32.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit32.Tag = 'edit8';
            app.GetButton16 = uibutton(app.Panel4, 'push');
            app.GetButton16.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton16.Tag = '16';
            app.GetButton16.Text = 'Get';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'A-1 (node 3)';
            app.XLabel_4 = uilabel(app.Panel3);
            app.XLabel_4.Text = 'X';
            app.YLabel_4 = uilabel(app.Panel3);
            app.YLabel_4.Text = 'Y';
            app.MoveButton_7 = uibutton(app.Panel3, 'push');
            app.MoveButton_7.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton_7.Tag = '3';
            app.MoveButton_7.Text = 'Move';
            app.PositionLabel3_1 = uilabel(app.Panel3);
            app.PositionLabel3_1.Text = 'Left';
            app.edit17 = uieditfield(app.Panel3, 'numeric');
            app.edit17.ValueDisplayFormat = '%.0f';
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit17.Tag = 'edit9';
            app.edit18 = uieditfield(app.Panel3, 'numeric');
            app.edit18.ValueDisplayFormat = '%.0f';
            app.edit18.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit18.Tag = 'edit10';
            app.GetButton9 = uibutton(app.Panel3, 'push');
            app.GetButton9.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton9.Tag = '9';
            app.GetButton9.Text = 'Get';
            app.PositionLabel3_2 = uilabel(app.Panel3);
            app.PositionLabel3_2.Text = 'Right';
            app.edit19 = uieditfield(app.Panel3, 'numeric');
            app.edit19.ValueDisplayFormat = '%.0f';
            app.edit19.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit19.Tag = 'edit11';
            app.edit20 = uieditfield(app.Panel3, 'numeric');
            app.edit20.ValueDisplayFormat = '%.0f';
            app.edit20.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit20.Tag = 'edit12';
            app.GetButton10 = uibutton(app.Panel3, 'push');
            app.GetButton10.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton10.Tag = '10';
            app.GetButton10.Text = 'Get';
            app.PositionLabel3_3 = uilabel(app.Panel3);
            app.PositionLabel3_3.Text = 'Up';
            app.edit21 = uieditfield(app.Panel3, 'numeric');
            app.edit21.ValueDisplayFormat = '%.0f';
            app.edit21.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit21.Tag = 'edit13';
            app.edit22 = uieditfield(app.Panel3, 'numeric');
            app.edit22.ValueDisplayFormat = '%.0f';
            app.edit22.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit22.Tag = 'edit14';
            app.GetButton11 = uibutton(app.Panel3, 'push');
            app.GetButton11.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton11.Tag = '11';
            app.GetButton11.Text = 'Get';
            app.PositionLabel3_4 = uilabel(app.Panel3);
            app.PositionLabel3_4.Text = 'Down';
            app.edit23 = uieditfield(app.Panel3, 'numeric');
            app.edit23.ValueDisplayFormat = '%.0f';
            app.edit23.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit23.Tag = 'edit15';
            app.edit24 = uieditfield(app.Panel3, 'numeric');
            app.edit24.ValueDisplayFormat = '%.0f';
            app.edit24.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit24.Tag = 'edit16';
            app.GetButton12 = uibutton(app.Panel3, 'push');
            app.GetButton12.ButtonPushedFcn = createCallbackFcn(app, @GetButton1Pushed, true);
            app.GetButton12.Tag = '12';
            app.GetButton12.Text = 'Get';
            app.PositionLeftRightTopBottomLabel = uilabel(app.UIFigure);
            app.PositionLeftRightTopBottomLabel.Text = '* Position : (L)eft, (R)ight, (T)op, (B)ottom';
            app.ScanningOptionPanel = uipanel(app.UIFigure);
            app.ScanningOptionPanel.Title = 'Scanning Option';
            app.VerticalLabel = uilabel(app.ScanningOptionPanel);
            app.VerticalLabel.Text = 'Vertical';
            app.HorizontalLabel = uilabel(app.ScanningOptionPanel);
            app.HorizontalLabel.Text = 'Horizontal';
            app.ObserveRateEditFieldLabel = uilabel(app.ScanningOptionPanel);
            app.ObserveRateEditFieldLabel.Text = 'Observe Rate';
            app.ObserveRateEditField = uieditfield(app.ScanningOptionPanel, 'numeric');
            app.ObserveRateEditField.Limits = [0.1 1.5];
            app.ObserveRateEditField.ValueDisplayFormat = '%.2f';
            app.ObserveRateEditField.Value = 1.1;
            app.OverlabRateEditFieldLabel = uilabel(app.ScanningOptionPanel);
            app.OverlabRateEditFieldLabel.Text = 'Overlab Rate';
            app.OverlabRateEditField1 = uieditfield(app.ScanningOptionPanel, 'numeric');
            app.OverlabRateEditField1.Limits = [0 0.5];
            app.OverlabRateEditField1.ValueDisplayFormat = '%.2f';
            app.OverlabRateEditField1.Value = 0.1;
            app.OverlabRateEditField2 = uieditfield(app.ScanningOptionPanel, 'numeric');
            app.OverlabRateEditField2.Limits = [0 0.5];
            app.OverlabRateEditField2.ValueDisplayFormat = '%.2f';
            app.OverlabRateEditField2.Value = 0.1;
            app.CameraROIPanel = uipanel(app.UIFigure);
            app.CameraROIPanel.Title = 'Camera ROI';
            app.XoffsetEditFieldLabel = uilabel(app.CameraROIPanel);
            app.XoffsetEditFieldLabel.Text = 'X offset';
            app.XoffsetEditField = uieditfield(app.CameraROIPanel, 'numeric');
            app.XoffsetEditField.Limits = [0 Inf];
            app.XoffsetEditField.ValueDisplayFormat = '%.0f';
            app.YoffsetEditFieldLabel = uilabel(app.CameraROIPanel);
            app.YoffsetEditFieldLabel.Text = 'Y offset';
            app.YoffsetEditField = uieditfield(app.CameraROIPanel, 'numeric');
            app.YoffsetEditField.Limits = [0 Inf];
            app.YoffsetEditField.ValueDisplayFormat = '%.0f';
            app.WidthEditFieldLabel = uilabel(app.CameraROIPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CameraROIPanel, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueDisplayFormat = '%.0f';
            app.HeightEditFieldLabel = uilabel(app.CameraROIPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CameraROIPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueDisplayFormat = '%.0f';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoorCorrWell(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end