classdef FittingSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIGridMain                      matlab.ui.container.GridLayout
        UIGridButton                    matlab.ui.container.GridLayout
        UIButtonCancel                  matlab.ui.control.Button
        UIButtonOK                      matlab.ui.control.Button
        UIButtonDefault                 matlab.ui.control.Button
        UIPanelFittingParameters        matlab.ui.container.Panel
        UIGridFIttingParameters         matlab.ui.container.GridLayout
        UIPanelFittingParametersSetting  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        UICheckBoxNegative              matlab.ui.control.CheckBox
        UIEditFieldSettingInitialValue  matlab.ui.control.NumericEditField
        InitialvalueEditFieldLabel      matlab.ui.control.Label
        UIDropDownSettingType           matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        UIListBoxParameters             matlab.ui.control.ListBox
        UIPanelFittingModel             matlab.ui.container.Panel
        UIGridFittingModel              matlab.ui.container.GridLayout
        UIImageModel                    matlab.ui.control.Image
        UIImageEquation                 matlab.ui.control.Image
        UILabelModel                    matlab.ui.control.Label
        UILabelEquation                 matlab.ui.control.Label
        UIDropDownModel                 matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        MainApp;
    end
    
    properties (Access = private)
        currentAnalyte;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;

            analyteNo = strcmp(app.MainApp.UIDropdownName.Items, MainApp.UIDropdownName.Value);
            analyte = app.MainApp.Analyte;
            app.currentAnalyte = analyte(analyteNo);
            currentModel = analyte(analyteNo).FittingVariable.FittingModel;

            if strcmp(currentModel, 'OneToOneStandard')
                app.UIDropDownModel.Value = '1:1 Standard Model (Langmuir Binding)';
            elseif strcmp(currentModel, 'OneToOneMassTransfer')
                app.UIDropDownModel.Value = '1:1 Diffusion Model (Mass transport rate)';
            end
            
            app.UIListBoxParameters.Items =...
                analyte(analyteNo).FittingVariable.(currentModel).Name;
            app.UIListBoxParameters.Value = app.UIListBoxParameters.Items{1};
            app.UIDropDownSettingType.Value = analyte(analyteNo).FittingVariable.(currentModel).Type{1};
            
            % disp(analyteNo)
            UIDropDownModelValueChanged(app, [])
        end

        % Value changed function: UIDropDownModel
        function UIDropDownModelValueChanged(app, event)
            if strcmp(app.UIDropDownModel.Value, '1:1 Standard Model (Langmuir Binding)')
                currentModel = 'OneToOneStandard';
            elseif strcmp(app.UIDropDownModel.Value, '1:1 Diffusion Model (Mass transport rate)')
                currentModel = 'OneToOneMassTransfer';
            end
            
            app.UIImageEquation.ImageSource = sprintf('%sEquation.png', currentModel);
            app.UIImageModel.ImageSource = sprintf('%sModel.png', currentModel);
            app.UIListBoxParameters.Items =...
                app.currentAnalyte.FittingVariable.(currentModel).Name;
            
            app.currentAnalyte.FittingVariable.FittingModel = currentModel;
            
            UIListBoxParametersValueChanged(app, [])
            
        end

        % Value changed function: UIListBoxParameters
        function UIListBoxParametersValueChanged(app, event)
            if strcmp(app.UIDropDownModel.Value, '1:1 Standard Model (Langmuir Binding)')
                currentModel = 'OneToOneStandard';
            elseif strcmp(app.UIDropDownModel.Value, '1:1 Diffusion Model (Mass transport rate)')
                currentModel = 'OneToOneMassTransfer';
            end
            
            currentAnalyte = app.currentAnalyte;
            currentType = currentAnalyte.FittingVariable.(currentModel).Type{...
                contains(currentAnalyte.FittingVariable.(currentModel).Name, app.UIListBoxParameters.Value)};
            currentInitialValue = currentAnalyte.FittingVariable.(currentModel).InitialValue{...
                contains(currentAnalyte.FittingVariable.(currentModel).Name, app.UIListBoxParameters.Value)}(1);
            
            app.UIEditFieldSettingInitialValue.Limits = [-inf, inf];
            app.UIEditFieldSettingInitialValue.Value = currentInitialValue;
            
            if strcmp(app.UIListBoxParameters.Value, 'BI')
                app.UIDropDownSettingType.Items = {'Local', 'None'};        
                if strcmp(currentType, 'Constant')
                    currentType = 'None';
                end
            else
                app.UIDropDownSettingType.Items = {'Global', 'Local', 'Constant'};
            end
            
            app.UIDropDownSettingType.Value = currentType;
            
            UIDropDownSettingTypeValueChanged(app, [])
        end

        % Value changed function: UIDropDownSettingType
        function UIDropDownSettingTypeValueChanged(app, event)
            if strcmp(app.UIDropDownModel.Value, '1:1 Standard Model (Langmuir Binding)')
                currentModel = 'OneToOneStandard';
            elseif strcmp(app.UIDropDownModel.Value, '1:1 Diffusion Model (Mass transport rate)')
                currentModel = 'OneToOneMassTransfer';
            end
            
            currentType = app.UIDropDownSettingType.Value;
            variableIdx = contains(app.currentAnalyte.FittingVariable.(currentModel).Name, app.UIListBoxParameters.Value);
            currentLowerBound = app.currentAnalyte.FittingVariable.(currentModel).LowerBound{variableIdx};
            if strcmp(currentType, 'None')
                currentType = 'Constant';
                app.UIEditFieldSettingInitialValue.Enable = 0;
            else
                app.UIEditFieldSettingInitialValue.Enable = 1;
            end
            
            if strcmp(app.UIListBoxParameters.Value, 'BI') && ~strcmp(app.UIDropDownSettingType.Value, 'None')
                app.UICheckBoxNegative.Enable = 1;
            else    
                app.UICheckBoxNegative.Enable = 0;
            end
            
            if currentLowerBound(1) < 0
                app.UICheckBoxNegative.Value = 1;
                app.UIEditFieldSettingInitialValue.Limits = [-inf inf];
            else
                app.UICheckBoxNegative.Value = 0;
                app.UIEditFieldSettingInitialValue.Limits = [0 inf];
            end
            
            app.currentAnalyte.FittingVariable.(currentModel).Type{...
                contains(app.currentAnalyte.FittingVariable.(currentModel).Name,...
                app.UIListBoxParameters.Value)} = ...
                currentType;
            
            UIEditFieldSettingInitialValueValueChanged(app, [])
            
        end

        % Value changed function: UIEditFieldSettingInitialValue
        function UIEditFieldSettingInitialValueValueChanged(app, event)
            UICheckBoxNegativeValueChanged(app, [])
            
        end

        % Value changed function: UICheckBoxNegative
        function UICheckBoxNegativeValueChanged(app, event)
            if strcmp(app.UIDropDownModel.Value, '1:1 Standard Model (Langmuir Binding)')
                currentModel = 'OneToOneStandard';
            elseif strcmp(app.UIDropDownModel.Value, '1:1 Diffusion Model (Mass transport rate)')
                currentModel = 'OneToOneMassTransfer';
            end
            variableIdx = contains(app.currentAnalyte.FittingVariable.(currentModel).Name, app.UIListBoxParameters.Value);
            
            if app.UICheckBoxNegative.Value == 1
                app.UIEditFieldSettingInitialValue.Limits = [-inf inf];
            else
                app.UIEditFieldSettingInitialValue.Limits = [0 inf];
            end
            
            [newInitialValue, newLowerBound, newUpperBound] = SetFitParameterRange(app.currentAnalyte, currentModel,...
                app.UIListBoxParameters.Value, app.currentAnalyte.FittingVariable.(currentModel).Type{variableIdx},...
                app.UIEditFieldSettingInitialValue.Value, app.UICheckBoxNegative.Value);
            app.currentAnalyte.FittingVariable.(currentModel).InitialValue{variableIdx} = newInitialValue;
            app.currentAnalyte.FittingVariable.(currentModel).LowerBound{variableIdx} = newLowerBound;
            app.currentAnalyte.FittingVariable.(currentModel).UpperBound{variableIdx} = newUpperBound;
                
            function [newInitialValue, newLowerBound, newUpperBound] =...
                SetFitParameterRange(currentAnalyte, currentModel, variable, type, initialValue, allowNegative)
                % Ex : SetFitParameterRange(analyte(analyteNo), 'OneToOneStandard', 'BI', 'Local', 0, 1)
                boundScale = 1e8;
                variableIdx = contains(currentAnalyte.FittingVariable.(currentModel).Name, variable);
                unitValue = ones(size(currentAnalyte.FittingVariable.(currentModel).InitialValue{variableIdx}));
                newInitialValue = unitValue * initialValue;
        
                if strcmp(type, 'Constant')
                    if strcmp(variable, 'BI')
                        newInitialValue = 0;
                    end
                    newLowerBound = newInitialValue;
                    newUpperBound = newInitialValue;
                else
                    if strcmp(variable, 'BI')
                        if allowNegative
                            newLowerBound = -unitValue * inf;
                        else
                            newLowerBound = unitValue * 0;            
                        end 
                        newUpperBound = unitValue * inf;
                    else
                        newLowerBound = newInitialValue / boundScale;
                        newUpperBound = newInitialValue * boundScale; 
                    end
                end
            end
            
        end

        % Button pushed function: UIButtonDefault
        function UIButtonDefaultPushed(app, event)
            analyteNo = strcmp(app.MainApp.UIDropdownName.Items, app.MainApp.UIDropdownName.Value);
            analyte = app.MainApp.Analyte;
            app.currentAnalyte.FittingVariable = analyte(analyteNo).DefaultFittingVariable;
            
            app.UIDropDownModel.Value = app.UIDropDownModel.Items{1};
            app.UIListBoxParameters.Value = app.UIListBoxParameters.Items{1};
            
            UIDropDownModelValueChanged(app, []);
            UIListBoxParametersValueChanged(app, []);
        end

        % Button pushed function: UIButtonOK
        function UIButtonOKPushed(app, event)
            analyteNo = strcmp(app.MainApp.UIDropdownName.Items, app.MainApp.UIDropdownName.Value);            
            app.MainApp.Analyte(analyteNo) = app.currentAnalyte;
            UIFigureCloseRequest(app, []);
        end

        % Button pushed function: UIButtonCancel
        function UIButtonCancelPushed(app, event)
            UIFigureCloseRequest(app, []);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIGridMain = uigridlayout(app.UIFigure);
            app.UIPanelFittingModel = uipanel(app.UIGridMain);
            app.UIPanelFittingModel.Title = 'Fitting model';
            app.UIGridFittingModel = uigridlayout(app.UIPanelFittingModel);
            app.UIDropDownModel = uidropdown(app.UIGridFittingModel);
            app.UIDropDownModel.Items = {'1:1 Standard Model (Langmuir Binding)', '1:1 Diffusion Model (Mass transport rate)'};
            app.UIDropDownModel.ValueChangedFcn = createCallbackFcn(app, @UIDropDownModelValueChanged, true);
            app.UIDropDownModel.Value = '1:1 Standard Model (Langmuir Binding)';
            app.UILabelEquation = uilabel(app.UIGridFittingModel);
            app.UILabelEquation.Text = 'Equation';
            app.UILabelModel = uilabel(app.UIGridFittingModel);
            app.UILabelModel.Text = 'Model';
            app.UIImageEquation = uiimage(app.UIGridFittingModel);
            app.UIImageEquation.ImageSource = 'oneToOneStandardEquation.png';
            app.UIImageModel = uiimage(app.UIGridFittingModel);
            app.UIImageModel.ImageSource = 'oneToOneStandardModel.png';
            app.UIPanelFittingParameters = uipanel(app.UIGridMain);
            app.UIPanelFittingParameters.Title = 'Fitting parameters';
            app.UIGridFIttingParameters = uigridlayout(app.UIPanelFittingParameters);
            app.UIListBoxParameters = uilistbox(app.UIGridFIttingParameters);
            app.UIListBoxParameters.Items = {'kon', 'koff', 'Rmax', 'BI'};
            app.UIListBoxParameters.ValueChangedFcn = createCallbackFcn(app, @UIListBoxParametersValueChanged, true);
            app.UIListBoxParameters.Value = 'kon';
            app.UIPanelFittingParametersSetting = uipanel(app.UIGridFIttingParameters);
            app.UIPanelFittingParametersSetting.Title = 'Setting';
            app.GridLayout4 = uigridlayout(app.UIPanelFittingParametersSetting);
            app.TypeDropDownLabel = uilabel(app.GridLayout4);
            app.TypeDropDownLabel.Text = 'Type :';
            app.UIDropDownSettingType = uidropdown(app.GridLayout4);
            app.UIDropDownSettingType.Items = {'Global', 'Local', 'Constant'};
            app.UIDropDownSettingType.ValueChangedFcn = createCallbackFcn(app, @UIDropDownSettingTypeValueChanged, true);
            app.UIDropDownSettingType.Value = 'Global';
            app.InitialvalueEditFieldLabel = uilabel(app.GridLayout4);
            app.InitialvalueEditFieldLabel.Text = 'Initial value :';
            app.UIEditFieldSettingInitialValue = uieditfield(app.GridLayout4, 'numeric');
            app.UIEditFieldSettingInitialValue.ValueChangedFcn = createCallbackFcn(app, @UIEditFieldSettingInitialValueValueChanged, true);
            app.UICheckBoxNegative = uicheckbox(app.GridLayout4);
            app.UICheckBoxNegative.ValueChangedFcn = createCallbackFcn(app, @UICheckBoxNegativeValueChanged, true);
            app.UICheckBoxNegative.Text = 'Allow negative value';
            app.UIGridButton = uigridlayout(app.UIGridMain);
            app.UIButtonDefault = uibutton(app.UIGridButton, 'push');
            app.UIButtonDefault.ButtonPushedFcn = createCallbackFcn(app, @UIButtonDefaultPushed, true);
            app.UIButtonDefault.Text = 'Default';
            app.UIButtonOK = uibutton(app.UIGridButton, 'push');
            app.UIButtonOK.ButtonPushedFcn = createCallbackFcn(app, @UIButtonOKPushed, true);
            app.UIButtonOK.Text = 'OK';
            app.UIButtonCancel = uibutton(app.UIGridButton, 'push');
            app.UIButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @UIButtonCancelPushed, true);
            app.UIButtonCancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end