classdef ImportTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable2  matlab.ui.control.Table
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        DataTable = [];
        PlotData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataTable)
            app.DataTable = dataTable;
            
            numRows = size(dataTable, 2);
            
            if mod(numRows, 2) == 0 && numRows >= 2
                
                for i = 1:numRows
                    
                    rowName{i} = dataTable.Properties.VariableNames{i};
                    
                    if mod(i, 2) == 0
                        
                        X = dataTable.(rowName{i-1});
                        Y = dataTable.(rowName{i});
                        
                        hold(app.UIAxes, 'On')                        
                        app.PlotData(i/2) = plot(app.UIAxes, X, Y);                        
%                         curveTable.RowName{i-1} = rowName{i-1};
%                         curveTable.RowName{i-1} = rowName{i};
%                         curveTable.Data(:, i-1) = X;
%                         curveTable.Data(:, i) = Y;
                        
                    end
                    
                end
                
%                 disp(curveTable)
            end
            hold(app.UIAxes, 'Off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportTest(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end