classdef MovePopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        MovetoPanel      matlab.ui.container.Panel
        YEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.NumericEditField
        XEditFieldLabel  matlab.ui.control.Label
        CancelButton     matlab.ui.control.Button
        OKButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            for i = 1:size(app.MainApp.selCell, 1)
                
                numNans = app.XEditField.Value;
                nans = [];
                
                if numNans > 0
                    nans = nan(numNans, 2);
                end
                
                valData = app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data;
                
%                 valData = app.MainApp.dataStruct...
%                     (app.MainApp.selCell(i, 1)).data(~isnan(app.MainApp.dataStruct...
%                     (app.MainApp.selCell(i, 1)).data));
                
%                 valData = reshape(valData, [], 2);

                if min(valData(:, 1)) +...
                    app.XEditField.Value >= app.MainApp.UIAxes.XLim(1)...
                    && max(valData(:, 1)) +...
                    app.XEditField.Value <= app.MainApp.UIAxes.XLim(2)...
                    && min(valData(:, 2)) +...
                    app.YEditField.Value >= app.MainApp.UIAxes.YLim(1)...
                    && max(valData(:, 2)) +...
                    app.YEditField.Value <= app.MainApp.UIAxes.YLim(2)
                
%                 if valData(1, 1) +...
%                     app.XEditField.Value >= app.MainApp.UIAxes.XLim(1)...
%                     && valData(end, 1) +...
%                     app.XEditField.Value <= app.MainApp.UIAxes.XLim(2)...
%                     && valData(1, 2) +...
%                     app.YEditField.Value >= app.MainApp.UIAxes.YLim(1)...
%                     && valData(end, 2) +...
%                     app.YEditField.Value <= app.MainApp.UIAxes.YLim(2)
                
                    app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 1) =...
                            app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 1) +...
                            app.XEditField.Value;
                    
                    app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data =...
                        [nans; app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data];
                    
                    app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).dataPlot.XData =...
                        app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 1);
                    
                    app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 2) =...
                            app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 2) +...
                            app.YEditField.Value;
                    
                    app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).dataPlot.YData =...
                        app.MainApp.dataStruct(app.MainApp.selCell(i, 1)).data(:, 2);
                    
                else
                    
                    uialert(app.MainApp.UIFigure, 'Plots cannot be out of X, Y limit range', '')                                        
                                                            
                end
                
            end
            
            delete(app.UIFigure)    
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MovetoPanel = uipanel(app.UIFigure);
            app.MovetoPanel.Title = 'Move to';
            app.XEditFieldLabel = uilabel(app.MovetoPanel);
            app.XEditFieldLabel.Text = 'X :';
            app.XEditField = uieditfield(app.MovetoPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.MovetoPanel);
            app.YEditFieldLabel.Text = 'Y : ';
            app.YEditField = uieditfield(app.MovetoPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MovePopup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end