classdef SelectFluoMode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CancelButton                 matlab.ui.control.Button
        OKButton                     matlab.ui.control.Button
        ReflecttoReferenceZCheckBox  matlab.ui.control.CheckBox
        ButtonGroupFluo              matlab.ui.container.ButtonGroup
        ButtonNone                   matlab.ui.control.RadioButton
        ButtonFITC                   matlab.ui.control.RadioButton
        ButtonPE                     matlab.ui.control.RadioButton
        ScanningChannelButtonGroup   matlab.ui.container.ButtonGroup
        ButtonCh12                   matlab.ui.control.RadioButton
        ButtonCh2                    matlab.ui.control.RadioButton
        ButtonCh1                    matlab.ui.control.RadioButton
        MagnificationButtonGroup     matlab.ui.container.ButtonGroup
        Button20x                    matlab.ui.control.RadioButton
        Button10x                    matlab.ui.control.RadioButton
        Button4x                     matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;

            global FluorMode lensMag scanCh
            
            if strcmp(FluorMode, 'PE')
                
                set(app.ButtonPE, 'Value', 1)
                    
            elseif strcmp(FluorMode, 'FITC')
                
                set(app.ButtonFITC, 'Value', 1)    
                
            else
                
                set(app.ButtonNone, 'Value', 1)    
                
            end
            
            if lensMag == 10
                
                set(app.Button10x, 'Value', 1)
                
            elseif lensMag == 20
                
                set(app.Button20x, 'Value', 1)
                
            else
                
                set(app.Button4x, 'Value', 1)
                
            end
            
            if length(scanCh) == 1
                
                if scanCh == 1
                    
                    set(app.ButtonCh1, 'Value', 1)
                    
                else
                    
                    set(app.ButtonCh2, 'Value', 1)
                    
                end
                
            else
                
                set(app.ButtonCh12, 'Value', 1)
                
            end
            
            tmp = app.ButtonGroupFluo.SelectedObject.Text;
            tmp = tmp(1);
            
            if strcmp(tmp, 'N')
                
                set(app.ButtonCh2, 'Enable', 'off')
                set(app.ButtonCh12, 'Enable', 'off')
                
            else
            
                set(app.ButtonCh2, 'Enable', 'on')
                set(app.ButtonCh12, 'Enable', 'on')
                
            end
            
            scrPos = get(0, 'ScreenSize');
            
            tmpPosX = MainApp.figure1.Position(1) - app.UIFigure.Position(3) - 10;
            tmpPosY = MainApp.figure1.Position(2) - app.UIFigure.Position(4) + MainApp.figure1.Position(4);
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.UIFigure.Position(1) = tmpPosX;
                app.UIFigure.Position(2) = tmpPosY;
                
            else
                
                app.UIFigure.Position(1) = 0;
                app.UIFigure.Position(2) = scrPos(4) - app.UIFigure.Position(4) - 50;
                
            end

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
%             global Ch1_Colormap Ch2_Colormap FluorMode FluorModeFile...
%                 lensMag lensMagDefault im_W_um im_H_um CoorCorrFile...
%                 well_center_X_default well_center_Y_default rect_width_half_default...
%                 well2wellVer_default well2wellHor_default im_W_um_default im_H_um_default...
%                 scanCh noFrameDefault Z_default_um refZ

            global Ch1_Colormap Ch2_Colormap FluorMode FluorModeFile...
                lensMag LensMagDefault CoorCorrFile scanCh Z_default_um...
                refZ ChipInform Pixel2umDefault

            Ch2_Colormap = Ch1_Colormap;
            
            tmp = app.ButtonGroupFluo.SelectedObject.Text(1);
            
            if strcmp(tmp, 'P')
            
                % PE (R&G panel)
                Ch2_Colormap(:, 3) = 0;
                set(app.MainApp.radiobutton_Ch2, 'FontColor', [1 1 0]);
                set(app.MainApp.radiobutton_Ch2, 'Visible', 'on');
                FluorMode = 'PE';
                
            elseif strcmp(tmp, 'F')
            
                % FITC (G panel)
                Ch2_Colormap(:, 3) = 0; 
                Ch2_Colormap(:, 1) = 0;
                set(app.MainApp.radiobutton_Ch2, 'FontColor', [0 1 0]);
                set(app.MainApp.radiobutton_Ch2, 'Visible', 'on');
                FluorMode = 'FITC';
                
            else
                
                % None
                FluorMode = 'None';
                set(app.MainApp.radiobutton_Ch2, 'Visible', 'off');
                
            end
                
%             if exist(CoorCorrFile, 'file') == 2
%                     
%                 load(CoorCorrFile)
%                     
%             else
%                     
%                 well_center_X = well_center_X_default;
%                 well_center_Y = well_center_Y_default;
%                 rect_width_half = rect_width_half_default;
% %                 well2well_X = well2well_X_default;
% %                 well2well_Y = well2well_Y_default;                
%                 well2wellVer = well2wellVer_default;
%                 well2wellHor = well2wellHor_default;
%                 
%                 noFrame = noFrameDefault;
%                     
%             end
            
%             for j = 1:96
%             
% %                 eval(sprintf('global C2_Chamb%d_X_um C2_Chamb%d_Y_um;', j, j));
%                 eval(sprintf('global C1_Chamb%d_X_um C2_Chamb%d_Y_um;', j, j));
%             
%             end
            
            tmp2 = app.MagnificationButtonGroup.SelectedObject.Text;
            tmp2 = tmp2(1:end-1);
            tmp2 = str2double(tmp2);
            
            lensMag = tmp2;
            pixel2um = Pixel2umDefault * LensMagDefault/lensMag;
%             im_W_um = im_W_um_default * lensMagDefault/lensMag;
%             im_H_um = im_H_um_default * lensMagDefault/lensMag;
            
%             if noFrame == 1
%                 
%                 rect_width_half = 0;
%                 
%             else
%             
%                 rect_width_half = ((noFrame - 1) / 2 + 0.01) * im_W_um_default;
%                 
%             end
%             
%             rect_width_half_mag = rect_width_half * lensMagDefault/lensMag;
            
            % end
            
%             C2_Chamb1_X_um = [well_center_X - rect_width_half_mag, well_center_X + rect_width_half_mag];
%             C2_Chamb1_Y_um = [well_center_Y - rect_width_half_mag, well_center_Y + rect_width_half_mag];
            
            
            for chipNo = 1:3

                chipInformStruct = ChipInform(chipNo);
                
                camROI = chipInformStruct.ROI;            
%                 pixel2um = Pixel2um;
                chipType = chipInformStruct.Type;
                measuredCoordinate = chipInformStruct.MeasuredCoordinate;
                chamberArea = CalculateChamberArea(chipType, measuredCoordinate);
                observeRate = chipInformStruct.ObserveRate;
                overlabRate = chipInformStruct.OverlabRate;
                chamberNum = chipInformStruct.ChamberNum;                                    
    
                [chipInformStruct.FrameNum, chipInformStruct.ChamberRange] = ...
                    GetCaptureRange(camROI, pixel2um, chamberArea, observeRate,...
                    overlabRate, chamberNum, measuredCoordinate, chipType);
                
                ChipInform(chipNo) = chipInformStruct;
                
            end

%             C1_Chamb1_X_um = [well_center_X - rect_width_half_mag, well_center_X + rect_width_half_mag];
%             C1_Chamb1_Y_um = [well_center_Y - rect_width_half_mag, well_center_Y + rect_width_half_mag];
%             
%             for i = 2:96
%                 
%                 if mod(i-1, 8) == 0
%                     
%                     eval(sprintf('C1_Chamb%d_X_um = C1_Chamb%d_X_um + well2wellVer(1, 1);', i, i-8));
%                     eval(sprintf('C1_Chamb%d_Y_um = C1_Chamb%d_Y_um + well2wellVer(1, 2);', i, i-8));
%             
%                 else
%             
%                     eval(sprintf('C1_Chamb%d_X_um = C1_Chamb%d_X_um + well2wellHor(1, 1);', i, i-1));
%                     eval(sprintf('C1_Chamb%d_Y_um = C1_Chamb%d_Y_um + well2wellHor(1, 2);', i, i-1));
%             
%                 end
%             
%             end
            
%             for i = 1:12
%                 
%                 for ii = 1:8
%                 
%                     eval(sprintf('C2_Chamb%d_X_um = C2_Chamb1_X_um + well2well_X*(ii-1);', (i-1)*8 + ii));
%                     eval(sprintf('C2_Chamb%d_Y_um = C2_Chamb1_Y_um + well2well_Y*(i-1);', (i-1)*8 + ii));
%                     
%                 end
%                 
%             end
            
            if get(app.ReflecttoReferenceZCheckBox, 'Value')
                
                if lensMag == 4
                
                    refZ = 8600;
            
                elseif lensMag == 10
            
                    refZ = 1000;
            
                else
            
                    refZ = 8500;
            
                end
                
                Z_default_um = refZ;
                set(app.MainApp.edit_RefZ, 'Value', refZ)
                
            end
            
            chName = app.ScanningChannelButtonGroup.SelectedObject.Text;
            scanCh = [str2double(chName(3)) str2double(chName(7))];
            scanCh(isnan(scanCh)) = [];
            
            set(app.MainApp.edit_Z_diff, 'Visible', size(scanCh, 2) == 2)
            set(app.MainApp.DiffLabel, 'Visible', size(scanCh, 2) == 2)
            set(app.MainApp.pushbutton_Set_Mode, 'Visible', size(scanCh, 2) == 2)
            
            try
            
                save(FluorModeFile, 'FluorMode')
                save(FluorModeFile, 'lensMag', '-append')    
                save(FluorModeFile, 'chName', '-append')    
                save(FluorModeFile, 'scanCh', '-append')
                
            catch
                
                disp('Cannot save the FluorMode File!')
                
            end
            
            try
                save(CoorCorrFile, 'ChipInform')
            catch 
                disp('Cannot Save the CoorCorr File!')
            end
            
            nameStr = sprintf('iMeasy100 (%s / %dx / %s)', FluorMode, lensMag, chName);
            set(app.MainApp.figure1, 'Name', nameStr);
            
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Selection changed function: ButtonGroupFluo
        function ButtonGroupFluoSelectionChanged(app, event)
            % Select None -> Scanning Ch : 'Ch1 Only'
            tmp = app.ButtonGroupFluo.SelectedObject.Text;
            tmp = tmp(1);
            
            if strcmp(tmp, 'N')
                
                set(app.ButtonCh1, 'Value', 1)
                set(app.ButtonCh2, 'Enable', 'off')
                set(app.ButtonCh12, 'Enable', 'off')
                
            else
            
                set(app.ButtonCh2, 'Enable', 'on')
                set(app.ButtonCh12, 'Enable', 'on')
                
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Configuration';
            app.UIFigure.Icon = 'Max_icon.png';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.MagnificationButtonGroup = uibuttongroup(app.UIFigure);
            app.MagnificationButtonGroup.Title = 'Magnification';
            app.Button4x = uiradiobutton(app.MagnificationButtonGroup);
            app.Button4x.Text = '4x';
            app.Button4x.Value = true;
            app.Button10x = uiradiobutton(app.MagnificationButtonGroup);
            app.Button10x.Text = '10x';
            app.Button20x = uiradiobutton(app.MagnificationButtonGroup);
            app.Button20x.Text = '20x';
            app.ScanningChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ScanningChannelButtonGroup.Title = 'Scanning Channel';
            app.ButtonCh1 = uiradiobutton(app.ScanningChannelButtonGroup);
            app.ButtonCh1.Text = 'Ch1 Only';
            app.ButtonCh1.Value = true;
            app.ButtonCh2 = uiradiobutton(app.ScanningChannelButtonGroup);
            app.ButtonCh2.Text = 'Ch2 Only';
            app.ButtonCh12 = uiradiobutton(app.ScanningChannelButtonGroup);
            app.ButtonCh12.Text = 'Ch1 & 2';
            app.ButtonGroupFluo = uibuttongroup(app.UIFigure);
            app.ButtonGroupFluo.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupFluoSelectionChanged, true);
            app.ButtonGroupFluo.Title = 'Fluorescence Mode (Ch2)';
            app.ButtonPE = uiradiobutton(app.ButtonGroupFluo);
            app.ButtonPE.Text = 'PE (488 / 575 nm)';
            app.ButtonPE.Value = true;
            app.ButtonFITC = uiradiobutton(app.ButtonGroupFluo);
            app.ButtonFITC.Text = 'FITC (490 / 525 nm)';
            app.ButtonNone = uiradiobutton(app.ButtonGroupFluo);
            app.ButtonNone.Text = 'None';
            app.ReflecttoReferenceZCheckBox = uicheckbox(app.UIFigure);
            app.ReflecttoReferenceZCheckBox.Text = 'Reflect to Reference Z';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectFluoMode(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end