classdef SelectSample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        uipanel7                matlab.ui.container.Panel
        pushbuttonStart         matlab.ui.control.Button
        uipanel6                matlab.ui.container.Panel
        pushbuttonDelete        matlab.ui.control.Button
        pushbuttonClear         matlab.ui.control.Button
        uipanel5                matlab.ui.container.Panel
        pushbuttonSave          matlab.ui.control.Button
        pushbuttonLoad          matlab.ui.control.Button
        uitablePlate            matlab.ui.control.Table
        uipanel4                matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        pushbuttonDown          matlab.ui.control.Button
        pushbuttonUp            matlab.ui.control.Button
        pushbuttonClearElement  matlab.ui.control.Button
        pushbuttonDelElement    matlab.ui.control.Button
        pushbuttonAddElement    matlab.ui.control.Button
        uipanel1                matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        textGroup               matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        textType                matlab.ui.control.Label
        text7                   matlab.ui.control.Label
        text9                   matlab.ui.control.Label
        textColor               matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        textRepNo               matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        textConcentration       matlab.ui.control.Label
        text17                  matlab.ui.control.Label
        textPosition            matlab.ui.control.Label
        text19                  matlab.ui.control.Label
        textUnit                matlab.ui.control.Label
        listboxElement          matlab.ui.control.ListBox
        uipanel3                matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        LabelGrpName            matlab.ui.control.Label
        AnalytesPanel           matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        labelAnalytes           matlab.ui.control.Label
        textName                matlab.ui.control.Label
        pushbuttonClearGroup    matlab.ui.control.Button
        pushbuttonDelGroup      matlab.ui.control.Button
        pushbuttonAddGroup      matlab.ui.control.Button
        listboxGroup            matlab.ui.control.ListBox
        uibuttongroup1          matlab.ui.container.ButtonGroup
        radiobutton2            matlab.ui.control.RadioButton
        radiobutton1            matlab.ui.control.RadioButton
        pushbuttonFill          matlab.ui.control.Button
    end

    
    properties (Access = private)

        dataTable = cell(12, 8);
        tableStyle = uistyle;
        tmpStruct = struct;
        progDlg;

    end
    
    properties (Access = public)
        
        indSel = [];        
        grpNum = [];
        grpStruct = struct;        
        mainApp;
        
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitablePlate.BackgroundColor = [1 1 1];
            app.uitablePlate.ColumnFormat = {[] [] [] [] [] [] [] []};            
            
        end      
            
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SelectSample_OpeningFcn(app, mainApp)

            app.mainApp = mainApp;
            
            app.grpStruct.Name          = '';
            app.grpStruct.Element       = {};
            app.grpStruct.Type          = '';
            app.grpStruct.Color         = '';
            app.grpStruct.RepNo         = '';
            app.grpStruct.Concentration = '';
            app.grpStruct.Unit          = '';
            app.grpStruct.Position      = '';
            app.grpStruct.StdNum        = '';
            app.grpStruct.UnkNum        = '';
            app.grpStruct.Intensity     = '';
            app.grpStruct.CodeNum       = [];
            app.grpStruct.ListAnalyte   = '';

            setappdata(0, 'DataStruct', app.tmpStruct)
                        
            defStyle = uistyle;
            defStyle.BackgroundColor = [1 1 1];
            set(app.uitablePlate, 'Data', app.dataTable);
            addStyle(app.uitablePlate, defStyle)
            
            scrPos = get(0, 'ScreenSize');
            
            tmpPosX = mainApp.figure1.Position(1) - app.figure1.Position(3) - 10;
            tmpPosY = mainApp.figure1.Position(2) - app.figure1.Position(4) + mainApp.figure1.Position(4);
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.figure1.Position(1) = tmpPosX;
                app.figure1.Position(2) = tmpPosY;
                
            else
                
                app.figure1.Position(1) = 0;
                app.figure1.Position(2) = scrPos(4) - app.figure1.Position(4) - 50;
                
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            try
                
                set(app.mainApp.text_Status, 'String', 'Auto Scanning is cancled.'); 
                delete(app.figure1)
                
            catch
                
                delete(app.figure1)
                
            end
        end

        % Value changed function: listboxGroup
        function listboxGroup_Callback(app, event)
            grpVal = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)

                app.LabelGrpName.Text = '';
                app.labelAnalytes.Text = '';
                app.listboxElement.Value = {};
                app.listboxElement.Items = {};
                return
            
            end
            
            DataStruct = getappdata(0, 'DataStruct');
            
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr = sprintf('Grp%d', grpNumSel);
            
            reNewElem(DataStruct, grpStr, app.listboxElement)
            RenewGrp(DataStruct, grpStr, app.LabelGrpName, app.labelAnalytes)
        end

        % Value changed function: listboxElement
        function listboxElement_Callback(app, event)
            grpVal = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
                
                grpStr = '';
                elemVal = '';
                
            else
            
                grpNumSel = str2double(grpVal{end}(7:end));
                grpStr = sprintf('Grp%d', grpNumSel);                
                elemVal = get(app.listboxElement, 'Value');
            
            end
            
            if ~isempty(elemVal)
                
                elemVal = elemVal(1);
            
            end
            
            DataStruct = getappdata(0, 'DataStruct');
            RenewElemStatus(app, grpStr, elemVal, DataStruct)
        end

        % Button pushed function: pushbuttonAddGroup
        function pushbuttonAddGroup_Callback(app, event)
            if isempty(app.grpNum)

                grpStr = 'Grp1';

            else

                grpStr = sprintf('Grp%d', app.grpNum(end, 1) + 1);
            
            end
            
            AddGroup(app, grpStr)
        end

        % Button pushed function: pushbuttonDelGroup
        function pushbuttonDelGroup_Callback(app, event)
            DataStruct = getappdata(0, 'DataStruct');
            
            if isempty(app.grpNum) || isempty(fieldnames(DataStruct))
            
                return
            
            end
            
            grpVal = get(app.listboxGroup, 'Value');
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr = sprintf('Grp%d', grpNumSel);
            
            if ~isempty(DataStruct.(grpStr).Position)
            
                for i = 1:size(DataStruct.(grpStr).Position, 1)
                
                    if ~isempty(DataStruct.(grpStr).Position{i, 1})
                
                        posIdx = Str2Idx(DataStruct.(grpStr).Position{i, 1});
                        app.dataTable{posIdx(1, 1), posIdx(1, 2)} = [];
                        app.tableStyle(posIdx(1, 1), posIdx(1, 2)).BackgroundColor = [1 1 1];
                        addStyle(app.uitablePlate, app.tableStyle(posIdx(1, 1), posIdx(1, 2)), 'cell', [posIdx(1, 1), posIdx(1, 2)])
                
                    end
                
                end
                
            end
            
            set(app.uitablePlate, 'Data', app.dataTable);
            grpIdx = find(app.grpNum == grpNumSel);
            [DataStruct, grpNum] = DelGrp(DataStruct, grpIdx, app.grpNum, app.listboxGroup);
            app.grpNum = grpNum;
            
            if isempty(DataStruct)
            
                return
            
            end
            
            setappdata(0, 'DataStruct', DataStruct)
            listboxGroup_Callback(app, [])
            listboxElement_Callback(app, [])
        end

        % Button pushed function: pushbuttonClearGroup
        function pushbuttonClearGroup_Callback(app, event)
            
            app.grpNum = [];
            set(app.listboxGroup, 'Items', {})
            set(app.listboxGroup, 'Value', {})
            
            DataStruct = getappdata(0, 'DataStruct');
            
            grpList = fieldnames(DataStruct);
            
            for ii = 1 : size(grpList, 1)
                
                if ~isempty(DataStruct.(grpList{ii}).Position)
            
                    for i = 1:size(DataStruct.(grpList{ii}).Position, 1)
                
                        if ~isempty(DataStruct.(grpList{ii}).Position{i, 1})
                
                            posIdx = Str2Idx(DataStruct.(grpList{ii}).Position{i, 1});
                            app.dataTable{posIdx(1, 1), posIdx(1, 2)} = [];
                            app.tableStyle(posIdx(1, 1), posIdx(1, 2)).BackgroundColor = [1 1 1];
                            addStyle(app.uitablePlate, app.tableStyle(posIdx(1, 1), posIdx(1, 2)), 'cell', [posIdx(1, 1), posIdx(1, 2)])
                
                        end
                
                    end
                
                end
                
            end
            
            set(app.uitablePlate, 'Data', app.dataTable);
            DataStruct = struct;
            setappdata(0, 'DataStruct', DataStruct);
            
            listboxGroup_Callback(app, [])
            listboxElement_Callback(app, [])
        end

        % Button pushed function: pushbuttonFill
        function pushbuttonFill_Callback(app, event)
            DataStruct = getappdata(0, 'DataStruct');
            
            grpVal    = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
            
                return
            
            end
            
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);
            
            if isempty(grpNumSel)
            
                return
            
            end
            
            elemVal    = get(app.listboxElement, 'Value');
            elemList   = get(app.listboxElement, 'Items');
            elemNumSel = zeros(1, size(elemVal, 2));
            
            for i = 1:size(elemVal, 2)
                
                for ii = 1:size(elemList, 2)
                
                    if strcmp(elemVal{1, i}, elemList{1, ii})
                        
                        elemNumSel(i) = ii;
                        
                    end
                    
                end
                
            end
            
            elemNumSel = sort(elemNumSel);
                        
            listData = DataStruct.(grpStr).Element(elemNumSel);
            listPos  = DataStruct.(grpStr).Position(elemNumSel);
            listCol  = DataStruct.(grpStr).Color(elemNumSel);
            
            dataTable = app.dataTable;
            
            [listPos, dataTable, tableStyle] = FillChamber(app, listData, dataTable, listPos, listCol, app.tableStyle);
            
            DataStruct.(grpStr).Position(elemNumSel) = listPos;
            app.dataTable = dataTable;
            
            setappdata(0, 'DataStruct', DataStruct)
            app.tableStyle = tableStyle;
            
            listboxElement_Callback(app, [])
        end

        % Button pushed function: pushbuttonClear
        function pushbuttonClear_Callback(app, event)
            if isempty(app.dataTable)
            
                return
            
            end
            
            app.progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Clearing Data...',...
                'Indeterminate', 'on');
            
            DataStruct = getappdata(0, 'DataStruct');
            grpList = fieldnames(DataStruct);
            
            for i = 1:size(grpList, 1)
            
                if ~isempty(DataStruct.(grpList{i, 1}).Position(:))
            
                    DataStruct.(grpList{i, 1}).Position(:) = {''};
            
                end
            
            end

            app.dataTable = cell(12, 8);
            
            defStyle = uistyle;
            defStyle.BackgroundColor = [1 1 1];
            addStyle(app.uitablePlate, defStyle)
            
            set(app.uitablePlate, 'Data', app.dataTable);
            
            setappdata(0, 'DataStruct', DataStruct)
            
            listboxElement_Callback(app, [])
            
            delete(app.progDlg)
        end

        % Button pushed function: pushbuttonStart
        function pushbuttonStart_Callback(app, event)
            if cellfun(@isempty, app.dataTable)
                
                uialert(app.figure1, 'There is no object to be scanned in any well.', '')
            
                return
            
            end
            
            app.mainApp.ScanProgDlg = uiprogressdlg(app.mainApp.figure1,...
                'Message', 'Auto Scanning...',...
                'Cancelable', 'on',...
                'Indeterminate', 'on');
            
            hidFig = uifigure('Visible', 'off', 'Name', 'Hidden Figure for Capture');            
            hidFig.Resize = 'off';
            hidTable = uitable(hidFig);
            hidTable.ColumnName = app.uitablePlate.ColumnName;
            hidTable.RowName = app.uitablePlate.RowName;
            
            
            hidTable.Units = app.uitablePlate.Units;
            hidTable.Position = app.uitablePlate.Position;
            hidTable.ColumnWidth = app.uitablePlate.ColumnWidth;
            hidTable.FontSize = app.uitablePlate.FontSize;
            
            hidFig.Units = app.uitablePlate.Units;
            hidFig.Position = app.uitablePlate.Position * 1.05;
            
            defStyle = uistyle;
            defStyle.BackgroundColor = [1 1 1];            
            addStyle(hidTable, defStyle)
            
            for row = 1:size(app.tableStyle, 1)
                
                for col = 1:size(app.tableStyle, 2)
                    
                    addStyle(hidTable, app.tableStyle(row, col), 'cell', [row, col])
                
                end
                
            end
            
            set(hidTable, 'Data', app.dataTable)            
            setappdata(0, 'DataTable', app.dataTable)
            setappdata(0, 'ListData1', get(app.listboxGroup, 'Items'))
            setappdata(0, 'ListVal1', get(app.listboxGroup, 'Value'))
            setappdata(0, 'ListData2', get(app.listboxElement, 'Items'))
            setappdata(0, 'ListVal2', get(app.listboxElement, 'Value'))
            setappdata(0, 'GrpNum', app.grpNum)

            DataStruct = getappdata(0, 'DataStruct');            

%             ReadyToScan(app.mainApp, DataStruct, app.figure1, hidFig);
            ReadyToScanWellPlate(app.mainApp, DataStruct, app.figure1, hidFig);
        end

        % Button pushed function: pushbuttonSave
        function pushbuttonSave_Callback(app, event)
            app.progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Saving Data...',...
                'Indeterminate', 'on');
            
            dataTable  = app.dataTable;
            tableStyle = app.tableStyle;
            DataStruct = getappdata(0, 'DataStruct');
            listData1  = get(app.listboxGroup, 'Items');
            listVal1   = get(app.listboxGroup, 'Value');
            listData2  = get(app.listboxElement, 'Items');
            listVal2   = get(app.listboxElement, 'Value');
            grpNum     = app.grpNum;
            
            SaveData(dataTable, DataStruct, listData1, listVal1, listData2, listVal2, grpNum, tableStyle)
            figure(app.figure1)
            app.figure1.WindowStyle = 'normal';
            app.figure1.WindowStyle = 'modal';
            
            delete(app.progDlg)
        end

        % Button pushed function: pushbuttonLoad
        function pushbuttonLoad_Callback(app, event)
            app.progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Loading Data...',...
                'Indeterminate', 'on');
            
            try
                
                [dataTable, DataStruct, listData1, listVal1, listData2, listVal2, grpNum, tableStyle] = LoadData;
                figure(app.figure1)
                app.figure1.WindowStyle = 'normal';
                app.figure1.WindowStyle = 'modal';
                
            catch
                
                figure(app.figure1)
                app.figure1.WindowStyle = 'normal';
                app.figure1.WindowStyle = 'modal';
                delete(app.progDlg)
                return
                
            end
            
            figure(app.figure1)
            
            
            for row = 1:size(tableStyle, 1)
                
                for col = 1:size(tableStyle, 2)
                    
                    addStyle(app.uitablePlate, tableStyle(row, col), 'cell', [row, col])
                
                end
                
            end
            
            set(app.uitablePlate, 'Data', dataTable)
            set(app.listboxGroup, 'Items', listData1)
            set(app.listboxGroup, 'Value', listVal1)
            set(app.listboxElement, 'Items', listData2)
            set(app.listboxElement, 'Value', listVal2)
            
            app.grpNum = grpNum;
            app.dataTable = dataTable;
            app.tableStyle = tableStyle;
            setappdata(0, 'DataStruct', DataStruct)
            
            listboxGroup_Callback(app, [])
            listboxElement_Callback(app, [])
            
            delete(app.progDlg)
        end

        % Button pushed function: pushbuttonDelete
        function pushbuttonDelete_Callback(app, event)
            app.progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Deleting Data...',...
                'Indeterminate', 'on');
            
            DataStruct = getappdata(0, 'DataStruct');
            grpStr = cell(length(app.grpNum), 1);
            
            for i = 1:length(app.grpNum)
            
                grpStr{i, 1} = sprintf('Grp%d', app.grpNum(i));
            
            end
            
            for i = 1:size(app.indSel, 1)
            
                app.dataTable{app.indSel(i, 1), app.indSel(i, 2)} = [];
                app.tableStyle(app.indSel(i, 1), app.indSel(i, 2)).BackgroundColor = [1 1 1];
                addStyle(app.uitablePlate, app.tableStyle(app.indSel(i, 1), app.indSel(i, 2)), 'cell', [app.indSel(i, 1), app.indSel(i, 2)])
            
                tgPos = Idx2Str([app.indSel(i, 1), app.indSel(i, 2)]);
            
                for iii = 1:size(grpStr, 1)
            
                    for ii = 1:length(DataStruct.(grpStr{iii, 1}).Position)
            
                        if ~isempty(DataStruct.(grpStr{iii, 1}).Position{ii})
            
                            if strcmp(DataStruct.(grpStr{iii, 1}).Position{ii}, tgPos)
            
                                DataStruct.(grpStr{iii, 1}).Position{ii} = '';
            
                            end
            
                        end
            
                    end
            
                end
            
            end
            
            setappdata(0, 'DataStruct', DataStruct)
            set(app.uitablePlate, 'Data', app.dataTable)
            listboxElement_Callback(app, [])
            
            delete(app.progDlg)
        end

        % Button pushed function: pushbuttonAddElement
        function pushbuttonAddElement_Callback(app, event)
            if isempty(get(app.listboxGroup, 'Value')) || isempty(get(app.listboxGroup, 'Items'))
                
                uialert(app.figure1, 'Please add a group!.', '')
            
                return
            
            end
            
            AddElement(app)
        end

        % Button pushed function: pushbuttonDelElement
        function pushbuttonDelElement_Callback(app, event)
            grpVal    = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
                
                return
                
            end
            
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);
            
            elemVal    = get(app.listboxElement, 'Value');
            elemList   = get(app.listboxElement, 'Items');
            elemNumSel = zeros(size(elemVal, 2), 1);
            
            for i = 1:size(elemVal, 2)
                
                for ii = 1:size(elemList, 2)
                
                    if strcmp(elemVal{1, i}, elemList{1, ii})
                        
                        elemNumSel(i) = ii;
                        
                    end
                    
                end
                
            end
            
            elemNumSel = sort(elemNumSel);

            DataStruct = getappdata(0, 'DataStruct');

            if ~isempty(grpNumSel)
            
                for i = 1:length(elemNumSel)
            
                    if ~isempty(DataStruct.(grpStr).Position{elemNumSel(i), 1})
            
                        posIdx = Str2Idx(DataStruct.(grpStr).Position{elemNumSel(i), 1});
                        app.dataTable{posIdx(1, 1), posIdx(1, 2)} = [];         
                        
                        app.tableStyle(posIdx(1, 1), posIdx(1, 2)).BackgroundColor = [1 1 1];
                        addStyle(app.uitablePlate, app.tableStyle(posIdx(1, 1), posIdx(1, 2)), 'cell', [posIdx(1, 1), posIdx(1, 2)])
                        
                        
                    end
            
                end
            
            end
            
            set(app.uitablePlate, 'Data', app.dataTable);
            DataStruct = delElem(DataStruct, grpNumSel, elemNumSel, app.listboxElement);
            
            if isempty(DataStruct)
            
                return
            
            end
            
            setappdata(0, 'DataStruct', DataStruct)
            listboxElement_Callback(app, [])
        end

        % Button pushed function: pushbuttonClearElement
        function pushbuttonClearElement_Callback(app, event)
            grpVal    = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
                
                return
                
            end
            
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);
            
            DataStruct = getappdata(0, 'DataStruct');
            
            if ~isempty(grpNumSel)
            
                for i = 1:size(DataStruct.(grpStr).Position, 1)
                    
                    if ~isempty(DataStruct.(grpStr).Position)
            
                        if ~isempty(DataStruct.(grpStr).Position{i, 1})
                
                            posIdx = Str2Idx(DataStruct.(grpStr).Position{i, 1});
                            app.dataTable{posIdx(1, 1), posIdx(1, 2)} = [];
                            app.tableStyle(posIdx(1, 1), posIdx(1, 2)).BackgroundColor = [1 1 1];
                            addStyle(app.uitablePlate, app.tableStyle(posIdx(1, 1), posIdx(1, 2)), 'cell', [posIdx(1, 1), posIdx(1, 2)])
                
                        end
                        
                    end
            
                end
            
            end
            
            set(app.uitablePlate, 'Data', app.dataTable);
            
            backupName = DataStruct.(grpStr).Name;
            DataStruct.(grpStr) = app.grpStruct;
            DataStruct.(grpStr).Name = backupName;
            
            set(app.listboxElement, 'Items', DataStruct.(grpStr).Element)
            set(app.listboxElement, 'Value', {})
            setappdata(0, 'DataStruct', DataStruct)
                        
            listboxElement_Callback(app, [])
        end

        % Button pushed function: pushbuttonUp
        function pushbuttonUp_Callback(app, event)
            DataStruct = getappdata(0, 'DataStruct');
            grpVal     = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
                
                return
                
            end
            
            grpNumSel  = str2double(grpVal{end}(7:end));

            DataStruct = ManElemOrder(DataStruct, grpNumSel, app.listboxElement, 'Up');
            
            if isempty(DataStruct)
            
                return
            
            end
            
            setappdata(0, 'DataStruct', DataStruct)
        end

        % Button pushed function: pushbuttonDown
        function pushbuttonDown_Callback(app, event)
            DataStruct = getappdata(0, 'DataStruct');
            grpVal     = get(app.listboxGroup, 'Value');
            
            if isempty(grpVal)
                
                return
                
            end
            
            grpNumSel  = str2double(grpVal{end}(7:end));
            
            DataStruct = ManElemOrder(DataStruct, grpNumSel, app.listboxElement, 'Down');
            
            if isempty(DataStruct)
            
                return
            
            end
            
            setappdata(0, 'DataStruct', DataStruct)
        end

        % Cell selection callback: uitablePlate
        function uitablePlate_CellSelectionCallback(app, event)
            app.indSel = event.DisplayIndices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Design a Chamber';
            app.figure1.Icon = 'Max_icon.png';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.pushbuttonFill = uibutton(app.GridLayout, 'push');
            app.pushbuttonFill.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFill_Callback, true);
            app.pushbuttonFill.Tag = 'pushbuttonFill';
            app.pushbuttonFill.Text = '<<';
            app.uibuttongroup1 = uibuttongroup(app.GridLayout);
            app.uibuttongroup1.Title = 'Direction';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = '→ & ↓';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = '← & ↑';
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Group';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout2 = uigridlayout(app.uipanel3);
            app.listboxGroup = uilistbox(app.GridLayout2);
            app.listboxGroup.Items = {};
            app.listboxGroup.Multiselect = 'on';
            app.listboxGroup.ValueChangedFcn = createCallbackFcn(app, @listboxGroup_Callback, true);
            app.listboxGroup.Tag = 'listboxGroup';
            app.listboxGroup.Value = {};
            app.pushbuttonAddGroup = uibutton(app.GridLayout2, 'push');
            app.pushbuttonAddGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddGroup_Callback, true);
            app.pushbuttonAddGroup.Tag = 'pushbutton10';
            app.pushbuttonAddGroup.Text = '+';
            app.pushbuttonDelGroup = uibutton(app.GridLayout2, 'push');
            app.pushbuttonDelGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDelGroup_Callback, true);
            app.pushbuttonDelGroup.Tag = 'pushbuttonDelGroup';
            app.pushbuttonDelGroup.Text = '-';
            app.pushbuttonClearGroup = uibutton(app.GridLayout2, 'push');
            app.pushbuttonClearGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClearGroup_Callback, true);
            app.pushbuttonClearGroup.Tag = 'pushbuttonClearGroup';
            app.pushbuttonClearGroup.Text = 'C';
            app.textName = uilabel(app.GridLayout2);
            app.textName.Tag = 'textName';
            app.textName.Text = 'Description :';
            app.AnalytesPanel = uipanel(app.GridLayout2);
            app.AnalytesPanel.Title = 'Analytes';
            app.GridLayout5 = uigridlayout(app.AnalytesPanel);
            app.labelAnalytes = uilabel(app.GridLayout5);
            app.labelAnalytes.Text = '';
            app.LabelGrpName = uilabel(app.GridLayout2);
            app.LabelGrpName.Text = '';
            app.uipanel4 = uipanel(app.GridLayout);
            app.uipanel4.Title = 'Element';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout3 = uigridlayout(app.uipanel4);
            app.listboxElement = uilistbox(app.GridLayout3);
            app.listboxElement.Items = {};
            app.listboxElement.Multiselect = 'on';
            app.listboxElement.ValueChangedFcn = createCallbackFcn(app, @listboxElement_Callback, true);
            app.listboxElement.Tag = 'listboxElement';
            app.listboxElement.Value = {};
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Title = 'Property';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout4 = uigridlayout(app.uipanel1);
            app.textUnit = uilabel(app.GridLayout4);
            app.textUnit.Tag = 'text20';
            app.textUnit.Text = '';
            app.text19 = uilabel(app.GridLayout4);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Unit :';
            app.textPosition = uilabel(app.GridLayout4);
            app.textPosition.Tag = 'text18';
            app.textPosition.Text = '';
            app.text17 = uilabel(app.GridLayout4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Position :';
            app.textConcentration = uilabel(app.GridLayout4);
            app.textConcentration.Tag = 'text16';
            app.textConcentration.Text = '';
            app.text15 = uilabel(app.GridLayout4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Conc. :';
            app.textRepNo = uilabel(app.GridLayout4);
            app.textRepNo.Tag = 'text14';
            app.textRepNo.Text = '';
            app.text13 = uilabel(app.GridLayout4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Rep. No :';
            app.textColor = uilabel(app.GridLayout4);
            app.textColor.Tag = 'text10';
            app.textColor.Text = '';
            app.text9 = uilabel(app.GridLayout4);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Color :';
            app.text7 = uilabel(app.GridLayout4);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Group :';
            app.textType = uilabel(app.GridLayout4);
            app.textType.Tag = 'text4';
            app.textType.Text = '';
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Type :';
            app.textGroup = uilabel(app.GridLayout4);
            app.textGroup.Tag = 'text8';
            app.textGroup.Text = '';
            app.pushbuttonAddElement = uibutton(app.GridLayout3, 'push');
            app.pushbuttonAddElement.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddElement_Callback, true);
            app.pushbuttonAddElement.Tag = 'pushbuttonAddElement';
            app.pushbuttonAddElement.Text = '+';
            app.pushbuttonDelElement = uibutton(app.GridLayout3, 'push');
            app.pushbuttonDelElement.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDelElement_Callback, true);
            app.pushbuttonDelElement.Tag = 'pushbuttonDelElement';
            app.pushbuttonDelElement.Text = '-';
            app.pushbuttonClearElement = uibutton(app.GridLayout3, 'push');
            app.pushbuttonClearElement.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClearElement_Callback, true);
            app.pushbuttonClearElement.Tag = 'pushbuttonClearElement';
            app.pushbuttonClearElement.Text = 'C';
            app.pushbuttonUp = uibutton(app.GridLayout3, 'push');
            app.pushbuttonUp.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUp_Callback, true);
            app.pushbuttonUp.Tag = 'pushbuttonUp';
            app.pushbuttonUp.Text = '▲';
            app.pushbuttonDown = uibutton(app.GridLayout3, 'push');
            app.pushbuttonDown.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDown_Callback, true);
            app.pushbuttonDown.Tag = 'pushbuttonDown';
            app.pushbuttonDown.Text = '▼';
            app.uipanel7 = uipanel(app.GridLayout);
            app.uipanel7.Tag = 'uipanel7';
            app.uitablePlate = uitable(app.uipanel7);
            app.uitablePlate.ColumnName = {'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'};
            app.uitablePlate.RowName = {'12'; '11'; '10'; '9'; '8'; '7'; '6'; '5'; '4'; '3'; '2'; '1'};
            app.uitablePlate.ColumnEditable = [false false false false false false false false];
            app.uitablePlate.CellSelectionCallback = createCallbackFcn(app, @uitablePlate_CellSelectionCallback, true);
            app.uitablePlate.Tag = 'uitablePlate';
            app.uipanel5 = uipanel(app.uipanel7);
            app.uipanel5.Tag = 'uipanel5';
            app.pushbuttonLoad = uibutton(app.uipanel5, 'push');
            app.pushbuttonLoad.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLoad_Callback, true);
            app.pushbuttonLoad.Tag = 'pushbuttonLoad';
            app.pushbuttonLoad.Text = 'Load';
            app.pushbuttonSave = uibutton(app.uipanel5, 'push');
            app.pushbuttonSave.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSave_Callback, true);
            app.pushbuttonSave.Tag = 'pushbuttonSave';
            app.pushbuttonSave.Text = 'Save';
            app.uipanel6 = uipanel(app.uipanel7);
            app.uipanel6.Tag = 'uipanel6';
            app.pushbuttonClear = uibutton(app.uipanel6, 'push');
            app.pushbuttonClear.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClear_Callback, true);
            app.pushbuttonClear.Tag = 'pushbuttonClear';
            app.pushbuttonClear.Text = 'Clear';
            app.pushbuttonDelete = uibutton(app.uipanel6, 'push');
            app.pushbuttonDelete.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDelete_Callback, true);
            app.pushbuttonDelete.Tag = 'pushbuttonDelete';
            app.pushbuttonDelete.Text = 'Delete';
            app.pushbuttonStart = uibutton(app.uipanel7, 'push');
            app.pushbuttonStart.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStart_Callback, true);
            app.pushbuttonStart.Tag = 'pushbutton15';
            app.pushbuttonStart.Text = 'Start Scan';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectSample(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SelectSample_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end