classdef FittingResult < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResultTable  matlab.ui.control.Table
        ResultAxes   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, resultMat, fitCurve, rawData, SetApp)
            app.ResultTable.Data = resultMat;
            app.ResultTable.RowName = SetApp.MainApp.UITable.Data.Var1;
            
            legendCell = cell(size(fitCurve, 2)*2, 1);
            
            for i = 1:size(fitCurve, 2)    
                
                rawFullX = [
                    rawData(i).Association.X;
                    rawData(i).Association.X(end) + rawData(i).Dissociation.X;                    
                    ];
                
                rawFullY = [
                    rawData(i).Association.Y    
                    rawData(i).Dissociation.Y
                    ];
                
                hold(app.ResultAxes, 'on')
                plot(app.ResultAxes, rawFullX, rawFullY)
                legendCell{2*i-1} = rawData(i).DesName;
                
                fitFullX = [
                    rawData(i).Association.X;
                    rawData(i).Association.X(end) + rawData(i).Dissociation.X
                    ];
                
                fitFullY = [
                    fitCurve(i).fitAssoY;
                    fitCurve(i).fitDissoY;
                    ];
                
                hold(app.ResultAxes, 'on')
                plot(app.ResultAxes, fitFullX, fitFullY, 'k')
                legendCell{2*i} = strcat(rawData(i).DesName, '(fitted)');
                
            end          
            
            legend(app.ResultAxes, legendCell)
            
            disableDefaultInteractivity(app.ResultAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultAxes = uiaxes(app.UIFigure);
            title(app.ResultAxes, 'Title')
            xlabel(app.ResultAxes, 'X')
            ylabel(app.ResultAxes, 'Y')
            zlabel(app.ResultAxes, 'Z')
            app.ResultTable = uitable(app.UIFigure);
            app.ResultTable.ColumnName = {'Rmax'; 'Kon'; 'Koff'; 'BI'; 'KD'; 'Chi2'};
            app.ResultTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingResult(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end