classdef MultiKinetics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OffsetButton         matlab.ui.control.Button
        KineticsStartButton  matlab.ui.control.Button
        MoveButton           matlab.ui.control.Button
        Image                matlab.ui.control.Image
        CutSectionButton     matlab.ui.control.Button
        CutRearButton        matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        OKButton             matlab.ui.control.Button
        CutFrontButton       matlab.ui.control.Button
        DeleteButton         matlab.ui.control.Button
        AddButton            matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        UIAxes               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        
        curPath = pwd; % Load data path
        
        numColDescName = 1;
        numColCon      = 2;
%         numColUnit     = 3;
%         numColfileName = 4;
%         numColValue    = 5;
        numColfileName = 3;
        numColValue    = 4;
        
        yLim = zeros(1, 2);
        xLim = zeros(1, 2);
        
        lineAsStart;
        lineAsEnd;     
        lineDisStart;
        lineDisEnd;
        
        lineCut;
        lineCut1;
        lineCut2;
%         lineOffset;
        
%         verLineRange = [-100000 100000];
        
        rotCol = {'Red', 'Green', 'Blue'};
        
        calTableStyle = uistyle('FontColor', 'Red');
        
        progDlg;
        
        curConc = '0'; % Current Concentration for kinetics analysis     
        
        desNameNum = 1;
    end
    
    properties (Access = public)
        dataNo = 0; % Number of data loaded
        selCell = []; % Selected cell on the UITable
        dataStruct = struct;
    end
    
    methods (Access = private)
        
        function DispAssoValue(app, dataNo)
            
            tmpData = app.dataStruct(dataNo).data;
            dValue = tmpData(app.dataStruct(dataNo).assoEnd, 2)...
                - tmpData(app.dataStruct(dataNo).assoStart, 2);
            app.UITable.Data{dataNo, app.numColValue} = {dValue};
            
        end    
        
        function DrawAssoRangeLine(app)
            
            app.xLim = app.UIAxes.XLim;
            app.yLim = app.UIAxes.YLim;
            
            asStartPos = [app.xLim(1), app.yLim(1); app.xLim(1), app.yLim(2)];
            asEndPos = [(app.xLim(1)+app.xLim(2))*1/3, app.yLim(1);...
                (app.xLim(1)+app.xLim(2))*1/3, app.yLim(2)];
            
%             asStartPos = [app.xLim(1), app.verLineRange(1); app.xLim(1), app.verLineRange(2)];
%             asEndPos = [(app.xLim(1)+app.xLim(2))*1/3, app.verLineRange(1);...
%                 (app.xLim(1)+app.xLim(2))*1/3, app.verLineRange(2)];
            
            app.lineAsStart = drawline('Parent', app.UIAxes,...
                 'Position', asStartPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Association Start', ...
                 'LabelVisible', 'hover', ...
                 'Color', [1 0 0], ...
                 'LineWidth', 2);
             
            app.lineAsEnd = drawline('Parent', app.UIAxes,...
                 'Position', asEndPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Association End', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 1 0], ...
                 'LineWidth', 2);
            
        end
        
        function DrawDissoRangeLine(app)
            
            app.xLim = app.UIAxes.XLim;
            app.yLim = app.UIAxes.YLim;
            
            disStartPos = [(app.xLim(1)+app.xLim(2))*2/3, app.yLim(1);...
                (app.xLim(1)+app.xLim(2))*2/3, app.yLim(2)];
            disEndPos = [app.xLim(2), app.yLim(1); app.xLim(2), app.yLim(2)];
%             disStartPos = [(app.xLim(1)+app.xLim(2))*2/3, app.verLineRange(1);...
%                 (app.xLim(1)+app.xLim(2))*2/3, app.verLineRange(2)];
%             disEndPos = [app.xLim(2), app.verLineRange(1); app.xLim(2), app.verLineRange(2)];
            
            app.lineDisStart = drawline('Parent', app.UIAxes,...
                 'Position', disStartPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Dissociation Start', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 0 1], ...
                 'LineWidth', 2);
             
            app.lineDisEnd = drawline('Parent', app.UIAxes,...
                 'Position', disEndPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Dissociation End', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 1 1], ...
                 'LineWidth', 2);
            
        end
        
        function DrawCutLine(app, lineLabel)
            
            app.xLim = app.UIAxes.XLim;
            app.yLim = app.UIAxes.YLim;
            
            if strcmp(lineLabel, app.CutSectionButton.Text)
                
                linePos1 = [app.xLim(1)+(app.xLim(2)-app.xLim(1))*0.25, app.UIAxes.YLim(1);...
                app.xLim(1)+(app.xLim(2)-app.xLim(1))*0.25, app.UIAxes.YLim(2)];
            
                linePos2 = [app.xLim(1)+(app.xLim(2)-app.xLim(1))*0.75, app.UIAxes.YLim(1);...
                app.xLim(1)+(app.xLim(2)-app.xLim(1))*0.75, app.UIAxes.YLim(2)];
            
                app.lineCut1 = drawline('Parent', app.UIAxes,...
                     'Position', linePos1, ...
                     'Deletable', 0, ...
                     'InteractionsAllowed', 'translate', ...
                     'Label', 'Cut Section Start', ...
                     'LabelVisible', 'on', ...
                     'Color', [0 1 0], ...
                     'LineWidth', 2);                
                 
                 app.lineCut2 = drawline('Parent', app.UIAxes,...
                     'Position', linePos2, ...
                     'Deletable', 0, ...
                     'InteractionsAllowed', 'translate', ...
                     'Label', 'Cut Section End', ...
                     'LabelVisible', 'on', ...
                     'Color', [0 1 0], ...
                     'LineWidth', 2);                
                
            else
                
                linePos = [app.xLim(1)+(app.xLim(2)-app.xLim(1))/2, app.UIAxes.YLim(1);...
                app.xLim(1)+(app.xLim(2)-app.xLim(1))/2, app.UIAxes.YLim(2)];
            
                app.lineCut = drawline('Parent', app.UIAxes,...
                     'Position', linePos, ...
                     'Deletable', 0, ...
                     'InteractionsAllowed', 'translate', ...
                     'Label', lineLabel, ...
                     'LabelVisible', 'on', ...
                     'Color', [0 1 0], ...
                     'LineWidth', 2);
                
            end
             
        end
        
        function FitScaleAxes(app)            
            
            app.UIAxes.XLimMode = 'Manual';
            app.UIAxes.YLimMode = 'Manual';
            
            minX = [];
            maxX = [];
            minY = [];
            maxY = [];
            margin = 0.05;
            
            for i=1:size(app.dataStruct, 2)
                
                minX = [minX, min(app.dataStruct(i).dataPlot.XData)];
                maxX = [maxX, max(app.dataStruct(i).dataPlot.XData)];
                minY = [minY, min(app.dataStruct(i).dataPlot.YData)];
                maxY = [maxY, max(app.dataStruct(i).dataPlot.YData)];
                
            end
            
%             app.UIAxes.XLim = [min(minX), max(maxX)] + [-min(minX), max(maxX)] * margin;            
%             app.UIAxes.YLim = [min(minY), max(maxY)] + [-min(minY), max(maxY)] * margin; 
            app.UIAxes.XLim = [min(minX), max(maxX)] + [-abs(max(maxX)-min(minX)), abs(max(maxX)-min(minX))] * margin;            
            app.UIAxes.YLim = [min(minY), max(maxY)] + [-abs(max(maxY)-min(minY)), abs(max(maxY)-min(minY))] * margin;            
            
            app.lineAsStart.Position(:, 2) = app.UIAxes.YLim';
            app.lineAsEnd.Position(:, 2) = app.UIAxes.YLim';
            app.lineDisStart.Position(:, 2) = app.UIAxes.YLim';
            app.lineDisEnd.Position(:, 2) = app.UIAxes.YLim';
            
            
            try
                app.lineCut.Position(:, 2) = app.UIAxes.YLim';
            end
            try
                app.lineCut1.Position(:, 2) = app.UIAxes.YLim';
            end
            try
                app.lineCut2.Position(:, 2) = app.UIAxes.YLim';
            end
            
        end
        
        function ArrangeVerticalLines(app)
            
            tmpRange = [];

            app.xLim = app.UIAxes.XLim;
            app.yLim = app.UIAxes.YLim;

            for ii = 1:size(app.dataStruct, 2)

                if isempty(app.dataStruct(ii).dataPlot)

                    continue;

                end

                tmpRange = [tmpRange, min(app.dataStruct(ii).dataPlot.XData),...
                    max(app.dataStruct(ii).dataPlot.XData)];

            end

            asStartPos = [min(tmpRange), app.yLim(1); min(tmpRange), app.yLim(2)];
            asEndPos = [min(tmpRange)+(max(tmpRange)-min(tmpRange))*1/3, app.yLim(1);...
                min(tmpRange)+(max(tmpRange)-min(tmpRange))*1/3, app.yLim(2)];

            disStartPos = [min(tmpRange)+(max(tmpRange)-min(tmpRange))*2/3, app.yLim(1);...
                min(tmpRange)+(max(tmpRange)-min(tmpRange))*2/3, app.yLim(2)];
            disEndPos = [max(tmpRange), app.yLim(1); max(tmpRange), app.yLim(2)];

            app.lineAsStart.Position = asStartPos;
            app.lineAsEnd.Position = asEndPos;
            app.lineDisStart.Position = disStartPos;
            app.lineDisEnd.Position = disEndPos;

        end        
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dataStruct(1:3) = struct;
            
            app.OKButton.Visible = 0;
            app.CancelButton.Visible = 0;
            
%             app.UITable.ColumnName = {'Description Name', 'Concentration', 'Unit',...
%                 'File Name', 'ΔAsso. Value'};
            app.UITable.ColumnName = {'Description Name', 'Concentration(M)',...
                'File Name', 'ΔAsso. Value'};
            app.UITable.RowName = 'numbered';
%             app.UITable.ColumnEditable = [true, true, true, false, false];
            app.UITable.ColumnEditable = [true, true, true, false];
            initData = table;
            
            for i = 1:3

%                 initData(i, :) = table("", {''},...
%                     categorical({'ug/ml'},...
%                     {'mg/ml'; 'ug/ml'; 'ng/ml'; 'pg/ml'}, 'Protected', true),...
%                     {''}, {[]});    
                initData(i, :) = table("", {''},...                    
                    {''}, {[]});    
                
            end
            
            app.UITable.Data = initData;
            
            DrawAssoRangeLine(app)
            DrawDissoRangeLine(app)
            
%             app.UIAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes)
                        
            objZoom = zoom(app.UIAxes);
            set(objZoom,'ActionPostCallback',{@ZoomPostCallback, objZoom}); %set callback
%             [tb, btns] = axtoolbar(app.UIAxes, {'pan', 'export', 'zoomin', 'zoomout', 'restoreview'});
            [tb, btns] = axtoolbar(app.UIAxes, {'pan', 'export', 'zoomin', 'zoomout'});
            btnRestore = axtoolbarbtn(tb, 'push');
            btnRestore.Icon = 'restoreview';
            btnRestore.ButtonPushedFcn = createCallbackFcn(app, @RestoreView, true);
            
            function RestoreView(app, event)
                               
                FitScaleAxes(app)
            
            end
            
            function ZoomPostCallback(~, b, objZoom)                
                
                app.lineAsStart.Position(:, 2) = b.Axes.YLim';
                app.lineAsEnd.Position(:, 2) = b.Axes.YLim';
                app.lineDisStart.Position(:, 2) = b.Axes.YLim';
                app.lineDisEnd.Position(:, 2) = b.Axes.YLim';
                app.lineCut.Position(:, 2) = b.Axes.YLim';
                app.lineCut1.Position(:, 2) = b.Axes.YLim';
                app.lineCut2.Position(:, 2) = b.Axes.YLim';
%                 pause(0.01)
                
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            tmpRange = [];
            
            [fileList, filePath] = uigetfile(...
                '*.txt', ...
                'Select the SPR data.', ...
                'MultiSelect', 'on', ...
                app.curPath);
            
            if isequal(fileList, 0)
                
                return
                
            end
            
            if iscell(fileList)
            
                fileNo = length(fileList);
        
            else
        
                fileNo = 1;
        
            end
            
            loadProgDlg = uiprogressdlg(app.UIFigure, 'Message', 'Loading data...', 'Indeterminate', 'on');
        
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        
            for i = 1:fileNo
                
                hold(app.UIAxes, 'on');
        
                    if iscell(fileList)
        
                        tempData = strcat(filePath, fileList{1, i});
        
                    else        
        
                        tempData = strcat(filePath, fileList);
        
                    end
        
                    data = importdata(tempData, '\t', 1);
        
                    if isnan(data.data(1, 2))
        
                        for d_ = 2:size(data.data, 1)
        
                            if ~isnan(data.data(d_, 2))                    
        
                                break
        
                            end
        
                        end
        
                        data.data(1:(d_-1), :) = [];
                        data.data(:, 1) = 1:size(data.data, 1);
        
                    end
        
                    if isnan(data.data(size(data.data, 1), 2))
        
                        for d_ = (size(data.data, 1)-1):-1:1
        
                            if ~isnan(data.data(d_, 2))                    
        
                                break
        
                            end
        
                        end
        
                        data.data((d_+1):end, :) = [];
        
                    end 
        
                    if isempty(data.data)
        
                        continue
        
                    end
        
                    app.dataNo = app.dataNo+1;
                    app.dataStruct(app.dataNo).dataPlot = plot(app.UIAxes, data.data(:, 1), data.data(:, 2));        
        
                    if iscell(fileList) % File Name
        
                        fileName = fileList{1, i};
                        app.UITable.Data{app.dataNo, app.numColfileName} = {fileName};
        
                    else        
        
                        fileName = fileList;
                        app.UITable.Data{app.dataNo, app.numColfileName} = {fileName};
        
                    end               
                    
                    app.dataStruct(app.dataNo).desName = sprintf('Data(%d)', app.desNameNum);
                    app.desNameNum = app.desNameNum + 1;
                    
%                     app.UITable.Data{app.dataNo, app.numColUnit} = {'mg/ml'};
                    app.UITable.Data{app.dataNo, app.numColDescName} =...
                        {app.dataStruct(app.dataNo).desName};

                    app.UITable.Data{app.dataNo, app.numColCon} = {'Please Insert'};
                    
                    app.dataStruct(app.dataNo).data = data.data;
                    app.dataStruct(app.dataNo).assoStart = data.data(1, 1);
                    app.dataStruct(app.dataNo).assoEnd = data.data(end, 1);
        
                    app.curPath = filePath;
                    hold(app.UIAxes, 'off');
                
            end
            
            FitScaleAxes(app)
            ArrangeVerticalLines(app)
            
%             app.xLim = app.UIAxes.XLim;
%             app.yLim = app.UIAxes.YLim;
            
%             for ii = 1:size(app.dataStruct, 2)
%                 if isempty(app.dataStruct(ii).dataPlot)
%                     
%                     continue;
%                     
%                 end
%                 
%                 tmpRange = [tmpRange, min(app.dataStruct(ii).dataPlot.XData),...
%                     max(app.dataStruct(ii).dataPlot.XData)];
%                 
%             end


% %             asStartPos = [app.xLim(1), app.yLim(1); app.xLim(1), app.yLim(2)];
% %             asEndPos = [(app.xLim(1)+app.xLim(2))*1/3, app.yLim(1);...
% %                 (app.xLim(1)+app.xLim(2))*1/3, app.yLim(2)];
% %             
% %             disStartPos = [(app.xLim(1)+app.xLim(2))*2/3, app.yLim(1);...
% %                 (app.xLim(1)+app.xLim(2))*2/3, app.yLim(2)];
% %             disEndPos = [app.xLim(2), app.yLim(1); app.xLim(2), app.yLim(2)];
%             
%             asStartPos = [min(tmpRange), app.yLim(1); min(tmpRange), app.yLim(2)];
%             asEndPos = [min(tmpRange)+(max(tmpRange)-min(tmpRange))*1/3, app.yLim(1);...
%                 min(tmpRange)+(max(tmpRange)-min(tmpRange))*1/3, app.yLim(2)];
%             
%             disStartPos = [min(tmpRange)+(max(tmpRange)-min(tmpRange))*2/3, app.yLim(1);...
%                 min(tmpRange)+(max(tmpRange)-min(tmpRange))*2/3, app.yLim(2)];
%             disEndPos = [max(tmpRange), app.yLim(1); max(tmpRange), app.yLim(2)];
%             
%             app.lineAsStart.Position = asStartPos;
%             app.lineAsEnd.Position = asEndPos;
%             app.lineDisStart.Position = disStartPos;
%             app.lineDisEnd.Position = disEndPos;
%             
%             app.UIAxes.XLimMode = 'Manual';
%             app.UIAxes.YLimMode = 'Manual';
            
            delete(loadProgDlg)
        end

        % Button pushed function: CutFrontButton, CutRearButton, 
        % CutSectionButton, OffsetButton
        function CutFrontButtonPushed(app, event)
            
            if isempty(app.selCell)                
                uialert(app.UIFigure, 'Select at least 1 cell on the table.', '')
                return                
            end
            
            if ishandle(app.lineCut)            
                delete(app.lineCut)                
            end
            if ishandle(app.lineCut1)            
                delete(app.lineCut1)                
            end
            if ishandle(app.lineCut2)            
                delete(app.lineCut2)                
            end
            
            app.OKButton.Visible = 1;
            app.CancelButton.Visible = 1;
            
            app.UITable.Enable = 'off';
            app.AddButton.Enable = 0;
            app.DeleteButton.Enable = 0;
            app.CutFrontButton.Enable = 0;
            app.CutRearButton.Enable = 0; 
            app.CutSectionButton.Enable = 0;
            app.OffsetButton.Enable = 0;
            app.lineAsStart.Visible = 0;
            app.lineAsEnd.Visible = 0;
            
            app.OKButton.UserData = event.Source.Text;
            DrawCutLine(app, event.Source.Text)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selCell = event.Indices;

            for i = 1:size(app.dataStruct, 2)
                
                if isfield(app.dataStruct(i), 'dataPlot')
                
                    app.dataStruct(i).dataPlot.LineWidth = 0.5;
                    
                end
                
            end
            
            for i = 1:size(app.selCell, 1)
                
                if isfield(app.dataStruct(app.selCell(i, 1)), 'dataPlot')
                
                    app.dataStruct(app.selCell(i, 1)).dataPlot.LineWidth = 4;
                    
                end
                
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if strcmp(app.OKButton.UserData, app.CutFrontButton.Text)
                
                for i=1:size(app.selCell(:, 1), 1)
                    
                    if app.lineCut.Position(1, 1)+1 <= ...
                            app.dataStruct(app.selCell(i, 1)).data(1, 1)
                        
                        continue
                        
                    end
                    
                    app.dataStruct(app.selCell(i, 1)).data...
                        (1:app.lineCut.Position(1)+1, :) = NaN;
                    
                    
                    app.dataStruct(app.selCell(i, 1)).dataPlot.XData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 1);
                    app.dataStruct(app.selCell(i, 1)).dataPlot.YData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 2);
                    
                end
                
                delete(app.lineCut)
                
            elseif strcmp(app.OKButton.UserData, app.CutRearButton.Text)
                
                for i=1:size(app.selCell(:, 1), 1)
                    
                    if app.lineCut.Position(1, 1) >= ...
                            app.dataStruct(app.selCell(i, 1)).data(end, 1)
                        
                        continue
                        
                    end
                    
                    app.dataStruct(app.selCell(i, 1)).data...
                        (app.lineCut.Position(1)+1:end, :) = NaN;
                    
                    app.dataStruct(app.selCell(i, 1)).dataPlot.XData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 1);
                    app.dataStruct(app.selCell(i, 1)).dataPlot.YData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 2);
                    
                end
                
                delete(app.lineCut)
                
            elseif strcmp(app.OKButton.UserData, app.CutSectionButton.Text)
               
               if app.lineCut1.Position(1, 1) >= app.lineCut2.Position(1, 1)
                    
                    uialert(app.UIFigure,...
                        'End point of cut section should be to the right side of Start point.', '')
                    
                    delete(app.lineCut1)
                    delete(app.lineCut2)
            
                    app.OKButton.Visible = 0;
                    app.CancelButton.Visible = 0;
                    
                    app.UITable.Enable = 'on';
                    app.AddButton.Enable = 1;
                    app.DeleteButton.Enable = 1;
                    app.CutFrontButton.Enable = 1; 
                    app.CutRearButton.Enable = 1; 
                    app.CutSectionButton.Enable = 1; 
                    app.OffsetButton.Enable = 1;
                    app.lineAsStart.Visible = 1;
                    app.lineAsEnd.Visible = 1;
                    
                    return
                
               end
                
                for i=1:size(app.selCell(:, 1), 1)
                    
                    if app.lineCut1.Position(1, 1)+1 >= ...
                            app.dataStruct(app.selCell(i, 1)).data(end, 1)...
                            || app.lineCut2.Position(1, 1)+1 <= ...
                            app.dataStruct(app.selCell(i, 1)).data(1, 1)
                        
                        continue
                        
                    end
                    
                    app.dataStruct(app.selCell(i, 1)).data...
                        (app.lineCut1.Position(1)+1:app.lineCut2.Position(1)+1, :) = NaN;
                    
                    app.dataStruct(app.selCell(i, 1)).dataPlot.XData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 1);
                    app.dataStruct(app.selCell(i, 1)).dataPlot.YData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 2);
                    
                end
                
                delete(app.lineCut1)
                delete(app.lineCut2)
                
            elseif strcmp(app.OKButton.UserData, app.OffsetButton.Text)
            
                for i=1:size(app.selCell(:, 1), 1)
            
                    if app.lineCut.Position(1, 1)+1 >= ...
                            app.dataStruct(app.selCell(i, 1)).data(end, 1)...
                            || app.lineCut.Position(1, 1)+1 <= ...
                            app.dataStruct(app.selCell(i, 1)).data(1, 1)
                        
                        continue
                        
                    end
                    
                    offsetRef = app.dataStruct(...
                        app.selCell(i, 1)).data(round(app.lineCut.Position(1))+1, 2);
                    app.dataStruct(app.selCell(i, 1)).data(:, 2) =...
                        app.dataStruct(app.selCell(i, 1)).data(:, 2) - offsetRef;                        
                    app.dataStruct(app.selCell(i, 1)).dataPlot.YData...
                        = app.dataStruct(app.selCell(i, 1)).data(:, 2);
            
                end
            
                delete(app.lineCut)
                
            end
            
            app.OKButton.Visible = 0;
            app.CancelButton.Visible = 0;
            
            app.UITable.Enable = 'on';
            app.AddButton.Enable = 1;
            app.DeleteButton.Enable = 1;
            app.CutFrontButton.Enable = 1; 
            app.CutRearButton.Enable = 1; 
            app.CutSectionButton.Enable = 1; 
            app.OffsetButton.Enable = 1;
            app.lineAsStart.Visible = 1;
            app.lineAsEnd.Visible = 1;
            
            FitScaleAxes(app)
            ArrangeVerticalLines(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            if ishandle(app.lineCut)            
                delete(app.lineCut)                
            end
            if ishandle(app.lineCut1)            
                delete(app.lineCut1)                
            end
            if ishandle(app.lineCut2)            
                delete(app.lineCut2)                
            end

            app.OKButton.Visible = 0;
            app.CancelButton.Visible = 0;
            
            app.UITable.Enable = 'on';
            app.AddButton.Enable = 1;
            app.DeleteButton.Enable = 1;
            app.CutFrontButton.Enable = 1; 
            app.CutRearButton.Enable = 1; 
            app.CutSectionButton.Enable = 1;
            app.OffsetButton.Enable = 1;
            app.lineAsStart.Visible = 1;
            app.lineAsEnd.Visible = 1;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if isempty(app.selCell)
                
                return
                
            end
            
            for i=1:size(app.selCell, 1)
                
                if isfield(app.dataStruct(app.selCell(i, 1)), 'dataPlot')
            
                    delete(app.dataStruct(app.selCell(i, 1)).dataPlot)
                    
                end
                
            end
            
            app.dataStruct(app.selCell(:, 1)) = [];            
            app.UITable.Data(app.selCell(:, 1), :) = [];            
            app.selCell = [];
            app.dataNo = size(app.dataStruct, 2);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            for i=1:app.dataNo
            
                app.dataStruct(i).assoStart = round(app.lineAsStart.Position(1, 1));
                app.dataStruct(i).assoEnd = round(app.lineAsEnd.Position(1, 1));
                
                try
                
                    DispAssoValue(app, i)
                    
                catch
                
                    uialert(app.UIFigure, 'Please select a proper Association Start & End Point.', '')
                    return
                    
                end
                
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            if isempty (app.selCell)
                
                uialert(app.UIFigure, 'Select more than 1 plot to move.', '')
                
            else
            
                MovePopup(app)
                
            end
        end

        % Button pushed function: KineticsStartButton
        function KineticsStartButtonPushed(app, event)
            for i=1:app.dataNo
            
                app.dataStruct(i).assoStart = floor(app.lineAsStart.Position(1, 1));
                app.dataStruct(i).assoEnd = floor(app.lineAsEnd.Position(1, 1));
                app.dataStruct(i).dissoStart = floor(app.lineDisStart.Position(1, 1));
                app.dataStruct(i).dissoEnd = floor(app.lineDisEnd.Position(1, 1));
                
                if app.dataStruct(i).assoStart < 1
                    
                    app.dataStruct(i).assoStart = 1;
                    
                end
                
                if app.dataStruct(i).assoEnd < 1
                    
                    app.dataStruct(i).assoEnd = 1;
                    
                end
                
                if app.dataStruct(i).dissoStart < 1
                    
                    app.dataStruct(i).dissoStart = 1;
                    
                end
                
                if app.dataStruct(i).dissoEnd < 1
                    
                    app.dataStruct(i).dissoEnd = 1;
                    
                end
                
                if app.dataStruct(i).assoStart >= app.dataStruct(i).assoEnd
                    
                    uialert(app.UIFigure, 'Association range error.', '', 'Modal', true)
                    return
                    
                end
                
                if app.dataStruct(i).dissoStart >= app.dataStruct(i).dissoEnd
                    
                    uialert(app.UIFigure, 'Dissociation range error.', '', 'Modal', true)
                    return
                    
                end
                
                if app.dataStruct(i).assoEnd > size(app.dataStruct(i).data, 1)...
                        || app.dataStruct(i).dissoEnd > size(app.dataStruct(i).data, 1)
                    
                    uialert(app.UIFigure, 'Out of Range', '', 'Modal', true)
                    return
                    
                end
                                
                if isnan(app.dataStruct(i).data(app.dataStruct(i).assoStart))...
                        || isnan(app.dataStruct(i).data(app.dataStruct(i).assoEnd))...
                        || isnan(app.dataStruct(i).data(app.dataStruct(i).dissoStart))...
                        || isnan(app.dataStruct(i).data(app.dataStruct(i).dissoEnd))
                    
                    uialert(app.UIFigure, 'Out of Range', '', 'Modal', true)
                    return
                    
                end
                
                dataX = app.dataStruct(i).data(:, 1);
                dataY = app.dataStruct(i).data(:, 2);
                
                assoX = dataX(app.dataStruct(i).assoStart : app.dataStruct(i).assoEnd);
                assoY = dataY(app.dataStruct(i).assoStart : app.dataStruct(i).assoEnd);
                
                dissoX = dataX(app.dataStruct(i).dissoStart : app.dataStruct(i).dissoEnd);
                dissoY = dataY(app.dataStruct(i).dissoStart : app.dataStruct(i).dissoEnd);
                
                app.dataStruct(i).kinAssoData = [assoX assoY];
                app.dataStruct(i).kinDissoData = [dissoX dissoY];
                
            end
            
            FittingSet(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;            

            if indices(1, 2) == 2
                
                numVal = str2double(newData);
                
                if isnan(numVal)
                    
                    numVal = str2double(newData(1:end-1));
                    
                    if isnan(numVal)
                        
                        app.UITable.Data{indices(1), indices(2)} = {event.PreviousData};
                        return
                        
                    elseif strcmp(newData(end), 'm')
                        
                        numVal = numVal * 10^(-3);
                        
                    elseif strcmp(newData(end), 'u')
                        
                        numVal = numVal * 10^(-6);
                        
                    elseif strcmp(newData(end), 'n')
                        
                        numVal = numVal * 10^(-9);
                        
                    elseif strcmp(newData(end), 'p')
                        
                        numVal = numVal * 10^(-12);
                        
                    elseif strcmp(newData(end), 'f')
                        
                        numVal = numVal * 10^(-15);
                        
                    elseif strcmp(newData(end), 'a')
                        
                        numVal = numVal * 10^(-18);
                        
                    end
                
                end
                
            app.UITable.Data{indices(1), indices(2)} = {num2str(numVal)};            
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Description Name'; 'Concentration'; 'Unit'; 'File Name'; 'Asso. Value'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CutFrontButton = uibutton(app.UIFigure, 'push');
            app.CutFrontButton.ButtonPushedFcn = createCallbackFcn(app, @CutFrontButtonPushed, true);
            app.CutFrontButton.Text = 'Cut Front';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CutRearButton = uibutton(app.UIFigure, 'push');
            app.CutRearButton.ButtonPushedFcn = createCallbackFcn(app, @CutFrontButtonPushed, true);
            app.CutRearButton.Text = 'Cut Rear';
            app.CutSectionButton = uibutton(app.UIFigure, 'push');
            app.CutSectionButton.ButtonPushedFcn = createCallbackFcn(app, @CutFrontButtonPushed, true);
            app.CutSectionButton.Text = 'Cut Section';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'refreshIcon.png';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.KineticsStartButton = uibutton(app.UIFigure, 'push');
            app.KineticsStartButton.ButtonPushedFcn = createCallbackFcn(app, @KineticsStartButtonPushed, true);
            app.KineticsStartButton.Text = 'Kinetics Start';
            app.OffsetButton = uibutton(app.UIFigure, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CutFrontButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiKinetics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end