classdef UI_Alias_DirectControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DirectControlUIFigure        matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        LogcountersButton            matlab.ui.control.Button
        StatusSerialNumberLabel      matlab.ui.control.Label
        SerialnumberLabel            matlab.ui.control.Label
        DoorStatusLabel              matlab.ui.control.Label
        DoorLabel                    matlab.ui.control.Label
        InformationPCBVersionLabel   matlab.ui.control.Label
        StatusSystemBootID           matlab.ui.control.Label
        StatusSoftwareVersionLabel   matlab.ui.control.Label
        StatusStatusLabel            matlab.ui.control.Label
        PCBversionLabel              matlab.ui.control.Label
        SystembootIDLabel            matlab.ui.control.Label
        SoftwareversionLabel         matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        DirectControlPanel           matlab.ui.container.Panel
        NeedlePanel                  matlab.ui.container.Panel
        ExchangeButton_2             matlab.ui.control.Button
        ValvePanel                   matlab.ui.container.Panel
        ValveButtonGroup             matlab.ui.container.ButtonGroup
        InjectButton                 matlab.ui.control.RadioButton
        LoadButton                   matlab.ui.control.RadioButton
        Panel_5                      matlab.ui.container.Panel
        TraycoolingPanel             matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        ActualLabel                  matlab.ui.control.Label
        CLabel_2                     matlab.ui.control.Label
        CLabel                       matlab.ui.control.Label
        SetpointSpinner              matlab.ui.control.Spinner
        SetpointLabel                matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        OffButton_2                  matlab.ui.control.RadioButton
        OnButton_2                   matlab.ui.control.RadioButton
        TrayPanel                    matlab.ui.container.Panel
        FrontButton                  matlab.ui.control.Button
        Panel_4                      matlab.ui.container.Panel
        InitialwashPanel             matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        StartButton_2                matlab.ui.control.Button
        SSVButtonGroup               matlab.ui.container.ButtonGroup
        Solvent6Button               matlab.ui.control.RadioButton
        Solvent5Button               matlab.ui.control.RadioButton
        Solvent4Button               matlab.ui.control.RadioButton
        Solvent3Button               matlab.ui.control.RadioButton
        Solvent2Button               matlab.ui.control.RadioButton
        Solvent1Button               matlab.ui.control.RadioButton
        FilltransportreservoirPanel  matlab.ui.container.Panel
        StartButton                  matlab.ui.control.Button
        timesthesyringevolumeLabel   matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        SyringePanel                 matlab.ui.container.Panel
        ExchangeButton               matlab.ui.control.Button
        EndButton                    matlab.ui.control.Button
        HomeButton                   matlab.ui.control.Button
        PanelIO                      matlab.ui.container.Panel
        OutputButtonGroup            matlab.ui.container.ButtonGroup
        OffButton                    matlab.ui.control.RadioButton
        OnButton                     matlab.ui.control.RadioButton
        InputsPanel                  matlab.ui.container.Panel
        StatusInput2Label            matlab.ui.control.Label
        StatusInput1Label            matlab.ui.control.Label
        Input2Label                  matlab.ui.control.Label
        Input1Label                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp; % UI_StartUp class
    end
    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            portDevice = app.MainApp.PortDevice;        
            
            uiDlg = uiprogressdlg(app.DirectControlUIFigure, 'Title', 'Please Wait',...
                'Message', 'Checking the connection', 'Indeterminate', 'on');
            
            try
                pfcInformation = "1000";
                pfcStatus = "1001";
                
                valueSerialNumber = "002509";
                valuePCBPartNumber = "000179";
                valuePCBRevisionNumber = "000181";
                
                valueExternalIOInputs = "000169";
                valueStatus = "000152";
                valueDoorSensor = "005300";
                valueInjectorValveSwitching = "005105";
                valueConfiguration = "000158";
                valueAuxiliary1DirectControl = "000161";
                valueSystemBootIDPartNumber = "000182";
                valueSystemBootID = "000184";
                valueSoftwarePartNumber = "000183";
                valueSoftwareRevisionXL = "000185";
                
                % Device Information                
                rxSerialNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueSerialNumber);
                rxPCBPartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valuePCBPartNumber);
                rxPCBRevisionNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valuePCBRevisionNumber);
                
                % Device Status
                rxExternalIOInputs =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueExternalIOInputs);
                rxStatus =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueStatus);
                rxDoorSensor =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueDoorSensor);
                rxInjectorValueSwtitching =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueInjectorValveSwitching);
                rxConfiguration =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueConfiguration);
                rxAuxiliary1DirectControl =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueAuxiliary1DirectControl);
                rxSystemBootIDPartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSystemBootIDPartNumber);
                rxSystemBootID =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSystemBootID);
                rxSoftwarePartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSoftwarePartNumber);
                rxSoftwareRevisionXL =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSoftwareRevisionXL);
                                
                
                % Serial Number
                app.StatusSerialNumberLabel.Text = rxSerialNumber(end-5:end);      
                
                % PCB Version (Part Number - Revision Number)
                PCBPartNumber = sprintf('0%s.%s', rxPCBPartNumber(end-5:end-3), rxPCBPartNumber(end-2:end));
                PCBRevisionNumber = rxPCBRevisionNumber(end-1:end);                
                app.InformationPCBVersionLabel.Text = strcat(PCBPartNumber, '-', PCBRevisionNumber);                
                
                % Configuration        
                if strcmp(rxConfiguration(end-4), '0')                    
                    disp('No 1 out 6 Value Available')
                elseif strcmp(rxConfiguration(end-4), '1')
                    disp('1 out 6 Value Available')
                end
                
                if strcmp(rxConfiguration(end-3), '0')                    
                    disp('No ISS-A available')
                elseif strcmp(rxConfiguration(end-3), '1')
                    disp('ISS-A available')
                end
                
                if strcmp(rxConfiguration(end-2), '0')                    
                    disp('No Syringe Valve Wash Port 2 available')
                elseif strcmp(rxConfiguration(end-2), '1')
                    disp('Syringe Valve Wash Port 2 available')
                end
        
                if strcmp(rxConfiguration(end-1), '0')                    
                    disp('No SSV option available')
                    app.SSVButtonGroup
                    for btnNo = 1:size(app.SSVButtonGroup, 2)
                        app.SSVButtonGroup.Buttons(btnNo).Enable = 'off';
                    end
                elseif strcmp(rxConfiguration(end-1), '1')
                    disp('SSV option available')
                    for btnNo = 1:size(app.SSVButtonGroup, 2)
                        app.SSVButtonGroup.Buttons(btnNo).Enable = 'on';
                    end
                end
                
                if strcmp(rxConfiguration(end), '0')                    
                    disp('No temperature control available')                                        
                    for childNo = size(app.TraycoolingPanel.Children)
                        app.TraycoolingPanel.Children(childNo).Enable = 'off';
                    end
                elseif strcmp(rxConfiguration(end), '1')
                    disp('Tray cooling available')
                    for childNo = size(app.TraycoolingPanel.Children)
                        app.TraycoolingPanel.Children(childNo).Enable = 'on';
                    end
                elseif strcmp(rxConfiguration(end), '2')
                    disp('Reserved for future use')
                elseif strcmp(rxConfiguration(end), '3')
                    disp('Tray cooling and heating available')
                end                
                
                % External IO Inputs
                if strcmp(rxExternalIOInputs(end), '0')
                    statusInput1 = "Low";
                else
                    statusInput1 = "High";
                end
                app.StatusInput1Label.Text = statusInput1;
                                
                if strcmp(rxExternalIOInputs(end-1), '0')
                    statusInput2 = "Low";
                else
                    statusInput2 = "High";
                end
                app.StatusInput2Label.Text = statusInput2;
                
                % Device Status
                if strcmp(rxStatus(end-3), '1')
                    statusStatus = "Error (Ask for Error Code)";
                else
                    if strcmp(rxStatus(end-2:end), "000")
                        statusStatus = "Idle";
                    else
                        statusStatus = rxStatus(end-2:end);                
                    end                
                end
                app.StatusStatusLabel.Text = statusStatus;   
                
                % Door Sensor
                if strcmp(rxDoorSensor(end), '1')
                    statusDoorSensor = "Closed";                
                else                
                    statusDoorSensor = "Open";                
                end            
                app.DoorStatusLabel.Text = statusDoorSensor;
                
                % Injector Value Swtitching
                if strcmp(rxInjectorValueSwtitching(end), '0')
                    app.InjectButton.Value = 1;
                elseif strcmp(rxInjectorValueSwtitching(end), '1')
                    app.LoadButton.Value = 1;
                elseif strcmp(rxInjectorValueSwtitching(end), '2')
                    disp('Injector Valve Switching : Illegal Position!')
                end
                
                % Auxiliary 1 Direct Control
                if strcmp(rxAuxiliary1DirectControl(end), '0')
                    disp('Auxiliary 1 direct control : Off')
                elseif strcmp(rxAuxiliary1DirectControl(end), '1')
                    disp('Auxiliary 1 direct control : On')
                end
                
                % System Boot ID (Part Number - Hardware version)
                bootID = sprintf('0%s.%s', rxSystemBootIDPartNumber(end-5:end-3), rxSystemBootIDPartNumber(end-2:end));
                
                if strcmp(rxSystemBootID(end-5), '0')
                    hwType = '';
                    minorVer = '';
                elseif strcmp(rxSystemBootID(end-5), '1')
                    hwType = 'T';
                    minorVer = strcat('-', rxSystemBootID(end-1:end));
                elseif strcmp(rxSystemBootID(end-5), '2')
                    hwType = 'P';
                    minorVer = '';
                end
                                
                app.StatusSystemBootID.Text =...
                    strcat(bootID, hwType, '-', rxSystemBootID(end-4), '.', rxSystemBootID(end-3:end-2), minorVer);
                
                % Software Version (Part Number - Revision XL)
                partNumber = sprintf('0%s.%s', rxSoftwarePartNumber(end-5:end-3), rxSoftwarePartNumber(end-2:end));
                
                if strcmp(rxSoftwareRevisionXL(end-5), '0')
                    hwType = '';
                    minorVer = '';
                elseif strcmp(rxSoftwareRevisionXL(end-5), '1')
                    hwType = 'T';
                    minorVer = strcat('-', rxSoftwareRevisionXL(end-1:end));
                elseif strcmp(rxSoftwareRevisionXL(end-5), '2')
                    hwType = 'P';
                    minorVer = '';
                end
                                
                app.StatusSoftwareVersionLabel.Text =...
                    strcat(partNumber, hwType, '-', rxSoftwareRevisionXL(end-4), '.', rxSoftwareRevisionXL(end-3:end-2), minorVer);
                
                close(uiDlg)
                
            catch                
                
                close(uiDlg)
                      
                ret = uiconfirm(app.DirectControlUIFigure,...
                    'Check the connection!',...
                    'Error', 'Icon', 'error', 'Options', {'OK'});               
                
                DirectControlUIFigureCloseRequest(app, [])
                
            end            
        end

        % Close request function: DirectControlUIFigure
        function DirectControlUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Selection changed function: OutputButtonGroup
        function OutputButtonGroupSelectionChanged(app, event)
            pfc = "0161";
            if strcmp(app.OutputButtonGroup.SelectedObject.Text, 'On')
                value = '000001';
            else 
                value = '000000';
            end            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: FrontButton
        function FrontButtonPushed(app, event)
            pfc = "5111";
            value = "000109";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            pfc = "5140";
            value = "000000";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            pfc = "5140";
            value = "000001";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: ExchangeButton
        function ExchangeButtonPushed(app, event)
            pfc = "5140";
            value = "000002";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Selection changed function: ValveButtonGroup
        function ValveButtonGroupSelectionChanged(app, event)
            pfc = "5105";
            if strcmp(app.ValveButtonGroup.SelectedObject.Text, 'Load')
                value = '000001';
            else 
                value = '000000';
            end            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            pfc = "5141";
            q0 = num2str(app.Spinner.Value);
            q1 = '0';
            q2 = '0'; % Syringe valve wash port, default / 1 : not working.
            value = strcat('000', q2, q1, q0);            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            pfc = "5130";
            q0 = '1'; % Start
            q1 = '0'; % Wash port 1            
            value = strcat('0000', q1, q0);            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            pfc = "5130";
            q0 = '0'; % Stop
            q1 = '0'; % Wash port 1            
            value = strcat('0000', q1, q0);            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: ExchangeButton_2
        function ExchangeButton_2Pushed(app, event)
            % Remove plate information            
            ret = uiconfirm(app.DirectControlUIFigure,...
                'Remove plates before exchanging the sample needle. Click OK when ready.Click Cancel to abort.',...
                'Information', 'Icon', 'Info', 'Options', {'OK', 'Cancel'});

            if strcmp(ret, 'Cancel')
                return
            end
            
            % Start / Stop
            pfc = "5100";
            value = "000000"; % Stop Run or Initialize when Ready            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
            
            % Search Sample Position
            pfc = "5111";
            q4 = '0'; % Plate number (No plate)            
            q3 = '0'; % q3/q2/q1/q0 : 0109 (Tray Front)
            q2 = '1';
            q1 = '0';
            q0 = '9';
            value = strcat('0', q4, q3, q2, q1, q0); 
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
            
            % Needle Horizontal Movement
            pfc = "5136";
            q5 = '0'; % Zero
            q4 = '4'; % Position (Exchange) -> q3/q2/q1/q0 : 0000
            q3 = '0'; 
            q2 = '0';
            q1 = '0';
            q0 = '0';
            value = strcat(q5, q4, q3, q2, q1, q0); 
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
            
            % Needle Vertical Movement
            pfc = "5135";
            q0 = '2'; % Down without vial or plate detection
            value = strcat('00000', q0); 
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)

            % Reset Exchange information            
            ret = uiconfirm(app.DirectControlUIFigure,...
                'Please exchange the sample needle. Click OK when exchanged.',...
                'Information', 'Icon', 'Info', 'Options', {'OK'});
            
            pfc = "5100";
            value = "000000"; % Stop Run or Initialize when Ready            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)   
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
        end

        % Button pushed function: LogcountersButton
        function LogcountersButtonPushed(app, event)
            UI_Alias_DirectControl_LogCounters(app.MainApp)           
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % Start / Stop (Initialize)
            pfc = "5100";
            value = "000000"; % Stop Run or Initialize when Ready            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            DirectControlUIFigureCloseRequest(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DirectControlUIFigure = uifigure('Visible', 'off');
            app.DirectControlUIFigure.Name = 'Direct Control';
            app.DirectControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @DirectControlUIFigureCloseRequest, true);
            app.DirectControlPanel = uipanel(app.DirectControlUIFigure);
            app.DirectControlPanel.Title = 'Direct Control';
            app.PanelIO = uipanel(app.DirectControlPanel);
            app.InputsPanel = uipanel(app.PanelIO);
            app.InputsPanel.Title = 'Inputs';
            app.Input1Label = uilabel(app.InputsPanel);
            app.Input1Label.Text = 'Input 1:';
            app.Input2Label = uilabel(app.InputsPanel);
            app.Input2Label.Text = 'Input 2:';
            app.StatusInput1Label = uilabel(app.InputsPanel);
            app.StatusInput1Label.Text = 'High';
            app.StatusInput2Label = uilabel(app.InputsPanel);
            app.StatusInput2Label.Text = 'High';
            app.OutputButtonGroup = uibuttongroup(app.PanelIO);
            app.OutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputButtonGroupSelectionChanged, true);
            app.OutputButtonGroup.Title = 'Output';
            app.OnButton = uiradiobutton(app.OutputButtonGroup);
            app.OnButton.Text = 'On';
            app.OffButton = uiradiobutton(app.OutputButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.Panel_4 = uipanel(app.DirectControlPanel);
            app.SyringePanel = uipanel(app.Panel_4);
            app.SyringePanel.Title = 'Syringe';
            app.HomeButton = uibutton(app.SyringePanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.EndButton = uibutton(app.SyringePanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.ExchangeButton = uibutton(app.SyringePanel, 'push');
            app.ExchangeButton.ButtonPushedFcn = createCallbackFcn(app, @ExchangeButtonPushed, true);
            app.ExchangeButton.Text = 'Exchange';
            app.FilltransportreservoirPanel = uipanel(app.Panel_4);
            app.FilltransportreservoirPanel.Title = 'Fill transport reservoir';
            app.Spinner = uispinner(app.FilltransportreservoirPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.Value = 1;
            app.timesthesyringevolumeLabel = uilabel(app.FilltransportreservoirPanel);
            app.timesthesyringevolumeLabel.Text = 'time(s) the syringe volume';
            app.StartButton = uibutton(app.FilltransportreservoirPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SSVButtonGroup = uibuttongroup(app.Panel_4);
            app.SSVButtonGroup.Title = 'SSV';
            app.Solvent1Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent1Button.Enable = 'off';
            app.Solvent1Button.Text = 'Solvent 1';
            app.Solvent1Button.Value = true;
            app.Solvent2Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent2Button.Enable = 'off';
            app.Solvent2Button.Text = 'Solvent 2';
            app.Solvent3Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent3Button.Enable = 'off';
            app.Solvent3Button.Text = 'Solvent 3';
            app.Solvent4Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent4Button.Enable = 'off';
            app.Solvent4Button.Text = 'Solvent 4';
            app.Solvent5Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent5Button.Enable = 'off';
            app.Solvent5Button.Text = 'Solvent 5';
            app.Solvent6Button = uiradiobutton(app.SSVButtonGroup);
            app.Solvent6Button.Enable = 'off';
            app.Solvent6Button.Text = 'Solvent 6';
            app.InitialwashPanel = uipanel(app.Panel_4);
            app.InitialwashPanel.Title = 'Initial wash';
            app.StartButton_2 = uibutton(app.InitialwashPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton = uibutton(app.InitialwashPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Panel_5 = uipanel(app.DirectControlPanel);
            app.TrayPanel = uipanel(app.Panel_5);
            app.TrayPanel.Title = 'Tray';
            app.FrontButton = uibutton(app.TrayPanel, 'push');
            app.FrontButton.ButtonPushedFcn = createCallbackFcn(app, @FrontButtonPushed, true);
            app.FrontButton.Text = 'Front';
            app.TraycoolingPanel = uipanel(app.Panel_5);
            app.TraycoolingPanel.Title = 'Tray cooling';
            app.ButtonGroup = uibuttongroup(app.TraycoolingPanel);
            app.OnButton_2 = uiradiobutton(app.ButtonGroup);
            app.OnButton_2.Enable = 'off';
            app.OnButton_2.Text = 'On';
            app.OnButton_2.Value = true;
            app.OffButton_2 = uiradiobutton(app.ButtonGroup);
            app.OffButton_2.Enable = 'off';
            app.OffButton_2.Text = 'Off';
            app.SetpointLabel = uilabel(app.TraycoolingPanel);
            app.SetpointLabel.Enable = 'off';
            app.SetpointLabel.Text = 'Setpoint:';
            app.SetpointSpinner = uispinner(app.TraycoolingPanel);
            app.SetpointSpinner.Enable = 'off';
            app.SetpointSpinner.Value = 4;
            app.CLabel = uilabel(app.TraycoolingPanel);
            app.CLabel.Enable = 'off';
            app.CLabel.Text = 'ºC';
            app.CLabel_2 = uilabel(app.TraycoolingPanel);
            app.CLabel_2.Enable = 'off';
            app.CLabel_2.Text = 'ºC';
            app.ActualLabel = uilabel(app.TraycoolingPanel);
            app.ActualLabel.Enable = 'off';
            app.ActualLabel.Text = 'Actual:';
            app.Label = uilabel(app.TraycoolingPanel);
            app.Label.Enable = 'off';
            app.Label.Text = '..';
            app.ValvePanel = uipanel(app.DirectControlPanel);
            app.ValvePanel.Title = 'Valve';
            app.ValveButtonGroup = uibuttongroup(app.ValvePanel);
            app.ValveButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ValveButtonGroupSelectionChanged, true);
            app.LoadButton = uiradiobutton(app.ValveButtonGroup);
            app.LoadButton.Text = 'Load';
            app.InjectButton = uiradiobutton(app.ValveButtonGroup);
            app.InjectButton.Text = 'Inject';
            app.InjectButton.Value = true;
            app.NeedlePanel = uipanel(app.DirectControlPanel);
            app.NeedlePanel.Title = 'Needle';
            app.ExchangeButton_2 = uibutton(app.NeedlePanel, 'push');
            app.ExchangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExchangeButton_2Pushed, true);
            app.ExchangeButton_2.Text = 'Exchange';
            app.Panel_2 = uipanel(app.DirectControlUIFigure);
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status:';
            app.SoftwareversionLabel = uilabel(app.Panel_2);
            app.SoftwareversionLabel.Text = 'Software version:';
            app.SystembootIDLabel = uilabel(app.Panel_2);
            app.SystembootIDLabel.Text = 'System boot ID:';
            app.PCBversionLabel = uilabel(app.Panel_2);
            app.PCBversionLabel.Text = 'PCB version:';
            app.StatusStatusLabel = uilabel(app.Panel_2);
            app.StatusStatusLabel.Text = 'Idle';
            app.StatusSoftwareVersionLabel = uilabel(app.Panel_2);
            app.StatusSoftwareVersionLabel.Text = '0840.110-1.13';
            app.StatusSystemBootID = uilabel(app.Panel_2);
            app.StatusSystemBootID.Text = '0840.141-2.10';
            app.InformationPCBVersionLabel = uilabel(app.Panel_2);
            app.InformationPCBVersionLabel.Text = '0840.601-11';
            app.DoorLabel = uilabel(app.Panel_2);
            app.DoorLabel.Text = 'Door:';
            app.DoorStatusLabel = uilabel(app.Panel_2);
            app.DoorStatusLabel.Text = 'Closed';
            app.SerialnumberLabel = uilabel(app.Panel_2);
            app.SerialnumberLabel.Text = 'Serial number:';
            app.StatusSerialNumberLabel = uilabel(app.Panel_2);
            app.StatusSerialNumberLabel.Text = '111177';
            app.LogcountersButton = uibutton(app.Panel_2, 'push');
            app.LogcountersButton.ButtonPushedFcn = createCallbackFcn(app, @LogcountersButtonPushed, true);
            app.LogcountersButton.Text = 'Log counters';
            app.InitializeButton = uibutton(app.Panel_2, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.CloseButton = uibutton(app.DirectControlUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.HelpButton = uibutton(app.DirectControlUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.DirectControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Alias_DirectControl(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DirectControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DirectControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DirectControlUIFigure)
        end
    end
end