classdef UI_Alias_DirectControl_LogCounters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogcountsAliasUIFigure  matlab.ui.Figure
        HelpButton              matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = public)
        MainApp; % MainApp
    end
    
    methods (Access = private)
        
        function RefreshCountLog(app)   
            
            % Countlog Injector valve
            pfc = "1001";            
            value = strcat('000600'); 
            rx1 = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            
            % Counterlog Syringe valve
            pfc = "1001";            
            value = strcat('000603'); 
            rx2 = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            
            % Counterlog Syringe
            pfc = "1001";            
            value = strcat('000604'); 
            rx3 = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            
            tableData = {false, 'Injector valve', str2double(rx1(end-5:end));
                false, 'Syringe valve', str2double(rx2(end-5:end));
                false, 'Syringe', str2double(rx3(end-5:end))};
            app.UITable.Data = tableData;
            
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            
            RefreshCountLog(app);           
            
            app.UITable.ColumnEditable = [true, false, false];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            pfc = '0640';            
            value = '002222';
                        
            for i = 1:size(app.UITable.Data, 1)
                if app.UITable.Data{i, 1}
                    value(3+i) = '0';                    
                end
            end
            disp(value)
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)         
            
            RefreshCountLog(app);     
        end

        % Close request function: LogcountsAliasUIFigure
        function LogcountsAliasUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            LogcountsAliasUIFigureCloseRequest(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LogcountsAliasUIFigure = uifigure('Visible', 'off');
            app.LogcountsAliasUIFigure.Name = 'Log counts Alias';
            app.LogcountsAliasUIFigure.CloseRequestFcn = createCallbackFcn(app, @LogcountsAliasUIFigureCloseRequest, true);
            app.UITable = uitable(app.LogcountsAliasUIFigure);
            app.UITable.ColumnName = {'Check'; 'Counter'; 'Number'};
            app.UITable.RowName = {};
            app.ClearButton = uibutton(app.LogcountsAliasUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CancelButton = uibutton(app.LogcountsAliasUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.LogcountsAliasUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.LogcountsAliasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Alias_DirectControl_LogCounters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LogcountsAliasUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.LogcountsAliasUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogcountsAliasUIFigure)
        end
    end
end