classdef UI_Alias_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingsUIFigure             matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        ProgrammableIOPanel          matlab.ui.container.Panel
        OutputButtonGroup            matlab.ui.container.ButtonGroup
        AlarmButton                  matlab.ui.control.RadioButton
        Auxiliary1Button             matlab.ui.control.RadioButton
        InjectMarkerButton           matlab.ui.control.RadioButton
        Input2ButtonGroup            matlab.ui.container.ButtonGroup
        StopButton_2                 matlab.ui.control.RadioButton
        FreezeButton_2               matlab.ui.control.RadioButton
        NextInjectionButton_2        matlab.ui.control.RadioButton
        Input1ButtonGroup            matlab.ui.container.ButtonGroup
        StopButton                   matlab.ui.control.RadioButton
        FreezeButton                 matlab.ui.control.RadioButton
        NextInjectionButton          matlab.ui.control.RadioButton
        FlowpathPanel                matlab.ui.container.Panel
        NeedlevolumeLEditField       matlab.ui.control.NumericEditField
        NeedlevolumeLEditFieldLabel  matlab.ui.control.Label
        LoopvolumeLEditField         matlab.ui.control.NumericEditField
        LoopvolumeLEditFieldLabel    matlab.ui.control.Label
        SyringevolumeLDropDown       matlab.ui.control.DropDown
        SyringevolumeLDropDownLabel  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        ResetoutputafterlastseriesLabel  matlab.ui.control.Label
        PrepmodeLabel                matlab.ui.control.Label
        ResetOutputCheckBox          matlab.ui.control.CheckBox
        PrepModeCheckBox             matlab.ui.control.CheckBox
        FreezelevelDropDown          matlab.ui.control.DropDown
        FreezelevelDropDownLabel     matlab.ui.control.Label
        NextInjectionActiveEdgeDropDown  matlab.ui.control.DropDown
        NextInjectionActiveEdgeDropDownLabel  matlab.ui.control.Label
        InjectMarkerPulsesEditField  matlab.ui.control.NumericEditField
        InjectMarkerPulsesEditFieldLabel  matlab.ui.control.Label
        TypeoftrayPanel              matlab.ui.container.Panel
        DirectionButtonGroup         matlab.ui.container.ButtonGroup
        ColumnButton                 matlab.ui.control.RadioButton
        RowButton                    matlab.ui.control.RadioButton
        RightplateDropDown           matlab.ui.control.DropDown
        RightplateDropDownLabel      matlab.ui.control.Label
        LeftplateDropDown            matlab.ui.control.DropDown
        LeftplateDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % MainApp
    end
    
    properties (Access = private)
        prevSetting = struct; % previous settings
        newSetting = struct;
    end
    
    methods (Access = private)
        
        function setting = SaveCurrentSetting(app)
            
            setting.LeftplateDropDown.Value = app.LeftplateDropDown.Value;
            setting.LeftplateDropDown.Value = app.RightplateDropDown.Value;
            setting.RowButton.Value = app.RowButton.Value;
            setting.ColumnButton.Value = app.ColumnButton.Value;
            setting.InjectMarkerPulsesEditField.Value = app.InjectMarkerPulsesEditField.Value;
            setting.NextInjectionActiveEdgeDropDown.Value = app.NextInjectionActiveEdgeDropDown.Value;
            setting.FreezelevelDropDown.Value = app.FreezelevelDropDown.Value;
            setting.PrepModeCheckBox.Value = app.PrepModeCheckBox.Value;
            setting.ResetOutputCheckBox.Value = app.ResetOutputCheckBox.Value;
            setting.SyringevolumeLDropDown.Value = app.SyringevolumeLDropDown.Value;
            setting.LoopvolumeLEditField.Value = app.LoopvolumeLEditField.Value;
            setting.NeedlevolumeLEditField.Value = app.NeedlevolumeLEditField.Value;
            setting.Input1ButtonGroup.SelectedObject.Text = app.Input1ButtonGroup.SelectedObject.Text;
            setting.Input2ButtonGroup.SelectedObject.Text = app.Input2ButtonGroup.SelectedObject.Text;
            setting.SelectedObject.Text = app.OutputButtonGroup.SelectedObject.Text;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            
            uiDlg = uiprogressdlg(app.SettingsUIFigure, 'Title', 'Please Wait',...
                    'Message', 'Applying Previous Settings', 'Indeterminate', 'on');
            
            % Initialize Left plate DropDown items
            app.LeftplateDropDown.Items = {
                '96 Low';
                '96 High';
                '384 Low';
                '48 Vials';
                '12 Vials'
                };
            app.RightplateDropDown.Items = {
                '96 Low';
                '96 High';
                '384 Low';
                '48 Vials';
                '12 Vials'
                };
            
            % Initialize Sytringe volume dropdown items
            app.SyringevolumeLDropDown.Items = {
                '500';
                '2500';
                };
            
            % Initialize Inject Marker Pulses EditField
            app.NextInjectionActiveEdgeDropDown.Items = {
                'Falling';
                'Rising'
                };
            
            % Initialize Freeze level DropDown Items
            app.FreezelevelDropDown.Items = {
                'Low';
                'High'
                };
            
            % (0152) Status
            pfc = "1001";
            value = "000152";
            
            % (0198) Use Prep Mode
            pfc = "1000";
            value = "000198";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);  
            
            if strcmp(rx(end), '0') % No
                app.PrepModeCheckBox.Value = 0;
            elseif strcmp(rx(end), '1') % Yes
                app.PrepModeCheckBox.Value = 1;
            end
            
            % (0200) Tray Segment setting
            pfc = "1000";
            value = "000200";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q3 = rx(end-3);
            q2 = rx(end-2);
            q1 = rx(end-1);
            q0 = rx(end);
            
            plates = {'96 Low', '96 High', '384 Low', '48 Vials', '12 Vials'};
            
            if strcmp(q3, '0') && strcmp(q2, '0')
                app.LeftplateDropDown.Value = plates{str2double(q1)+1};
                app.RightplateDropDown.Value = plates{str2double(q0)+1};
            elseif strcmp(q3, '3') && strcmp(q2, '3')
                disp('Spark single plate')
            end
            
            % (0125) Syringe Volume
            pfc = "1000";
            value = "000125";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q3 = rx(end-3);
            q2 = rx(end-2);
            q1 = rx(end-1);
            q0 = rx(end);
            
            syringeVolume = str2double(strcat(q3, q2, q1, q0));
            syringeVolumeStr = num2str(syringeVolume);            
            app.SyringevolumeLDropDown.Value = syringeVolumeStr;
            
            % (0107) Loop Volume
            pfc = "1000";
            value = "000107";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q3 = rx(end-3);
            q2 = rx(end-2);
            q1 = rx(end-1);
            q0 = rx(end);
            
            loopVolume = str2double(strcat(q3, q2, q1, q0));            
            app.LoopvolumeLEditField.Value = loopVolume;  
            
            % (0126) Tubing volume (needle <-> valve)
            pfc = "1000";
            value = "000126";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q2 = rx(end-2);
            q1 = rx(end-1);
            q0 = rx(end);
            
            loopVolume = str2double(strcat(q2, q1, q0));            
            app.LoopvolumeLEditField.Value = loopVolume;                        
            
            % (0201) Process Plate in row or column
            pfc = "1000";
            value = "000201";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q0 = rx(end);
            
            if strcmp(q0, '1')
                app.RowButton.Value = 1;
            elseif strcmp(q0, '2')
                app.ColumnButton.Value = 1;
            end
            
            % (0189) Programmable Inputs / Outputs
            pfc = "1000";
            value = "000189";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);  
            q2 = rx(end-2);
            q1 = rx(end-1);
            q0 = rx(end);
            
            % Output (relay)
            if strcmp(q0, '0')
                app.InjectMarkerButton.Value = 1;
            elseif strcmp(q0, '1')
                app.Auxiliary1Button.Value = 1;
            elseif strcmp(q0, '2')
                app.AlarmButton.Value = 1;
            end
            
            % Input 1
            if strcmp(q1, '0')
                app.NextInjectionButton.Value = 1;
            elseif strcmp(q1, '1')
                app.FreezeButton.Value = 1;
            elseif strcmp(q1, '2')
                app.StopButton.Value = 1;
            end
            
            % Input 2
            if strcmp(q2, '0')
                app.NextInjectionButton_2.Value = 1;
            elseif strcmp(q2, '1')
                app.FreezeButton_2.Value = 1;
            elseif strcmp(q2, '2')
                app.StopButton_2.Value = 1;
            end
            
            % (0187) Inject Marker Pulse
            pfc = "1000";
            value = "000187";            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);              
            q1 = rx(end-1);
            q0 = rx(end);
            
            injectionMarkerPulse = str2double(strcat(q1, '.', q0));
            app.InjectMarkerPulsesEditField.Value = injectionMarkerPulse;
            
            % (0188) Next Injection Active Edge
            pfc = "1000";
            value = "000188";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);  
            q0 = rx(end);
            
            if strcmp(q0, '0')
                app.NextInjectionActiveEdgeDropDown.Value = 'Falling';
            elseif strcmp(q0, '1')
                app.NextInjectionActiveEdgeDropDown.Value = 'Rising';
            end
            
            % (0202) Freeze Input Active Level
            pfc = "1000";
            value = "000202";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);                          
            q0 = rx(end);
            
            if strcmp(q0, '0')
                app.FreezelevelDropDown.Value = 'Low';
            elseif strcmp(q0, '1')
                app.FreezelevelDropDown.Value = 'High';
            end
            
            % (0195) Reset outputs after last series
            pfc = "1000";
            value = "000195";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            q0 = rx(end);
            
            app.ResetOutputCheckBox.Value = str2double(q0);                
            app.prevSetting = SaveCurrentSetting(app);
            
            close(uiDlg)
        end

        % Value changed function: PrepModeCheckBox
        function PrepModeCheckBoxValueChanged(app, event)
            if app.PrepModeCheckBox.Value == 1
                app.SyringevolumeLDropDown.Value = '2500';
                app.LoopvolumeLEditField.Limits = [0, 10000];
                app.LoopvolumeLEditField.Value = 10000;
                app.LoopvolumeLEditField.Enable = 'off';
                app.NeedlevolumeLEditField.Value = 60;
                app.LeftplateDropDown.Value = '12 Vials';
                app.LeftplateDropDown.Enable = 'off';
                app.RightplateDropDown.Value = '12 Vials';
                app.RightplateDropDown.Enable = 'off';
            else
                app.SyringevolumeLDropDown.Value = '500';
                app.LoopvolumeLEditField.Limits = [0, 5000];
                app.LoopvolumeLEditField.Value = 100;
                app.LoopvolumeLEditField.Enable = 'on';
                app.NeedlevolumeLEditField.Value = 15;                
                app.LeftplateDropDown.Enable = 'on';                
                app.RightplateDropDown.Enable = 'on';                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            SettingsUIFigureCloseRequest(app, [])
        end

        % Close request function: SettingsUIFigure
        function SettingsUIFigureCloseRequest(app, event)
            app.newSetting = SaveCurrentSetting(app);
            
            if isequal(app.newSetting, app.prevSetting)
                delete(app)
                return
            end
                
            ret = uiconfirm(app.SettingsUIFigure,...
                'Settings have been changed. Program the Alias with the new settings?',...
                'Confirm', 'Icon', 'question', 'Options', {'Yes', 'No', 'Cancel'}); 
            
            if strcmp(ret, 'Yes')
            % Apply new settings
                uiDlg = uiprogressdlg(app.SettingsUIFigure, 'Title', 'Please Wait',...
                    'Message', 'Applying New Settings', 'Indeterminate', 'on');
                
                % (0198) Use Prep Mode
                pfc = "0198";
                q0 = num2str(app.PrepModeCheckBox.Value);
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);  
                
                % (0200) Tray Segment setting
                pfc = "0200";
                plates = {'96 Low', '96 High', '384 Low', '48 Vials', '12 Vials'};
                
                idxLeft = find(strcmp(plates, app.LeftplateDropDown.Value));
                q1 = num2str(idxLeft - 1);
                idxRight = find(strcmp(plates, app.RightplateDropDown.Value));
                q0 = num2str(idxRight - 1);
                value = strcat("0000", q1, q0); % q3/q2 : "00" (Separate plates)                
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0125) Syringe Volume
                pfc = "0125";
                syringeVolume = sprintf('%04.0f', str2double(app.SyringevolumeLDropDown.Value));
                value = strcat("00", syringeVolume);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                %%%% (0107) Loop Volume
                pfc = "0107";
                loopVolume = sprintf('%04.0f', app.LoopvolumeLEditField.Value);
                value = strcat("00", loopVolume);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                %%%% (0126) Tubing volume (needle <-> valve)
                pfc = "0126";
                tubingVolume = sprintf('%03.0f', app.NeedlevolumeLEditField.Value);
                value = strcat("000", tubingVolume);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0201) Process Plate in row or column
                pfc = "0201";
                q0 = num2str(app.ColumnButton.Value + 1);
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0189) Programmable Inputs / Outputs
                pfc = "0189";
                % Output
                if strcmp(app.OutputButtonGroup.SelectedObject.Text, 'Inject Marker')
                    q0 = '0';
                elseif strcmp(app.OutputButtonGroup.SelectedObject.Text, 'Auxiliary 1')
                    q0 = '1';
                elseif strcmp(app.OutputButtonGroup.SelectedObject.Text, 'Alarm')
                    q0 = '2';
                end
                
                % Input 1
                if strcmp(app.Input1ButtonGroup.SelectedObject.Text, 'Next Injection')
                    q1 = '0';
                elseif strcmp(app.Input1ButtonGroup.SelectedObject.Text, 'Freeze')
                    q1 = '1';
                elseif strcmp(app.Input1ButtonGroup.SelectedObject.Text, 'Stop')
                    q1 = '2';
                end
                
                % Input 2
                if strcmp(app.Input2ButtonGroup.SelectedObject.Text, 'Next Injection')
                    q2 = '0';
                elseif strcmp(app.Input2ButtonGroup.SelectedObject.Text, 'Freeze')
                    q2 = '1';
                elseif strcmp(app.Input2ButtonGroup.SelectedObject.Text, 'Stop')
                    q2 = '2';
                end
                
                value = strcat("000", q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                %%%%% (0187) Inject Marker Pulse
                pfc = "0187";
                injectMarkerPulse = sprintf('%1.1f', app.InjectMarkerPulsesEditField.Value);
                q1 = injectMarkerPulse(1);
                q0 = injectMarkerPulse(3);
                
                value = strcat("0000", q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0188) Next Injection Active Edge
                pfc = "0188";
                if strcmp(app.NextInjectionActiveEdgeDropDown.Value, 'Falling')
                    q0 = '0';
                elseif strcmp(app.NextInjectionActiveEdgeDropDown.Value, 'Rising')
                    q0 = '1';
                end
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0202) Freeze Input Active Level
                pfc = "0202";
                if strcmp(app.FreezelevelDropDown.Value, 'Low')
                    q0 = '0';
                elseif strcmp(app.FreezelevelDropDown.Value, 'High')
                    q0 = '1';
                end
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                % (0195) Reset outputs after last series
                pfc = "0195";
                q0 = num2str(app.ResetOutputCheckBox.Value);
                
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                
                close(uiDlg)                
                delete(app)
            elseif strcmp(ret, 'No')
                delete(app)
            elseif strcmp(ret, 'Cancel')
                return
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingsUIFigure = uifigure('Visible', 'off');
            app.SettingsUIFigure.Name = 'Settings';
            app.SettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SettingsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.SettingsUIFigure);
            app.TypeoftrayPanel = uipanel(app.Panel);
            app.TypeoftrayPanel.Title = 'Type of tray';
            app.LeftplateDropDownLabel = uilabel(app.TypeoftrayPanel);
            app.LeftplateDropDownLabel.Text = 'Left plate:';
            app.LeftplateDropDown = uidropdown(app.TypeoftrayPanel);
            app.LeftplateDropDown.Items = {'48 Vials', 'Option 2', 'Option 3', 'Option 4'};
            app.LeftplateDropDown.Value = '48 Vials';
            app.RightplateDropDownLabel = uilabel(app.TypeoftrayPanel);
            app.RightplateDropDownLabel.Text = 'Right plate:';
            app.RightplateDropDown = uidropdown(app.TypeoftrayPanel);
            app.RightplateDropDown.Items = {'48 Vials', 'Option 2', 'Option 3', 'Option 4'};
            app.RightplateDropDown.Value = '48 Vials';
            app.DirectionButtonGroup = uibuttongroup(app.TypeoftrayPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.RowButton = uiradiobutton(app.DirectionButtonGroup);
            app.RowButton.Text = '↑ →';
            app.RowButton.Value = true;
            app.ColumnButton = uiradiobutton(app.DirectionButtonGroup);
            app.ColumnButton.Text = '→ ↑';
            app.Panel_3 = uipanel(app.Panel);
            app.InjectMarkerPulsesEditFieldLabel = uilabel(app.Panel_3);
            app.InjectMarkerPulsesEditFieldLabel.Text = 'Inject Marker Pulse (s):';
            app.InjectMarkerPulsesEditField = uieditfield(app.Panel_3, 'numeric');
            app.InjectMarkerPulsesEditField.Limits = [0.1 2];
            app.InjectMarkerPulsesEditField.ValueDisplayFormat = '%.1f';
            app.InjectMarkerPulsesEditField.Value = 1;
            app.NextInjectionActiveEdgeDropDownLabel = uilabel(app.Panel_3);
            app.NextInjectionActiveEdgeDropDownLabel.Text = 'Next Injection Active Edge:';
            app.NextInjectionActiveEdgeDropDown = uidropdown(app.Panel_3);
            app.NextInjectionActiveEdgeDropDown.Items = {'Falling', 'Option 2', 'Option 3', 'Option 4'};
            app.NextInjectionActiveEdgeDropDown.Value = 'Falling';
            app.FreezelevelDropDownLabel = uilabel(app.Panel_3);
            app.FreezelevelDropDownLabel.Text = 'Freeze level:';
            app.FreezelevelDropDown = uidropdown(app.Panel_3);
            app.FreezelevelDropDown.Items = {'Low', 'Option 2', 'Option 3', 'Option 4'};
            app.FreezelevelDropDown.Value = 'Low';
            app.PrepModeCheckBox = uicheckbox(app.Panel_3);
            app.PrepModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrepModeCheckBoxValueChanged, true);
            app.PrepModeCheckBox.Text = '';
            app.ResetOutputCheckBox = uicheckbox(app.Panel_3);
            app.ResetOutputCheckBox.Text = '';
            app.PrepmodeLabel = uilabel(app.Panel_3);
            app.PrepmodeLabel.Text = 'Prep mode';
            app.ResetoutputafterlastseriesLabel = uilabel(app.Panel_3);
            app.ResetoutputafterlastseriesLabel.Text = 'Reset output after last series';
            app.FlowpathPanel = uipanel(app.Panel);
            app.FlowpathPanel.Title = 'Flowpath';
            app.SyringevolumeLDropDownLabel = uilabel(app.FlowpathPanel);
            app.SyringevolumeLDropDownLabel.Text = 'Syringe volume (μL):';
            app.SyringevolumeLDropDown = uidropdown(app.FlowpathPanel);
            app.SyringevolumeLDropDown.Items = {'500', '2500'};
            app.SyringevolumeLDropDown.Enable = 'off';
            app.SyringevolumeLDropDown.Value = '500';
            app.LoopvolumeLEditFieldLabel = uilabel(app.FlowpathPanel);
            app.LoopvolumeLEditFieldLabel.Text = 'Loop volume (μL):';
            app.LoopvolumeLEditField = uieditfield(app.FlowpathPanel, 'numeric');
            app.LoopvolumeLEditField.Limits = [0 5000];
            app.LoopvolumeLEditField.ValueDisplayFormat = '%.0f';
            app.LoopvolumeLEditField.Value = 1000;
            app.NeedlevolumeLEditFieldLabel = uilabel(app.FlowpathPanel);
            app.NeedlevolumeLEditFieldLabel.Text = 'Needle volume (μL):';
            app.NeedlevolumeLEditField = uieditfield(app.FlowpathPanel, 'numeric');
            app.NeedlevolumeLEditField.Limits = [0 999];
            app.NeedlevolumeLEditField.ValueDisplayFormat = '%.0f';
            app.NeedlevolumeLEditField.Value = 15;
            app.ProgrammableIOPanel = uipanel(app.Panel);
            app.ProgrammableIOPanel.Title = 'Programmable I/O';
            app.Input1ButtonGroup = uibuttongroup(app.ProgrammableIOPanel);
            app.Input1ButtonGroup.Title = 'Input 1';
            app.NextInjectionButton = uiradiobutton(app.Input1ButtonGroup);
            app.NextInjectionButton.Text = 'Next Injection';
            app.NextInjectionButton.Value = true;
            app.FreezeButton = uiradiobutton(app.Input1ButtonGroup);
            app.FreezeButton.Text = 'Freeze';
            app.StopButton = uiradiobutton(app.Input1ButtonGroup);
            app.StopButton.Text = 'Stop';
            app.Input2ButtonGroup = uibuttongroup(app.ProgrammableIOPanel);
            app.Input2ButtonGroup.Title = 'Input 2';
            app.NextInjectionButton_2 = uiradiobutton(app.Input2ButtonGroup);
            app.NextInjectionButton_2.Text = 'Next Injection';
            app.NextInjectionButton_2.Value = true;
            app.FreezeButton_2 = uiradiobutton(app.Input2ButtonGroup);
            app.FreezeButton_2.Text = 'Freeze';
            app.StopButton_2 = uiradiobutton(app.Input2ButtonGroup);
            app.StopButton_2.Text = 'Stop';
            app.OutputButtonGroup = uibuttongroup(app.ProgrammableIOPanel);
            app.OutputButtonGroup.Title = 'Output';
            app.InjectMarkerButton = uiradiobutton(app.OutputButtonGroup);
            app.InjectMarkerButton.Text = 'Inject Marker';
            app.InjectMarkerButton.Value = true;
            app.Auxiliary1Button = uiradiobutton(app.OutputButtonGroup);
            app.Auxiliary1Button.Text = 'Auxiliary 1';
            app.AlarmButton = uiradiobutton(app.OutputButtonGroup);
            app.AlarmButton.Text = 'Alarm';
            app.CloseButton = uibutton(app.SettingsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.HelpButton = uibutton(app.SettingsUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.SettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Alias_Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingsUIFigure)
        end
    end
end