classdef UI_Alias_iMSPR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iMSPRUIFigure                   matlab.ui.Figure
        TrayFrontButton                 matlab.ui.control.Button
        InitializeButton                matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        InitialWashLabel                matlab.ui.control.Label
        InitialWashCheckBox             matlab.ui.control.CheckBox
        NeedleHeightDropDown            matlab.ui.control.DropDown
        NeedleHeightLabel               matlab.ui.control.Label
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        ColumnButton                    matlab.ui.control.RadioButton
        RowButton                       matlab.ui.control.RadioButton
        RightplateDropDown              matlab.ui.control.DropDown
        RightplateDropDownLabel         matlab.ui.control.Label
        LeftplateDropDown               matlab.ui.control.DropDown
        LeftplateDropDownLabel          matlab.ui.control.Label
        SamplesPanel                    matlab.ui.container.Panel
        StabilizationTimesEditField     matlab.ui.control.NumericEditField
        StabilizationTimesEditFieldLabel  matlab.ui.control.Label
        InjectionTimesEditField         matlab.ui.control.NumericEditField
        InjectionTimesEditFieldLabel    matlab.ui.control.Label
        AsperateVolumeuLEditField       matlab.ui.control.NumericEditField
        AsperateVolumeuLEditFieldLabel  matlab.ui.control.Label
        NumberofInjectionsSpinner       matlab.ui.control.Spinner
        NumberofInjectionsLabel         matlab.ui.control.Label
        LastSampleDropDown              matlab.ui.control.DropDown
        LastSampleLabel                 matlab.ui.control.Label
        FirstSampleDropDown             matlab.ui.control.DropDown
        FirstSampleLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    methods (Access = private)
        
        function TraySegmentDropdownItems(app, rxTraySegmentSetting)

            % q3
            if strcmp(rxTraySegmentSetting(end-3), '0')                
                disp('Spark separate left plate')
            elseif strcmp(rxTraySegmentSetting(end-3), '3')
                disp('Spark single plate')
            else
                disp('Reserved')
            end
            % q2
            if strcmp(rxTraySegmentSetting(end-2), '0')
                disp('Spark separate right plate')
            elseif strcmp(rxTraySegmentSetting(end-2), '3')
                disp('Spark single plate')
            else
                disp('Reserved')
            end
        
            if strcmp(rxTraySegmentSetting(end-3), '0') && strcmp(rxTraySegmentSetting(end-2), '0')                    
                % q1 (Left)
                if strcmp(rxTraySegmentSetting(end-1), '0')
                    leftPlate = '96 Low';
                    disp(leftPlate)
                    width = 8;
                    height = 12;
                elseif strcmp(rxTraySegmentSetting(end-1), '1')
                    leftPlate = '96 High';
                    disp(leftPlate)
                    width = 8;
                    height = 12;
                elseif strcmp(rxTraySegmentSetting(end-1), '2')
                    leftPlate = '384 Low';
                    disp(leftPlate)
                    width = 16;
                    height = 24;
                elseif strcmp(rxTraySegmentSetting(end-1), '3')
                    leftPlate = '48 Vials';
                    disp(leftPlate)                    
                    width = 6;
                    height = 8;
                elseif strcmp(rxTraySegmentSetting(end-1), '4')
                    leftPlate = '12 Vials';
                    disp(leftPlate)                              
                    width = 3;
                    height = 4;
                end 
                    
                leftPositions = MakePositionLetters('Left', width, height);
        
                %q0 (Right)
                if strcmp(rxTraySegmentSetting(end), '0')                    
                    rightPlate = '96 Low';
                    disp(rightPlate)                    
                    width = 8;
                    height = 12;
                elseif strcmp(rxTraySegmentSetting(end), '1')
                    rightPlate = '96 High';
                    disp(rightPlate)                    
                    width = 8;
                    height = 12;
                elseif strcmp(rxTraySegmentSetting(end), '2')
                    rightPlate = '384 Low';
                    disp(rightPlate)                    
                    width = 16;
                    height = 24;
                elseif strcmp(rxTraySegmentSetting(end), '3')
                    rightPlate = '48 Vials';
                    disp(rightPlate)                    
                    width = 6;
                    height = 8;
                elseif strcmp(rxTraySegmentSetting(end), '4')
                    rightPlate = '12 Vials';
                    disp(rightPlate)                              
                    width = 3;
                    height = 4;
                end         
                                
                rightPositions = MakePositionLetters('Right', width, height);
                positions = [leftPositions; rightPositions];
        
            elseif strcmp(rxTraySegmentSetting(end-3), '3') && strcmp(rxTraySegmentSetting(end-2), '3')                
                if strcmp(rxTraySegmentSetting(end-1:end), '00')
                    disp('84+3 Vials')
                elseif strcmp(rxTraySegmentSetting(end-1:end), '11')
                    disp('108 Vials')
                elseif strcmp(rxTraySegmentSetting(end-1:end), '22')
                    disp('30 Vials')
                end
        
                positions = {};
            end
                   
            app.FirstSampleDropDown.Items = positions;
            app.LastSampleDropDown.Items = positions;
            app.FirstSampleDropDown.Value = 'Right A1';
            app.LastSampleDropDown.Value = 'Right A3';
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            
            uiDlg = uiprogressdlg(app.iMSPRUIFigure, 'Title', 'Please Wait',...
                    'Message', 'Checking the connection', 'Indeterminate', 'on');
            
            % Initialize Left plate DropDown items
            app.LeftplateDropDown.Items = {
                '96 Low';
                '96 High';
                '384 Low';
                '48 Vials';
                '12 Vials'
                };
            app.RightplateDropDown.Items = {
                '96 Low';
                '96 High';
                '384 Low';
                '48 Vials';
                '12 Vials'
                };
            
            try
            
                % (0200) Tray Segment setting
                pfc = "1000";
                value = "000200";
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
                q3 = rx(end-3);
                q2 = rx(end-2);
                q1 = rx(end-1);
                q0 = rx(end);
                
                plates = {'96 Low', '96 High', '384 Low', '48 Vials', '12 Vials'};
                
                if strcmp(q3, '0') && strcmp(q2, '0')
                    app.LeftplateDropDown.Value = plates{str2double(q1)+1};
                    app.RightplateDropDown.Value = plates{str2double(q0)+1};
                elseif strcmp(q3, '3') && strcmp(q2, '3')
                    disp('Spark single plate')
                end
                
                % Set Dropdown items (First, Last Sample)
                pfcInformation = "1000";
                valueTraySegmentSetting = "000200"; % 1000000200 : Tray Segment Setting                 
                rxTraySegmentSetting =...
                        AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfcInformation, valueTraySegmentSetting);
                TraySegmentDropdownItems(app, rxTraySegmentSetting)     
                
                % Initialize NeedleHeightDropDown items
                tmpMat = [2:0.5:6]';
                tmpCell = cell(size(tmpMat));
                
                for i = 1:size(tmpMat, 1)
                    
                    tmpCell{i, 1} = num2str(tmpMat(i, 1), '%.1f');
                    
                end                        
                app.NeedleHeightDropDown.Items = tmpCell;
                app.NeedleHeightDropDown.Value = '2.0';
            catch                
                close(uiDlg)
                ret = uiconfirm(app.iMSPRUIFigure,...
                    'Check the connection!',...
                    'Error', 'Icon', 'error', 'Options', {'OK'});               

                iMSPRUIFigureCloseRequest(app, [])                
            end
        end

        % Value changed function: LeftplateDropDown
        function LeftplateDropDownValueChanged(app, event)
            % (0200) Tray Segment setting
            pfc = "0200";
            plates = {'96 Low', '96 High', '384 Low', '48 Vials', '12 Vials'};
            
            idxLeft = find(strcmp(plates, app.LeftplateDropDown.Value));
            q1 = num2str(idxLeft - 1);
            idxRight = find(strcmp(plates, app.RightplateDropDown.Value));
            q0 = num2str(idxRight - 1);
            value = strcat("0000", q1, q0); % q3/q2 : "00" (Separate plates)                
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            tStart = 0;
            fprintf('Start Running at : %d sec\n', tStart)
            tRunning = tic;            
            
            portDevice = app.MainApp.PortDevice;
            timeOut = 60;
            
            %% 1. Ready
            rxInitialize = "61010152000310";
            
            % (0200) Tray Segment setting
            pfc = "0200";
            plates = {'96 Low', '96 High', '384 Low', '48 Vials', '12 Vials'};
            
            idxLeft = find(strcmp(plates, app.LeftplateDropDown.Value));
            q1 = num2str(idxLeft - 1);
            idxRight = find(strcmp(plates, app.RightplateDropDown.Value));
            q0 = num2str(idxRight - 1);
            value = strcat("0000", q1, q0); % q3/q2 : "00" (Separate plates)                
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            
            % (0130) Sample needle height : 6 0 (6.0 mm with increments of 0.5 mm)
            pfc = "0130";            
            q1 = app.NeedleHeightDropDown.Value(1);
            q0 = app.NeedleHeightDropDown.Value(3);
            value = strcat("0000", q1, q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % Initial Washing
            if app.InitialWashCheckBox.Value == 1            
                pfc = "5130";
                value = "000001";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                washTimeOut = 300;
                rx = WaitUntilWantedRX(portDevice, [], washTimeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            end
            
            firstIdx = find(strcmp(app.FirstSampleDropDown.Items, app.FirstSampleDropDown.Value));
            lastIdx = find(strcmp(app.FirstSampleDropDown.Items, app.LastSampleDropDown.Value));
            
            traySegmentCell = cell(size(app.FirstSampleDropDown.Items))';
            for i = 1:size(app.FirstSampleDropDown.Items, 2)
                tmpMat = zeros(1, 3);
                if strcmp(app.FirstSampleDropDown.Items{1, i}(1), 'L')
                    tmpMat(1) = 1;
                elseif strcmp(app.FirstSampleDropDown.Items{1, i}(1), 'R')
                    tmpMat(1) = 2;                    
                end
                splitStr = split(app.FirstSampleDropDown.Items{1, i});
                rowLetter = splitStr{2}(1);                
                tmpMat(2) = double(rowLetter) - double('A') + 1; % Row Number
                tmpMat(3) = str2double(splitStr{2}(2)); % Column Number                
                traySegmentCell{i, 1} = tmpMat;
            end
            
            targetTraySegmentCell = traySegmentCell(firstIdx:lastIdx, :);
            
            for i = 1:size(targetTraySegmentCell, 1)
                % Repeat NumberofInjectionsSpinner.Value times.
                for repeatNo = 1:app.NumberofInjectionsSpinner.Value
                    
                    % Sample Loop Wash before every injection
                    % 1. Needle Vertical : Up
                    pfc = "5135";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 2. Needle Horizontal : Waste
                    pfc = "5136";
                    value = "020000"; % Waste
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 3. Needle Vertical : Down
                    pfc = "5135";
                    value = "000001"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 4. Syringe Valve : Wash Port 1 (Running buffer)
                    pfc = "5137";
                    value = "000001"; % Wash port 1
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 5. Asperate (200 + dead Vol const uL)
                    pfc = "5138";                
                    q2q1q0 = '200';
                    value = strcat('000', q2q1q0);
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 6. Syringe Valve : Needle
                    pfc = "5137";
                    value = "000002";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 7. Injection Valve : Load
                    pfc = "5105";
                    value = "000001";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 8. Dispense (200 + dead Vol const uL)
                    pfc = "5140";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);

                    if strcmp(rx, rxInitialize)
                        return
                    end   
                    
                    % 9. Needle Vertical : Up
                    pfc = "5135";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Sample Injection
                    % 2. Search sample position (Tray move : Vertical Position)            
                    pfc = "5111";
                    if targetTraySegmentCell{i, 1}(1) == 1 % Left
                        q4 = '1';
                    elseif targetTraySegmentCell{i, 1}(1) == 2 % Right
                        q4 = '2';
                    end
                    
                    q3q2q1q0 = sprintf('%04.0f', targetTraySegmentCell{i, 1}(3)); % Column                
                    value = strcat('0', q4, q3q2q1q0); % Column Number    
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 3. Needle horizontal Move (Needle move : Horizontal Position)
                    pfc = "5136";
                    q4 = '3'; % Plate
                    if targetTraySegmentCell{i, 1}(1) == 1 % Left
                        q3q2 = '01';
                    elseif targetTraySegmentCell{i, 1}(1) == 2 % Right
                        q3q2 = '02';
                    end
                    
                    q1q0 = sprintf('%02.0f', targetTraySegmentCell{i, 1}(2)); % Row
                    value = strcat('0', q4, q3q2, q1q0); % Row Number    
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
%                      % 4. Needle vertical down
%                     pfc = "5135";
%                     value = "000001"; % Down
%                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
%                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
%                     
%                     if strcmp(rx, rxInitialize)
%                         return
%                     end
                    
                    % 5. Injection valve (Load)
                    pfc = "5105";
                    value = "000001"; % Load
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
%                 % Repeat NumberofInjectionsSpinner.Value times.
%                 for repeatNo = 1:app.NumberofInjectionsSpinner.Value
                
                    % 6. Syringe valve (Needle)
                    pfc = "5137";
                    value = "000002";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
% %                     % 6.5 Asperate air gap (5uL)
% %                     pfc = "5138";                
% %                     q2q1q0 = '001';
% %                     value = strcat('000', q2q1q0);
% %                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
% %                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
% %                     
% %                     if strcmp(rx, rxInitialize)
% %                         return
% %                     end
                    
                    
                    % 4. Needle vertical down
                    pfc = "5135";
                    value = "000001"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    % 7. Aspirate (sample volume + alpha)
                    pfc = "5138";                
                    q2q1q0 = sprintf('%03.0f', app.AsperateVolumeuLEditField.Value);
                    value = strcat('000', q2q1q0);
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 10. Needle vertical up
%                     pfc = "5135";
%                     value = "000000";
%                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
%                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
%                     
%                     if strcmp(rx, rxInitialize)
%                         return
%                     end
                    
                    % 6.5 Asperate air gap (20uL)
%                     pfc = "5138";                
%                     q2q1q0 = '020';
%                     value = strcat('000', q2q1q0);
%                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
%                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
%                     
%                     if strcmp(rx, rxInitialize)
%                         return
%                     end
                    
                    % 8. Injection valve (Inject)
                    pfc = "5105";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 9. Injection start Signal
                    tInject = toc(tRunning);
                    fprintf('Injection Start : %d sec\n', tInject);                  
    %                 if ~isempty(rx)                
    %                     app.LogCell{end+1, 1} = msg;         
    %                     app.LogTextArea.Value = flipud(app.LogCell);
    %                 end
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    pause(app.InjectionTimesEditField.Value)
                    
                    % 9.5. Injection valve (Load)
                    pfc = "5105";
                    value = "000001";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    tLoad = toc(tRunning);
                    fprintf('Injection Finished : %d sec\n', tLoad);                    
                    
                    % Discard Remaining sample in Syringe.
                    % Syringe Speed (High)
                    pfc = "0131";
                    value = "000003"; % High
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 1. Syringe valve (Waste)
                    pfc = "5137";
                    value = "000003"; % Waste
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 2. Move Syringe (Home)
                    pfc = "5140";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end                    
%                 end
            
                    % 10. Needle vertical up
                    pfc = "5135";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    % 11. Needle horizontal → waste
                    pfc = "5136";
                    value = "020000"; % Waste
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    % 12. Needle vertical down
                    pfc = "5135";
                    value = "000001"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 12.5. Injection valve (Inject)
%                     pfc = "5105";
%                     value = "000000";
%                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
%                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
%                     
%                     if strcmp(rx, rxInitialize)
%                         return
%                     end
                
                    % 13. Compressor on / off (5 sec)
                    pfc = "5134";
                    value = "000001"; % On
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    pause(5)
                
                    pfc = "5134";
                    value = "000000"; % Off
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Syringe Speed (High)
%                     pfc = "0131";
%                     value = "000003"; % High
%                     rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
%                     rx = WaitUntilWantedRX(portDevice, [], timeOut);
%                     
%                     if strcmp(rx, rxInitialize)
%                         return
%                     end
                    
    %                 % 13.5. Move Syringe (Home)
    %                 pfc = "5140";
    %                 value = "000000";
    %                 rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
    %                 rx = WaitUntilWantedRX(portDevice, [], timeOut);
    %                 
    %                 if strcmp(rx, rxInitialize)
    %                     return
    %                 end
    %             
                    % 14. Syringe valve (Wash port, Buffer)
                    pfc = "5137";
                    value = "000001"; % Wash port 1
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 15. Move Syringe (End)
                    pfc = "5140";
                    value = "000001";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    % 16. Syringe valve (Needle)
                    pfc = "5137";
                    value = "000002"; % Needle
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    % 17. Move Syringe (Home)
                    pfc = "5140";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 18. Needle vertical up
                    pfc = "5135";
                    value = "000000"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % 19. Needle Wash
                    % Needle move to needle wash port
                    pfc = "5136";
                    value = "010000"; % Wash
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Needle vertical down
                    pfc = "5135";
                    value = "000001"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Syringe valve Buffer (Wash Port 1)
                    pfc = "5137";
                    value = "000001"; % Wash port 1
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Asperate running buffer 500 ul
                    pfc = "5140";
                    value = "000001";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Syringe valve Needle
                    pfc = "5137";
                    value = "000002"; % Needle
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Dispense 500 ul
                    pfc = "5140";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Needle vertical up
                    pfc = "5135";
                    value = "000000"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Syringe valve waste
                    pfc = "5137";
                    value = "000003"; % Waste
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Asperate 200
                    pfc = "5138";                
                    q2q1q0 = '200';
                    value = strcat('000', q2q1q0);
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Disperate 200
                    pfc = "5140";
                    value = "000000";
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Needle move to wash port 1 (Tick)
                    pfc = "5136";
                    value = "020000"; % Waste
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Needle vertical down
                    pfc = "5135";
                    value = "000001"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Compressor 2 sec
                    pfc = "5134";
                    value = "000001"; % On
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                
                    pause(2)
                
                    pfc = "5134";
                    value = "000000"; % Off
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Needle vertical up
                    pfc = "5135";
                    value = "000000"; % Down
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    % Syringe speed (Normal)
                    pfc = "0131";
                    value = "000002"; % Normal
                    rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                    rx = WaitUntilWantedRX(portDevice, [], timeOut);
                    
                    if strcmp(rx, rxInitialize)
                        return
                    end
                    
                    tStabStart = toc(tRunning);
                    fprintf('Stabilization Start : %d sec\n', tStabStart);
                    
                    % Stabilization (After Washing)
                    pause(app.StabilizationTimesEditField.Value);
                    
                    tStabEnd = toc(tRunning);
                    fprintf('Stabilization End : %d sec\n', tStabEnd);
                end
                        
            end
            % 20. Initialize
            pfc = "5100";
            value = "000000";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            timeOut = 120;
            
            pfc = "5100";
            value = "000000";
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            rx = WaitUntilWantedRX(app.MainApp.PortDevice, [], timeOut);
            disp(rx)
            
%             if ~isempty(rx)                
%                 app.LogCell{end+1, 1} = rx;         
%                 app.LogTextArea.Value = flipud(app.LogCell);
%             end
        end

        % Button pushed function: TrayFrontButton
        function TrayFrontButtonPushed(app, event)
            pfc = "5111";
            value = "000109";            
            timeOut = 30;
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            rx = WaitUntilWantedRX(app.MainApp.PortDevice, [], timeOut);
            disp(rx)
        end

        % Close request function: iMSPRUIFigure
        function iMSPRUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            iMSPRUIFigureCloseRequest(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iMSPRUIFigure = uifigure('Visible', 'off');
            app.iMSPRUIFigure.Name = 'iMSPR';
            app.iMSPRUIFigure.CloseRequestFcn = createCallbackFcn(app, @iMSPRUIFigureCloseRequest, true);
            app.SamplesPanel = uipanel(app.iMSPRUIFigure);
            app.SamplesPanel.Title = 'Samples';
            app.FirstSampleLabel = uilabel(app.SamplesPanel);
            app.FirstSampleLabel.Text = 'First Sample:';
            app.FirstSampleDropDown = uidropdown(app.SamplesPanel);
            app.FirstSampleDropDown.Items = {'Left A1', 'Option 2', 'Option 3', 'Option 4'};
            app.FirstSampleDropDown.Value = 'Left A1';
            app.LastSampleLabel = uilabel(app.SamplesPanel);
            app.LastSampleLabel.Text = 'Last Sample:';
            app.LastSampleDropDown = uidropdown(app.SamplesPanel);
            app.LastSampleDropDown.Items = {'Left A3', 'Option 2', 'Option 3', 'Option 4'};
            app.LastSampleDropDown.Value = 'Left A3';
            app.NumberofInjectionsLabel = uilabel(app.SamplesPanel);
            app.NumberofInjectionsLabel.Text = 'Number of Injections:';
            app.NumberofInjectionsSpinner = uispinner(app.SamplesPanel);
            app.NumberofInjectionsSpinner.Limits = [1 9];
            app.NumberofInjectionsSpinner.Value = 1;
            app.AsperateVolumeuLEditFieldLabel = uilabel(app.SamplesPanel);
            app.AsperateVolumeuLEditFieldLabel.Text = 'Asperate Volume (uL):';
            app.AsperateVolumeuLEditField = uieditfield(app.SamplesPanel, 'numeric');
            app.AsperateVolumeuLEditField.Value = 50;
            app.InjectionTimesEditFieldLabel = uilabel(app.SamplesPanel);
            app.InjectionTimesEditFieldLabel.Text = 'Injection Time (s):';
            app.InjectionTimesEditField = uieditfield(app.SamplesPanel, 'numeric');
            app.InjectionTimesEditField.Value = 10;
            app.StabilizationTimesEditFieldLabel = uilabel(app.SamplesPanel);
            app.StabilizationTimesEditFieldLabel.Text = 'Stabilization Time (s):';
            app.StabilizationTimesEditField = uieditfield(app.SamplesPanel, 'numeric');
            app.StabilizationTimesEditField.Limits = [0 3600];
            app.StabilizationTimesEditField.ValueDisplayFormat = '%.0f';
            app.StabilizationTimesEditField.Value = 10;
            app.SettingsPanel = uipanel(app.iMSPRUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.LeftplateDropDownLabel = uilabel(app.SettingsPanel);
            app.LeftplateDropDownLabel.Text = 'Left plate:';
            app.LeftplateDropDown = uidropdown(app.SettingsPanel);
            app.LeftplateDropDown.Items = {'48 Vials', 'Option 2', 'Option 3', 'Option 4'};
            app.LeftplateDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftplateDropDownValueChanged, true);
            app.LeftplateDropDown.Enable = 'off';
            app.LeftplateDropDown.Value = '48 Vials';
            app.RightplateDropDownLabel = uilabel(app.SettingsPanel);
            app.RightplateDropDownLabel.Text = 'Right plate:';
            app.RightplateDropDown = uidropdown(app.SettingsPanel);
            app.RightplateDropDown.Items = {'48 Vials', 'Option 2', 'Option 3', 'Option 4'};
            app.RightplateDropDown.Value = '48 Vials';
            app.DirectionButtonGroup = uibuttongroup(app.SettingsPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.RowButton = uiradiobutton(app.DirectionButtonGroup);
            app.RowButton.Text = '↑ →';
            app.RowButton.Value = true;
            app.ColumnButton = uiradiobutton(app.DirectionButtonGroup);
            app.ColumnButton.Enable = 'off';
            app.ColumnButton.Text = '→ ↑';
            app.NeedleHeightLabel = uilabel(app.SettingsPanel);
            app.NeedleHeightLabel.Text = 'Needle Height:';
            app.NeedleHeightDropDown = uidropdown(app.SettingsPanel);
            app.NeedleHeightDropDown.Items = {'2.0', 'Option 2', 'Option 3', 'Option 4'};
            app.NeedleHeightDropDown.Value = '2.0';
            app.InitialWashCheckBox = uicheckbox(app.SettingsPanel);
            app.InitialWashCheckBox.Text = '';
            app.InitialWashLabel = uilabel(app.SettingsPanel);
            app.InitialWashLabel.Text = 'Initial Wash';
            app.StartButton = uibutton(app.iMSPRUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CloseButton = uibutton(app.iMSPRUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InitializeButton = uibutton(app.iMSPRUIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.TrayFrontButton = uibutton(app.iMSPRUIFigure, 'push');
            app.TrayFrontButton.ButtonPushedFcn = createCallbackFcn(app, @TrayFrontButtonPushed, true);
            app.TrayFrontButton.Text = 'Tray Front';
            % Show the figure after all components are created
            app.iMSPRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Alias_iMSPR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iMSPRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iMSPRUIFigure)
        end
    end
end