classdef UI_BiaScreening_DataProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        XExtendsToEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        XExtendsFromEditField           matlab.ui.control.NumericEditField
        XExtendsEditFieldLabel          matlab.ui.control.Label
        StabilizationStartEditField     matlab.ui.control.NumericEditField
        StabilizationStartEditFieldLabel  matlab.ui.control.Label
        BulkyEndEditField               matlab.ui.control.NumericEditField
        BulkyEndEditFieldLabel          matlab.ui.control.Label
        BulkyStartEditField             matlab.ui.control.NumericEditField
        BulkyStartEditFieldLabel        matlab.ui.control.Label
        StabilizationEndEditField       matlab.ui.control.NumericEditField
        StabilizationEndEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numData; % Number of data (X, Y set)
    end
    
    properties (Access = public)
        MainApp; % MainApp (StartUp)
        CurrentPath; % MainApp CurrentPath
        CellData; % A cell including whole data        
        FitData; % Fitted line bulky start ~ end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            app.CurrentPath = MainApp.CurrentPath;
            ResetButtonPushed(app, [])                
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Check X extends range is valid
            if app.XExtendsFromEditField.Value >= app.XExtendsToEditField.Value || app.XExtendsFromEditField.Value > app.StabilizationEndEditField.Value || app.XExtendsToEditField.Value < app.StabilizationStartEditField.Value
                uialert(app.UIFigure, 'Please check the X Extends Range!', 'Error')
                return
            end
            
            % Initialize CellData to MainApp.TargetDataBia
            app.numData = size(app.MainApp.TargetDataBia.data, 2)/2;
            app.CellData = cell(1, app.numData);
            for i = 1:app.numData
                app.CellData{1, i} = app.MainApp.TargetDataBia.data(:, 2*i-1 : 2*i);
                app.CellData{1, i}(:, 1) = app.CellData{1, i}(:, 1) - app.CellData{1, i}(1, 1); % X(1) -> 0
                app.CellData{1, i}(:, 2) = app.CellData{1, i}(:, 2) - app.CellData{1, i}(1, 2); % Y(1) -> 0
            end
            
            stabilzationEnd = app.StabilizationEndEditField.Value;
            bulkyStart = app.BulkyStartEditField.Value;
            bulkyEnd = app.BulkyEndEditField.Value;
            stabilizationStart = app.StabilizationStartEditField.Value;            
            
            deltaRU = zeros(app.numData, 1);
            
            cla(app.UIAxes);
            
            for i = 1 : app.numData
                idxStabilizationEnd = find(app.CellData{1, i}(:, 1) == stabilzationEnd);
                idxBulkyStart = find(app.CellData{1, i}(:, 1) == bulkyStart);
                idxBulkyEnd = find(app.CellData{1, i}(:, 1) == bulkyEnd);
                idxStabilizationStart = find(app.CellData{1, i}(:, 1) == stabilizationStart);
                
                app.CellData{1, i}(idxStabilizationEnd:idxBulkyStart-1, 2) = NaN;
                app.CellData{1, i}(idxBulkyStart:idxBulkyEnd, 2) = app.CellData{1, i}(idxBulkyStart:idxBulkyEnd, 2) - app.CellData{1, i}(idxBulkyStart, 2);
                app.CellData{1, i}(idxBulkyEnd+1:idxStabilizationStart, 2) = NaN;
                
                % Linear fitting
                x = app.CellData{1, i}(idxBulkyStart:idxBulkyEnd, 1);
                y = app.CellData{1, i}(idxBulkyStart:idxBulkyEnd, 2);
               
                p = polyfit(x, y, 1);
                yfit = polyval(p, x);
%                 yresid = y - yfit;
%                 SSresid = sum(yresid.^2);
%                 SStotal = (length(y)-1) * var(y);
%                 rsq = 1 - SSresid/SStotal;
                
                % X Extend range setting
                XExtendsFromIdx = find(app.CellData{1, i}==app.XExtendsFromEditField.Value);
                XExtendsToIdx = find(app.CellData{1, i}==app.XExtendsToEditField.Value);
                
                if XExtendsToIdx < size(app.CellData{1, i}, 1)-1
                    app.CellData{1, i}(XExtendsToIdx+1:end, :) = [];
                end
                
                if XExtendsFromIdx > 1 
                    app.CellData{1, i}(1:XExtendsFromIdx-1, :) = [];
                end
                
                hold(app.UIAxes, 'on'); plot(app.UIAxes, app.CellData{1, i}(:, 1), app.CellData{1, i}(:, 2));
                hold(app.UIAxes, 'on'); plot(app.UIAxes, x, yfit, 'LineWidth', 2);
                try
                    deltaRU(i, 1) = yfit(end) - yfit(1);
                catch
                    disp(i)
                end
                
                app.FitData{1, i} = [x yfit];
                
            end
            
%             disp(deltaRU)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.numData = size(app.MainApp.TargetDataBia.data, 2)/2;
            app.CellData = cell(1, app.numData);
            
            cla(app.UIAxes);
            for i = 1:app.numData
                app.CellData{1, i} = app.MainApp.TargetDataBia.data(:, 2*i-1 : 2*i);
                app.CellData{1, i}(:, 1) = app.CellData{1, i}(:, 1) - app.CellData{1, i}(1, 1);
                app.CellData{1, i}(:, 2) = app.CellData{1, i}(:, 2) - app.CellData{1, i}(1, 2); % Y(1) -> 0
                hold(app.UIAxes, 'on'); plot(app.UIAxes, app.CellData{1, i}(:, 1), app.CellData{1, i}(:, 2))
            end
        end

        % Value changed function: XExtendsFromEditField, 
        % XExtendsToEditField
        function XExtendsFromEditFieldValueChanged(app, event)
            newVal = event.Value;
            
            if newVal < app.CellData{1, 1}(1, 1)
                event.Source.Value = app.CellData{1, 1}(1, 1);
            elseif newVal > app.CellData{1, 1}(end, 1)
                event.Source.Value = app.CellData{1, 1}(end, 1);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            UI_iMScreening_Evaluate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BiaDataProcessing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Setting (s)';
            app.StabilizationEndEditFieldLabel = uilabel(app.SettingsPanel);
            app.StabilizationEndEditFieldLabel.Text = 'Stabilization End :';
            app.StabilizationEndEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.StabilizationEndEditField.Limits = [1 Inf];
            app.StabilizationEndEditField.RoundFractionalValues = 'on';
            app.StabilizationEndEditField.ValueDisplayFormat = '%.0f';
            app.StabilizationEndEditField.Value = 60;
            app.BulkyStartEditFieldLabel = uilabel(app.SettingsPanel);
            app.BulkyStartEditFieldLabel.Text = 'Bulky Start :';
            app.BulkyStartEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BulkyStartEditField.Limits = [1 Inf];
            app.BulkyStartEditField.RoundFractionalValues = 'on';
            app.BulkyStartEditField.ValueDisplayFormat = '%.0f';
            app.BulkyStartEditField.Value = 70;
            app.BulkyEndEditFieldLabel = uilabel(app.SettingsPanel);
            app.BulkyEndEditFieldLabel.Text = 'Bulky End :';
            app.BulkyEndEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.BulkyEndEditField.Limits = [1 Inf];
            app.BulkyEndEditField.RoundFractionalValues = 'on';
            app.BulkyEndEditField.ValueDisplayFormat = '%.0f';
            app.BulkyEndEditField.Value = 120;
            app.StabilizationStartEditFieldLabel = uilabel(app.SettingsPanel);
            app.StabilizationStartEditFieldLabel.Text = 'Stabilization Start :';
            app.StabilizationStartEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.StabilizationStartEditField.Limits = [1 Inf];
            app.StabilizationStartEditField.RoundFractionalValues = 'on';
            app.StabilizationStartEditField.ValueDisplayFormat = '%.0f';
            app.StabilizationStartEditField.Value = 130;
            app.XExtendsEditFieldLabel = uilabel(app.SettingsPanel);
            app.XExtendsEditFieldLabel.Text = 'X Extends :';
            app.XExtendsFromEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.XExtendsFromEditField.ValueChangedFcn = createCallbackFcn(app, @XExtendsFromEditFieldValueChanged, true);
            app.XExtendsFromEditField.Value = 40;
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '~';
            app.XExtendsToEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.XExtendsToEditField.ValueChangedFcn = createCallbackFcn(app, @XExtendsFromEditFieldValueChanged, true);
            app.XExtendsToEditField.Value = 150;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_BiaScreening_DataProcessing(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end