classdef UI_Communication_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectComportUIFigure  matlab.ui.Figure
        OkButton               matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        portList % Serial port list
    end
    
    properties (Access = public)
        MainApp; % UI_StartUp class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;            
            app.portList = cellstr(seriallist('available'));
            
            if ~isempty(app.MainApp.PortDevice)
                if isvalid(app.MainApp.PortDevice)                    
                   app.portList(end+1) = cellstr(app.MainApp.PortDevice.Port);                    
                end                
            end
            
            if isempty(app.portList)                
                uialert(app.MainApp.AliasServiceManagerUIFigure, 'Port Error', 'No available COM Port!')
                SelectComportUIFigureCloseRequest(app, [])
%                 app.OkButton.Enable = 0;            
            else            
                app.DropDown.Items = app.portList; 
                if ~isempty(app.MainApp.PortDevice)
                    if isvalid(app.MainApp.PortDevice)
                        app.DropDown.Value = app.MainApp.PortDevice.Port;
                    end
                end
                app.OkButton.Enable = 1;            
            end
        end

        % Close request function: SelectComportUIFigure
        function SelectComportUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            SelectComportUIFigureCloseRequest(app, event)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app.MainApp.PortDevice)
            
            baudRate = 9600;
            terminator = "CR";
            timeout = 1;
            
            portName = app.DropDown.Value;
            app.MainApp.PortDevice = serialport(portName, baudRate);                
            configureTerminator(app.MainApp.PortDevice, terminator)
            app.MainApp.PortDevice.Timeout = timeout;
            flush(app.MainApp.PortDevice)
            disp(app.MainApp.PortDevice)
    
            try
                app.MainApp.LogCell{end+1, 1} = 'Alias connected!';         
                app.MainApp.LogTextArea.Value = flipud(app.MainApp.LogCell);
            catch
                disp('No Log Text Area!')
            end
            
            SelectComportUIFigureCloseRequest(app, event)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectComportUIFigure = uifigure('Visible', 'off');
            app.SelectComportUIFigure.Name = 'Select Com port';
            app.SelectComportUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectComportUIFigureCloseRequest, true);
            app.DropDown = uidropdown(app.SelectComportUIFigure);
            app.DropDown.Items = {'COM1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'COM1';
            app.CancelButton = uibutton(app.SelectComportUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.SelectComportUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.SelectComportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Communication_Settings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SelectComportUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SelectComportUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectComportUIFigure)
        end
    end
end