classdef UI_SelectComPort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        OkButton      matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        DropDown      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        portList % Serial port list
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.portList = cellstr(seriallist('available'));
            
            if isempty(app.portList)
                
                uialert(app, 'Port Error', 'No available COM Port!')
                app.OkButton.Enable = 0;
            
            else
            
                app.DropDown.Items = app.portList;                      
                app.OkButton.Enable = 1;
            
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            baudRate = 9600;
            Terminator = "CR";
            
            portName = app.DropDown.Value;
            portDevice = serialport(portName, baudRate);
            configureTerminator(portDevice, Terminator)
            flush(portDevice)
            
            UIFigureCloseRequest(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_SelectComPort

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end