classdef UI_StartUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AliasServiceManagerUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        CommunicationMenu            matlab.ui.container.Menu
        Communication_Settings       matlab.ui.container.Menu
        AliasMenu                    matlab.ui.container.Menu
        Alias_Run                    matlab.ui.container.Menu
        Alias_DirectControl          matlab.ui.container.Menu
        Alias_Settings               matlab.ui.container.Menu
        iMSPRMenu                    matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        StartuploadButton            matlab.ui.control.Button
        BrowseButton                 matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        FiletoprogramLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp; % UI_StartUp class
        PortDevice; % Alias port device
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind)
        end

        % Menu selected function: Communication_Settings
        function Communication_SettingsSelected(app, event)
           % Run select COM port UI
           UI_Communication_Settings(app)
        end

        % Menu selected function: Alias_Settings
        function Alias_SettingsMenuSelected(app, event)
            UI_Alias_Settings(app)
        end

        % Menu selected function: Alias_DirectControl
        function Alias_DirectControlMenuSelected(app, event)
            UI_Alias_DirectControl(app)
        end

        % Menu selected function: Alias_Run
        function Alias_RunMenuSelected(app, event)
            UI_Alias_Run(app)
        end

        % Close request function: AliasServiceManagerUIFigure
        function AliasServiceManagerUIFigureCloseRequest(app, event)
            delete(instrfind)
            try
                delete(app.PortDevice)
            end
            delete(app)            
        end

        % Menu selected function: iMSPRMenu
        function iMSPRMenuSelected(app, event)
            UI_Alias_iMSPR(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AliasServiceManagerUIFigure = uifigure('Visible', 'off');
            app.AliasServiceManagerUIFigure.Name = 'Alias Service Manager';
            app.AliasServiceManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @AliasServiceManagerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.AliasServiceManagerUIFigure);
            app.FileMenu.Text = 'File';
            app.CommunicationMenu = uimenu(app.AliasServiceManagerUIFigure);
            app.CommunicationMenu.Text = 'Communication';
            app.Communication_Settings = uimenu(app.CommunicationMenu);
            app.Communication_Settings.MenuSelectedFcn = createCallbackFcn(app, @Communication_SettingsSelected, true);
            app.Communication_Settings.Text = 'Settings';
            app.AliasMenu = uimenu(app.AliasServiceManagerUIFigure);
            app.AliasMenu.Text = 'Alias';
            app.Alias_Run = uimenu(app.AliasMenu);
            app.Alias_Run.MenuSelectedFcn = createCallbackFcn(app, @Alias_RunMenuSelected, true);
            app.Alias_Run.Text = 'Run';
            app.Alias_DirectControl = uimenu(app.AliasMenu);
            app.Alias_DirectControl.MenuSelectedFcn = createCallbackFcn(app, @Alias_DirectControlMenuSelected, true);
            app.Alias_DirectControl.Text = 'Direct Control';
            app.Alias_Settings = uimenu(app.AliasMenu);
            app.Alias_Settings.MenuSelectedFcn = createCallbackFcn(app, @Alias_SettingsMenuSelected, true);
            app.Alias_Settings.Text = 'Settings';
            app.iMSPRMenu = uimenu(app.AliasMenu);
            app.iMSPRMenu.MenuSelectedFcn = createCallbackFcn(app, @iMSPRMenuSelected, true);
            app.iMSPRMenu.Text = 'iMSPR';
            app.HelpMenu = uimenu(app.AliasServiceManagerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.FiletoprogramLabel = uilabel(app.AliasServiceManagerUIFigure);
            app.FiletoprogramLabel.Text = 'File to program:';
            app.Label = uilabel(app.AliasServiceManagerUIFigure);
            app.Label.Text = '';
            app.BrowseButton = uibutton(app.AliasServiceManagerUIFigure, 'push');
            app.BrowseButton.Text = 'Browse...';
            app.StartuploadButton = uibutton(app.AliasServiceManagerUIFigure, 'push');
            app.StartuploadButton.Enable = 'off';
            app.StartuploadButton.Text = 'Start upload';
            % Show the figure after all components are created
            app.AliasServiceManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_StartUp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AliasServiceManagerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AliasServiceManagerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AliasServiceManagerUIFigure)
        end
    end
end