classdef UI_iMScreening_DataProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataProcessingUIFigure       matlab.ui.Figure
        NextButton                   matlab.ui.control.Button
        CalculateDiffButton          matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        FisrtinjectionstartsecPanel  matlab.ui.container.Panel
        ApplyButton                  matlab.ui.control.Button
        ReferenceEditField           matlab.ui.control.NumericEditField
        ReferenceEditFieldLabel      matlab.ui.control.Label
        TargetEditField              matlab.ui.control.NumericEditField
        TargetEditFieldLabel         matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        targetX; % X data of targetData
        targetY; % Y data of targetData
        referenceX; % X data of referenceData
        referenceY; % Y data of referenceData
        targetYShifted; % Y data of shifted targetData
        referenceYShifted; % Y data of shifted referenceData
    end
    
    properties (Access = public)
        DiffY = []; % Target Y - Reference Y
        LogData % Autosampler LogData from UI_iMScreening_StartUp
        CurrentPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CurrentPath = MainApp.CurrentPath;
            app.LogData = MainApp.LogData;
            
            app.targetX = MainApp.TargetData.data(:, 1);
            app.targetY = MainApp.TargetData.data(:, 2);
            app.targetY = app.targetY - app.targetY(1);
            app.referenceX = MainApp.ReferenceData.data(:, 1);
            app.referenceY = MainApp.ReferenceData.data(:, 2);
            app.referenceY = app.referenceY - app.referenceY(1);
            
            plot(app.UIAxes, app.targetX, app.targetY)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.referenceX, app.referenceY)
            legend(app.UIAxes, 'Target', 'Reference')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            gap = app.ReferenceEditField.Value - app.TargetEditField.Value;
            
            app.targetYShifted = app.targetY;
            app.referenceYShifted = app.referenceY;

            if gap >= 0 % Ref is right than Target
                app.targetYShifted(end-gap+1:end) = [];
                app.referenceYShifted(1:gap) = [];
            else % Ref is left than Target
                app.targetYShifted(1:abs(gap)) = [];
                app.referenceYShifted(end-abs(gap)+1:end) = [];
            end
            
            cla(app.UIAxes)
            plot(app.UIAxes, app.targetYShifted)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.referenceYShifted)
            legend(app.UIAxes, 'Target', 'Reference')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.targetYShifted = app.targetY;
            app.referenceYShifted = app.referenceY;
            app.DiffY = [];
            
            cla(app.UIAxes)
            plot(app.UIAxes, app.targetYShifted)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.referenceYShifted)
            legend(app.UIAxes, 'Target', 'Reference')
        end

        % Button pushed function: CalculateDiffButton
        function CalculateDiffButtonPushed(app, event)
            app.DiffY = app.targetYShifted - app.referenceYShifted;
            
            cla(app.UIAxes)
            plot(app.UIAxes, app.DiffY)            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if isempty(app.DiffY)
                return
            end
            
            UI_iMScreening_Evaluate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataProcessingUIFigure = uifigure('Visible', 'off');
            app.DataProcessingUIFigure.Name = 'Data Processing';
            app.UIAxes = uiaxes(app.DataProcessingUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FisrtinjectionstartsecPanel = uipanel(app.DataProcessingUIFigure);
            app.FisrtinjectionstartsecPanel.Title = 'Fisrt injection start (sec)';
            app.TargetEditFieldLabel = uilabel(app.FisrtinjectionstartsecPanel);
            app.TargetEditFieldLabel.Text = 'Target :';
            app.TargetEditField = uieditfield(app.FisrtinjectionstartsecPanel, 'numeric');
            app.TargetEditField.Limits = [1 Inf];
            app.TargetEditField.RoundFractionalValues = 'on';
            app.TargetEditField.ValueDisplayFormat = '%.0f';
            app.TargetEditField.Value = 1;
            app.ReferenceEditFieldLabel = uilabel(app.FisrtinjectionstartsecPanel);
            app.ReferenceEditFieldLabel.Text = 'Reference :';
            app.ReferenceEditField = uieditfield(app.FisrtinjectionstartsecPanel, 'numeric');
            app.ReferenceEditField.Limits = [1 Inf];
            app.ReferenceEditField.RoundFractionalValues = 'on';
            app.ReferenceEditField.ValueDisplayFormat = '%.0f';
            app.ReferenceEditField.Value = 1;
            app.ApplyButton = uibutton(app.FisrtinjectionstartsecPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.DataProcessingUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalculateDiffButton = uibutton(app.DataProcessingUIFigure, 'push');
            app.CalculateDiffButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDiffButtonPushed, true);
            app.CalculateDiffButton.Text = 'Calculate Diff';
            app.NextButton = uibutton(app.DataProcessingUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.DataProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_iMScreening_DataProcessing(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataProcessingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DataProcessingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataProcessingUIFigure)
        end
    end
end