classdef UI_iMScreening_Evaluate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EvaluationUIFigure         matlab.ui.Figure
        ExportDataButton           matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        iMSPRTab                   matlab.ui.container.Tab
        AverageRangeXPanel         matlab.ui.container.Panel
        CalculateButton            matlab.ui.control.Button
        ToEditField                matlab.ui.control.NumericEditField
        ToEditFieldLabel           matlab.ui.control.Label
        FromEditField              matlab.ui.control.NumericEditField
        FromEditFieldLabel         matlab.ui.control.Label
        BiacoreTab                 matlab.ui.container.Tab
        X2X1Panel                  matlab.ui.container.Panel
        LinearFittingCheckBox      matlab.ui.control.CheckBox
        PositiveNormalizeCheckBox  matlab.ui.control.CheckBox
        CalculateButton_2          matlab.ui.control.Button
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        SelectallCheckBox          matlab.ui.control.CheckBox
        ResetButton                matlab.ui.control.Button
        NegativeButton             matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        periodDiffY; % Target-Ref curves of each cycle
        periodDiffYDiffNegative; % Diffrence from negative
        currentLinePlot; % Current lines on the app.UIAxes
        xBar = []; % X axis of bar graph
        result = []; % Y axis of bar grpah
        currentPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ProcessingApp)
            if strcmp(ProcessingApp.UIFigure.Name, 'BiaDataProcessing')
                app.currentPath = ProcessingApp.CurrentPath;
                for i = 1:size(ProcessingApp.CellData, 2)
                    app.periodDiffY{i, 1} = ProcessingApp.CellData{1, i}(:, 2);
                    hold(app.UIAxes, 'On')
                    app.currentLinePlot{i, 1} = plot(app.UIAxes, 0:length(app.periodDiffY{i, 1})-1, app.periodDiffY{i, 1});
                    hold(app.UIAxes, 'Off')
                end
            elseif strcmp(ProcessingApp.UIFigure.Name, 'iMDataProcessing')
                timingData = ProcessingApp.LogData;
                divideString = ' : ';
                tmpEvent = split(timingData, divideString);
                
                [x, ~] = find(ismember(tmpEvent, 'Injection Start'));
                tmpTimeStr = tmpEvent(x, 2);
                
                injectionStartTime = zeros(size(tmpTimeStr));
                for i = 1:size(tmpTimeStr, 1)
                    tmpSplit = split(tmpTimeStr{i, 1}, ' ');
                    injectionStartTime(i) = str2double(tmpSplit{1});
                end

%                 tmpTimeNum = str2double(split(tmpTimeStr, ' '));
%                 injectionStartTime = tmpTimeNum(:, 1);
                
                [x, ~] = find(ismember(tmpEvent, 'Stabilization End'));
                tmpTimeStr = tmpEvent(x, 2);

                stabilizationEndTime = zeros(size(tmpTimeStr));
                for i = 1:size(tmpTimeStr, 1)
                    tmpSplit = split(tmpTimeStr{i, 1}, ' ');
                    stabilizationEndTime(i) = str2double(tmpSplit{1});
                end
%                 tmpTimeNum = str2double(split(tmpTimeStr, ' '));
%                 stabilizationEndTime = tmpTimeNum(:, 1);
                
                if size(injectionStartTime, 1) ~= size(stabilizationEndTime, 1); return; end
                
                correctedInjectionStartTime = ProcessingApp.TargetEditField.Value;
                tmpStartTime = injectionStartTime(1);
                injectionStartTime = injectionStartTime - tmpStartTime + correctedInjectionStartTime;
                stabilizationEndTime = stabilizationEndTime - tmpStartTime + correctedInjectionStartTime;
                
                % Cut data from InjectionStart to StabilizationEnd
                cyclePeroid = round(mean(stabilizationEndTime - injectionStartTime));
                app.currentLinePlot = cell(size(app.periodDiffYDiffNegative, 1), 1);
                
                for i = 1:size(injectionStartTime, 1)
                    if size(ProcessingApp.DiffY, 1) < injectionStartTime(i)+cyclePeroid
                        ProcessingApp.DiffY(size(ProcessingApp.DiffY, 1)+1 : injectionStartTime(i)+cyclePeroid) = nan;
                    end
                    app.periodDiffY{i, 1} = ProcessingApp.DiffY(injectionStartTime(i) : injectionStartTime(i)+cyclePeroid);    
                    app.periodDiffY{i, 1} = app.periodDiffY{i, 1} - app.periodDiffY{i, 1}(1);
                    hold(app.UIAxes, 'On')
                    app.currentLinePlot{i, 1} = plot(app.UIAxes, 0:length(app.periodDiffY{i, 1})-1, app.periodDiffY{i, 1});
                    hold(app.UIAxes, 'Off')
                end
            end        
            
            app.periodDiffYDiffNegative = app.periodDiffY;
            
            index = [1:size(app.periodDiffY, 1)]';
            mode = {'Target', 'Positive', 'Negative'};            
            modeData    = cell(size(index, 1), 1);
            modeData(:) = {'Target'};  % Default value      
            select = true(size(app.periodDiffY, 1), 1);
            tableData = cat(2, num2cell(index), modeData, num2cell(select));
                 
            columnname =   {'Index', 'Type', 'Select'};
            columnformat = {'numeric', mode, 'logical'};
            columneditable = [true, true, true]; 
            app.UITable.Data = tableData;
            app.UITable.ColumnName = columnname;
            app.UITable.ColumnFormat = columnformat;
            app.UITable.ColumnEditable = columneditable;
            
%             enableDefaultInteractivity(app.UIAxes)
        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            negativeIndex = find(ismember(app.UITable.Data(:, 2), 'Negative'));
            app.periodDiffYDiffNegative = app.periodDiffY;
            
            for i = 1:size(negativeIndex, 1)
                if i == size(negativeIndex, 1)
                    for j = negativeIndex(i):size(app.periodDiffYDiffNegative, 1)
                        app.periodDiffYDiffNegative{j, 1} = app.periodDiffY{j, 1} - app.periodDiffY{negativeIndex(i), 1};
                    end
                else
                    for j = negativeIndex(i):negativeIndex(i+1)
                        app.periodDiffYDiffNegative{j, 1} = app.periodDiffY{j, 1} - app.periodDiffY{negativeIndex(i), 1};
                    end
                end
            end
            
            app.currentLinePlot = cell(size(app.periodDiffYDiffNegative, 1), 1);            
            cla(app.UIAxes)
            for i = 1:size(app.periodDiffYDiffNegative, 1)
%             for i = 1:4
                hold(app.UIAxes, 'On')
                app.currentLinePlot{i, 1} = plot(app.UIAxes, app.periodDiffYDiffNegative{i, 1});  
                hold(app.UIAxes, 'Off')
            end            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.periodDiffYDiffNegative = app.periodDiffY;
            
            app.currentLinePlot = cell(size(app.periodDiffYDiffNegative, 1), 1);
            cla(app.UIAxes)
            for i = 1:size(app.periodDiffYDiffNegative, 1)
                hold(app.UIAxes, 'On')
                app.currentLinePlot{i, 1} = plot(app.UIAxes, app.periodDiffYDiffNegative{i, 1});
                hold(app.UIAxes, 'Off')
            end
            
            index = [1:size(app.periodDiffY, 1)]';
            mode = {'Target', 'Positive', 'Negative'};            
            modeData    = cell(size(index, 1), 1);
            modeData(:) = {'Target'};  % Default value      
            select = true(size(app.periodDiffY, 1), 1);
            tableData = cat(2, num2cell(index), modeData, num2cell(select));
                 
            columnname =   {'Index', 'Type', 'Select'};
            columnformat = {'numeric', mode, 'logical'};
            columneditable = [true, true, true]; 
            app.UITable.Data = tableData;
            app.UITable.ColumnName = columnname;
            app.UITable.ColumnFormat = columnformat;
            app.UITable.ColumnEditable = columneditable;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.ToEditField.Value <= app.FromEditField.Value
                return
            end
            
            app.xBar = []; app.result = [];
            for i = 1:size(app.periodDiffYDiffNegative, 1)
                app.result(end+1) = mean(app.periodDiffYDiffNegative{i, 1}(app.FromEditField.Value:app.ToEditField.Value));
            end
            app.xBar = [app.UITable.Data{:, 1}];
            
            ax = app.UIAxes2;
            cla(ax)
            b = bar(ax, app.result);            
            b.FaceColor = 'flat';
            negativeIndex = find(ismember(app.UITable.Data(:, 2), 'Negative'));
            positiveIndex = find(ismember(app.UITable.Data(:, 2), 'Positive'));            
            for i = 1:length(negativeIndex)
                b.CData(negativeIndex(i), :) = [255, 0, 0];
            end            
            for i = 1:length(positiveIndex)
                b.CData(positiveIndex(i), :) = [0, 255, 0];
            end
            
            b.DataTipTemplate.DataTipRows(1) = [];
            b.DataTipTemplate.DataTipRows(1).Label = 'Avg';
            row = dataTipTextRow('Idx', app.xBar);
            b.DataTipTemplate.DataTipRows(end+1) = row;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            for i = 1:length(app.currentLinePlot)
                app.currentLinePlot{i, 1}.LineWidth = 0.2;
            end
            app.currentLinePlot{indices(1), 1}.LineWidth = 10;
        end

        % Button down function: AverageRangeXPanel, EvaluationUIFigure, 
        % Panel, Panel_2
        function UIFigureButtonDown(app, event)
            try
                for i = 1:length(app.currentLinePlot)
                    app.currentLinePlot{i, 1}.LineWidth = 0.2;
                end
            catch
                disp('UI_iMScreening_Evaluate.mlapp, UIFigureButtonDown')
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1, 2) == 1 % Index column
                for i = indices(1, 1) : size(app.UITable.Data, 1)                    
                    if strcmp(app.UITable.Data{i, 2}, 'Target')
                        app.UITable.Data{i, 1} = newData;
                        newData = newData + 1;
                    end
                end
            elseif indices(1, 2) == 2 % Type Column
                if ~strcmp(newData, 'Target')            
                   app.UITable.Data{indices(1, 1), 1} = nan;
                end
            elseif indices(1, 2) == 3 % Select Column
                if newData == true
                    set(app.currentLinePlot{indices(1, 1)}, 'Visible', 'On')
                elseif newData == false
                    set(app.currentLinePlot{indices(1, 1)}, 'Visible', 'Off')
                end
            end
        end

        % Value changed function: SelectallCheckBox
        function SelectallCheckBoxValueChanged(app, event)
            value = app.SelectallCheckBox.Value;
            for i = 1:size(app.UITable.Data, 1)
                app.UITable.Data{i, 3} = value;
            end
            for i = 1:size(app.currentLinePlot, 1)
                set(app.currentLinePlot{i, 1}, 'Visible', app.UITable.Data{i, 3})
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            selPath = uigetdir(app.currentPath, 'Save folder');
            
            if isequal(selPath, 0); return; end
            
            curveData = zeros(size(app.currentLinePlot{1, 1}.YData, 2), size(app.currentLinePlot, 1));
            for i = 1:size(app.currentLinePlot, 1)
                curveData(:, i) = app.currentLinePlot{i, 1}.YData;
            end
            curveData = [[app.UITable.Data{:, 1}]; curveData];
            curveTable = array2table(curveData);
            
            formatOut = 'yymmddHHMMSS';
            dateStr = datestr(now, formatOut);
            extension = 'txt';
            
            curveFileName = strcat('Processed_curve', dateStr, '.', extension);
            fullFile = fullfile(selPath, curveFileName);
            writetable(curveTable, fullFile, 'Delimiter', 'tab')
            
            if isempty(app.result); return; end
            index = [app.UITable.Data{:, 1}]';
            type = {app.UITable.Data{:, 2}}';
            barData = [num2cell(index) num2cell(app.result') type ];
            indexCell = {'Index', 'Average', 'Type'};
            barTable = cell2table(barData, 'VariableNames', indexCell);
            
            resultFileName = strcat('Screening_result', dateStr, '.', extension);
            fullFile = fullfile(selPath, resultFileName);
            writetable(barTable, fullFile, 'Delimiter', 'tab')
            app.currentPath = selPath;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if app.X2EditField.Value <= app.X1EditField.Value
                return
            end
            
            idxFitStart = app.X1EditField.Value+1;
            idxFitEnd = app.X2EditField.Value+1;
            app.xBar = []; app.result = [];
            if app.LinearFittingCheckBox.Value
                for i = 1:size(app.periodDiffYDiffNegative, 1)
                    x = idxFitStart:idxFitEnd;
                    y = app.periodDiffYDiffNegative{i, 1}(idxFitStart:idxFitEnd);
                    p = polyfit(x, y, 1);
                    yfit = polyval(p, x);
                    app.result(end+1) = yfit(end) - yfit(1);
                end
            else
                for i = 1:size(app.periodDiffYDiffNegative, 1)                    
                    app.result(end+1) = app.periodDiffYDiffNegative{i, 1}(idxFitEnd) - app.periodDiffYDiffNegative{i, 1}(idxFitStart);
                end
            end
            
            app.xBar = [app.UITable.Data{:, 1}];
            
            negativeIndex = find(ismember(app.UITable.Data(:, 2), 'Negative'));
            positiveIndex = find(ismember(app.UITable.Data(:, 2), 'Positive')); 
            
            tmpResult = zeros(size(app.result));
            if app.PositiveNormalizeCheckBox.Value && ~isempty(positiveIndex)           
                for i = 1:size(positiveIndex, 1)
                    if i == size(positiveIndex, 1)
                        for j = positiveIndex(i):length(app.result)
                            tmpResult(j) = app.result(j) / app.result(positiveIndex(i));
                        end
                    else
                        for j = positiveIndex(i):positiveIndex(i+1)
                            tmpResult(j) = app.result(j) / app.result(positiveIndex(i));
                        end
                    end
                end
                app.result = tmpResult;
            end
            
            ax = app.UIAxes2;
            cla(ax)
            b = bar(ax, app.result);            
            b.FaceColor = 'flat';
                    
            for i = 1:length(negativeIndex)
                b.CData(negativeIndex(i), :) = [255, 0, 0];
            end            
            for i = 1:length(positiveIndex)
                b.CData(positiveIndex(i), :) = [0, 255, 0];
            end
            
            b.DataTipTemplate.DataTipRows(1) = [];
            b.DataTipTemplate.DataTipRows(1).Label = 'Avg';
            row = dataTipTextRow('Idx', app.xBar);
            b.DataTipTemplate.DataTipRows(end+1) = row;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EvaluationUIFigure = uifigure('Visible', 'off');
            app.EvaluationUIFigure.Name = 'Evaluation';
            app.EvaluationUIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.EvaluationUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.EvaluationUIFigure);
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NegativeButton = uibutton(app.Panel, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = '- Negative';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectallCheckBox = uicheckbox(app.Panel);
            app.SelectallCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallCheckBoxValueChanged, true);
            app.SelectallCheckBox.Text = 'Select all';
            app.SelectallCheckBox.Value = true;
            app.Panel_2 = uipanel(app.EvaluationUIFigure);
            app.Panel_2.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes2 = uiaxes(app.Panel_2);
            app.TabGroup = uitabgroup(app.Panel_2);
            app.iMSPRTab = uitab(app.TabGroup);
            app.iMSPRTab.Title = 'iMSPR';
            app.AverageRangeXPanel = uipanel(app.iMSPRTab);
            app.AverageRangeXPanel.Title = 'Average Range (X)';
            app.AverageRangeXPanel.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.FromEditFieldLabel = uilabel(app.AverageRangeXPanel);
            app.FromEditFieldLabel.Text = 'From :';
            app.FromEditField = uieditfield(app.AverageRangeXPanel, 'numeric');
            app.FromEditField.RoundFractionalValues = 'on';
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.ToEditFieldLabel = uilabel(app.AverageRangeXPanel);
            app.ToEditFieldLabel.Text = 'To :';
            app.ToEditField = uieditfield(app.AverageRangeXPanel, 'numeric');
            app.ToEditField.RoundFractionalValues = 'on';
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.CalculateButton = uibutton(app.AverageRangeXPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BiacoreTab = uitab(app.TabGroup);
            app.BiacoreTab.Title = 'Biacore';
            app.X2X1Panel = uipanel(app.BiacoreTab);
            app.X2X1Panel.Title = 'X2 - X1';
            app.X1EditFieldLabel = uilabel(app.X2X1Panel);
            app.X1EditFieldLabel.Text = 'X1 :';
            app.X1EditField = uieditfield(app.X2X1Panel, 'numeric');
            app.X1EditField.RoundFractionalValues = 'on';
            app.X1EditField.ValueDisplayFormat = '%.0f';
            app.X2EditFieldLabel = uilabel(app.X2X1Panel);
            app.X2EditFieldLabel.Text = 'X2 :';
            app.X2EditField = uieditfield(app.X2X1Panel, 'numeric');
            app.X2EditField.RoundFractionalValues = 'on';
            app.X2EditField.ValueDisplayFormat = '%.0f';
            app.CalculateButton_2 = uibutton(app.X2X1Panel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PositiveNormalizeCheckBox = uicheckbox(app.X2X1Panel);
            app.PositiveNormalizeCheckBox.Text = 'Positive Normalize';
            app.PositiveNormalizeCheckBox.Value = true;
            app.LinearFittingCheckBox = uicheckbox(app.X2X1Panel);
            app.LinearFittingCheckBox.Text = 'Linear Fitting';
            app.LinearFittingCheckBox.Value = true;
            app.ExportDataButton = uibutton(app.EvaluationUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.EvaluationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_iMScreening_Evaluate(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EvaluationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EvaluationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EvaluationUIFigure)
        end
    end
end