classdef UI_iMerge_StartUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PlotButton         matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        MergeButton        matlab.ui.control.Button
        DeleteButton       matlab.ui.control.Button
        UpButton           matlab.ui.control.Button
        DownButton         matlab.ui.control.Button
        ListBox            matlab.ui.control.ListBox
        ListBoxLabel       matlab.ui.control.Label
        LoadResultsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        MainApp % This Application
        ResultFilePath; % Currently added folder name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            selPath = uigetdir(pwd);
            if isequal(selPath, 0); return; end
            
            app.ListBox.Items = {};
            listing = dir(selPath);
            nonDirList = {listing(~[listing.isdir]).name};
            resultFileList = nonDirList(contains(nonDirList, 'Screening_result'));
            app.ResultFilePath = selPath;
            
            set(app.ListBox, 'Items', resultFileList)  
            app.UITable.Data = {};
        end

        % Button pushed function: DownButton, UpButton
        function Button_3Pushed(app, event)
            % Up button
            listItems = app.ListBox.Items;
            selectItem = app.ListBox.Value;
            selectItemIndex = find(ismember(listItems, selectItem));
            
            if strcmp(event.Source.Text, '▲')
                targetIndex = selectItemIndex - 1;
                for i = 1:length(listItems)
                    if selectItemIndex > 1 && i == targetIndex
                        listItems{i} = selectItem;
                        listItems{selectItemIndex} = app.ListBox.Items{i};
                    end
                end
            elseif strcmp(event.Source.Text, '▼')
                targetIndex = selectItemIndex + 1;
                for i = 1:length(listItems)
                    if selectItemIndex < length(listItems) && i == targetIndex
                        listItems{i} = selectItem;
                        listItems{selectItemIndex} = app.ListBox.Items{i};
                    end
                end
            end
            
            app.ListBox.Items = listItems;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            listItems = app.ListBox.Items;
            selectItem = app.ListBox.Value;
            selectItemIndex = ismember(listItems, selectItem);
            listItems(ismember(listItems, selectItem)) = [];
            app.ListBox.Items = listItems;
            
            try
                app.ListBox.Value = app.ListBox.Items{selectItemIndex};
            catch ME
                disp(ME)
                try
                    app.ListBox.Value = app.ListBox.Items{end};
                catch ME2
                    disp(ME2)
                end
            end
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            if isempty(app.ListBox.Items); return; end
            
            app.UITable.Data = {};
            
            fileList = app.ListBox.Items; 
            mergedTables = [];
            
            for i = 1:length(fileList)
                fileName = fullfile(app.ResultFilePath, fileList{i});
                tmpTable = readtable(fileName);
                mergedTables = [mergedTables; tmpTable]; 
            end
            
            indexColumn = array2table([1:size(mergedTables, 1)]', 'VariableNames', {'Order'});
            mergedTables = [indexColumn, mergedTables];
            
            app.UITable.Data = mergedTables;
            app.UITable.ColumnName = {'Order', 'Index', 'Result (RU)', 'Type'};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            UI_iMerge_DisplayResult(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadResultsButton = uibutton(app.UIFigure, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.DownButton.Text = '▼';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.UpButton.Text = '▲';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '-';
            app.MergeButton = uibutton(app.UIFigure, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_iMerge_StartUp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end