classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LogPanel             matlab.ui.container.Panel
        ClearButton          matlab.ui.control.Button
        LogTextArea          matlab.ui.control.TextArea
        ResevedPanel         matlab.ui.container.Panel
        CycleButton          matlab.ui.control.Button
        ClearErrorsButton    matlab.ui.control.Button
        ErrorCheckButton     matlab.ui.control.Button
        StatusButton         matlab.ui.control.Button
        InitializeButton     matlab.ui.control.Button
        ConnectButton        matlab.ui.control.Button
        ProtocolPanel        matlab.ui.container.Panel
        SendButton_9         matlab.ui.control.Button
        MemoEditField_9      matlab.ui.control.EditField
        ETXEditField_9       matlab.ui.control.NumericEditField
        ValueEditField_9     matlab.ui.control.NumericEditField
        PFCEditField_9       matlab.ui.control.NumericEditField
        AIEditField_9        matlab.ui.control.NumericEditField
        IDEditField_9        matlab.ui.control.NumericEditField
        STXEditField_9       matlab.ui.control.NumericEditField
        SendButton_8         matlab.ui.control.Button
        MemoEditField_8      matlab.ui.control.EditField
        ETXEditField_8       matlab.ui.control.NumericEditField
        ValueEditField_8     matlab.ui.control.NumericEditField
        PFCEditField_8       matlab.ui.control.NumericEditField
        AIEditField_8        matlab.ui.control.NumericEditField
        IDEditField_8        matlab.ui.control.NumericEditField
        STXEditField_8       matlab.ui.control.NumericEditField
        SendButton_7         matlab.ui.control.Button
        MemoEditField_7      matlab.ui.control.EditField
        ETXEditField_7       matlab.ui.control.NumericEditField
        ValueEditField_7     matlab.ui.control.NumericEditField
        PFCEditField_7       matlab.ui.control.NumericEditField
        AIEditField_7        matlab.ui.control.NumericEditField
        IDEditField_7        matlab.ui.control.NumericEditField
        STXEditField_7       matlab.ui.control.NumericEditField
        SendButton_6         matlab.ui.control.Button
        MemoEditField_6      matlab.ui.control.EditField
        ETXEditField_6       matlab.ui.control.NumericEditField
        ValueEditField_6     matlab.ui.control.NumericEditField
        PFCEditField_6       matlab.ui.control.NumericEditField
        AIEditField_6        matlab.ui.control.NumericEditField
        IDEditField_6        matlab.ui.control.NumericEditField
        STXEditField_6       matlab.ui.control.NumericEditField
        SendButton_5         matlab.ui.control.Button
        SendButton_4         matlab.ui.control.Button
        SendButton_3         matlab.ui.control.Button
        SendButton_2         matlab.ui.control.Button
        SendButton           matlab.ui.control.Button
        MemoEditField        matlab.ui.control.EditField
        MemoEditFieldLabel   matlab.ui.control.Label
        ETXEditField         matlab.ui.control.NumericEditField
        ETXEditFieldLabel    matlab.ui.control.Label
        ValueEditField       matlab.ui.control.NumericEditField
        ValueEditFieldLabel  matlab.ui.control.Label
        PFCEditField         matlab.ui.control.NumericEditField
        PFCEditFieldLabel    matlab.ui.control.Label
        AIEditField          matlab.ui.control.NumericEditField
        AIEditFieldLabel     matlab.ui.control.Label
        IDEditField          matlab.ui.control.NumericEditField
        IDEditFieldLabel     matlab.ui.control.Label
        STXEditField         matlab.ui.control.NumericEditField
        STXEditFieldLabel    matlab.ui.control.Label
        MemoEditField_5      matlab.ui.control.EditField
        MemoEditField_4      matlab.ui.control.EditField
        MemoEditField_3      matlab.ui.control.EditField
        MemoEditField_2      matlab.ui.control.EditField
        ETXEditField_5       matlab.ui.control.NumericEditField
        ValueEditField_5     matlab.ui.control.NumericEditField
        PFCEditField_5       matlab.ui.control.NumericEditField
        AIEditField_5        matlab.ui.control.NumericEditField
        IDEditField_5        matlab.ui.control.NumericEditField
        STXEditField_5       matlab.ui.control.NumericEditField
        ETXEditField_4       matlab.ui.control.NumericEditField
        ValueEditField_4     matlab.ui.control.NumericEditField
        PFCEditField_4       matlab.ui.control.NumericEditField
        AIEditField_4        matlab.ui.control.NumericEditField
        IDEditField_4        matlab.ui.control.NumericEditField
        STXEditField_4       matlab.ui.control.NumericEditField
        ETXEditField_3       matlab.ui.control.NumericEditField
        ValueEditField_3     matlab.ui.control.NumericEditField
        PFCEditField_3       matlab.ui.control.NumericEditField
        AIEditField_3        matlab.ui.control.NumericEditField
        IDEditField_3        matlab.ui.control.NumericEditField
        STXEditField_3       matlab.ui.control.NumericEditField
        ETXEditField_2       matlab.ui.control.NumericEditField
        ValueEditField_2     matlab.ui.control.NumericEditField
        PFCEditField_2       matlab.ui.control.NumericEditField
        AIEditField_2        matlab.ui.control.NumericEditField
        IDEditField_2        matlab.ui.control.NumericEditField
        STXEditField_2       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        MainApp; % UI_StartUp class
        PortDevice; % Alias port device % Description
        LogCell = ''; % Description
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
        function SaveProtocols(app)
            stxEditStr = 'STXEditField';
            idEditStr = 'IDEditField';
            aiEditStr = 'AIEditField';
            pfcEditStr = 'PFCEditField';
            valueEditStr = 'ValueEditField';
            etxEditStr = 'ETXEditField';
            memoEditStr = 'MemoEditField';
            
            for i = 1:9
                if i == 1                    
                    stxEdit = stxEditStr;
                    idEdit = idEditStr;
                    aiEdit = aiEditStr;
                    pfcEdit = pfcEditStr;
                    valueEdit = valueEditStr;
                    etxEdit = etxEditStr;
                    memoEdit = memoEditStr;
                else                    
                    stxEdit = strcat(stxEditStr, '_', num2str(i));
                    idEdit = strcat(idEditStr, '_', num2str(i));
                    aiEdit = strcat(aiEditStr, '_', num2str(i));
                    pfcEdit = strcat(pfcEditStr, '_', num2str(i));
                    valueEdit = strcat(valueEditStr, '_', num2str(i));
                    etxEdit = strcat(etxEditStr, '_', num2str(i));
                    memoEdit = strcat(memoEditStr, '_', num2str(i));
                end
                
                saveData.(stxEdit) = app.(stxEdit).Value;
                saveData.(idEdit) = app.(idEdit).Value;
                saveData.(aiEdit) = app.(aiEdit).Value;
                saveData.(pfcEdit) = app.(pfcEdit).Value;
                saveData.(valueEdit) = app.(valueEdit).Value;
                saveData.(etxEdit) = app.(etxEdit).Value;
                saveData.(memoEdit) = app.(memoEdit).Value;    
            end
            
            saveFile = fullfile(pwd, 'protocols.mat');
            save(saveFile, 'saveData')            
        end
        
        
        function LoadProtocols(app)
            stxEditStr = 'STXEditField';
            idEditStr = 'IDEditField';
            aiEditStr = 'AIEditField';
            pfcEditStr = 'PFCEditField';
            valueEditStr = 'ValueEditField';
            etxEditStr = 'ETXEditField';
            memoEditStr = 'MemoEditField';
            
            loadFile = fullfile(pwd, 'protocols.mat');
            
            try
                load(loadFile, 'saveData')
                
                for i = 1:9
                    if i == 1                    
                        stxEdit = stxEditStr;
                        idEdit = idEditStr;
                        aiEdit = aiEditStr;
                        pfcEdit = pfcEditStr;
                        valueEdit = valueEditStr;
                        etxEdit = etxEditStr;
                        memoEdit = memoEditStr;
                    else                    
                        stxEdit = strcat(stxEditStr, '_', num2str(i));
                        idEdit = strcat(idEditStr, '_', num2str(i));
                        aiEdit = strcat(aiEditStr, '_', num2str(i));
                        pfcEdit = strcat(pfcEditStr, '_', num2str(i));
                        valueEdit = strcat(valueEditStr, '_', num2str(i));
                        etxEdit = strcat(etxEditStr, '_', num2str(i));
                        memoEdit = strcat(memoEditStr, '_', num2str(i));
                    end
            
                    app.(stxEdit).Value = saveData.(stxEdit);
                    app.(idEdit).Value = saveData.(idEdit);
                    app.(aiEdit).Value = saveData.(aiEdit);
                    app.(pfcEdit).Value = saveData.(pfcEdit);
                    app.(valueEdit).Value = saveData.(valueEdit);
                    app.(etxEdit).Value = saveData.(etxEdit);
                    app.(memoEdit).Value = saveData.(memoEdit);    
                end        
            catch
                disp('Data Load failed')
                return
            end
        end
        
        function SampleInjectCycle(app, portDevice, timeOut, cycleNum, initialWash)
            %% 1. Ready
            rxInitialize = "61010152000310";
            
            % Tray Segment (48 vials)
            pfc = "0200";
            value = "000003";
            rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
            rx = WaitUntilWantedRX(portDevice, [], timeOut);
            % Tray Segment check
            % Initial Wash
            if initialWash == 1            
                pfc = "5130";
                value = "000001";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                washTimeOut = 300;
                rx = WaitUntilWantedRX(portDevice, [], washTimeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            end
            
            %% Cycle Start
            
            for i = 1:cycleNum
            
                % 2. Search sample position (Tray move : Vertical Position)
                pfc = "5111";
                value = strcat('02000', num2str(i));
%                 value = "020001"; % Right plate A1
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 3. Needle horizontal Move (Needle move : Horizontal Position)
                pfc = "5136";
%                 value = strcat('03020', num2str(i));
                value = "030201"; % Right plate A1
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 4. Needle vertical down
                pfc = "5135";
                value = "000001"; % Down
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
            
                % 5. Injection valve (Load)
                pfc = "5105";
                value = "000001"; % Load
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 6. Syringe valve (Needle)
                pfc = "5137";
                value = "000002";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 7. Aspirate (sample volume + alpha)
                pfc = "5138";
                value = "000100";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 8. Injection valve (Inject)
                pfc = "5105";
                value = "000000";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 9. Injection start Signal
                currentTime = char(datetime);
                msg = sprintf('Injection Start at %s', currentTime(end-7:end));
                if ~isempty(rx)                
                    app.LogCell{end+1, 1} = msg;         
                    app.LogTextArea.Value = flipud(app.LogCell);
                end
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 10. Needle vertical up
                pfc = "5135";
                value = "000000";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 11. Needle horizontal → waste
                pfc = "5136";
                value = "020000"; % Waste
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 12. Needle vertical down
                pfc = "5135";
                value = "000001"; % Down
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 13. Compressor on / off (5 sec)
                pfc = "5134";
                value = "000001"; % On
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                pause(5)
            
                pfc = "5134";
                value = "000000"; % Off
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
                
                % Syringe Speed (High)
                pfc = "0131";
                value = "000003"; % High
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
                
                % 13.5. Move Syringe (Home)
                pfc = "5140";
                value = "000000";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 14. Syringe valve (Wash port, Buffer)
                pfc = "5137";
                value = "000001"; % Wash port 1
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
                
                % 15. Move Syringe (End)
                pfc = "5140";
                value = "000001";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 16. Syringe valve (Needle)
                pfc = "5137";
                value = "000002"; % Needle
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 17. Move Syringe (Home)
                pfc = "5140";
                value = "000000";
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
                
                % Syringe speed (Normal)
                pfc = "0131";
                value = "000002"; % Normal
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
            
                % 18. Washing Cycle
%                 disp('Washing.....')
            
                % 19. Needle vertical up
                pfc = "5135";
                value = "000000"; % Down
                rx = AliasCommunication(portDevice, [], [], [], [], pfc, value);
                rx = WaitUntilWantedRX(portDevice, [], timeOut);
                
                if strcmp(rx, rxInitialize)
                    return
                end
                        
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             disp(app.LogTextArea)
            LoadProtocols(app)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            UI_Communication_Settings(app)
        end

        % Button pushed function: SendButton, SendButton_2, 
        % SendButton_3, SendButton_4, SendButton_5, SendButton_6, 
        % SendButton_7, SendButton_8, SendButton_9
        function SendButtonPushed(app, event)
            stxEdit = 'STXEditField';
            etxEdit = 'ETXEditField';
            idEdit = 'IDEditField';
            aiEdit = 'AIEditField';
            pfcEdit = 'PFCEditField';
            valueEdit = 'ValueEditField';
            
            if ~strcmp(event.Source.Tag, '1')
                
                stxEdit = strcat(stxEdit, '_', event.Source.Tag);
                etxEdit = strcat(etxEdit, '_', event.Source.Tag);
                idEdit = strcat(idEdit, '_', event.Source.Tag);
                aiEdit = strcat(aiEdit, '_', event.Source.Tag);
                pfcEdit = strcat(pfcEdit, '_', event.Source.Tag);
                valueEdit = strcat(valueEdit, '_', event.Source.Tag);
                
            end
            
            stx = app.(stxEdit).Value;
            etx = app.(etxEdit).Value;
            id = sprintf('%02.0f', app.(idEdit).Value);
            ai = sprintf('%02.0f', app.(aiEdit).Value);
            pfc = sprintf('%04.0f', app.(pfcEdit).Value);
            value = sprintf('%06.0f', app.(valueEdit).Value);
            rx = AliasCommunication(app.PortDevice, stx, etx, id, ai, pfc, value);
                        
            if ~isempty(rx)                
                app.LogCell{end+1, 1} = rx;         
                app.LogTextArea.Value = flipud(app.LogCell);
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.LogTextArea.Value = '';
            app.LogCell = '';
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            pfc = "5100";
            value = "000000";
            rx = AliasCommunication(app.PortDevice, [], [], [], [], pfc, value);
            
            if ~isempty(rx)                
                app.LogCell{end+1, 1} = rx;         
                app.LogTextArea.Value = flipud(app.LogCell);
            end
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            pfc = "1001";
            value = "000152";
            rx = AliasCommunication(app.PortDevice, [], [], [], [], pfc, value);
            
            if ~isempty(rx)                
                app.LogCell{end+1, 1} = rx;         
                app.LogTextArea.Value = flipud(app.LogCell);
            end
        end

        % Button pushed function: ErrorCheckButton
        function ErrorCheckButtonPushed(app, event)
            pfc = "1001";
            value = "000155";
            rx = AliasCommunication(app.PortDevice, [], [], [], [], pfc, value);
            
            if ~isempty(rx)                
                app.LogCell{end+1, 1} = rx;         
                app.LogTextArea.Value = flipud(app.LogCell);
            end
        end

        % Button pushed function: ClearErrorsButton
        function ClearErrorsButtonPushed(app, event)
            pfc = "0156";
            value = "000001";
            rx = AliasCommunication(app.PortDevice, [], [], [], [], pfc, value);
            
            if ~isempty(rx)                
                app.LogCell{end+1, 1} = rx;         
                app.LogTextArea.Value = flipud(app.LogCell);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ret = uiconfirm(app.UIFigure,...
                    'Do you want Save Protocols',...
                    'Confirm', 'Icon', 'question', 'Options', {'Yes', 'No'}); 
            
            if strcmp(ret, 'Yes')
                SaveProtocols(app)
            end
            
            SaveProtocols(app)
            delete(app)                        
        end

        % Button pushed function: CycleButton
        function CycleButtonPushed(app, event)
            SampleInjectCycle(app, app.PortDevice, 30, 3, 0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProtocolPanel = uipanel(app.UIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.STXEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_2.ValueDisplayFormat = '%01.0f';
            app.STXEditField_2.Value = 2;
            app.IDEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_2.ValueDisplayFormat = '%02.0f';
            app.IDEditField_2.Value = 61;
            app.AIEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_2.ValueDisplayFormat = '%02.0f';
            app.AIEditField_2.Value = 1;
            app.PFCEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_2.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_2.Value = 100;
            app.ValueEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_2.ValueDisplayFormat = '%06.0f';
            app.ETXEditField_2 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_2.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_2.Value = 3;
            app.STXEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_3.ValueDisplayFormat = '%01.0f';
            app.STXEditField_3.Value = 2;
            app.IDEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_3.ValueDisplayFormat = '%02.0f';
            app.IDEditField_3.Value = 61;
            app.AIEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_3.ValueDisplayFormat = '%02.0f';
            app.AIEditField_3.Value = 1;
            app.PFCEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_3.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_3.Value = 100;
            app.ValueEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_3.ValueDisplayFormat = '%06.0f';
            app.ETXEditField_3 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_3.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_3.Value = 3;
            app.STXEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_4.ValueDisplayFormat = '%01.0f';
            app.STXEditField_4.Value = 2;
            app.IDEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_4.ValueDisplayFormat = '%02.0f';
            app.IDEditField_4.Value = 61;
            app.AIEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_4.ValueDisplayFormat = '%02.0f';
            app.AIEditField_4.Value = 1;
            app.PFCEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_4.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_4.Value = 100;
            app.ValueEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_4.ValueDisplayFormat = '%06.0f';
            app.ETXEditField_4 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_4.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_4.Value = 3;
            app.STXEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_5.ValueDisplayFormat = '%01.0f';
            app.STXEditField_5.Value = 2;
            app.IDEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_5.ValueDisplayFormat = '%02.0f';
            app.IDEditField_5.Value = 61;
            app.AIEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_5.ValueDisplayFormat = '%02.0f';
            app.AIEditField_5.Value = 1;
            app.PFCEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_5.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_5.Value = 100;
            app.ValueEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_5.ValueDisplayFormat = '%06.0f';
            app.ETXEditField_5 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_5.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_5.Value = 3;
            app.MemoEditField_2 = uieditfield(app.ProtocolPanel, 'text');
            app.MemoEditField_3 = uieditfield(app.ProtocolPanel, 'text');
            app.MemoEditField_4 = uieditfield(app.ProtocolPanel, 'text');
            app.MemoEditField_5 = uieditfield(app.ProtocolPanel, 'text');
            app.STXEditFieldLabel = uilabel(app.ProtocolPanel);
            app.STXEditFieldLabel.Text = 'STX';
            app.STXEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField.ValueDisplayFormat = '%01.0f';
            app.STXEditField.Value = 2;
            app.IDEditFieldLabel = uilabel(app.ProtocolPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField.ValueDisplayFormat = '%02.0f';
            app.IDEditField.Value = 61;
            app.AIEditFieldLabel = uilabel(app.ProtocolPanel);
            app.AIEditFieldLabel.Text = 'AI';
            app.AIEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField.ValueDisplayFormat = '%02.0f';
            app.AIEditField.Value = 1;
            app.PFCEditFieldLabel = uilabel(app.ProtocolPanel);
            app.PFCEditFieldLabel.Text = 'PFC';
            app.PFCEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField.ValueDisplayFormat = '%04.0f';
            app.PFCEditField.Value = 1001;
            app.ValueEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField.ValueDisplayFormat = '%06.0f';
            app.ValueEditField.Value = 152;
            app.ETXEditFieldLabel = uilabel(app.ProtocolPanel);
            app.ETXEditFieldLabel.Text = 'ETX';
            app.ETXEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField.ValueDisplayFormat = '%01.0f';
            app.ETXEditField.Value = 3;
            app.MemoEditFieldLabel = uilabel(app.ProtocolPanel);
            app.MemoEditFieldLabel.Text = 'Memo';
            app.MemoEditField = uieditfield(app.ProtocolPanel, 'text');
            app.SendButton = uibutton(app.ProtocolPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Tag = '1';
            app.SendButton.Text = 'Send';
            app.SendButton_2 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_2.Tag = '2';
            app.SendButton_2.Text = 'Send';
            app.SendButton_3 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_3.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_3.Tag = '3';
            app.SendButton_3.Text = 'Send';
            app.SendButton_4 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_4.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_4.Tag = '4';
            app.SendButton_4.Text = 'Send';
            app.SendButton_5 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_5.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_5.Tag = '5';
            app.SendButton_5.Text = 'Send';
            app.STXEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_6.ValueDisplayFormat = '%01.0f';
            app.STXEditField_6.Value = 2;
            app.IDEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_6.ValueDisplayFormat = '%02.0f';
            app.IDEditField_6.Value = 61;
            app.AIEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_6.ValueDisplayFormat = '%02.0f';
            app.AIEditField_6.Value = 1;
            app.PFCEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_6.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_6.Value = 1001;
            app.ValueEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_6.ValueDisplayFormat = '%06.0f';
            app.ValueEditField_6.Value = 152;
            app.ETXEditField_6 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_6.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_6.Value = 3;
            app.MemoEditField_6 = uieditfield(app.ProtocolPanel, 'text');
            app.SendButton_6 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_6.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_6.Tag = '6';
            app.SendButton_6.Text = 'Send';
            app.STXEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_7.ValueDisplayFormat = '%01.0f';
            app.STXEditField_7.Value = 2;
            app.IDEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_7.ValueDisplayFormat = '%02.0f';
            app.IDEditField_7.Value = 61;
            app.AIEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_7.ValueDisplayFormat = '%02.0f';
            app.AIEditField_7.Value = 1;
            app.PFCEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_7.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_7.Value = 1001;
            app.ValueEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_7.ValueDisplayFormat = '%06.0f';
            app.ValueEditField_7.Value = 152;
            app.ETXEditField_7 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_7.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_7.Value = 3;
            app.MemoEditField_7 = uieditfield(app.ProtocolPanel, 'text');
            app.SendButton_7 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_7.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_7.Tag = '7';
            app.SendButton_7.Text = 'Send';
            app.STXEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_8.ValueDisplayFormat = '%01.0f';
            app.STXEditField_8.Value = 2;
            app.IDEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_8.ValueDisplayFormat = '%02.0f';
            app.IDEditField_8.Value = 61;
            app.AIEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_8.ValueDisplayFormat = '%02.0f';
            app.AIEditField_8.Value = 1;
            app.PFCEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_8.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_8.Value = 1001;
            app.ValueEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_8.ValueDisplayFormat = '%06.0f';
            app.ValueEditField_8.Value = 152;
            app.ETXEditField_8 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_8.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_8.Value = 3;
            app.MemoEditField_8 = uieditfield(app.ProtocolPanel, 'text');
            app.SendButton_8 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_8.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_8.Tag = '8';
            app.SendButton_8.Text = 'Send';
            app.STXEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.STXEditField_9.ValueDisplayFormat = '%01.0f';
            app.STXEditField_9.Value = 2;
            app.IDEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.IDEditField_9.ValueDisplayFormat = '%02.0f';
            app.IDEditField_9.Value = 61;
            app.AIEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.AIEditField_9.ValueDisplayFormat = '%02.0f';
            app.AIEditField_9.Value = 1;
            app.PFCEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.PFCEditField_9.ValueDisplayFormat = '%04.0f';
            app.PFCEditField_9.Value = 1001;
            app.ValueEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ValueEditField_9.ValueDisplayFormat = '%06.0f';
            app.ValueEditField_9.Value = 152;
            app.ETXEditField_9 = uieditfield(app.ProtocolPanel, 'numeric');
            app.ETXEditField_9.ValueDisplayFormat = '%01.0f';
            app.ETXEditField_9.Value = 3;
            app.MemoEditField_9 = uieditfield(app.ProtocolPanel, 'text');
            app.SendButton_9 = uibutton(app.ProtocolPanel, 'push');
            app.SendButton_9.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton_9.Tag = '9';
            app.SendButton_9.Text = 'Send';
            app.ResevedPanel = uipanel(app.UIFigure);
            app.ResevedPanel.Title = 'Reseved';
            app.ConnectButton = uibutton(app.ResevedPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.InitializeButton = uibutton(app.ResevedPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusButton = uibutton(app.ResevedPanel, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Text = 'Status';
            app.ErrorCheckButton = uibutton(app.ResevedPanel, 'push');
            app.ErrorCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorCheckButtonPushed, true);
            app.ErrorCheckButton.Text = 'Error Check';
            app.ClearErrorsButton = uibutton(app.ResevedPanel, 'push');
            app.ClearErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearErrorsButtonPushed, true);
            app.ClearErrorsButton.Text = 'Clear Errors';
            app.CycleButton = uibutton(app.ResevedPanel, 'push');
            app.CycleButton.ButtonPushedFcn = createCallbackFcn(app, @CycleButtonPushed, true);
            app.CycleButton.Text = 'Cycle';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.LogTextArea.Editable = 'off';
            app.ClearButton = uibutton(app.LogPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end