classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIConfigurationsUIFigure  matlab.ui.Figure
        ContrastSliderLabel        matlab.ui.control.Label
        ConSlider                  matlab.ui.control.Slider
        BrightnessSliderLabel      matlab.ui.control.Label
        BrtSlider                  matlab.ui.control.Slider
        BrtEditField               matlab.ui.control.EditField
        ConEditField               matlab.ui.control.EditField
        InitialButton              matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        ConfirmButton              matlab.ui.control.Button
    end

    properties (Access = public)
        MainApp % Description
    end
    
    properties (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            
            if isempty(app.MainApp.Brightness)
                
                app.BrtSlider.Value = app.MainApp.BrightnessInit;
                app.BrtEditField.Value = num2str(app.MainApp.BrightnessInit);
                
            else
                
                app.BrtSlider.Value = app.MainApp.Brightness;
                app.BrtEditField.Value = num2str(app.MainApp.Brightness);
                
            end
            
            if isempty(app.MainApp.Contrast)
                
                app.ConSlider.Value = app.MainApp.ContrastInit;
                app.ConEditField.Value = num2str(app.MainApp.ContrastInit);   
                
            else
                
                app.ConSlider.Value = app.MainApp.Contrast;
                app.ConEditField.Value = num2str(app.MainApp.Contrast);      
                
            end
            
            SetButtonStatus(app.MainApp, 'ConfigurationOn')
            
            app.ROIConfigurationsUIFigure.Position(1) = app.MainApp.DropletCounterUIFigure.Position(1);
            app.ROIConfigurationsUIFigure.Position(2) = app.MainApp.DropletCounterUIFigure.Position(2)...
                + app.MainApp.DropletCounterUIFigure.Position(4) - app.ROIConfigurationsUIFigure.Position(4);
              
        end

        % Value changed function: BrtEditField, BrtSlider, 
        % ConEditField, ConSlider
        function ValueChanged(app, event)
            ConfigValueSet(app, event)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ConfigApply(app)
        end

        % Close request function: ROIConfigurationsUIFigure
        function ROIConfigurationsUIFigureCloseRequest(app, event)
            SetButtonStatus(app.MainApp, 'ConfigurationOff')
            
%             app.MainApp.ROICheckBox.Enable = 1;
            app.MainApp.ROIRect.InteractionsAllowed = 'all';
            delete(app)            
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            ConfigApply(app)
            ROIConfigurationsUIFigureCloseRequest(app, event)
        end

        % Button pushed function: InitialButton
        function InitialButtonPushed(app, event)
            app.BrtSlider.Value = app.MainApp.BrightnessInit;
            app.BrtEditField.Value = num2str(app.MainApp.BrightnessInit);
            app.ConSlider.Value = app.MainApp.ContrastInit;
            app.ConEditField.Value = num2str(app.MainApp.ContrastInit);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIConfigurationsUIFigure = uifigure('Visible', 'off');
            app.ROIConfigurationsUIFigure.Name = 'ROI Configurations';
            app.ROIConfigurationsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ROIConfigurationsUIFigureCloseRequest, true);
            app.ContrastSliderLabel = uilabel(app.ROIConfigurationsUIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ConSlider = uislider(app.ROIConfigurationsUIFigure);
            app.ConSlider.Limits = [0 200];
            app.ConSlider.MajorTicks = [0 40 80 120 160 200];
            app.ConSlider.MajorTickLabels = {'0', '40', '80', '120', '160', '200'};
            app.ConSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ConSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200];
            app.ConSlider.Tag = 'ConSld';
            app.ConSlider.Value = 100;
            app.BrightnessSliderLabel = uilabel(app.ROIConfigurationsUIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrtSlider = uislider(app.ROIConfigurationsUIFigure);
            app.BrtSlider.Limits = [0 200];
            app.BrtSlider.MajorTicks = [0 40 80 120 160 200];
            app.BrtSlider.MajorTickLabels = {'0', '40', '80', '120', '160', '200'};
            app.BrtSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.BrtSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200];
            app.BrtSlider.Tag = 'BrtSld';
            app.BrtSlider.Value = 100;
            app.BrtEditField = uieditfield(app.ROIConfigurationsUIFigure, 'text');
            app.BrtEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.BrtEditField.Tag = 'BrtEdt';
            app.ConEditField = uieditfield(app.ROIConfigurationsUIFigure, 'text');
            app.ConEditField.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ConEditField.Tag = 'ConEdt';
            app.InitialButton = uibutton(app.ROIConfigurationsUIFigure, 'push');
            app.InitialButton.ButtonPushedFcn = createCallbackFcn(app, @InitialButtonPushed, true);
            app.InitialButton.Text = 'Default';
            app.ApplyButton = uibutton(app.ROIConfigurationsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ConfirmButton = uibutton(app.ROIConfigurationsUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'OK';
            % Show the figure after all components are created
            app.ROIConfigurationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIConfigurationsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIConfigurationsUIFigure)
        end
    end
end