classdef AudtiTrail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ButtonGroup2          matlab.ui.container.ButtonGroup
        Button3_2             matlab.ui.control.ToggleButton
        Button2_2             matlab.ui.control.ToggleButton
        Button_3              matlab.ui.control.ToggleButton
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        Spinner               matlab.ui.control.Spinner
        SpinnerLabel          matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        Button3               matlab.ui.control.RadioButton
        Button2               matlab.ui.control.RadioButton
        Button_2              matlab.ui.control.RadioButton
        NumberEditField       matlab.ui.control.NumericEditField
        NumberEditFieldLabel  matlab.ui.control.Label
        CheckBox              matlab.ui.control.CheckBox
        UITable               matlab.ui.control.Table
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        Button                matlab.ui.control.Button
    end

    
    properties (Access = public)
        FileId;
    end
    methods (Access = private)

        function decryptedMsg = DecryptMessage(app, encryptedMsg)
            INPUT_LENGTH = 16;    
            resultDecrypt = '';
            for i = 1:floor(size(encryptedMsg, 2)/(INPUT_LENGTH*2))    
                input = encryptedMsg(INPUT_LENGTH*2*(i-1)+1:INPUT_LENGTH*2*(i-1)+INPUT_LENGTH*2);    
                output = UseCipher('Decrypt', input);
                orgStr = char(hex2dec(reshape(output,2,[])'))';
                resultDecrypt = [resultDecrypt, orgStr];
            end
            decryptedMsg = resultDecrypt;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LastName = ["Sanchez";"Johnson";"Zhang";"Diaz";"Brown"];
            Age = [38;43;38;40;49];
            Smoker = [true;false;true;false;true];
            Height = [71;69;64;67;64];
            Weight = [176;163;131;133;119];
            BloodPressure = [124 93; 109 77; 125 83; 117 75; 122 80];
            patients = table(LastName,Age,Smoker,Height,Weight,BloodPressure);
          
            app.UITable.Data = patients;
            app.UITable.ColumnEditable = true(1, 6);
            
            fileName = datestr(now,'yyyy-mmmm-dd-HH-MM-SS');
            app.FileId = fopen(strcat(fileName, '.txt'), 'w');
            
            openedTime = datestr(now, 'yyyy-mmmm-dd HH:MM:SS');  
            msg = sprintf('Opened at : %s', openedTime);
            encryptedMsg = EncrypteAuditMessage(msg);
            fprintf(app.FileId, encryptedMsg);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            srcName = 'Play';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            srcName = 'Name';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            srcName = 'Options';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            srcName = 'Information';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            srcName = 'Include';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changed function: NumberEditField
        function NumberEditFieldValueChanged(app, event)
            srcName = 'Age';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            srcName = 'Radio';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            srcName = 'Spinner';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            srcName = 'List';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            srcName = 'Toggle';
            encryptedMsg = GenerateAuditMessage(app, event, srcName);            
%             decryptedMsg = DecryptMessage(app, encryptedMsg);
%             disp(decryptedMsg)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closedTime = datestr(now, 'yyyy-mmmm-dd HH:MM:SS');  
            msg = sprintf('\nClosed at : %s', closedTime);
            encryptedMsg = EncrypteAuditMessage(msg);
            fprintf(app.FileId, encryptedMsg);
            fclose(app.FileId);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.NumberEditFieldLabel = uilabel(app.UIFigure);
            app.NumberEditFieldLabel.Text = 'Number :';
            app.NumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberEditField.ValueChangedFcn = createCallbackFcn(app, @NumberEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.ButtonGroup2.Title = 'Button Group2';
            app.Button_3 = uitogglebutton(app.ButtonGroup2);
            app.Button_3.Text = 'Button';
            app.Button_3.Value = true;
            app.Button2_2 = uitogglebutton(app.ButtonGroup2);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.ButtonGroup2);
            app.Button3_2.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudtiTrail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end