classdef MagageAudit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PreviewButton      matlab.ui.control.Button
        ExportItemsButton  matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        ListBox            matlab.ui.control.ListBox
        DeleteButton       matlab.ui.control.Button
        AddButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        addedFiles;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'MultiSelect', 'On');
            if isequal(0, file) || isequal(0, path)
                return;
            end            

            if ~iscell(file)
                file = {file};
                path = {path};
            end

            app.addedFiles = [app.addedFiles, fullfile(path, file)];
            app.ListBox.Items = [app.ListBox.Items, file];
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            
            progressdlg = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', 'Message', 'Decrypting input...', 'Indeterminate', 'on');
            if isempty(app.ListBox.Value); return; end

            idx = strcmp(app.ListBox.Value, app.ListBox.Value{1});
            idx = idx(1);
            fileName = app.addedFiles{idx};
            fileId = fopen(fileName);
            encryptedLog = fscanf(fileId, '%s');
            
            %% Decrypt
            INPUT_LENGTH = 16;
            resultDecrypt = '';
            for i = 1:floor(size(encryptedLog, 2)/(INPUT_LENGTH*2))
                input = encryptedLog(INPUT_LENGTH*2*(i-1)+1:INPUT_LENGTH*2*(i-1)+INPUT_LENGTH*2);    
                output = UseCipher('Decrypt', input);
                orgStr = char(hex2dec(reshape(output,2,[])'))';
                resultDecrypt = [resultDecrypt, orgStr];
            end

            app.TextArea.Value = resultDecrypt;
            close(progressdlg);

        end

        % Button pushed function: ExportItemsButton
        function ExportItemsButtonPushed(app, event)
            if isempty(app.ListBox.Value); return; end            

            folderName = uigetdir(pwd);
            if isequal(folderName, 0); return; end

            progressdlg = uiprogressdlg(app.UIFigure,...
                'Title', 'Please Wait',...
                'Message', 'Generating PDF...',...
                'Indeterminate', 'on');            
            for ii = 1:length(app.ListBox.Value)
                idx = strcmp(app.ListBox.Value, app.ListBox.Value{ii});
                idx = find(idx); idx = idx(1);
                filePath = app.addedFiles{idx};
                fileId = fopen(filePath);
                encryptedLog = fscanf(fileId, '%s');
                
                %% Decrypt
                INPUT_LENGTH = 16;
                resultDecrypt = '';
                for i = 1:floor(size(encryptedLog, 2)/(INPUT_LENGTH*2))
                    input = encryptedLog(INPUT_LENGTH*2*(i-1)+1:INPUT_LENGTH*2*(i-1)+INPUT_LENGTH*2);    
                    output = UseCipher('Decrypt', input);
                    orgStr = char(hex2dec(reshape(output,2,[])'))';
                    resultDecrypt = [resultDecrypt, orgStr];
                end
                
                fileName = app.ListBox.Value{ii};
                idxDot = strfind(fileName, '.txt');
                idxDot = idxDot(end);
                fileName = strcat(fileName(1:idxDot-1), '.pdf');
                if isempty(fileName); return; end

                savePath = fullfile(folderName, fileName);
                GenerateReport(savePath, resultDecrypt);                
            end
            close(progressdlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ExportItemsButton = uibutton(app.UIFigure, 'push');
            app.ExportItemsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportItemsButtonPushed, true);
            app.ExportItemsButton.Text = 'Export Items';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagageAudit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end