classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UITable         matlab.ui.control.Table
        DropDown        matlab.ui.control.DropDown
        DropDownLabel   matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
    end

    methods (Access = private)
        
        function auditTrail(app, eventName, srcName, srcType, remarks)            
            dateStr = datestr(now);
            userName = 'user1';
            audit = sprintf('[%s] "%s" (%s/%s/%s) %s', dateStr, userName, srcName, srcType, eventName, remarks);
            disp(audit);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LastName = ["Sanchez";"Johnson";"Zhang";"Diaz";"Brown"];
            Age = [38;43;38;40;49];
            Smoker = [true;false;true;false;true];
            Height = [71;69;64;67;64];
            Weight = [176;163;131;133;119];
            BloodPressure = [124 93; 109 77; 125 83; 117 75; 122 80];
            patients = table(LastName,Age,Smoker,Height,Weight,BloodPressure);
            
            app.UITable.Data = patients;
            app.UITable.ColumnEditable = true(1, 6);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            eventName = event.EventName;
            srcName = event.Source.Text;
            srcType = event.Source.Type;
            remarks = '';
            auditTrail(app, eventName, srcName, srcType, remarks)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            eventName = event.EventName;
            srcName = 'EditField';
            srcType = event.Source.Type;
            remarks = sprintf('%s -> %s', event.PreviousValue, event.Value);
            auditTrail(app, eventName, srcName, srcType, remarks)
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            eventName = event.EventName;
            srcName = 'DropDown';
            srcType = event.Source.Type;
            remarks = sprintf('%s -> %s', event.PreviousValue, event.Value);
            auditTrail(app, eventName, srcName, srcType, remarks)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            eventName = event.EventName;
            srcName = 'uitable';
            srcType = event.Source.Type;

            if ischar(event.PreviousData) || isstring(event.PreviousData)
                changedString = sprintf('%s -> %s', event.PreviousData, event.NewData);
            else
                changedString = sprintf('%d -> %d', event.PreviousData, event.NewData);
            end

            remarks = sprintf('%s (indices : [%d, %d])', changedString, event.Indices(1, 1), event.Indices(1, 2));
            auditTrail(app, eventName, srcName, srcType, remarks)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end