classdef resultApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResultTextArea       matlab.ui.control.TextArea
        ResultTextAreaLabel  matlab.ui.control.Label
        OKButton             matlab.ui.control.Button
        FolderNameLabel      matlab.ui.control.Label
        LoadFolderButton     matlab.ui.control.Button
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CurrentPath = pwd; % Description
        CircleROI;
        Montage1;
        Montage2;
        WBC_Min = 5;
        WBC_Max = 2000;
        MalScan_WBC_Thr = 0.03;
    end
    
    methods (Access = private)
        
        function montageImg = makeMontage(app, imgCell, verNo, horNo)

            imgSize = size(imgCell{1});
            montageImg = zeros(imgSize(1) * horNo, imgSize(2) * verNo, imgSize(3));
            
            startPix = [1, 1];
            j = 1;
            for ii = 1:verNo
                startPix(2) = 1;
                for i = 1:horNo
                    try
                    montageImg(startPix(1):startPix(1)+size(imgCell{j}, 1)-1, startPix(2):startPix(2)+size(imgCell{j}, 1)-1, :) = imgCell{j};
                    startPix(2) = startPix(2) + size(imgCell{i}, 2);
                    j = j+1;
                    catch
                        disp(j)
                    end
                end
                if j < horNo*verNo
                    startPix(1) = startPix(1) + size(imgCell{j}, 1);
                end
            end
        end

        function scanOrderMat = SqrOrderMat(app, noFrame)

            metWidth = noFrame;
            
            ordMet = zeros(metWidth, metWidth);
            [c, r] = size(ordMet);
            
            if mod(metWidth, 2) == 1
            
                cntCor = [(1+c)/2, (1+r)/2];
                
            else
                
                cntCor = [c/2+1, r/2];
                
            end
            
            R = [0 1];
            U = [-1 0];
            L = [0 -1];
            D = [1 0];
            
            numDir = [R; U; L; D];
            numRep = 0;
            
            j = 1;
            while sum(numRep) < metWidth^2
                
                numRep(end+1) = j;
                numRep(end+1) = j;
                j = j+1;
                
            end
            
            numRep(end) = [];
            numRep(end) = [];
            numRep(end+1) = j-2;
            numRep(1) = [];
            
            % Center (Begins)
            numCor = cntCor;
            ordMet(numCor(1), numCor(2)) = 1;
            
            i = 1;
            iii = 1;
            iiii = 1;
            
            while i <= size(numRep, 2)
                    
                for ii = 1:numRep(i)
                    
                    if mod(iiii, 4) == 0
                        
                        dirNum = 4;
                        
                    else
                        
                        dirNum = mod(iiii, 4);
                        
                    end
                    
                    numCor = numCor + numDir(dirNum, :);        
                    ordMet(numCor(1), numCor(2)) = iii+1;
                    iii = iii+1;
            
                end
                
                iiii = iiii+1;    
                i = i+1;
                
            end
            
            scanOrderMat = ordMet;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            
            
            folderName = uigetdir([], '');
            disp(folderName)
            if isequal(folderName, 0)
                return
            end
            app.FolderNameLabel.Text = folderName;
            app.CurrentPath = folderName;

            noFrame = 4;
            scanOrderMat = SqrOrderMat(app, noFrame);
            scanOrderMat = scanOrderMat';
            [~, sortedIdxScan] = sort(scanOrderMat(:), 'ascend');
            [~, sortedIdxScan] = sort(sortedIdxScan, 'ascend');

            listDir = dir(folderName);
            nameList = {listDir.name};
            listFile = nameList([listDir.isdir] == 0);
            listFM = listFile(contains(listFile, 'FM'));

            idxStr = split(listFM, '_');
            idxStr = idxStr(:, :, 1);
            idxNum = str2double(idxStr);
            [~, sortedIdx] = sort(idxNum, 'ascend');
            sortedListFM = listFM(sortedIdx);
            sortedListFM = sortedListFM(sortedIdxScan);

            orgImgCell = cell(length(sortedListFM), 1);

            for i = 1:length(orgImgCell)
                orgImgCell{i} = imread(fullfile(folderName, sortedListFM{i}));
                orgImgCell{i} = double(orgImgCell{i})/65535;
            end

            for i = 1:length(orgImgCell)
                orgImgCell{i} = imread(fullfile(folderName, sortedListFM{i}));
                orgImgCell{i} = double(orgImgCell{i})/65535;
            end
            
            app.Montage1 = makeMontage(app, orgImgCell, noFrame, noFrame);
            % imshow(montageImg1, 'Parent', ax)
            
            img = cell(length(listFM), 1);
            BWImg = cell(length(listFM), 1);
            
            for i = 1:length(listFM)    
                img{i} = imread(fullfile(folderName, sortedListFM{i}));
                try     
                    img{i} = rgb2gray(img{i});        
                end
                img{i} = img{i} - mean(img{i}, 'all');

                BW = imbinarize(medfilt2(img{i}), app.MalScan_WBC_Thr);    
                BW3 = 255 * repmat(uint8(BW), 1, 1, 3);
                BWImg{i} = BW3;            
            
            end
            sortedListBW = BWImg;
            app.Montage2 = makeMontage(app, sortedListBW, noFrame, noFrame);
            app.Montage2 = logical(app.Montage2(:, :, 1));           


            imshow(app.Montage1, 'Parent', app.UIAxes)
            
            y = size(app.Montage1, 1)/2;
            x = size(app.Montage1, 2)/2;

            app.CircleROI = drawcircle(app.UIAxes, 'Radius', round(size(app.Montage1, 1)*0.45), 'Center', round([x, y]));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            label = bwlabel(app.Montage2);
            stats = regionprops(app.Montage2, 'Area', 'centroid', 'PixelIdxList');
            idx = find([stats.Area]>app.WBC_Min&[stats.Area]<app.WBC_Max);
            BW2 = ismember(label, idx);
            stats2 = regionprops(BW2, 'Centroid', 'MajorAxisLength', 'MinorAxisLength');
            centers = cat(1, stats2.Centroid);
            MajorAxisLength = cat(1, stats2.MajorAxisLength);
            MinorAxisLength = cat(1, stats2.MinorAxisLength);
            diameters = mean([MajorAxisLength, MinorAxisLength], 2);
            radii = diameters/2;
            numbers = length(radii);            

            newCenters = [];
            newRadii = [];
            for i = 1:numbers
                if app.CircleROI.Radius >= ((centers(i, 1)-app.CircleROI.Center(1))^2+(centers(i, 2)-app.CircleROI.Center(2))^2)^0.5
                    newCenters(end+1, :) = centers(i, :);
                    newRadii(end+1) = radii(i);
                end
            end
            
            imshow(app.Montage2, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2, 'On');
            viscircles(app.UIAxes_2, newCenters, newRadii, 'Color', 'r');

            app.ResultTextArea.Value = sprintf('Total number of Objects : %d', length(newRadii));

            viscircles(app.UIAxes_2, app.CircleROI.Center, app.CircleROI.Radius, 'Color', app.CircleROI.Color)
            hold(app.UIAxes_2, 'Off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.FolderNameLabel = uilabel(app.UIFigure);
            app.FolderNameLabel.Text = 'Folder Name';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result :';
            app.ResultTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end