classdef easySCAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        pushbutton_connect             matlab.ui.control.Button
        ScanningPanel                  matlab.ui.container.Panel
        edit_RefZ                      matlab.ui.control.NumericEditField
        RefZLabel                      matlab.ui.control.Label
        pushbutton_Open                matlab.ui.control.Button
        pushbutton_Set_Folder          matlab.ui.control.Button
        pushbutton_Capture             matlab.ui.control.Button
        pushbutton_RunSave             matlab.ui.control.Button
        checkbox_withAF                matlab.ui.control.CheckBox
        ImageLogo                      matlab.ui.control.Image
        StatusPanel                    matlab.ui.container.Panel
        text_Status                    matlab.ui.control.Label
        StageTab                       matlab.ui.container.Tab
        StageControlPanel              matlab.ui.container.Panel
        FocusingPanel                  matlab.ui.container.Panel
        Rep3CheckBox                   matlab.ui.control.CheckBox
        GetTestImgsCheckBox            matlab.ui.control.CheckBox
        DefaultButton                  matlab.ui.control.Button
        edit_Range                     matlab.ui.control.NumericEditField
        RangeLabel                     matlab.ui.control.Label
        pushbutton_AF                  matlab.ui.control.Button
        edit_Z_diff                    matlab.ui.control.NumericEditField
        DiffLabel                      matlab.ui.control.Label
        pushbutton_Set_Mode            matlab.ui.control.Button
        pushbutton_Ref                 matlab.ui.control.Button
        edit_Z                         matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        pushbutton_Z_out               matlab.ui.control.Button
        pushbutton_Z_in                matlab.ui.control.Button
        listbox_StageUnit_Z            matlab.ui.control.ListBox
        PositionPanel_2                matlab.ui.container.Panel
        edit_Y                         matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        edit_X                         matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        pushbutton_X_right             matlab.ui.control.Button
        pushbutton_Y_down              matlab.ui.control.Button
        pushbutton_X_left              matlab.ui.control.Button
        pushbutton_Y_up                matlab.ui.control.Button
        listbox_StageUnit              matlab.ui.control.ListBox
        pushbutton_Reset               matlab.ui.control.Button
        SetTab                         matlab.ui.container.Tab
        PreviewConfigurationPanel      matlab.ui.container.Panel
        CameraSettingPanel             matlab.ui.container.Panel
        pushbutton_Cam_Load            matlab.ui.control.Button
        pushbutton_Cam_Save            matlab.ui.control.Button
        slider_Gain                    matlab.ui.control.Slider
        GainSliderLabel                matlab.ui.control.Label
        edit_Gain                      matlab.ui.control.NumericEditField
        slider_Gamma                   matlab.ui.control.Slider
        GammaSliderLabel               matlab.ui.control.Label
        edit_Gamma                     matlab.ui.control.NumericEditField
        slider_Exp                     matlab.ui.control.Slider
        ExposureSliderLabel            matlab.ui.control.Label
        edit_Exp                       matlab.ui.control.NumericEditField
        edit_Inten                     matlab.ui.control.NumericEditField
        slider_Inten                   matlab.ui.control.Slider
        LEDSliderLabel                 matlab.ui.control.Label
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        radiobutton_Off                matlab.ui.control.RadioButton
        radiobutton_Ch2                matlab.ui.control.RadioButton
        radiobutton_Ch1                matlab.ui.control.RadioButton
        ChambTab                       matlab.ui.container.Tab
        ContainerPanel                 matlab.ui.container.Panel
        uibuttongroup_C3               matlab.ui.container.ButtonGroup
        togglebutton_C3_Chamb1         matlab.ui.control.ToggleButton
        axes_C3                        matlab.ui.control.UIAxes
        uibuttongroup_C2               matlab.ui.container.ButtonGroup
        togglebutton_C2_Chamb3         matlab.ui.control.ToggleButton
        togglebutton_C2_Chamb2         matlab.ui.control.ToggleButton
        togglebutton_C2_Chamb1         matlab.ui.control.ToggleButton
        axes_C2                        matlab.ui.control.UIAxes
        uibuttongroup_C1               matlab.ui.container.ButtonGroup
        LabelVer8                      matlab.ui.control.Label
        LabelVer7                      matlab.ui.control.Label
        LabelVer6                      matlab.ui.control.Label
        LabelVer5                      matlab.ui.control.Label
        LabelVer4                      matlab.ui.control.Label
        LabelVer3                      matlab.ui.control.Label
        LabelVer2                      matlab.ui.control.Label
        LabelVer1                      matlab.ui.control.Label
        LabelHor12                     matlab.ui.control.Label
        LabelHor1                      matlab.ui.control.Label
        LabelHor2                      matlab.ui.control.Label
        LabelHor3                      matlab.ui.control.Label
        LabelHor4                      matlab.ui.control.Label
        LabelHor5                      matlab.ui.control.Label
        LabelHor6                      matlab.ui.control.Label
        LabelHor7                      matlab.ui.control.Label
        LabelHor8                      matlab.ui.control.Label
        LabelHor9                      matlab.ui.control.Label
        LabelHor10                     matlab.ui.control.Label
        LabelHor11                     matlab.ui.control.Label
        togglebutton_C1_Chamb96        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb88        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb80        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb72        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb64        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb56        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb48        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb40        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb32        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb24        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb16        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb95        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb87        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb79        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb71        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb63        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb55        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb47        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb39        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb31        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb23        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb15        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb94        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb86        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb78        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb70        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb62        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb54        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb46        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb38        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb30        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb22        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb14        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb93        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb85        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb77        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb69        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb61        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb53        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb45        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb37        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb29        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb21        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb13        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb92        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb84        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb76        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb68        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb60        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb52        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb44        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb36        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb28        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb20        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb12        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb91        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb83        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb75        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb67        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb59        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb51        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb43        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb35        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb27        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb19        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb11        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb90        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb82        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb74        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb66        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb58        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb50        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb42        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb34        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb26        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb18        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb10        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb89        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb81        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb73        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb65        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb57        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb49        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb41        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb33        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb25        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb17        matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb9         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb8         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb7         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb6         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb5         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb4         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb3         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb2         matlab.ui.control.ToggleButton
        togglebutton_C1_Chamb1         matlab.ui.control.ToggleButton
        axes_C1                        matlab.ui.control.UIAxes
        popupmenu_chip                 matlab.ui.control.DropDown
        uibuttongroup_Manual           matlab.ui.container.ButtonGroup
        axes_Manual                    matlab.ui.control.UIAxes
        PosTab                         matlab.ui.container.Tab
        PositionPanel                  matlab.ui.container.Panel
        axes_Canvas                    matlab.ui.control.UIAxes
        TipTab                         matlab.ui.container.Tab
        HiddenOptionsPanel             matlab.ui.container.Panel
        F12EmergencyKeyEnableallbuttonsLabel  matlab.ui.control.Label
        F11StageChamberCalibrationLabel  matlab.ui.control.Label
        F10OpticCameraSettingBeforeconnectLabel  matlab.ui.control.Label
        F9OpticCalibrationWindowLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        diffBackup = 0; % Description      
        set_BM = 0;
        set_FM = 0;
    end
    
    properties (Access = public)
        figure_camera;
        axes_camera;
        ScanProgDlg; % Progress dialogue for Auto Scanning
        HProfile; % For isCode (AutoScan with AutoFocus)
        Fit; % For isCode (AutoScan with AutoFocus)
        AFProgDlg;
        Log = struct;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off all
            clear global
            
%             global NoofChip scanMenu logo_img FluorMode...
%                 lensMag chName AFRange AFInterval refZ
            global scanMenu logo_img FluorMode...
                lensMag chName AFRange refZ ChipInform MainChipNo
            
            global_var(app)
            
            for chipNumi = 1:size(ChipInform, 2)

                grpStr = sprintf('uibuttongroup_C%d', chipNumi);

                grpWidth = app.(grpStr).Position(3);
                grpHeight = app.(grpStr).Position(4);                
                
                numVer = ChipInform(chipNumi).ChamberNum(1);
                numHor = ChipInform(chipNumi).ChamberNum(2);
                
%                 btnWidth = grpWidth / (numVer+2);
%                 btnHeight = grpHeight / (numHor+2);
                
                if strcmp(ChipInform(chipNumi).Type, 'WellPlate')
                    
                    btnWidth = grpWidth / (numVer+2);
                    btnHeight = grpHeight / (numHor+2);

                    labelVer = ['A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'];
                    labelHor = 12:-1:1;
                    labelWidth = btnWidth;
                    labelHeight = btnHeight;
                    
                    for i = 1:length(labelVer)
                            
                        labelStr = sprintf('LabelVer%d', i);
                        labelX = labelWidth * i;
                        labelY = grpHeight - labelHeight;
                        app.(labelStr).Position = [labelX, labelY, labelWidth, labelHeight];
                        app.(labelStr).Text = labelVer(i);
                            
                    end
                    
                    for i = 1:length(labelHor)
                        
                        labelStr = sprintf('LabelHor%d', i);
                        labelX = 0;
                        labelY = grpHeight - (labelHeight * (i+1));
                        app.(labelStr).Position = [labelX, labelY, labelWidth, labelHeight];
                        app.(labelStr).Text = num2str(labelHor(i));
                        
                    end
                    
                    for i = 1:numVer
                    
                        for ii = 1:numHor
                            
                            numBtn = numVer * (ii-1) + i;
                            btnStr = sprintf('togglebutton_C%d_Chamb%d', chipNumi, numBtn);
                            app.(btnStr).Text = '';
                            btnX = btnWidth * (i - 1);
                            btnY = grpHeight - (btnHeight * ii);        
                            app.(btnStr).Position = [btnX+labelWidth btnY-labelHeight btnWidth btnHeight];
                            app.(btnStr).Tag = btnStr;
                            
                        end
                        
                    end

                else
                    
                    btnWidth = grpWidth / numVer - 30;
                    
                    if numHor == 1
                        
                        btnHeight = grpHeight *2/3 - 30;
                       
                    else
                        
                        btnHeight = grpHeight / numHor - 30;
                       
                    end
                    
                    labelWidth = 0;
                    labelHeight = 0;
                    
                    for i = 1:numVer
                    
                        for ii = 1:numHor
                            
                            numBtn = numVer * (ii-1) + i;
                            btnStr = sprintf('togglebutton_C%d_Chamb%d', chipNumi, numBtn);
                            app.(btnStr).Text = '';
                            btnX = btnWidth * (i - 1);
                            btnY = grpHeight - (btnHeight * ii);        
                            app.(btnStr).Position =...
                                [15+btnX+labelWidth,...
                                btnY-labelHeight-30,...
                                btnWidth btnHeight];     
                            app.(btnStr).Tag = btnStr;
                            
                        end
                        
                    end

                end
                
%                 for i = 1:numVer
%                     
%                     for ii = 1:numHor
%                         
%                         numBtn = numVer * (ii-1) + i;
%                         btnStr = sprintf('togglebutton_C%d_Chamb%d', chipNumi, numBtn);
%                         app.(btnStr).Text = '';
%                         btnX = btnWidth * (i - 1);
%                         btnY = grpHeight - (btnHeight * ii);        
%                         app.(btnStr).Position = [btnX+labelWidth btnY-labelHeight btnWidth btnHeight];
%                         app.(btnStr).Tag = btnStr;
%                         
%                     end
%                     
%                 end
                
                axisStr = sprintf('axes_C%d', chipNumi);
                axis(app.(axisStr), 'off')
                set(app.(axisStr), 'Visible', 'Off')
                set(app.(grpStr), 'Visible', 'Off')

            end
            
            set(app.uibuttongroup_Manual, 'Visible', 'off')
%             set(app.uibuttongroup_C1, 'Visible', 'on')            
%             mainBtnGrpStr = sprintf('uibuttongroup_C%d', MainChipNo);
%             set(app.(mainBtnGrpStr), 'Visible', 'on')
            mainToggleBtn = sprintf('togglebutton_C%d_Chamb1', MainChipNo);            
            set(app.(mainToggleBtn), 'Value', 1);
            popupItems = get(app.popupmenu_chip, 'items');
            set(app.popupmenu_chip, 'Value', popupItems{MainChipNo})
            
            set(app.edit_Range, 'Value', AFRange)
            set(app.edit_RefZ, 'Value', refZ)


            if strcmp(FluorMode, 'PE')
            
                set(app.radiobutton_Ch2, 'FontColor', [1 1 0]);
                set(app.radiobutton_Ch2, 'Visible', 'on');
            
            elseif strcmp(FluorMode, 'FITC')
            
                set(app.radiobutton_Ch2, 'FontColor', [0 1 0]);
                set(app.radiobutton_Ch2, 'Visible', 'on');
            
            else
            
                % None
                FluorMode = 'None';
                set(app.radiobutton_Ch2, 'Visible', 'off');
            
            end
            
            nameStr = sprintf('iMeasy100 (%s / %dx / %s)', FluorMode, lensMag, chName);
            set(app.figure1, 'Name', nameStr);
            app.ImageLogo.ImageSource = logo_img;

%             for i = 1:size(ChipInform, 2)
% 
%                 btnGrpStr = sprintf('uibuttongroup_C%d', i);
%                 set(app.(btnGrpStr), 'Visible', 'off')
%             
%             end
                                    
            main_button_Enable(app, 'off');
            set(app.pushbutton_connect, 'Enable', 'on');
            axis(app.axes_camera, 'off');
            axis(app.axes_Canvas, 'off');
            set(app.text_Status, 'Text', 'Ready to Connect');
            
            scanMenu = 2;
            
            scrPos = get(0, 'ScreenSize');
            defFigPos = app.figure1.Position;
            
            app.figure1.Position(1) = scrPos(3) - defFigPos(3) - 10;
            app.figure1.Position(2) = scrPos(4) - defFigPos(4) - 50;            
            
            app.Fit.Gauss.Type = fittype('gauss1');
            app.Fit.Gauss.Opt = fitoptions('Method', 'NonlinearLeastSquares');
            app.Fit.Gauss.Opt.Upper = [Inf, 256, Inf];
            EncodeType = 2;
            Make_HProfile(app, EncodeType, imread('Code(Type2).png'));
            app.HProfile.Mplex.EncodeType{EncodeType}.NumCircle = EncodeType^2;
            app.HProfile.Mplex.EncodeType{EncodeType}.MinSideLth = 150;
            app.HProfile.Mplex.EncodeType{EncodeType}.RemoveR.RectEdge = 5.5/57;
            app.HProfile.Mplex.EncodeType{EncodeType}.RemoveR.SectEdge = 2.75/23;
            app.HProfile.Mplex.EncodeType{EncodeType}.RemoveR.SectCorner = 2.75/23*2;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(1) = 13;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(2) = 12;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(3) = 14;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(4) = 23;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(5) = 34;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(6) = 24;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(7) = 1;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(8) = 3;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(9) = 2;
            app.HProfile.Mplex.EncodeType{EncodeType}.CodeMatrix(10) = 4;
            
            app.ScanProgDlg = uiprogressdlg(app.figure1);
            delete(app.ScanProgDlg)
            app.AFProgDlg = uiprogressdlg(app.figure1);
            delete(app.AFProgDlg)
            
            app.Log.Name = 'easySCAN_log';
            app.Log.FileDir = fullfile('C:', 'BIOROOTS', 'Maximultix', 'easySCAN', 'logs');
            app.Log.FilePath = fullfile...
                (app.Log.FileDir, strcat(datestr(now, 'yyyy-mm-dd HH-MM-SS'), '.txt'));
            
            if isfile(app.Log.Name)
            
                fileattrib(app.Log.Name, '+h');
            
            end
            
            if ~isfolder(app.Log.FileDir)
            
                mkdir(app.Log.FileDir);
            
            else
            
                LogDirList = dir(app.Log.FileDir);
                LogList = natsort({LogDirList(cell2mat({LogDirList.isdir})==0).name});
                LogListLth = length(LogList);
            
                if 99<LogListLth
            
                    for LogListNum = 1:(LogListLth-99)
            
                        delete(fullfile(app.Log.FileDir, LogList{LogListNum}));
            
                    end
            
                end
            
            end
        end

        % Button pushed function: pushbutton_connect
        function pushbutton_connectButtonPushed(app, event)
%             global Main_port DeviceName CamName MainPortNo PortNoFile...
%                 vid src ExpRange GammaRange GainRange IntenRange Ch1_Inten...
%                 C1_Chamb1_X_um C1_Chamb1_Y_um max_step max_step_X...
%                 max_step_Y max_step_Z X_abs_um Y_abs_um Z_abs_um...
%                 step_per_um_X step_per_um_Y Z_default_um step_per_um_Z...
%                 MovDiff_um ROIPosition CamInform;
            global Main_port DeviceName CamName MainPortNo PortNoFile...
                vid src ExpRange GammaRange GainRange IntenRange Ch1_Inten...
                max_step max_step_X...
                max_step_Y max_step_Z X_abs_um Y_abs_um Z_abs_um...
                step_per_um_X step_per_um_Y Z_default_um step_per_um_Z...
                MovDiff_um CamInform ChipInform MainChipNo CurrentChip
            
            set(app.pushbutton_connect, 'Enable', 'off');
            set(app.text_Status, 'Text', 'Connecting......');
            
            pause(0.05);
            
            port_over = 1;
            cam_err = 1;
            
            delete(instrfind);
            imaqreset;
            
%             [cam_err, vid, src, vidRes, CamInform] = CamConnect(CamName, ChipInform(1).ROI);            
            [cam_err, vid, src, vidRes, CamInform] = CamConnect(CamName, ChipInform(MainChipNo).ROI);            
            
            
            if cam_err==1
            
                set(app.text_Status, 'Text', 'Check the Camera Connection!');
                set(app.pushbutton_connect, 'Enable', 'on');
            
                return;
            
            end
            
            if ~isempty(Main_port)
            
                delete(Main_port);
            
            end
            
            i = 1;
            temp_PortNo = [MainPortNo, 1:30];
            
            while 1
            
                try
            
                    if length(temp_PortNo)<i
            
                        break;
            
                    end
            
                    stStr = sprintf('Checking COM%d port......', temp_PortNo(i));
                    set(app.text_Status, 'Text', stStr)
                    pause(0.05);
            
                    portStr = sprintf('COM%d', temp_PortNo(i));
                    Main_port = serial(portStr);
                    set(Main_port, 'BaudRate', 9600, 'DataBits', 8, 'parity', 'none',...
                        'stopbits', 1, 'FlowControl', 'none');
                    Main_port.Timeout = 0.1;
                    Main_port.Terminator = 'CR';
            
                    fopen(Main_port);
            
                    TX = '$D#NAME';
                    RX = strcat(TX, '#', DeviceName, '#OK');
            
                    tic;
                    t = 0;
                
                    while t<1
                
                        t = toc;
                
                    end
                
                    [~, ~] = comm2portExcReconn('Main_port', TX, RX);
                    [~, Err] = comm2portExcReconn('Main_port', TX, RX);
            
                    if Err == 1
            
                        fclose(Main_port);
                        delete(Main_port);
            
                        i = i+1;
            
                        continue;
            
                    else
                        
                        txStr = sprintf('$L#1#ON#%d', Ch1_Inten);
                        rxStr = strcat(txStr, '#OK');
                        [~] = comm2port(app, 'Main_port', txStr, rxStr);
                        [~] = comm2port(app, 'Main_port', '$L#2#OFF', '$L#2#OFF#OK');
                        port_over = 0;
            
                        break;
            
                    end
            
                catch
            
                    delete(Main_port);
            
                    i = i+1;
            
                end
            
            end
            
            if port_over==1
                
                set(app.text_Status, 'Text', 'Check the cable connections!')
                set(app.pushbutton_connect, 'Enable', 'on')
            
                return;
            
            end
            
            Main_port.Timeout = 40;
            
            if isempty(max_step_X)||isempty(max_step_Y)||isempty(max_step_Z)
            
                set(app.text_Status, 'Text', 'Carlibarting the stage coordinates.')
                [~] = stg_calibr(app);
                
            
            end
            
            set(app.text_Status, 'Text', 'Initiallizing...')
            calcul_step4chamb;
            
%             C1_Chamb1_X = round(C1_Chamb1_X_um * step_per_um_X);
%             C1_Chamb1_Y = round(C1_Chamb1_Y_um * step_per_um_Y);            
            C1_Chamb1_X = round(ChipInform(MainChipNo).ChamberRange{1, 1}(1) * step_per_um_X);
            C1_Chamb1_Y = round(ChipInform(MainChipNo).ChamberRange{1, 2}(1) * step_per_um_Y);
            Z_default_step = round(Z_default_um * step_per_um_Z);
            
            [~, ~] = comm2portExcReconn('Main_port', '$S#Z#B#40000', []);
            
            maxTX = sprintf('$S#X#F#%d', max_step);
            maxRX = strcat(maxTX, '#EM');
            [~, comm_err_XO] = comm2portExcReconn('Main_port', maxTX, maxRX);
            TX = strcat('$S#X#B#', num2str(C1_Chamb1_X(1)-MovDiff_um));
            [~, comm_err_XR] = comm2portExcReconn('Main_port', TX, []);
            
            maxTX = sprintf('$S#Y#F#%d', max_step);
            maxRX = strcat(maxTX, '#EM');
            [~, comm_err_YO] = comm2portExcReconn('Main_port', maxTX, maxRX);
            TX = strcat('$S#Y#B#', num2str(C1_Chamb1_Y(1)-MovDiff_um));
            [~, comm_err_YU] = comm2portExcReconn('Main_port', TX, []);
            
            maxTX = sprintf('$S#Z#F#%d', max_step);
            maxRX = strcat(maxTX, '#EM');
            [~, comm_err_ZO] = comm2portExcReconn('Main_port', maxTX, maxRX);            
            TX = strcat('$S#Z#B#', num2str(Z_default_step-MovDiff_um));
            [~, comm_err_ZU] = comm2portExcReconn('Main_port', TX, []);
            
            
            if comm_err_XO||comm_err_XR||comm_err_YO||comm_err_YU||comm_err_ZO||comm_err_ZU
            
                try
            
                    [~, ~] = comm2portExcReconn('Main_port', '$L#1#OFF', []);
            
                end

                set(app.text_Status, 'Text', 'Check the device connections!');
                set(app.pushbutton_connect, 'Enable', 'on');
            
                return;
            
            end
            
            X_abs_um = ChipInform(MainChipNo).ChamberRange{1, 1}(1);
            Y_abs_um = ChipInform(MainChipNo).ChamberRange{1, 2}(1);
%             X_abs_um = C1_Chamb1_X_um(1);
%             Y_abs_um = C1_Chamb1_Y_um(1);
            Z_abs_um = Z_default_um;
%             pupItems = get(app.popupmenu_chip, 'Items');            
%             set(app.popupmenu_chip, 'Value', pupItems{1});
            
            CurrentChip = MainChipNo;
            PopupChipCallback(app)
            main_button_Enable(app, 'off');
            
            MainPortNo = temp_PortNo(i);
            save(PortNoFile, 'MainPortNo');
            stStr = sprintf('Connected. (COM%d)', temp_PortNo(i));
            set(app.text_Status, 'Text', stStr)
            pause(0.01);
            set(app.text_Status, 'Text', 'Searching the camera......');
            
            pause(0.05);

%             [cam_err, vid, src, vidRes, CamInform] = CamConnect(CamName, ROIPosition);
%             [cam_err, vid, src, vidRes, CamInform] = CamConnect(CamName, ChipInform(1).ROI);            
%             
%             if cam_err==1
%             
%                 set(app.text_Status, 'Text', 'Check the Camera Connection!');
%                 set(app.pushbutton_connect, 'Enable', 'on');
%             
%                 return;
%             
%             end
            
            set(app.text_Status, 'Text', 'Camera Setting...');
            
            app.figure_camera = figure;
            app.figure_camera.MenuBar = 'None';
            app.figure_camera.ToolBar = 'None';
            app.figure_camera.CloseRequestFcn = '';
            app.figure_camera.Name = 'Camera Preview';
            app.figure_camera.NumberTitle = 'off';
            app.axes_camera = axes(app.figure_camera);
            app.figure_camera.UserData = app;
            app.figure_camera.WindowKeyPressFcn = @(app, event) CamKeyPressFcn(app, event);
            
            isConnection = 1;
            SettingCameraROI(app, ChipInform(MainChipNo).ROI, isConnection)
                        
%             scrPos = get(0, 'ScreenSize');
            
%             if vidRes(1)/2 > scrPos(3) && vidRes(2)/2 > scrPos(4)
%                 
%                 app.figure_camera.Position(3) = scrPos(3)/2 - 10;
%                 app.figure_camera.Position(4) = scrPos(4)/2 - 10;
%                 app.figure_camera.Position(1) = 10;
%                 app.figure_camera.Position(2) = 50;
%                 
%             else
%                 
%                 app.figure_camera.Position(3) = vidRes(1)/2;
%                 app.figure_camera.Position(4) = vidRes(2)/2;
%                 app.figure_camera.Position(1) = app.figure1.Position(1) - app.figure_camera.Position(3) - 10;
%                 app.figure_camera.Position(2) = scrPos(4) - app.figure_camera.Position(4) - 50;        
%                 
%             end
            
%             nBands = get(vid, 'NumberOfBands');
%             previewImage = image(zeros(vidRes(2), vidRes(1), nBands), 'parent', app.axes_camera);
%             preview(vid, previewImage);
%             CamInform.Exp.Tic = tic;
%             CamInform.Exp.AdaptTime = 0;
%             CamInform.Exp.Prv = src.ExposureTime;
%             CamInform.Pos.Tic = CamInform.Exp.Tic;
%             CamInform.Pos.AdaptTime = 0;
%             start(vid)
            
%             app.axes_camera.PlotBoxAspectRatioMode = 'manual';
%             app.axes_camera.PlotBoxAspectRatio = [vidRes(1) vidRes(2) 1];
%             app.axes_camera.InnerPosition = [0.01 0.01 0.98 0.98];
            
            set(app.text_Status, 'Text', 'Software Option Setting...');
            
            set(app.slider_Exp, 'Limits', ExpRange)            
            set(app.slider_Gamma, 'Limits', GammaRange)
            set(app.slider_Gamma, 'MinorTicks', GammaRange(1):0.1:GammaRange(2))
            set(app.slider_Gain, 'Limits', GainRange)
            set(app.slider_Inten, 'Limits', IntenRange)
            
            set(app.slider_Exp, 'Majorticks', [50, 500, 1000, 1500, 2000])
            set(app.slider_Exp, 'MajortickLabels', {'50', '500', '1000', '1500', '2000'})
            set(app.slider_Gamma, 'Majorticks', [1, 1.5, 2, 2.5, 3, 3.5, 4])
            set(app.slider_Gamma, 'MajortickLabels', {'1', '1.5', '2', '2.5', '3', '3.5', '4'})
            set(app.slider_Gain, 'Majorticks', [0, 5, 10, 15, 20, 24])
            set(app.slider_Gain, 'MajortickLabels', {'0', '5', '10', '15', '20', '24'})
            set(app.slider_Inten, 'Majorticks', [0, 50, 100, 150, 200, 250])
            set(app.slider_Inten, 'MajortickLabels', {'0', '50', '100', '150', '200', '250'})
            
            set(app.edit_X, 'Value', X_abs_um);
            set(app.edit_Y, 'Value', Y_abs_um);
            set(app.edit_Z, 'Value', Z_abs_um);
            Ch_set(app, 1);
            set(app.text_Status, 'Text', 'Connected Successfully!');
            set(app.pushbutton_connect, 'Visible', 'off');
            set(app.ImageLogo, 'Visible', 'on')
            main_button_Enable(app, 'on');
            
            mainBtnGrpStr = sprintf('uibuttongroup_C%d', MainChipNo);
            set(app.(mainBtnGrpStr), 'Visible', 'on')
            
            btnStr = sprintf('togglebutton_C%d_Chamb1', CurrentChip);
            selectedButton = app.(btnStr);
            btnTag = selectedButton.Tag;
            btnNum = str2double(btnTag(22:end));
            btnTag = btnTag(1:21);            
            togglebutton_Chamb_Act(app, btnTag, btnNum, ChipInform(CurrentChip))            
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            global Main_port NoofChannel ChPropList AFRange AFInterval refZ ChPropFile AFPropFile
            
            if isfile(app.Log.Name)
                
                if ~isfolder(app.Log.FileDir)
            
                    mkdir(app.Log.FileDir);
            
                end
            
                copyfile(app.Log.Name, app.Log.FilePath)
                fileattrib(app.Log.FilePath, '-h')
                delete(app.Log.Name)
            
            end
            
            Date = date;
            
            try
            
                save(ChPropFile, 'Date');
            
                for i = 1:length(ChPropList)
            
                    ChPropList_i = ChPropList{i};
            
                    for j = 1:NoofChannel
            
                        eval(sprintf('ChPropList_ij = ''Ch%d_%s'';', j, ChPropList_i));
                        eval(sprintf('global %s;', ChPropList_ij));
                        eval(sprintf('save(ChPropFile, ''%s'', ''-append'');', ChPropList_ij));
            
                    end
            
                end
                
            end
            
            try
                
                save(AFPropFile, 'AFRange')
                save(AFPropFile, 'AFInterval', '-append')
                save(AFPropFile, 'refZ', '-append')
                
            end
            
            try
            
                frpintf(Main_port, '$L#1#OFF')
                frpintf(Main_port, '$L#2#OFF')
                fclose(Main_port);
                
            catch
                
                delete(instrfind)
                
            end
            
            clear global;
            close all force;
        end

        % Value changed function: edit_X
        function edit_XValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            global X_abs_um C_Manual_X_um cur_Chip NoofChip X_curDir MovDiff_um
            
            tmp_um = app.edit_X.Value;
            
            if cur_Chip==(NoofChip+1)
            
               if tmp_um <= C_Manual_X_um(1)
            
                    TX = strcat('$S#', 'X', '#', 'F', '#', '999999');
                    [~] = comm2port('Main_port', TX, []);
                    X_curDir = 'B';
                    X_abs_um = MovDiff_um;
                    set(app.edit_X, 'Value', X_abs_um);
                    disp_Manual_inform
                    return
            
               elseif tmp_um >= C_Manual_X_um(2)
            
                    TX = strcat('$S#', 'X', '#', 'B', '#', '999999');
                    [~] = comm2port('Main_port', TX, []);
                    X_curDir = 'F';
                    X_abs_um = C_Manual_X_um(2) - MovDiff_um;
                    set(app.edit_X, 'Value', X_abs_um);
                    disp_Manual_inform
                    return
            
               end
            
            end
            
            if X_abs_um < tmp_um
            
                tmp_dir =  'B';
                tmp_inq_um = tmp_um - X_abs_um;
                Stage_Control(app, 'X', tmp_dir, tmp_inq_um);
            
            elseif X_abs_um > tmp_um
            
                tmp_dir = 'F';
                tmp_inq_um = X_abs_um - tmp_um;
                Stage_Control(app, 'X', tmp_dir, tmp_inq_um);
            
            end
            
            delete(progDlg)

            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Y
        function edit_YValueChanged(app, event)
            global Y_abs_um C_Manual_Y_um cur_Chip NoofChip Y_curDir MovDiff_um
            
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            tmp_um = app.edit_Y.Value;
            
            if cur_Chip==(NoofChip+1)
            
               if tmp_um <= C_Manual_Y_um(1)
            
                    TX = strcat('$S#', 'Y', '#', 'F', '#', '999999');
                    [~] = comm2port(app, 'Main_port', TX, []);
                    Y_curDir = 'B';
                    Y_abs_um = MovDiff_um;
                    set(app.edit_Y, 'Value', Y_abs_um);
                    disp_Manual_inform(app)
                    return
            
               elseif tmp_um >= C_Manual_Y_um(2)
            
                    TX = strcat('$S#', 'Y', '#', 'B', '#', '999999');
                    [~] = comm2port(app, 'Main_port', TX, []);
                    Y_curDir = 'F';
                    Y_abs_um = C_Manual_Y_um(2) - MovDiff_um;
                    set(app.edit_Y, 'Value', Y_abs_um);
                    disp_Manual_inform(app)
                    return
            
               end
            
            end
            
            if Y_abs_um < tmp_um
            
                tmp_dir =  'B';
                tmp_inq_um = tmp_um - Y_abs_um;
                Stage_Control(app, 'Y', tmp_dir, tmp_inq_um);
            
            elseif Y_abs_um > tmp_um
            
                tmp_dir = 'F';
                tmp_inq_um = Y_abs_um - tmp_um;
                Stage_Control(app, 'Y', tmp_dir, tmp_inq_um);
            
            end
            
            delete(progDlg)
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Z
        function edit_ZValueChanged(app, event)
            global Z_abs_um C_Manual_Z_um Z_curDir MovDiff_um
            
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            tmp_um = app.edit_Z.Value;
            
            if tmp_um <= C_Manual_Z_um(1)
            
                TX = strcat('$S#', 'Z', '#', 'F', '#', '999999');
                [~] = comm2port(app, 'Main_port', TX, []);
                Z_curDir = 'B';
                Z_abs_um = MovDiff_um;
                set(app.edit_Z, 'Value', Z_abs_um);
                return
            
            elseif tmp_um >= C_Manual_Z_um(2)
            
                TX = strcat('$S#', 'Z', '#', 'B', '#', '999999');
                [~] = comm2port(app, 'Main_port', TX, []);
                Z_curDir = 'F';
                Z_abs_um = C_Manual_Z_um(2) - MovDiff_um;
                set(app.edit_Z, 'Value', Z_abs_um);
                return
            
            end
            
            if Z_abs_um < tmp_um
            
                tmp_dir =  'B';
                tmp_inq_um = tmp_um - Z_abs_um;
                Stage_Control(app, 'Z', tmp_dir, tmp_inq_um);
            
            elseif Z_abs_um > tmp_um
            
                tmp_dir = 'F';
                tmp_inq_um = Z_abs_um - tmp_um;
                Stage_Control(app, 'Z', tmp_dir, tmp_inq_um);
            
            end
            
            delete(progDlg)
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_X_left
        function pushbutton_X_leftButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'X', 'F', []);
            
            delete(progDlg)
                  
            figure(app.figure1)
            figure(app.figure_camera)            
            
        end

        % Button pushed function: pushbutton_X_right
        function pushbutton_X_rightButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'X', 'B', []);
            
            delete(progDlg)
                    
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Y_up
        function pushbutton_Y_upButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'Y', 'F', []);
            
            delete(progDlg)
               
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Y_down
        function pushbutton_Y_downButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'Y', 'B', []);
            
            delete(progDlg)


            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Z_in
        function pushbutton_Z_inButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'Z', 'F', []);
            
            delete(progDlg)
            
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Z_out
        function pushbutton_Z_outButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Stage Control...', 'Indeterminate', 'on');
            
            Stage_Control(app, 'Z', 'B', []);
            
            delete(progDlg)
            
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Reset
        function pushbutton_ResetButtonPushed(app, event)
            global X_abs_um Y_abs_um
            
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Reset X, Y Stage', 'Indeterminate', 'on');
            prevX = X_abs_um;
            prevY = Y_abs_um;
            
            ResetStage(app, [1 1 0])
            Stage_Control(app, 'X', 'B', prevX)
            Stage_Control(app, 'Y', 'B', prevY)
            delete(progDlg)
            
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_AF
        function pushbutton_AFButtonPushed(app, event)
            global AF_flag Z_abs_um
            
            app.AFProgDlg = uiprogressdlg(app.figure1,...
                'Message', 'Auto Focusing',...
                'Indeterminate', 'on',...
                'Cancelable', 'on');
            
            AF_flag = 1;
            
            if app.GetTestImgsCheckBox.Value == 1
                
                GetAFImgs(app)
                
            else
                
                AutoFocusing(app, Z_abs_um)

            end

            delete(app.AFProgDlg)
            
            AF_flag = 0;
            
            figure(app.figure_camera)
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Bright')
                
                Ch_set(app, 1)
                
            elseif strcmp(selectedButton.Text, 'Fluorescence')
                
                Ch_set(app, 2)
                
            else
                
                [~] = comm2port(app, 'Main_port', '$L#1#OFF', '$L#1#OFF#OK');
                [~] = comm2port(app, 'Main_port', '$L#2#OFF', '$L#2#OFF#OK');
                
            end
            
            delete(progDlg)
            
            figure(app.figure_camera)
        end

        % Value changed function: slider_Inten
        function slider_IntenValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            slider_Prop(app, 'Inten');
            
            delete(progDlg)
                   
            figure(app.figure_camera)
        end

        % Value changed function: slider_Exp
        function slider_ExpValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            slider_Prop(app, 'Exp');
            
            delete(progDlg)
                     
            figure(app.figure_camera)
        end

        % Value changed function: slider_Gamma
        function slider_GammaValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            slider_Prop(app, 'Gamma');
            
            delete(progDlg)
            
            figure(app.figure_camera)
        end

        % Value changed function: slider_Gain
        function slider_GainValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            slider_Prop(app, 'Gain');
            
            delete(progDlg)
                
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Cam_Save
        function pushbutton_Cam_SaveButtonPushed(app, event)
            global set_folder cur_Channel
            
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Saving...', 'Indeterminate', 'on');
            
            Inten_val  = get(app.edit_Inten, 'Value');
            Exp_val    = get(app.edit_Exp, 'Value');
            Gamma_val  = get(app.edit_Gamma, 'Value');
            Gain_val   = get(app.edit_Gain, 'Value');
            
            def_folder = set_folder;
            def_file   = sprintf('camera_setting_Ch%d.mat', cur_Channel);
            def_name   = fullfile(def_folder, def_file);
            
            try
            
                [file, path] = uiputfile('*.mat', '', def_name);
            
            catch
            
                def_name = fullfile(pwd, def_file);
                [file, path] = uiputfile('*.mat', '', def_name);
            
            end
            
            if file == 0
            
                return;
            
            else
            
                set_folder_file = fullfile(path, file);
                save(set_folder_file, 'Inten_val', 'Exp_val', 'Gamma_val', 'Gain_val');
                set_folder = path;
            
            end
            
            delete(progDlg)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Cam_Load
        function pushbutton_Cam_LoadButtonPushed(app, event)
            global set_folder cur_Channel
            
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Loading...', 'Indeterminate', 'on');
            
            eval(sprintf('global Ch%d_Inten;', cur_Channel));
            eval(sprintf('global Ch%d_Exp;', cur_Channel));
            eval(sprintf('global Ch%d_Gamma;', cur_Channel));
            eval(sprintf('global Ch%d_Gain;', cur_Channel));
            
            def_folder = set_folder;
            def_file = sprintf('camera_setting_Ch%d.mat', cur_Channel);
            def_name = fullfile(def_folder, def_file);
            
            try
            
                [file, path] = uigetfile('*.mat', '', def_name);
            
            catch
            
                def_name = fullfile(pwd, def_file);
                [file, path] = uigetfile('*.mat', '', def_name);
            
            end
            
            if file == 0
            
                return;
            
            else
            
                set_folder_file = fullfile(path, file);
                load(set_folder_file);
                set_folder = path;
            
                try
            
                    eval(sprintf('Ch%d_Inten = Inten_val;', cur_Channel));
                    eval(sprintf('Ch%d_Exp = Exp_val;', cur_Channel));
                    eval(sprintf('Ch%d_Gamma = Gamma_val;', cur_Channel));
                    eval(sprintf('Ch%d_Gain = Gain_val;', cur_Channel));
                    Ch_set(app, cur_Channel);
            
                end
            
            end
            
            delete(progDlg)
            figure(app.figure_camera)
        end

        % Value changed function: popupmenu_chip
        function popupmenu_chipValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Container Changing...', 'Indeterminate', 'on');
            
            PopupChipCallback(app)
            
            delete(progDlg)
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            global StageFlag CurrentChip ChipInform

            if StageFlag == 1
                
                return;
                
            end
            
            key = event.Key;
            if strcmp(key, 'leftarrow')

                Stage_Control(app, 'X', 'F', []);
            
            elseif strcmp(key, 'rightarrow')
            
                    Stage_Control(app, 'X', 'B', []);
            
            elseif strcmp(key, 'uparrow')
            
                    Stage_Control(app, 'Y', 'F', []);
            
            elseif strcmp(key, 'downarrow')
            
                    Stage_Control(app, 'Y', 'B', []);
            
            elseif strcmp(key, 'pageup')
            
                    Stage_Control(app, 'Z', 'F', []);
            
            elseif strcmp(key, 'pagedown')
            
                    Stage_Control(app, 'Z', 'B', []);
            
            elseif strcmp(key, 'space')
            
                    progDlg = uiprogressdlg(app.figure1,...
                            'Message', 'Image Capture...', 'Indeterminate', 'on');
                        
                    Capture_Image(app)
                    delete(progDlg)
                    
                    figure(app.figure1)
                    figure(app.figure_camera)
                    
%             elseif strcmp(key, 'f8')
%         
%                 if ~get(app.pushbutton_connect, 'Visible')
%                 
%                     MeasureMoveTime(app)
%                     
%                 end
            
            elseif strcmp(key, 'f9')
                
                if ~get(app.pushbutton_connect, 'Visible')
                
                    OpticCarlibration(app)
                    
                end    
        
            elseif strcmp(key, 'f10')
                
                if get(app.pushbutton_connect, 'Visible')
                    
                    SelectFluoMode(app)
                    
                end
                
            elseif strcmp(key, 'f11')
                
                if ~get(app.pushbutton_connect, 'Visible')
                    
                    if size(ChipInform, 2)+1 == CurrentChip
                        
                        return
                        
                    end
                    
                    CoorCorrWell(app)
                    
                end
                
            elseif strcmp(key, 'f12')
                
                main_button_Enable(app, 'on')
                app.GetTestImgsCheckBox.Visible = 1;
                app.Rep3CheckBox.Visible = 1;
                                
            end        
        end

        % Button pushed function: pushbutton_Capture
        function pushbutton_CaptureButtonPushed(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Image Capture...', 'Indeterminate', 'on');
            
            Capture_Image(app)
            
            delete(progDlg)
                       
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Set_Folder
        function pushbutton_Set_FolderButtonPushed(app, event)
            pushbutton_Set_Folder(app) %#ok<ADPROPLC> 
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Open
        function pushbutton_OpenButtonPushed(app, event)
            global cap_folder
            
            try
            
                winopen(cap_folder)
                
            catch
                
                disp('Folder open is failed.')
            
            end
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_RunSave
        function pushbutton_RunSaveButtonPushed(app, event)
            global CurrentChip ChipInform
            
            if strcmp(ChipInform(CurrentChip).Type, 'WellPlate')
            
                SelectSample(app)
                
            elseif strcmp(ChipInform(CurrentChip).Type, 'Droplet')               
                                
                if size(ChipInform(CurrentChip).ChamberRange, 1) == 1
                
                    app.ScanProgDlg = uiprogressdlg(app.figure1,...
                        'Message', 'Auto Scanning...',...
                        'Cancelable', 'on',...
                        'Indeterminate', 'on');
                    
                    selBtnNo = 1;
                    
                    ScanDropletChip(app, ChipInform(CurrentChip), selBtnNo)
                    
                elseif size(ChipInform(CurrentChip).ChamberRange, 1) > 1
                
                    mainAppPos = app.figure1.Position;
                    w = mainAppPos(4)*0.7;
                    h = mainAppPos(4);
                    x = mainAppPos(1) - w - 10;
                    y = mainAppPos(2);
                    mainFigPosition = [x, y, w, h];
                    GenerateChamberSettingWindow(...
                        app, size(ChipInform(CurrentChip).ChamberRange, 1),...
                        mainFigPosition, ChipInform(CurrentChip))
                    
                end
            end
        end

        % Callback function
        function pushbutton_StopButtonPushed(app, event)
%             global Stop_flag;
%             
%             Stop_flag = 1;
            
%             set(app.pushbutton_Stop, 'Enable', 'off');
%             set(app.text_Status, 'Text', 'Please wait......');
%             
%             pause(0.05);
        end

        % Value changed function: edit_Inten
        function edit_IntenValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            edit_Prop(app, 'Inten')
            
            delete(progDlg)
                   
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Exp
        function edit_ExpValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            edit_Prop(app, 'Exp')
            
            delete(progDlg)
                    
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Gamma
        function edit_GammaValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            edit_Prop(app, 'Gamma')
            
            delete(progDlg)
                   
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Gain
        function edit_GainValueChanged(app, event)
            progDlg = uiprogressdlg(app.figure1,...
                'Message', 'Camera Setting...', 'Indeterminate', 'on');
            
            edit_Prop(app, 'Gain')
            
            delete(progDlg)
                   
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Set_Mode
        function pushbutton_Set_ModeButtonPushed(app, event)
            global Z_abs_um
            
            tmp = get(app.pushbutton_Set_Mode, 'Text');
            
            if strcmp(tmp, 'Set BM')    
            
                app.set_BM = Z_abs_um;
                set(app.edit_Z_diff, 'Value', 0);
                set(app.edit_Z_diff, 'Enable', 'off')
                set(app.pushbutton_Set_Mode, 'Text', 'Set FM')
                
            elseif strcmp(tmp, 'Set FM')
            
                app.set_FM = Z_abs_um;
                set(app.edit_Z_diff, 'Value', app.set_BM-app.set_FM)
                set(app.pushbutton_Set_Mode, 'Text', 'Clear')    
                
            else
                
                app.set_BM = Z_abs_um;
                app.set_FM = Z_abs_um;
                set(app.edit_Z_diff, 'Value', app.set_BM-app.set_FM)
                set(app.pushbutton_Set_Mode, 'Text', 'Set BM')
                set(app.edit_Z_diff, 'Enable', 'on')
                
            end
            
            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_RefZ
        function edit_RefZValueChanged(app, event)
            global refZ
            
            refZ = event.Source.Value;

            figure(app.figure1)
            figure(app.figure_camera)
        end

        % Value changed function: edit_Range
        function edit_RangeValueChanged(app, event)
            global AFRange
            
            if event.Source.Value > 200
                
                AFRange = event.Source.Value;
                
            else
                
                event.Source.Value = 200;
                
            end
                        
            figure(app.figure1)      
            figure(app.figure_camera)
        end

        % Callback function
        function edit_IntervalValueChanged(app, event)
            global AFInterval
            
            AFInterval = event.Source.Value;
            
            figure(app.figure1)         
            figure(app.figure_camera)
        end

        % Button pushed function: pushbutton_Ref
        function pushbutton_RefButtonPushed(app, event)
            global refZ
            
            refZ = app.edit_Z.Value;
            app.edit_RefZ.Value = refZ;
            
            figure(app.figure1)   
            figure(app.figure_camera)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            global AFRange AFRangeDefault
            
            AFRange = AFRangeDefault;
            app.edit_Range.Value = AFRange;
            
            figure(app.figure1)     
            figure(app.figure_camera)
        end

        % Value changed function: listbox_StageUnit
        function listbox_StageUnitValueChanged(app, event)
            figure(app.figure1)            
            figure(app.figure_camera)
        end

        % Value changed function: listbox_StageUnit_Z
        function listbox_StageUnit_ZValueChanged(app, event)
            figure(app.figure1)               
            figure(app.figure_camera)
        end

        % Value changed function: checkbox_withAF
        function checkbox_withAFValueChanged(app, event)
            figure(app.figure1)             
            figure(app.figure_camera)
        end

        % Value changed function: edit_Z_diff
        function edit_Z_diffValueChanged(app, event)
            figure(app.figure1)             
            figure(app.figure_camera)
        end

        % Selection changed function: uibuttongroup_C1, uibuttongroup_C2
        function uibuttongroup_SelectionChanged(app, event)
            global ChipInform CurrentChip
            
%             progDlg = uiprogressdlg(app.figure1,...
%                 'Message', 'Chamber Moving...', 'Indeterminate', 'on');
            
%             chipNo = str2double(event.Source.Title(7:end));            
            selectedButton = event.Source.SelectedObject;
            btnTag = selectedButton.Tag;
            btnNum = str2double(btnTag(22:end));
            btnTag = btnTag(1:21);
            
            togglebutton_Chamb_Act(app, btnTag, btnNum, ChipInform(CurrentChip))
            
%             delete(progDlg)            
                  
            figure(app.figure1)
            figure(app.figure_camera)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'iMeasy for MultiPlex';
            app.figure1.Icon = 'Max_icon.png';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.TabGroup = uitabgroup(app.figure1);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.StatusPanel = uipanel(app.MainTab);
            app.StatusPanel.Title = 'Status';
            app.text_Status = uilabel(app.StatusPanel);
            app.text_Status.Text = '';
            app.ImageLogo = uiimage(app.MainTab);
            app.ImageLogo.Visible = 'off';
            app.ScanningPanel = uipanel(app.MainTab);
            app.ScanningPanel.Title = 'Scanning';
            app.checkbox_withAF = uicheckbox(app.ScanningPanel);
            app.checkbox_withAF.ValueChangedFcn = createCallbackFcn(app, @checkbox_withAFValueChanged, true);
            app.checkbox_withAF.Text = 'Auto Focus';
            app.pushbutton_RunSave = uibutton(app.ScanningPanel, 'push');
            app.pushbutton_RunSave.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_RunSaveButtonPushed, true);
            app.pushbutton_RunSave.Text = 'Auto Scan';
            app.pushbutton_Capture = uibutton(app.ScanningPanel, 'push');
            app.pushbutton_Capture.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CaptureButtonPushed, true);
            app.pushbutton_Capture.Text = 'Snap';
            app.pushbutton_Set_Folder = uibutton(app.ScanningPanel, 'push');
            app.pushbutton_Set_Folder.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Set_FolderButtonPushed, true);
            app.pushbutton_Set_Folder.Text = '...';
            app.pushbutton_Open = uibutton(app.ScanningPanel, 'push');
            app.pushbutton_Open.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_OpenButtonPushed, true);
            app.pushbutton_Open.Text = 'Open';
            app.RefZLabel = uilabel(app.ScanningPanel);
            app.RefZLabel.Text = 'Ref Z :';
            app.edit_RefZ = uieditfield(app.ScanningPanel, 'numeric');
            app.edit_RefZ.Limits = [0 Inf];
            app.edit_RefZ.ValueDisplayFormat = '%.0f';
            app.edit_RefZ.ValueChangedFcn = createCallbackFcn(app, @edit_RefZValueChanged, true);
            app.pushbutton_connect = uibutton(app.MainTab, 'push');
            app.pushbutton_connect.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_connectButtonPushed, true);
            app.pushbutton_connect.Text = 'Connect';
            app.StageTab = uitab(app.TabGroup);
            app.StageTab.Title = 'Stage';
            app.StageControlPanel = uipanel(app.StageTab);
            app.StageControlPanel.Title = 'Stage Control';
            app.PositionPanel_2 = uipanel(app.StageControlPanel);
            app.PositionPanel_2.Title = 'Position';
            app.pushbutton_Reset = uibutton(app.PositionPanel_2, 'push');
            app.pushbutton_Reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ResetButtonPushed, true);
            app.pushbutton_Reset.Text = 'Reset';
            app.listbox_StageUnit = uilistbox(app.PositionPanel_2);
            app.listbox_StageUnit.Items = {'Coarse', 'Medium', 'Fine'};
            app.listbox_StageUnit.ValueChangedFcn = createCallbackFcn(app, @listbox_StageUnitValueChanged, true);
            app.listbox_StageUnit.Value = 'Coarse';
            app.pushbutton_Y_up = uibutton(app.PositionPanel_2, 'push');
            app.pushbutton_Y_up.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Y_upButtonPushed, true);
            app.pushbutton_Y_up.Text = '▲';
            app.pushbutton_X_left = uibutton(app.PositionPanel_2, 'push');
            app.pushbutton_X_left.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_X_leftButtonPushed, true);
            app.pushbutton_X_left.Text = '◀';
            app.pushbutton_Y_down = uibutton(app.PositionPanel_2, 'push');
            app.pushbutton_Y_down.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Y_downButtonPushed, true);
            app.pushbutton_Y_down.Text = '▼';
            app.pushbutton_X_right = uibutton(app.PositionPanel_2, 'push');
            app.pushbutton_X_right.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_X_rightButtonPushed, true);
            app.pushbutton_X_right.Text = '▶';
            app.XLabel = uilabel(app.PositionPanel_2);
            app.XLabel.Text = 'X :';
            app.edit_X = uieditfield(app.PositionPanel_2, 'numeric');
            app.edit_X.Limits = [0 Inf];
            app.edit_X.ValueDisplayFormat = '%.0f';
            app.edit_X.ValueChangedFcn = createCallbackFcn(app, @edit_XValueChanged, true);
            app.YEditFieldLabel = uilabel(app.PositionPanel_2);
            app.YEditFieldLabel.Text = 'Y :';
            app.edit_Y = uieditfield(app.PositionPanel_2, 'numeric');
            app.edit_Y.Limits = [0 Inf];
            app.edit_Y.ValueDisplayFormat = '%.0f';
            app.edit_Y.ValueChangedFcn = createCallbackFcn(app, @edit_YValueChanged, true);
            app.FocusingPanel = uipanel(app.StageControlPanel);
            app.FocusingPanel.Title = 'Focusing';
            app.listbox_StageUnit_Z = uilistbox(app.FocusingPanel);
            app.listbox_StageUnit_Z.Items = {'Coarse', 'Medium', 'Fine'};
            app.listbox_StageUnit_Z.ValueChangedFcn = createCallbackFcn(app, @listbox_StageUnit_ZValueChanged, true);
            app.listbox_StageUnit_Z.Value = 'Coarse';
            app.pushbutton_Z_in = uibutton(app.FocusingPanel, 'push');
            app.pushbutton_Z_in.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Z_inButtonPushed, true);
            app.pushbutton_Z_in.Text = '▲';
            app.pushbutton_Z_out = uibutton(app.FocusingPanel, 'push');
            app.pushbutton_Z_out.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Z_outButtonPushed, true);
            app.pushbutton_Z_out.Text = '▼';
            app.ZEditFieldLabel = uilabel(app.FocusingPanel);
            app.ZEditFieldLabel.Text = 'Z :';
            app.edit_Z = uieditfield(app.FocusingPanel, 'numeric');
            app.edit_Z.Limits = [0 Inf];
            app.edit_Z.ValueDisplayFormat = '%.0f';
            app.edit_Z.ValueChangedFcn = createCallbackFcn(app, @edit_ZValueChanged, true);
            app.pushbutton_Ref = uibutton(app.FocusingPanel, 'push');
            app.pushbutton_Ref.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_RefButtonPushed, true);
            app.pushbutton_Ref.Text = '→ Ref';
            app.pushbutton_Set_Mode = uibutton(app.FocusingPanel, 'push');
            app.pushbutton_Set_Mode.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Set_ModeButtonPushed, true);
            app.pushbutton_Set_Mode.Text = 'Set BM';
            app.DiffLabel = uilabel(app.FocusingPanel);
            app.DiffLabel.Text = 'Diff :';
            app.edit_Z_diff = uieditfield(app.FocusingPanel, 'numeric');
            app.edit_Z_diff.ValueDisplayFormat = '%.0f';
            app.edit_Z_diff.ValueChangedFcn = createCallbackFcn(app, @edit_Z_diffValueChanged, true);
            app.pushbutton_AF = uibutton(app.FocusingPanel, 'push');
            app.pushbutton_AF.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AFButtonPushed, true);
            app.pushbutton_AF.Text = 'Auto Focus';
            app.RangeLabel = uilabel(app.FocusingPanel);
            app.RangeLabel.Text = 'Range :';
            app.edit_Range = uieditfield(app.FocusingPanel, 'numeric');
            app.edit_Range.Limits = [100 2000];
            app.edit_Range.ValueDisplayFormat = '%.0f';
            app.edit_Range.ValueChangedFcn = createCallbackFcn(app, @edit_RangeValueChanged, true);
            app.edit_Range.Value = 200;
            app.DefaultButton = uibutton(app.FocusingPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.GetTestImgsCheckBox = uicheckbox(app.FocusingPanel);
            app.GetTestImgsCheckBox.Visible = 'off';
            app.GetTestImgsCheckBox.Text = 'Get Test Imgs';
            app.Rep3CheckBox = uicheckbox(app.FocusingPanel);
            app.Rep3CheckBox.Visible = 'off';
            app.Rep3CheckBox.Text = 'Rep(3)';
            app.SetTab = uitab(app.TabGroup);
            app.SetTab.Title = 'Set';
            app.PreviewConfigurationPanel = uipanel(app.SetTab);
            app.PreviewConfigurationPanel.Title = 'Preview Configuration';
            app.ModeButtonGroup = uibuttongroup(app.PreviewConfigurationPanel);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.radiobutton_Ch1 = uiradiobutton(app.ModeButtonGroup);
            app.radiobutton_Ch1.Text = 'Bright';
            app.radiobutton_Ch1.Value = true;
            app.radiobutton_Ch2 = uiradiobutton(app.ModeButtonGroup);
            app.radiobutton_Ch2.Text = 'Fluorescence';
            app.radiobutton_Off = uiradiobutton(app.ModeButtonGroup);
            app.radiobutton_Off.Text = 'Off';
            app.CameraSettingPanel = uipanel(app.PreviewConfigurationPanel);
            app.CameraSettingPanel.Title = 'Camera Setting';
            app.LEDSliderLabel = uilabel(app.CameraSettingPanel);
            app.LEDSliderLabel.Text = 'LED';
            app.slider_Inten = uislider(app.CameraSettingPanel);
            app.slider_Inten.ValueChangedFcn = createCallbackFcn(app, @slider_IntenValueChanged, true);
            app.slider_Inten.Tag = 'LED Intensity';
            app.edit_Inten = uieditfield(app.CameraSettingPanel, 'numeric');
            app.edit_Inten.ValueChangedFcn = createCallbackFcn(app, @edit_IntenValueChanged, true);
            app.edit_Exp = uieditfield(app.CameraSettingPanel, 'numeric');
            app.edit_Exp.ValueChangedFcn = createCallbackFcn(app, @edit_ExpValueChanged, true);
            app.ExposureSliderLabel = uilabel(app.CameraSettingPanel);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.slider_Exp = uislider(app.CameraSettingPanel);
            app.slider_Exp.ValueChangedFcn = createCallbackFcn(app, @slider_ExpValueChanged, true);
            app.slider_Exp.Tag = 'Exposure Time';
            app.edit_Gamma = uieditfield(app.CameraSettingPanel, 'numeric');
            app.edit_Gamma.ValueChangedFcn = createCallbackFcn(app, @edit_GammaValueChanged, true);
            app.GammaSliderLabel = uilabel(app.CameraSettingPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.slider_Gamma = uislider(app.CameraSettingPanel);
            app.slider_Gamma.ValueChangedFcn = createCallbackFcn(app, @slider_GammaValueChanged, true);
            app.slider_Gamma.Tag = 'Gamma';
            app.edit_Gain = uieditfield(app.CameraSettingPanel, 'numeric');
            app.edit_Gain.ValueChangedFcn = createCallbackFcn(app, @edit_GainValueChanged, true);
            app.GainSliderLabel = uilabel(app.CameraSettingPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.slider_Gain = uislider(app.CameraSettingPanel);
            app.slider_Gain.ValueChangedFcn = createCallbackFcn(app, @slider_GainValueChanged, true);
            app.slider_Gain.Tag = 'Gain';
            app.pushbutton_Cam_Save = uibutton(app.CameraSettingPanel, 'push');
            app.pushbutton_Cam_Save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Cam_SaveButtonPushed, true);
            app.pushbutton_Cam_Save.Text = 'Save';
            app.pushbutton_Cam_Load = uibutton(app.CameraSettingPanel, 'push');
            app.pushbutton_Cam_Load.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Cam_LoadButtonPushed, true);
            app.pushbutton_Cam_Load.Text = 'Load';
            app.ChambTab = uitab(app.TabGroup);
            app.ChambTab.Title = 'Chamb';
            app.ContainerPanel = uipanel(app.ChambTab);
            app.ContainerPanel.Title = 'Container';
            app.uibuttongroup_Manual = uibuttongroup(app.ContainerPanel);
            app.axes_Manual = uiaxes(app.uibuttongroup_Manual);
            app.popupmenu_chip = uidropdown(app.ContainerPanel);
            app.popupmenu_chip.Items = {'96 Well Plate (SPL, Half)', 'Droplet Chip 1 (Smaller)', 'Droplet Chip 2 (Larger)', 'Manual'};
            app.popupmenu_chip.ValueChangedFcn = createCallbackFcn(app, @popupmenu_chipValueChanged, true);
            app.popupmenu_chip.Value = '96 Well Plate (SPL, Half)';
            app.uibuttongroup_C1 = uibuttongroup(app.ContainerPanel);
            app.uibuttongroup_C1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup_SelectionChanged, true);
            app.axes_C1 = uiaxes(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb1 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb1.Text = '';
            app.togglebutton_C1_Chamb1.Value = true;
            app.togglebutton_C1_Chamb2 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb2.Text = '';
            app.togglebutton_C1_Chamb3 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb3.Text = '';
            app.togglebutton_C1_Chamb4 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb4.Text = '';
            app.togglebutton_C1_Chamb5 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb5.Text = '';
            app.togglebutton_C1_Chamb6 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb6.Text = '';
            app.togglebutton_C1_Chamb7 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb7.Text = '';
            app.togglebutton_C1_Chamb8 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb8.Text = '';
            app.togglebutton_C1_Chamb9 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb9.Text = '';
            app.togglebutton_C1_Chamb17 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb17.Text = '';
            app.togglebutton_C1_Chamb25 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb25.Text = '';
            app.togglebutton_C1_Chamb33 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb33.Text = '';
            app.togglebutton_C1_Chamb41 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb41.Text = '';
            app.togglebutton_C1_Chamb49 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb49.Text = '';
            app.togglebutton_C1_Chamb57 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb57.Text = '';
            app.togglebutton_C1_Chamb65 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb65.Text = '';
            app.togglebutton_C1_Chamb73 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb73.Text = '';
            app.togglebutton_C1_Chamb81 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb81.Text = '';
            app.togglebutton_C1_Chamb89 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb89.Text = '';
            app.togglebutton_C1_Chamb10 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb10.Text = '';
            app.togglebutton_C1_Chamb18 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb18.Text = '';
            app.togglebutton_C1_Chamb26 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb26.Text = '';
            app.togglebutton_C1_Chamb34 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb34.Text = '';
            app.togglebutton_C1_Chamb42 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb42.Text = '';
            app.togglebutton_C1_Chamb50 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb50.Text = '';
            app.togglebutton_C1_Chamb58 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb58.Text = '';
            app.togglebutton_C1_Chamb66 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb66.Text = '';
            app.togglebutton_C1_Chamb74 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb74.Text = '';
            app.togglebutton_C1_Chamb82 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb82.Text = '';
            app.togglebutton_C1_Chamb90 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb90.Text = '';
            app.togglebutton_C1_Chamb11 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb11.Text = '';
            app.togglebutton_C1_Chamb19 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb19.Text = '';
            app.togglebutton_C1_Chamb27 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb27.Text = '';
            app.togglebutton_C1_Chamb35 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb35.Text = '';
            app.togglebutton_C1_Chamb43 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb43.Text = '';
            app.togglebutton_C1_Chamb51 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb51.Text = '';
            app.togglebutton_C1_Chamb59 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb59.Text = '';
            app.togglebutton_C1_Chamb67 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb67.Text = '';
            app.togglebutton_C1_Chamb75 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb75.Text = '';
            app.togglebutton_C1_Chamb83 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb83.Text = '';
            app.togglebutton_C1_Chamb91 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb91.Text = '';
            app.togglebutton_C1_Chamb12 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb12.Text = '';
            app.togglebutton_C1_Chamb20 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb20.Text = '';
            app.togglebutton_C1_Chamb28 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb28.Text = '';
            app.togglebutton_C1_Chamb36 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb36.Text = '';
            app.togglebutton_C1_Chamb44 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb44.Text = '';
            app.togglebutton_C1_Chamb52 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb52.Text = '';
            app.togglebutton_C1_Chamb60 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb60.Text = '';
            app.togglebutton_C1_Chamb68 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb68.Text = '';
            app.togglebutton_C1_Chamb76 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb76.Text = '';
            app.togglebutton_C1_Chamb84 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb84.Text = '';
            app.togglebutton_C1_Chamb92 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb92.Text = '';
            app.togglebutton_C1_Chamb13 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb13.Text = '';
            app.togglebutton_C1_Chamb21 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb21.Text = '';
            app.togglebutton_C1_Chamb29 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb29.Text = '';
            app.togglebutton_C1_Chamb37 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb37.Text = '';
            app.togglebutton_C1_Chamb45 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb45.Text = '';
            app.togglebutton_C1_Chamb53 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb53.Text = '';
            app.togglebutton_C1_Chamb61 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb61.Text = '';
            app.togglebutton_C1_Chamb69 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb69.Text = '';
            app.togglebutton_C1_Chamb77 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb77.Text = '';
            app.togglebutton_C1_Chamb85 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb85.Text = '';
            app.togglebutton_C1_Chamb93 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb93.Text = '';
            app.togglebutton_C1_Chamb14 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb14.Text = '';
            app.togglebutton_C1_Chamb22 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb22.Text = '';
            app.togglebutton_C1_Chamb30 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb30.Text = '';
            app.togglebutton_C1_Chamb38 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb38.Text = '';
            app.togglebutton_C1_Chamb46 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb46.Text = '';
            app.togglebutton_C1_Chamb54 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb54.Text = '';
            app.togglebutton_C1_Chamb62 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb62.Text = '';
            app.togglebutton_C1_Chamb70 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb70.Text = '';
            app.togglebutton_C1_Chamb78 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb78.Text = '';
            app.togglebutton_C1_Chamb86 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb86.Text = '';
            app.togglebutton_C1_Chamb94 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb94.Text = '';
            app.togglebutton_C1_Chamb15 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb15.Text = '';
            app.togglebutton_C1_Chamb23 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb23.Text = '';
            app.togglebutton_C1_Chamb31 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb31.Text = '';
            app.togglebutton_C1_Chamb39 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb39.Text = '';
            app.togglebutton_C1_Chamb47 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb47.Text = '';
            app.togglebutton_C1_Chamb55 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb55.Text = '';
            app.togglebutton_C1_Chamb63 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb63.Text = '';
            app.togglebutton_C1_Chamb71 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb71.Text = '';
            app.togglebutton_C1_Chamb79 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb79.Text = '';
            app.togglebutton_C1_Chamb87 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb87.Text = '';
            app.togglebutton_C1_Chamb95 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb95.Text = '';
            app.togglebutton_C1_Chamb16 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb16.Text = '';
            app.togglebutton_C1_Chamb24 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb24.Text = '';
            app.togglebutton_C1_Chamb32 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb32.Text = '';
            app.togglebutton_C1_Chamb40 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb40.Text = '';
            app.togglebutton_C1_Chamb48 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb48.Text = '';
            app.togglebutton_C1_Chamb56 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb56.Text = '';
            app.togglebutton_C1_Chamb64 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb64.Text = '';
            app.togglebutton_C1_Chamb72 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb72.Text = '';
            app.togglebutton_C1_Chamb80 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb80.Text = '';
            app.togglebutton_C1_Chamb88 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb88.Text = '';
            app.togglebutton_C1_Chamb96 = uitogglebutton(app.uibuttongroup_C1);
            app.togglebutton_C1_Chamb96.Text = '';
            app.LabelHor11 = uilabel(app.uibuttongroup_C1);
            app.LabelHor11.Text = '12';
            app.LabelHor10 = uilabel(app.uibuttongroup_C1);
            app.LabelHor10.Text = '12';
            app.LabelHor9 = uilabel(app.uibuttongroup_C1);
            app.LabelHor9.Text = '12';
            app.LabelHor8 = uilabel(app.uibuttongroup_C1);
            app.LabelHor8.Text = '12';
            app.LabelHor7 = uilabel(app.uibuttongroup_C1);
            app.LabelHor7.Text = '12';
            app.LabelHor6 = uilabel(app.uibuttongroup_C1);
            app.LabelHor6.Text = '12';
            app.LabelHor5 = uilabel(app.uibuttongroup_C1);
            app.LabelHor5.Text = '12';
            app.LabelHor4 = uilabel(app.uibuttongroup_C1);
            app.LabelHor4.Text = '12';
            app.LabelHor3 = uilabel(app.uibuttongroup_C1);
            app.LabelHor3.Text = '12';
            app.LabelHor2 = uilabel(app.uibuttongroup_C1);
            app.LabelHor2.Text = '12';
            app.LabelHor1 = uilabel(app.uibuttongroup_C1);
            app.LabelHor1.Text = '12';
            app.LabelHor12 = uilabel(app.uibuttongroup_C1);
            app.LabelHor12.Text = '12';
            app.LabelVer1 = uilabel(app.uibuttongroup_C1);
            app.LabelVer1.Text = '12';
            app.LabelVer2 = uilabel(app.uibuttongroup_C1);
            app.LabelVer2.Text = '12';
            app.LabelVer3 = uilabel(app.uibuttongroup_C1);
            app.LabelVer3.Text = '12';
            app.LabelVer4 = uilabel(app.uibuttongroup_C1);
            app.LabelVer4.Text = '12';
            app.LabelVer5 = uilabel(app.uibuttongroup_C1);
            app.LabelVer5.Text = '12';
            app.LabelVer6 = uilabel(app.uibuttongroup_C1);
            app.LabelVer6.Text = '12';
            app.LabelVer7 = uilabel(app.uibuttongroup_C1);
            app.LabelVer7.Text = '12';
            app.LabelVer8 = uilabel(app.uibuttongroup_C1);
            app.LabelVer8.Text = '12';
            app.uibuttongroup_C2 = uibuttongroup(app.ContainerPanel);
            app.uibuttongroup_C2.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup_SelectionChanged, true);
            app.axes_C2 = uiaxes(app.uibuttongroup_C2);
            app.togglebutton_C2_Chamb1 = uitogglebutton(app.uibuttongroup_C2);
            app.togglebutton_C2_Chamb1.Text = 'Button';
            app.togglebutton_C2_Chamb1.Value = true;
            app.togglebutton_C2_Chamb2 = uitogglebutton(app.uibuttongroup_C2);
            app.togglebutton_C2_Chamb2.Text = 'Button';
            app.togglebutton_C2_Chamb3 = uitogglebutton(app.uibuttongroup_C2);
            app.togglebutton_C2_Chamb3.Text = 'Button';
            app.uibuttongroup_C3 = uibuttongroup(app.ContainerPanel);
            app.axes_C3 = uiaxes(app.uibuttongroup_C3);
            app.togglebutton_C3_Chamb1 = uitogglebutton(app.uibuttongroup_C3);
            app.togglebutton_C3_Chamb1.Text = 'Button';
            app.togglebutton_C3_Chamb1.Value = true;
            app.PosTab = uitab(app.TabGroup);
            app.PosTab.Title = 'Pos';
            app.PositionPanel = uipanel(app.PosTab);
            app.PositionPanel.Title = 'Position';
            app.axes_Canvas = uiaxes(app.PositionPanel);
            app.axes_Canvas.XTick = [];
            app.axes_Canvas.YTick = [];
            app.TipTab = uitab(app.TabGroup);
            app.TipTab.Title = 'Tip';
            app.HiddenOptionsPanel = uipanel(app.TipTab);
            app.HiddenOptionsPanel.Title = 'Hidden Options';
            app.F9OpticCalibrationWindowLabel = uilabel(app.HiddenOptionsPanel);
            app.F9OpticCalibrationWindowLabel.Text = 'F9 : Optic Calibration Window';
            app.F10OpticCameraSettingBeforeconnectLabel = uilabel(app.HiddenOptionsPanel);
            app.F10OpticCameraSettingBeforeconnectLabel.Text = 'F10 : Optic / Camera Setting (Before connect)';
            app.F11StageChamberCalibrationLabel = uilabel(app.HiddenOptionsPanel);
            app.F11StageChamberCalibrationLabel.Text = 'F11 : Stage / Chamber Calibration';
            app.F12EmergencyKeyEnableallbuttonsLabel = uilabel(app.HiddenOptionsPanel);
            app.F12EmergencyKeyEnableallbuttonsLabel.Text = 'F12 : Emergency Key (Enable all buttons)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = easySCAN

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end