classdef AddGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddGroupUIFigure    matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        AnalyteNameLabel    matlab.ui.control.Label
        CheckBox1           matlab.ui.control.CheckBox
        CheckBox2           matlab.ui.control.CheckBox
        CheckBox3           matlab.ui.control.CheckBox
        CheckBox4           matlab.ui.control.CheckBox
        CheckBox5           matlab.ui.control.CheckBox
        CheckBox6           matlab.ui.control.CheckBox
        CheckBox7           matlab.ui.control.CheckBox
        CheckBox8           matlab.ui.control.CheckBox
        CheckBox9           matlab.ui.control.CheckBox
        CheckBox10          matlab.ui.control.CheckBox
        SpeciesLabel        matlab.ui.control.Label
        DropDown1           matlab.ui.control.DropDown
        DropDown2           matlab.ui.control.DropDown
        DropDown3           matlab.ui.control.DropDown
        DropDown4           matlab.ui.control.DropDown
        DropDown5           matlab.ui.control.DropDown
        DropDown6           matlab.ui.control.DropDown
        DropDown7           matlab.ui.control.DropDown
        DropDown8           matlab.ui.control.DropDown
        DropDown9           matlab.ui.control.DropDown
        DropDown10          matlab.ui.control.DropDown
        CheckBoxAll         matlab.ui.control.CheckBox
        CodeLabel           matlab.ui.control.Label
        LabelCode1          matlab.ui.control.Label
        LabelCode2          matlab.ui.control.Label
        LabelCode3          matlab.ui.control.Label
        LabelCode4          matlab.ui.control.Label
        LabelCode5          matlab.ui.control.Label
        LabelCode6          matlab.ui.control.Label
        LabelCode7          matlab.ui.control.Label
        LabelCode8          matlab.ui.control.Label
        LabelCode9          matlab.ui.control.Label
        LabelCode10         matlab.ui.control.Label
        NoneLabel_6         matlab.ui.control.Label
        IL1Label_2          matlab.ui.control.Label
        IL6Label            matlab.ui.control.Label
        IL10Label_2         matlab.ui.control.Label
        TNFLabel            matlab.ui.control.Label
        IFNLabel            matlab.ui.control.Label
        NoneLabel_2         matlab.ui.control.Label
        NoneLabel_3         matlab.ui.control.Label
        NoneLabel_4         matlab.ui.control.Label
        NoneLabel_5         matlab.ui.control.Label
        OKButton            matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        DescriptionLabel    matlab.ui.control.Label
        EditFieldGroupName  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        numCheckBox = 10; % Number of enalbed checkboxes
        selApp;
        grpStr;
        analyteName = {
            'None';
            'IL-1β';
            'IL-6';
            'IL-10';
            'TNF-α';
            'IFN-γ';
            'None';
            'None';
            'None';
            'None';
            }        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, selApp, grpStr)
            app.selApp = selApp;
            app.grpStr = grpStr;
            app.EditFieldGroupName.Value = app.grpStr;
            
            tmpPosX = selApp.figure1.Position(1) + selApp.figure1.Position(3)/2 - app.AddGroupUIFigure.Position(3)/2;
            tmpPosY = selApp.figure1.Position(2) + selApp.figure1.Position(4)/2 - app.AddGroupUIFigure.Position(4)/2;
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.AddGroupUIFigure.Position(1) = tmpPosX;
                app.AddGroupUIFigure.Position(2) = tmpPosY;
                
            else
                
                app.AddGroupUIFigure.Position(1) = 10;
                app.AddGroupUIFigure.Position(2) = 50;
                
            end
                
                
        end

        % Value changed function: CheckBoxAll
        function CheckBoxAllValueChanged(app, event)
            value = app.CheckBoxAll.Value;
            
            app.numCheckBox = 10;
            
            for i = 1:app.numCheckBox
                
%                 checkBoxStr = sprintf('CheckBox%d', i-1);
                checkBoxStr = sprintf('CheckBox%d', i);
                
                if app.(checkBoxStr).Enable == 1
                    
                    app.(checkBoxStr).Value = value;
                    
                end
                
            end
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5
        function CheckBox1ValueChanged(app, event)
            value = event.Source.Value;
            
            if value == 0
                
                app.CheckBoxAll.Value = 0;
                
            end
            
        end

        % Callback function: AddGroupUIFigure, CancelButton
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            selCodeBool = zeros(app.numCheckBox, 1);
            
            for i=1:app.numCheckBox
                
                checkBoxStr = sprintf('CheckBox%d', i);
                selCodeBool(i, 1) = app.(checkBoxStr).Value;                
            
            end
            
            selCodeNum = find(selCodeBool == 1);
            
            if isempty(selCodeNum)
                
                uialert(app.AddGroupUIFigure, 'Please select at least 1 analyte.', '')
                return
                
            end
            
            if isempty(app.selApp.grpNum)

                app.selApp.grpNum = 1;
                
            else
                
                app.selApp.grpNum(end+1, 1) = app.selApp.grpNum(end, 1) + 1;
            
            end
            
            DataStruct = getappdata(0, 'DataStruct');            
            DataStruct.(app.grpStr) = app.selApp.grpStruct;
            DataStruct.(app.grpStr).Name = app.EditFieldGroupName.Value;    
            
            strAnalyte = cell(size(selCodeNum));            
            j = 1;
            
            for i = selCodeNum'
                
                dropDownStr = sprintf('DropDown%d', i);
                strAnalyte{j, 1} = sprintf('C%d : %s(%s)', i, app.analyteName{i, 1}, app.(dropDownStr).Value);
                j = j+1;
                
            end
                        
            app.selApp.labelAnalytes.Text = strAnalyte;            
            DataStruct.(app.grpStr).CodeNum = selCodeNum;
            
            set(app.selApp.LabelGrpName, 'Text', app.grpStr);

            strList = cell(length(app.selApp.grpNum), 1);
            
            for i=1:length(app.selApp.grpNum)
            
                strList{i, 1} = sprintf('Group %d', app.selApp.grpNum(i));
            
            end
            
            set(app.selApp.listboxGroup, 'Items', strList)
            set(app.selApp.listboxGroup, 'Value', strList(app.selApp.grpNum(end)))
            DataStruct.(app.grpStr).ListAnalyte = strAnalyte;
            setappdata(0, 'DataStruct', DataStruct);      
          
            reNewElem(DataStruct, app.grpStr, app.selApp.listboxElement)
            RenewGrp(DataStruct, app.grpStr, app.selApp.LabelGrpName, app.selApp.labelAnalytes)
            
            delete(app.AddGroupUIFigure)
        end

        % Key press function: AddGroupUIFigure
        function AddGroupUIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddGroupUIFigure = uifigure('Visible', 'off');
            app.AddGroupUIFigure.Name = 'Add Group';
            app.AddGroupUIFigure.Icon = 'Max_icon.png';
            app.AddGroupUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddGroupUIFigure.KeyPressFcn = createCallbackFcn(app, @AddGroupUIFigureKeyPress, true);
            app.Panel = uipanel(app.AddGroupUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.AnalyteNameLabel = uilabel(app.GridLayout);
            app.AnalyteNameLabel.Text = 'Analyte Name';
            app.CheckBox1 = uicheckbox(app.GridLayout);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = '';
            app.CheckBox2 = uicheckbox(app.GridLayout);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = '';
            app.CheckBox3 = uicheckbox(app.GridLayout);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.GridLayout);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.GridLayout);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = '';
            app.CheckBox6 = uicheckbox(app.GridLayout);
            app.CheckBox6.Text = '';
            app.CheckBox7 = uicheckbox(app.GridLayout);
            app.CheckBox7.Text = '';
            app.CheckBox8 = uicheckbox(app.GridLayout);
            app.CheckBox8.Text = '';
            app.CheckBox9 = uicheckbox(app.GridLayout);
            app.CheckBox9.Text = '';
            app.CheckBox10 = uicheckbox(app.GridLayout);
            app.CheckBox10.Text = '';
            app.SpeciesLabel = uilabel(app.GridLayout);
            app.SpeciesLabel.Text = 'Species';
            app.DropDown1 = uidropdown(app.GridLayout);
            app.DropDown1.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown1.Value = 'Human';
            app.DropDown2 = uidropdown(app.GridLayout);
            app.DropDown2.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown2.Value = 'Human';
            app.DropDown3 = uidropdown(app.GridLayout);
            app.DropDown3.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown3.Value = 'Human';
            app.DropDown4 = uidropdown(app.GridLayout);
            app.DropDown4.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown4.Value = 'Human';
            app.DropDown5 = uidropdown(app.GridLayout);
            app.DropDown5.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown5.Value = 'Human';
            app.DropDown6 = uidropdown(app.GridLayout);
            app.DropDown6.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown6.Value = 'Human';
            app.DropDown7 = uidropdown(app.GridLayout);
            app.DropDown7.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown7.Value = 'Human';
            app.DropDown8 = uidropdown(app.GridLayout);
            app.DropDown8.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown8.Value = 'Human';
            app.DropDown9 = uidropdown(app.GridLayout);
            app.DropDown9.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown9.Value = 'Human';
            app.DropDown10 = uidropdown(app.GridLayout);
            app.DropDown10.Items = {'Human', 'Mouse', 'Rat'};
            app.DropDown10.Value = 'Human';
            app.CheckBoxAll = uicheckbox(app.GridLayout);
            app.CheckBoxAll.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAllValueChanged, true);
            app.CheckBoxAll.Text = '';
            app.CodeLabel = uilabel(app.GridLayout);
            app.CodeLabel.Text = 'Code#';
            app.LabelCode1 = uilabel(app.GridLayout);
            app.LabelCode1.Text = '1';
            app.LabelCode2 = uilabel(app.GridLayout);
            app.LabelCode2.Text = '2';
            app.LabelCode3 = uilabel(app.GridLayout);
            app.LabelCode3.Text = '3';
            app.LabelCode4 = uilabel(app.GridLayout);
            app.LabelCode4.Text = '4';
            app.LabelCode5 = uilabel(app.GridLayout);
            app.LabelCode5.Text = '5';
            app.LabelCode6 = uilabel(app.GridLayout);
            app.LabelCode6.Text = '6';
            app.LabelCode7 = uilabel(app.GridLayout);
            app.LabelCode7.Text = '7';
            app.LabelCode8 = uilabel(app.GridLayout);
            app.LabelCode8.Text = '8';
            app.LabelCode9 = uilabel(app.GridLayout);
            app.LabelCode9.Text = '9';
            app.LabelCode10 = uilabel(app.GridLayout);
            app.LabelCode10.Text = '10';
            app.NoneLabel_6 = uilabel(app.GridLayout);
            app.NoneLabel_6.Text = 'None';
            app.IL1Label_2 = uilabel(app.GridLayout);
            app.IL1Label_2.Text = 'IL-1β';
            app.IL6Label = uilabel(app.GridLayout);
            app.IL6Label.Text = 'IL-6';
            app.IL10Label_2 = uilabel(app.GridLayout);
            app.IL10Label_2.Text = 'IL-10';
            app.TNFLabel = uilabel(app.GridLayout);
            app.TNFLabel.Text = 'TNF-α';
            app.IFNLabel = uilabel(app.GridLayout);
            app.IFNLabel.Text = 'IFN-γ';
            app.NoneLabel_2 = uilabel(app.GridLayout);
            app.NoneLabel_2.Text = 'None';
            app.NoneLabel_3 = uilabel(app.GridLayout);
            app.NoneLabel_3.Text = 'None';
            app.NoneLabel_4 = uilabel(app.GridLayout);
            app.NoneLabel_4.Text = 'None';
            app.NoneLabel_5 = uilabel(app.GridLayout);
            app.NoneLabel_5.Text = 'None';
            app.OKButton = uibutton(app.AddGroupUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.AddGroupUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.DescriptionLabel = uilabel(app.AddGroupUIFigure);
            app.DescriptionLabel.Text = 'Description :';
            app.EditFieldGroupName = uieditfield(app.AddGroupUIFigure, 'text');
            % Show the figure after all components are created
            app.AddGroupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddGroup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddGroupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddGroupUIFigure)
        end
    end
end