classdef AddUnknown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddUnknownUIFigure   matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        SelectaColorLabel    matlab.ui.control.Label
        NumbersLabel         matlab.ui.control.Label
        ReplicationsLabel    matlab.ui.control.Label
        ListOrderLabel       matlab.ui.control.Label
        pushbutton4          matlab.ui.control.Button
        edit3                matlab.ui.control.NumericEditField
        edit4                matlab.ui.control.NumericEditField
        popupmenu3           matlab.ui.control.DropDown
        PreviewListBoxLabel  matlab.ui.control.Label
        listbox1             matlab.ui.control.ListBox
        PreviewButton        matlab.ui.control.Button
        OKButton             matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        selApp; % Select Chamber App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, selApp)
            app.selApp = selApp;
            
            tmpPosX = selApp.figure1.Position(1) + selApp.figure1.Position(3)/2 - app.AddUnknownUIFigure.Position(3)/2;
            tmpPosY = selApp.figure1.Position(2) + selApp.figure1.Position(4)/2 - app.AddUnknownUIFigure.Position(4)/2;
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.AddUnknownUIFigure.Position(1) = tmpPosX;
                app.AddUnknownUIFigure.Position(2) = tmpPosY;
                
            else
                
                app.AddUnknownUIFigure.Position(1) = 10;
                app.AddUnknownUIFigure.Position(2) = 50;
                
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
%             MainElemListHandle = getappdata(0, 'MainElemListHandle');
%             MainGrpListHandle  = getappdata(0, 'MainGrpListHandle');
%             MainTextHandle     = getappdata(0, 'MainTextHandle');
            DataStruct         = getappdata(0, 'DataStruct');
                        
            grpVal    = get(app.selApp.listboxGroup, 'Value');
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);  
            % grpStr = sprintf('Grp%d', get(MainGrpListHandle, 'Value'));
            
            % stdNum = DataStruct.(grpStr).StdNum;
            unkNum = DataStruct.(grpStr).UnkNum;
            
            [unkList, listColor, listType, listRepNo, listCon, listPos, stdNum, unkNum, listUnit, listInt]...
                = MakeUnkList(app, unkNum);
            
            DataStruct.(grpStr).Element       = [DataStruct.(grpStr).Element;       unkList];
            DataStruct.(grpStr).Color         = [DataStruct.(grpStr).Color;         listColor];
            DataStruct.(grpStr).Type          = [DataStruct.(grpStr).Type;          listType];
            DataStruct.(grpStr).RepNo         = [DataStruct.(grpStr).RepNo;         listRepNo];
            DataStruct.(grpStr).Concentration = [DataStruct.(grpStr).Concentration; listCon];
            DataStruct.(grpStr).Position      = [DataStruct.(grpStr).Position;      listPos];
            DataStruct.(grpStr).StdNum        = [DataStruct.(grpStr).StdNum;        stdNum];
            DataStruct.(grpStr).UnkNum        = [DataStruct.(grpStr).UnkNum;        unkNum];
            DataStruct.(grpStr).Unit          = [DataStruct.(grpStr).Unit;          listUnit];
            DataStruct.(grpStr).Intensity     = [DataStruct.(grpStr).Intensity;     listInt];
            
            if isempty(DataStruct.(grpStr).Element)
                
                val = 0;
                
            else
                
            %     val = size(get(MainElemListHandle, 'String'), 1)+1;
                val = size(get(app.selApp.listboxElement, 'Items'), 1)+1;
                
            end
            
            setappdata(0, 'DataStruct', DataStruct)
            
            set(app.selApp.listboxElement, 'Items', DataStruct.(grpStr).Element)
            set(app.selApp.listboxElement, 'Value', DataStruct.(grpStr).Element{val})
            
            % set(MainElemListHandle, 'Value', val)
            % set(MainElemListHandle, 'String', DataStruct.(grpStr).Element)
            
            RenewElemStatus(app.selApp, grpStr, DataStruct.(grpStr).Element{val}, DataStruct)
            % RenewElemStatus(MainTextHandle, grpStr, val, DataStruct)
            
            delete(app.AddUnknownUIFigure)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.AddUnknownUIFigure)
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
%             MainGrpListHandle  = getappdata(0, 'MainGrpListHandle');
            DataStruct         = getappdata(0, 'DataStruct');
            % grpStr             = sprintf('Grp%d', get(MainGrpListHandle, 'Value'));
            
            grpVal    = get(app.selApp.listboxGroup, 'Value');
            grpNumSel = str2double(grpVal{end}(7:end));
            grpStr    = sprintf('Grp%d', grpNumSel);  
            
            unkNum             = DataStruct.(grpStr).UnkNum;
            
            listPrev = MakeUnkList(app, unkNum);
            set(app.listbox1, 'Items', listPrev)
        end

        % Key press function: AddUnknownUIFigure
        function AddUnknownUIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, 'return')
                
                OKButtonPushed(app, event)
                
            elseif strcmp(key, 'escape')
                
                delete(app)
                
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            prevColor = get(event.Source, 'BackgroundColor');

            selColor = uisetcolor(prevColor);
            app.AddUnknownUIFigure.WindowStyle = 'normal';
            app.AddUnknownUIFigure.WindowStyle = 'modal';
            figure(app.AddUnknownUIFigure)
            
            if length(selColor) == 1
                
                return
                
            end
            
            set(event.Source, 'BackgroundColor', selColor)
            
%             figure(app.AddUnknownUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddUnknownUIFigure = uifigure('Visible', 'off');
            app.AddUnknownUIFigure.Name = 'Add Unknown';
            app.AddUnknownUIFigure.Icon = 'Max_icon.png';
            app.AddUnknownUIFigure.KeyPressFcn = createCallbackFcn(app, @AddUnknownUIFigureKeyPress, true);
            app.Panel = uipanel(app.AddUnknownUIFigure);
            app.SelectaColorLabel = uilabel(app.Panel);
            app.SelectaColorLabel.Text = 'Select a Color :';
            app.NumbersLabel = uilabel(app.Panel);
            app.NumbersLabel.Text = 'Numbers :';
            app.ReplicationsLabel = uilabel(app.Panel);
            app.ReplicationsLabel.Text = 'Replications :';
            app.ListOrderLabel = uilabel(app.Panel);
            app.ListOrderLabel.Text = 'List Order :';
            app.pushbutton4 = uibutton(app.Panel, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Text = '';
            app.edit3 = uieditfield(app.Panel, 'numeric');
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 5;
            app.edit4 = uieditfield(app.Panel, 'numeric');
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Value = 2;
            app.popupmenu3 = uidropdown(app.Panel);
            app.popupmenu3.Items = {'Num → Rep', 'Rep → Num'};
            app.popupmenu3.Value = 'Num → Rep';
            app.PreviewListBoxLabel = uilabel(app.Panel);
            app.PreviewListBoxLabel.Text = 'Preview';
            app.listbox1 = uilistbox(app.Panel);
            app.listbox1.Items = {};
            app.listbox1.Value = {};
            app.PreviewButton = uibutton(app.AddUnknownUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.OKButton = uibutton(app.AddUnknownUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.AddUnknownUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.AddUnknownUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddUnknown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddUnknownUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddUnknownUIFigure)
        end
    end
end