classdef AnalysisFocus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzeAutoFocusUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        LoadafolderButton         matlab.ui.control.Button
        ImageListListBox          matlab.ui.control.ListBox
        Panel_2                   matlab.ui.container.Panel
        AnalysisButton            matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
        Panel_3                   matlab.ui.container.Panel
        UITable2                  matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        UITable3                  matlab.ui.control.Table
        RESETButton               matlab.ui.control.Button
        ContextMenu               matlab.ui.container.ContextMenu
        CopyMenu                  matlab.ui.container.Menu
        ContextMenu2              matlab.ui.container.ContextMenu
        CopyMenu_2                matlab.ui.container.Menu
        ContextMenu3              matlab.ui.container.ContextMenu
        CopyMenu_3                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        fileList; % List of files in the folder.
        numResult = 1;
        selPath = pwd;
        
        Selected = struct;        
        dataStruct = struct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageListListBox.Items = "";
            
            app.UITable.Data = cell(1, 2);
            app.UITable2.Data = cell(1, 5);
            app.UITable3.Data = cell(1, 5);
            
%             app.UITable.Data = nan(1, 2);
%             app.UITable2.Data = nan(1, 5);
%             app.UITable3.Data = nan(2, 5);
        end

        % Button pushed function: LoadafolderButton
        function LoadafolderButtonPushed(app, event)
            selPath = uigetdir(app.selPath);
            
            if isequal(selPath, 0)
                
                return
                
            end
            
            app.ImageListListBox.Items = "";
            cla(app.UIAxes)
            app.Image.ImageSource = "";
%             app.UITable.Data = nan(1, 2);
            app.UITable.Data = cell(1, 2);
            
            contList = dir(selPath);
            fileTmp = {contList.name};
            foldIdx = {contList.isdir};
            fileTmp = fileTmp(~cell2mat(foldIdx));
            fileNum = split(fileTmp, '.');
            fileNum = fileNum(:, :, 1);
            sortedFileNum = sort(str2double(fileNum))';
            sortedFileNum(isnan(sortedFileNum)) = [];
            cellFileNum = num2cell(sortedFileNum);
            cellFileNum = cellfun(@num2str, cellFileNum, 'UniformOutput', false);
            
            fileList = strcat(cellFileNum, '.png');            
            app.ImageListListBox.Items = fileList;
            
            app.fileList = fullfile(selPath, fileList);            
            app.selPath = selPath;
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            progDlg = uiprogressdlg(app.AnalyzeAutoFocusUIFigure, 'Message', 'Analysing...', 'Indeterminate', 'on');
            
            %% Get GmagInten           
            
            GData = zeros(size(app.fileList, 1), 2);
            GData(:, 2) = 1:size(GData, 1);

            for i = 1:size(app.fileList, 1)    
                
                Z_im{i, 1} = imread(app.fileList{i, 1});    
                
            end
            
            tStart = tic;
            
            for i = 1:size(app.fileList, 1)    
                
%                 Z_im = imread(app.fileList{i, 1});    
                [Gmag, ~] = imgradient(im2double(Z_im{i, 1}));    
                GData(i, 1) = mean(Gmag, 'All');
                
%                 S = sort(Gmag(:), 'descend');
%                 GmagInten(i, 1) = mean(S(1:round(numel(Gmag)*0.001)));
                
            end
            
            %% Get NumGmag
            
%             FitType  = fittype('gauss1');
%             FitOpts  = fitoptions('Method', 'NonlinearLeastSquares');
%             FitOpts.Upper = [Inf, 256, Inf];
%             
%             NumGmag = zeros(size(app.fileList, 2), 1);
            
%             tStart = tic;
            
%             for i = 1:size(app.fileList, 1)    
%     
%                 Z_im = imread(app.fileList{i, 1});    
%                 [Gmag, ~] = imgradient(im2double(Z_im));    
%                 Gmag8 = im2uint8(Gmag);
%                 Gmag8Hist = imhist(Gmag8);
%                 [Gmag8HistMax, Gmag8HistMaxIdx] = max(Gmag8Hist);      
%                 FitOpts.Lower = [0, Gmag8HistMaxIdx, 0];
%                 FitOpts.StartPoint = [Gmag8HistMax, Gmag8HistMaxIdx, std(double(Gmag8(:)))*2^0.5];
%                 FitResult = fit((Gmag8HistMaxIdx:256)', Gmag8Hist(Gmag8HistMaxIdx:256), FitType, FitOpts);    
%                 Gmag8Avg = FitResult.b1-1;
%                 Gmag8Std = FitResult.c1/(2^0.5);    
%                 GmagBgThr = (Gmag8Avg+Gmag8Std*6)/256;
%                 Find_Gmag = find(GmagBgThr<Gmag);
%                 NumGmag(i, 1) = length(Find_Gmag);
%                 
%                 Z1 = zeros(size(Z_im));
%                 Z1(Find_Gmag) = 1;
%                 CC1 = bwconncomp(Z1);
%                 
%             end
            
%             FindIdx = find(NumGmag);
            
%             if ~isempty(FindIdx)    
%    
%                 Z = zeros(1, FindIdx(end));
%                 Z(FindIdx) = 1;
%                 CC = bwconncomp(Z);
%                 Lth_Obj = [];
%                 
% %                 figure
% %                 imshow(Z)
%             
%                 for i = 1:CC.NumObjects
%             
%                     Lth_Obj(i) = length(CC.PixelIdxList{i});
%             
%                 end
%             
%                 [~, MaxIdx] = max(Lth_Obj);
%                 ValidPoint = CC.PixelIdxList{MaxIdx};    
%                 GmagIntenVP = GmagInten(ValidPoint);
%                 NumGmagVP = NumGmag(ValidPoint);
%                 [~, GmagIntenVPMaxIdx] = max(GmagIntenVP);
%                 [~, VPDetRng(1)] = max(NumGmagVP(1:GmagIntenVPMaxIdx));
%                 [~, VPDetRng(2)] = max(NumGmagVP(GmagIntenVPMaxIdx:end));
%                 VPDetRng(2) = VPDetRng(2)+GmagIntenVPMaxIdx-1;
%                 [~, FocalPoint] = min(NumGmagVP(VPDetRng(1):VPDetRng(2)));
%                 FocalPoint = FocalPoint+VPDetRng(1)-1+ValidPoint(1)-1;       
%             
%             end
            

            [~, maxGmagIdx] = max(GData(:, 1));
            app.dataStruct(app.numResult).focusValue = GData(:, 1);
            gmagRange = 8;
            
            if maxGmagIdx <= gmagRange/2
                
                vZoneRange(1) = 1;                
                
            else
                
                vZoneRange(1) = maxGmagIdx - gmagRange/2;
                
            end
            
            if maxGmagIdx + gmagRange/2 > size(GData, 1)
                
                vZoneRange(2) = GData(end, 2);
                
            else                
                
                vZoneRange(2) = maxGmagIdx+gmagRange/2;
                
            end
            
            uGI = GData(vZoneRange(1) : vZoneRange(2), 1);
            uGIZ = GData(vZoneRange(1) : vZoneRange(2), 2);
            
            VzoneVal = [];
            VzoneIdx = [];
            
            for uGINum = 2:(length(uGI)-1)
                
                [MinVal, MinIdx] = min(uGI((uGINum-1):(uGINum+1)));
                
                if MinIdx==2
                    
                    VzoneVal(end+1) = MinVal;
                    VzoneIdx(end+1) = uGINum;
                    
                end
                    
            end
            
            if isempty(VzoneIdx)
                
                [~, VzoneIdx] = max(uGI);
                
            else    
                
                [~, MinIdx] = min(VzoneVal);
                VzoneIdx = VzoneIdx(MinIdx);
                
            end
            
            FocalPoint = uGIZ(VzoneIdx);
            
            tEnd = toc(tStart);
            
            app.UITable.Data = cell(size(GData, 1), 2);
            app.UITable.Data(:, 2) = cellstr(num2str(GData(:, 1)));
            plotGmagInten = plot(app.UIAxes, GData(:, 1));            
            hold(app.UIAxes, 'off')
            
%             app.dataStruct(app.numResult).focusValue = NumGmag;
            
%             app.UITable.Data = nan(size(NumGmag, 1), 2);
%             app.UITable.Data(:, 2) = NumGmag;
            
%             app.UITable.Data = cell(size(NumGmag, 1), 2);
%             app.UITable.Data(:, 2) = cellstr(num2str(NumGmag));
%             plotNumGmag = plot(app.UIAxes, NumGmag);            
%             hold(app.UIAxes, 'off')
%             fprintf('Focused on %d\n', FocalPoint)            

            listZ = [];
            um_interval = [];
            infFileName = fullfile(app.selPath, 'FocusInform.mat');
            
            try
                
                load(infFileName, 'listZ')
                load(infFileName, 'um_interval')
                
            catch
                
%                 disp('Invalid Focus Inform file.') % Warning!!
%                 delete(progDlg)
                return
                
            end
            
            if isempty(listZ)
                
                %Warning : Z coordinate problem!
                return
                
            end
                
%             listZ = round(listZ * 10) / 10;
            
            app.dataStruct(app.numResult).listZ = listZ;
%             plotNumGmag.XData = listZ;
            plotGmagInten.XData = listZ;
            hold(app.UIAxes, 'on')
            plotFocalPoint = plot(app.UIAxes, listZ(FocalPoint, 1),...
                GData(FocalPoint, 1), 'Marker', 'o',...
                'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r');
%             plotFocalPoint = plot(app.UIAxes, listZ(FocalPoint, 1),...
%                 NumGmag(FocalPoint, 1), 'Marker', 'o',...
%                 'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r');
            
            cellListZ = mat2cell(listZ, ones(1, size(listZ, 1)));
            cellListZ = cellfun(@num2str, cellListZ, 'UniformOutput', false);
            
            app.UITable.Data(:, 1) = cellListZ;
            app.UITable2.Data{app.numResult, 1} = num2str(max(listZ));
            app.UITable2.Data{app.numResult, 2} = num2str(min(listZ));
            app.UITable2.Data{app.numResult, 4} = num2str(listZ(FocalPoint, 1));
            
%                 app.UITable.Data(:, 1) = listZ;
%                 app.UITable2.Data(app.numResult, 1) = max(listZ);
%                 app.UITable2.Data(app.numResult, 2) = min(listZ);
%                 app.UITable2.Data(app.numResult, 4) = listZ(FocalPoint, 1);
%                 app.UITable2.Data(app.numResult, 4) = listZ(FocalPoint, 1) - min(listZ);
                
%             end
            
            if ~isempty(um_interval)
                
%                 app.UITable2.Data(app.numResult, 3) = um_interval;
                app.UITable2.Data{app.numResult, 3} = num2str(um_interval);
            
            end
            
            app.Image.ImageSource = app.fileList{FocalPoint, 1};            
%             app.UITable2.Data(app.numResult, 5) = tEnd;
            app.UITable2.Data{app.numResult, 5} = num2str(tEnd);
            
            cellTable2 = cellfun(@str2double, app.UITable2.Data, 'UniformOutput', false);
            matTable2 = cell2mat(cellTable2);
            matAvg = mean(matTable2);
            cellAvg = num2cell(matAvg);
            cellAvg = cellfun(@num2str, cellAvg, 'UniformOutput', false);
            matStd = std(matTable2);
            cellStd = num2cell(matStd);
            cellStd = cellfun(@num2str, cellStd, 'UniformOutput', false);
                
            app.UITable3.Data(1, :) = cellAvg;
            app.UITable3.Data(2, :) = cellStd;

%             app.UITable3.Data(2, :) = std(app.UITable2.Data);            
            app.numResult = app.numResult+1;
            delete(progDlg)
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.fileList; % List of files in the folder.
            app.numResult = 1;
            app.selPath = pwd;
            
            app.ImageListListBox.Items = "";
            cla(app.UIAxes)
            app.Image.ImageSource = "";
            
            app.UITable.Data = num2cell(nan(1, 2));
            app.UITable2.Data = num2cell(nan(1, 5));
            app.UITable3.Data = num2cell(nan(2, 5));
        end

        % Menu selected function: CopyMenu, CopyMenu_2, CopyMenu_3
        function CopyMenuSelected(app, event)
            try
            
                R1 = app.Selected.(event.Source.Tag)(1, 1);
                R2 = app.Selected.(event.Source.Tag)(end, 1);            
                C1 = app.Selected.(event.Source.Tag)(1, 2);
                C2 = app.Selected.(event.Source.Tag)(end, 2);
                
                for i = 1:size(app.Selected.(event.Source.Tag), 1)
                    
                    TempData{app.Selected.(event.Source.Tag)(i, 1), app.Selected.(event.Source.Tag)(i, 2)} =...
                        app.(event.Source.Tag).Data{app.Selected.(event.Source.Tag)(i, 1), app.Selected.(event.Source.Tag)(i, 2)};
                    
                end
    
                mat2clip(TempData(R1:R2, C1:C2));
                
            catch
                
                disp('Error to copy data')
                
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.Selected.UITable = event.Indices;
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            app.Selected.UITable2 = event.Indices;
        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
            app.Selected.UITable3 = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzeAutoFocusUIFigure = uifigure('Visible', 'off');
            app.AnalyzeAutoFocusUIFigure.Name = 'Analyze AutoFocus';
            app.AnalyzeAutoFocusUIFigure.Icon = 'Max_icon.png';
            app.Panel = uipanel(app.AnalyzeAutoFocusUIFigure);
            app.LoadafolderButton = uibutton(app.Panel, 'push');
            app.LoadafolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadafolderButtonPushed, true);
            app.LoadafolderButton.Text = 'Load a folder';
            app.ImageListListBox = uilistbox(app.Panel);
            app.Panel_2 = uipanel(app.AnalyzeAutoFocusUIFigure);
            app.AnalysisButton = uibutton(app.Panel_2, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Z'; 'Focus Value'};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_3 = uipanel(app.AnalyzeAutoFocusUIFigure);
            app.UITable2 = uitable(app.Panel_3);
            app.UITable2.ColumnName = {'Max Z'; 'Min Z'; 'Interval'; 'Focused Z'; 'Focusing Time'};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.Image = uiimage(app.Panel_3);
            app.UITable3 = uitable(app.Panel_3);
            app.UITable3.ColumnName = {'Max Z'; 'Min Z'; 'Interval'; 'Focused Z'; 'Focusing Time'};
            app.UITable3.RowName = {'Avg'; 'SD'};
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.RESETButton = uibutton(app.AnalyzeAutoFocusUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ContextMenu = uicontextmenu(app.AnalyzeAutoFocusUIFigure);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            app.CopyMenu.Tag = 'UITable';
            app.ContextMenu2 = uicontextmenu(app.AnalyzeAutoFocusUIFigure);
            app.ContextMenu2.Tag = 'UIT';
            % Assign app.ContextMenu2
            app.UITable2.ContextMenu = app.ContextMenu2;
            app.CopyMenu_2 = uimenu(app.ContextMenu2);
            app.CopyMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu_2.Text = 'Copy';
            app.CopyMenu_2.Tag = 'UITable2';
            app.ContextMenu3 = uicontextmenu(app.AnalyzeAutoFocusUIFigure);
            % Assign app.ContextMenu3
            app.UITable3.ContextMenu = app.ContextMenu3;
            app.CopyMenu_3 = uimenu(app.ContextMenu3);
            app.CopyMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu_3.Text = 'Copy';
            app.CopyMenu_3.Tag = 'UITable3';
            % Show the figure after all components are created
            app.AnalyzeAutoFocusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisFocus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzeAutoFocusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzeAutoFocusUIFigure)
        end
    end
end