classdef MeasureMoveTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XEditField         matlab.ui.control.NumericEditField
        UITable            matlab.ui.control.Table
        StartButton        matlab.ui.control.Button
        DropDown           matlab.ui.control.DropDown
        toMaxCheckBox      matlab.ui.control.CheckBox
        AxisLabel          matlab.ui.control.Label
        DistancestepLabel  matlab.ui.control.Label
        ContextMenu        matlab.ui.container.ContextMenu
        CopyMenu           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        MainApp % Description
        Selected;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            app.UITable.Data = {};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global step_per_um_X step_per_um_Y step_per_um_Z
            
            stepPerUm = [step_per_um_X step_per_um_Y step_per_um_Z];
            
            axisOrd = 'XYZ';            
            axisStr = get(app.DropDown, 'Value');
            isZeroToMax = get(app.toMaxCheckBox, 'Value');
            
            tmpAxisMat = zeros(1, 3);
            axisNum = strfind(axisOrd, axisStr);
            tmpAxisMat(1, axisNum) = 1;
            
            ResetStage(app.MainApp, tmpAxisMat)
            
            if isZeroToMax == 1
                
                distVal = 999999;
                
            else
                
                distVal = get(app.XEditField, 'Value');
                
            end
            
            ticMove = tic;        
            
            TX = strcat('$S#', axisStr, '#', 'B', '#', num2str(distVal));
            [~] = comm2port(app, 'Main_port', TX, []);
            TX2 = '$S#S';
            RX = comm2port(app, 'Main_port', TX2, []);
            stepVal = str2double(RX(6:end-3));
            umVal = round(stepVal / stepPerUm(axisNum) * 10)/10;

            timeMove = toc(ticMove);
            
            app.UITable.Data{end+1, 1} = axisStr;
            app.UITable.Data{end, 2} = stepVal;
            app.UITable.Data{end, 3} = umVal;
            app.UITable.Data{end, 4} = timeMove;
            app.UITable.Data{end, 5} = stepVal / timeMove; % step/s
            app.UITable.Data{end, 6} = umVal / timeMove; % um/s
        end

        % Value changed function: toMaxCheckBox
        function toMaxCheckBoxValueChanged(app, event)
            value = app.toMaxCheckBox.Value;
            
            if value == 1
                
                set(app.XEditField, 'Enable', 'Off')
                
            else
                
                set(app.XEditField, 'Enable', 'On')
                
            end
            
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            try
            
                R1 = app.Selected.(event.Source.Tag)(1, 1);
                R2 = app.Selected.(event.Source.Tag)(end, 1);            
                C1 = app.Selected.(event.Source.Tag)(1, 2);
                C2 = app.Selected.(event.Source.Tag)(end, 2);
                
                for i = 1:size(app.Selected.(event.Source.Tag), 1)
                    
                    TempData{app.Selected.(event.Source.Tag)(i, 1), app.Selected.(event.Source.Tag)(i, 2)} =...
                        app.(event.Source.Tag).Data{app.Selected.(event.Source.Tag)(i, 1), app.Selected.(event.Source.Tag)(i, 2)};
                    
                end
    
                mat2clip(TempData(R1:R2, C1:C2));
                
            catch
                
                disp('Error to copy data')
                
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.Selected.UITable = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [0 999999];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Value = 100;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Axis'; 'Step'; 'Dist (um)'; 'Time (s)'; 'step/s'; 'um/s'};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'X', 'Y', 'Z'};
            app.DropDown.Value = 'X';
            app.toMaxCheckBox = uicheckbox(app.UIFigure);
            app.toMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @toMaxCheckBoxValueChanged, true);
            app.toMaxCheckBox.Text = '0 to Max';
            app.AxisLabel = uilabel(app.UIFigure);
            app.AxisLabel.Text = 'Axis';
            app.DistancestepLabel = uilabel(app.UIFigure);
            app.DistancestepLabel.Text = 'Distance (step)';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            app.CopyMenu.Tag = 'UITable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeasureMoveTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end