classdef OpticCarlibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartMeasureButton     matlab.ui.control.StateButton
        CenterLabel            matlab.ui.control.Label
        LabelVerticalCenter    matlab.ui.control.Label
        Diff_LLabel            matlab.ui.control.Label
        LabelVerticalDiffL     matlab.ui.control.Label
        Diff_RLabel            matlab.ui.control.Label
        LabelVerticalDiffR     matlab.ui.control.Label
        CenterLabel_2          matlab.ui.control.Label
        LabelHorizontalCenter  matlab.ui.control.Label
        Diff_ULabel            matlab.ui.control.Label
        LabelHorizontalDiffU   matlab.ui.control.Label
        Diff_DLabel            matlab.ui.control.Label
        LabelHorizontalDiffD   matlab.ui.control.Label
        UIAxesX                matlab.ui.control.UIAxes
        UIAxesY                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            scrPos = get(0, 'ScreenSize');
            
            tmpPosX = MainApp.figure1.Position(1) - app.UIFigure.Position(3) - 10;
            tmpPosY = MainApp.figure1.Position(2) - app.UIFigure.Position(4) + MainApp.figure1.Position(4);
            
            if tmpPosX > 0 && tmpPosY > 0
            
                app.UIFigure.Position(1) = tmpPosX;
                app.UIFigure.Position(2) = tmpPosY;
                
            else
                
                app.UIFigure.Position(1) = 0;
                app.UIFigure.Position(2) = scrPos(4) - app.UIFigure.Position(4) - 50;
                
            end
        end

        % Value changed function: StartMeasureButton
        function StartMeasureButtonValueChanged(app, event)
            global vid
            
            value = app.StartMeasureButton.Value;
            
            if value == 1
                
                set(app.StartMeasureButton, 'Text', 'Measuring...')
                
                while value == 1
                    
                    try
                    
                        value = app.StartMeasureButton.Value;
                        
                        img = getsnapshot(vid);
                        x1 = 1:size(img, 2);
                        y1 = sum(img, 1);                                    
                        plot(x1, y1, 'Parent', app.UIAxesX)
                        xMid = round(size(x1, 2)/2);
                        xline(xMid, 'Parent', app.UIAxesX)
                        app.LabelVerticalCenter.Text = num2str(y1(xMid));
                        app.LabelVerticalDiffL.Text = num2str(y1(xMid) - y1(1));
                        app.LabelVerticalDiffR.Text = num2str(y1(xMid) - y1(end));
                        
                        pause(0.2)                
                        
                        x2 = sum(img, 2);
                        y2 = 1:size(img, 1);
                        plot(x2, y2, 'Parent', app.UIAxesY)
                        yMid = round(size(y2, 2)/2);
                        yline(yMid, 'Parent', app.UIAxesY)
                        app.LabelHorizontalCenter.Text = num2str(x2(yMid));
                        app.LabelHorizontalDiffU.Text = num2str(x2(yMid) - x2(1));
                        app.LabelHorizontalDiffD.Text = num2str(x2(yMid) - x2(end));
                        
                        pause(0.2)
                        
                    catch
                        
                        break
                        
                    end
                    
                end
                
            else
                
                set(app.StartMeasureButton, 'Text', 'Measure Start')
                
            end  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StartMeasureButton.Value = 0;
            pause(1)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Optic Carlibration';
            app.UIFigure.Icon = 'Max_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartMeasureButton = uibutton(app.UIFigure, 'state');
            app.StartMeasureButton.ValueChangedFcn = createCallbackFcn(app, @StartMeasureButtonValueChanged, true);
            app.StartMeasureButton.Text = 'Start Measure';
            app.CenterLabel = uilabel(app.UIFigure);
            app.CenterLabel.Text = 'Center :';
            app.LabelVerticalCenter = uilabel(app.UIFigure);
            app.LabelVerticalCenter.Text = '0';
            app.Diff_LLabel = uilabel(app.UIFigure);
            app.Diff_LLabel.Text = 'Diff_L :';
            app.LabelVerticalDiffL = uilabel(app.UIFigure);
            app.LabelVerticalDiffL.Text = '0';
            app.Diff_RLabel = uilabel(app.UIFigure);
            app.Diff_RLabel.Text = 'Diff_R :';
            app.LabelVerticalDiffR = uilabel(app.UIFigure);
            app.LabelVerticalDiffR.Text = '0';
            app.CenterLabel_2 = uilabel(app.UIFigure);
            app.CenterLabel_2.Text = 'Center :';
            app.LabelHorizontalCenter = uilabel(app.UIFigure);
            app.LabelHorizontalCenter.Text = '0';
            app.Diff_ULabel = uilabel(app.UIFigure);
            app.Diff_ULabel.Text = 'Diff_U :';
            app.LabelHorizontalDiffU = uilabel(app.UIFigure);
            app.LabelHorizontalDiffU.Text = '0';
            app.Diff_DLabel = uilabel(app.UIFigure);
            app.Diff_DLabel.Text = 'Diff_D :';
            app.LabelHorizontalDiffD = uilabel(app.UIFigure);
            app.LabelHorizontalDiffD.Text = '0';
            app.UIAxesX = uiaxes(app.UIFigure);
            title(app.UIAxesX, 'Vertical')
            app.UIAxesY = uiaxes(app.UIFigure);
            title(app.UIAxesY, 'Horizontal')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpticCarlibration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end