classdef UI_Alias_Run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RunUIFigure                     matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        StatusSerialNumberLabel         matlab.ui.control.Label
        SerialnumberLabel               matlab.ui.control.Label
        DoorStatusLabel                 matlab.ui.control.Label
        DoorLabel                       matlab.ui.control.Label
        InformationPCBVersionLabel      matlab.ui.control.Label
        StatusSystemBootID              matlab.ui.control.Label
        StatusSoftwareVersionLabel      matlab.ui.control.Label
        StatusStatusLabel               matlab.ui.control.Label
        PCBversionLabel                 matlab.ui.control.Label
        SystembootIDLabel               matlab.ui.control.Label
        SoftwareversionLabel            matlab.ui.control.Label
        WashPanel                       matlab.ui.container.Panel
        WashVolumeSpinner               matlab.ui.control.Spinner
        WashVolumeSpinnerLabel          matlab.ui.control.Label
        xthesyringevolume500uLLabel_2   matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        BetweenInjectionsButton         matlab.ui.control.RadioButton
        BetweenSamplesButton            matlab.ui.control.RadioButton
        NoneButton_2                    matlab.ui.control.RadioButton
        SamplesPanel                    matlab.ui.container.Panel
        NumberofInjectionsSpinner       matlab.ui.control.Spinner
        NumberofInjectionsSpinnerLabel  matlab.ui.control.Label
        LastSampleDropDown              matlab.ui.control.DropDown
        LastSampleDropDownLabel         matlab.ui.control.Label
        FirstSampleDropDown             matlab.ui.control.DropDown
        FirstSampleDropDownLabel        matlab.ui.control.Label
        InjectionPanel                  matlab.ui.container.Panel
        AnalysistimeEditFieldS          matlab.ui.control.NumericEditField
        AnalysistimeEditFieldM          matlab.ui.control.NumericEditField
        AnalysistimeEditFieldH          matlab.ui.control.NumericEditField
        AnalysistimeHMSEditFieldLabel   matlab.ui.control.Label
        uLLabel_2                       matlab.ui.control.Label
        xthesyringevolume500uLLabel     matlab.ui.control.Label
        FillTransportSpinner            matlab.ui.control.Spinner
        FillTransportSpinnerLabel       matlab.ui.control.Label
        InjectionvolumeSpinner          matlab.ui.control.Spinner
        InjectionvolumeSpinnerLabel     matlab.ui.control.Label
        uLLabel                         matlab.ui.control.Label
        FlushvolumeSpinner              matlab.ui.control.Spinner
        FlushvolumeSpinnerLabel         matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        uLPickupButton                  matlab.ui.control.RadioButton
        PartialLoopfillButton           matlab.ui.control.RadioButton
        FullLoopButton                  matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        SyringeSpeedDropDown            matlab.ui.control.DropDown
        SyringeSpeedDropDownLabel       matlab.ui.control.Label
        NeedleHeightDropDown            matlab.ui.control.DropDown
        NeedleHeightDropDownLabel       matlab.ui.control.Label
        SkipMissingSampleCheckBox       matlab.ui.control.CheckBox
        HeadspacePressureCheckBox       matlab.ui.control.CheckBox
        AirSegmentCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        MainApp % MainApp
    end
    
    properties (Access = private)        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.MainApp = MainApp;
            portDevice = app.MainApp.PortDevice; 
            
            uiDlg = uiprogressdlg(app.RunUIFigure, 'Title', 'Please Wait',...
                    'Message', 'Checking the connection', 'Indeterminate', 'on');
            
            try
            
                pfcInformation = "1000";
                pfcStatus = "1001";
                
                % Device Information
                valueStatus = "000152"; % 1001000152 : Status      
                valueSystemBootIDPartNumber = "000182"; % 1001000182 : System Boot ID Part Number
                valueSystemBootID = "000184"; % 1001000184 : System Boot ID                
                valueSoftwarePartNumber = "000183"; % 1001000183 : Software Part Number
                valueSoftwareRevisionXL = "000185"; % 1001000185 : Software Revision XL
                valueDoorSensor = "005300"; % 1001005300 : Door Sensor      
                
                % Device Status
                valueSerialNumber = "002509"; % 1000002509 : Serial Number      
                valuePCBPartNumber = "000179"; % 1000000179 : PCB Part Number
                valuePCBRevisionNumber = "000181"; % 1000000181 : PCB Revision Number
                valueTraySegmentSetting = "000200"; % 1000000200 : Tray Segment Setting                 
                valueLoopVolume = "000107"; % 1000000107 : Loop Volume (0000 ~ 9999)
                valueTubingVolume = "000126"; % 1000000126 : Tubing Volume (000 ~ 999)
                valueSyringeVolume = "000125"; % 1000000125 : Syringe Volume (00050, 00100, 00250, 00500,
                % 01000, 02500)                
                valueUsePrepMode = "000198"; % 1000000198 : Use Prep Mode
                valueSyringeSpeed = "000131";                
                
                % Device Information                
                rxSerialNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueSerialNumber);
                rxPCBPartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valuePCBPartNumber);
                rxPCBRevisionNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valuePCBRevisionNumber);                
                rxTraySegmentSetting =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueTraySegmentSetting);
                rxvalueLoopVolume =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueLoopVolume);
                rxvalueTubingVolume =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueTubingVolume);
                rxvalueSyringeVolume =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueSyringeVolume);
                rxUsePrepMode =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueUsePrepMode);
                rxSyringeSpeed =...
                    AliasCommunication(portDevice, [], [], [], [], pfcInformation, valueSyringeSpeed);
                
                % Device Status
                rxStatus =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueStatus);
                rxSystemBootIDPartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSystemBootIDPartNumber);
                rxSystemBootID =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSystemBootID);
                rxSoftwarePartNumber =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSoftwarePartNumber);
                rxSoftwareRevisionXL =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueSoftwareRevisionXL);
                rxDoorSensor =...
                    AliasCommunication(portDevice, [], [], [], [], pfcStatus, valueDoorSensor);
                
                % Serial Number
                app.StatusSerialNumberLabel.Text = rxSerialNumber(end-5:end);      
                
                % PCB Version (Part Number - Revision Number)
                PCBPartNumber = sprintf('0%s.%s', rxPCBPartNumber(end-5:end-3), rxPCBPartNumber(end-2:end));
                PCBRevisionNumber = rxPCBRevisionNumber(end-1:end);                
                app.InformationPCBVersionLabel.Text = strcat(PCBPartNumber, '-', PCBRevisionNumber);
                                
                % Status
                if strcmp(rxStatus(end-3), '1')
                    statusStatus = "Error (Ask for Error Code)";
                else
                    if strcmp(rxStatus(end-2:end), "000")
                        statusStatus = "Idle";
                    else
                        statusStatus = rxStatus(end-2:end);                
                    end                
                end
                app.StatusStatusLabel.Text = statusStatus;  
                
                % System Boot ID (Part Number - Hardware version)
                bootID = sprintf('0%s.%s', rxSystemBootIDPartNumber(end-5:end-3), rxSystemBootIDPartNumber(end-2:end));
                    
                if strcmp(rxSystemBootID(end-5), '0')
                    hwType = '';
                    minorVer = '';
                elseif strcmp(rxSystemBootID(end-5), '1')
                    hwType = 'T';
                    minorVer = strcat('-', rxSystemBootID(end-1:end));
                elseif strcmp(rxSystemBootID(end-5), '2')
                    hwType = 'P';
                    minorVer = '';
                end
                                    
                app.StatusSystemBootID.Text =...
                    strcat(bootID, hwType, '-', rxSystemBootID(end-4), '.', rxSystemBootID(end-3:end-2), minorVer);
                
                % Tray Segment setting
                % q3
                if strcmp(rxTraySegmentSetting(end-3), '0')                
                    disp('Spark separate left plate')
                elseif strcmp(rxTraySegmentSetting(end-3), '3')
                    disp('Spark single plate')
                else
                    disp('Reserved')
                end
                % q2
                if strcmp(rxTraySegmentSetting(end-2), '0')
                    disp('Spark separate right plate')
                elseif strcmp(rxTraySegmentSetting(end-2), '3')
                    disp('Spark single plate')
                else
                    disp('Reserved')
                end
                
                if strcmp(rxTraySegmentSetting(end-3), '0') && strcmp(rxTraySegmentSetting(end-2), '0')                    
                    % q1
                    if strcmp(rxTraySegmentSetting(end-1), '0')
                        disp('96 Low')
                        width = 8;
                        height = 12;
                    elseif strcmp(rxTraySegmentSetting(end-1), '1')
                        disp('96 High')
                        width = 8;
                        height = 12;
                    elseif strcmp(rxTraySegmentSetting(end-1), '2')
                        disp('384 Low')
                        width = 16;
                        height = 24;
                    elseif strcmp(rxTraySegmentSetting(end-1), '3')
                        disp('48 Vials')
                        width = 6;
                        height = 8;
                    elseif strcmp(rxTraySegmentSetting(end-1), '4')
                        disp('12 Vials')                
                        width = 3;
                        height = 4;
                    end 
                    
                    leftPositions = MakePositionLetters('Left', width, height);
                    
                    %q0
                    if strcmp(rxTraySegmentSetting(end), '0')
                        disp('96 Low')
                        width = 8;
                        height = 12;
                    elseif strcmp(rxTraySegmentSetting(end), '1')
                        disp('96 High')
                        width = 8;
                        height = 12;
                    elseif strcmp(rxTraySegmentSetting(end), '2')
                        disp('384 Low')
                        width = 16;
                        height = 24;
                    elseif strcmp(rxTraySegmentSetting(end), '3')
                        disp('48 Vials')
                        width = 6;
                        height = 8;
                    elseif strcmp(rxTraySegmentSetting(end), '4')
                        disp('12 Vials')                
                        width = 3;
                        height = 4;
                    end               
                    
                    rightPositions = MakePositionLetters('Right', width, height);
                    positions = [leftPositions; rightPositions];
                    
                elseif strcmp(rxTraySegmentSetting(end-3), '3') && strcmp(rxTraySegmentSetting(end-2), '3')                
                    if strcmp(rxTraySegmentSetting(end-1:end), '00')
                        disp('84+3 Vials')
                    elseif strcmp(rxTraySegmentSetting(end-1:end), '11')
                        disp('108 Vials')
                    elseif strcmp(rxTraySegmentSetting(end-1:end), '22')
                        disp('30 Vials')
                    end
                    
                    positions = {};
                end
                
                app.FirstSampleDropDown.Items = positions;
                app.LastSampleDropDown.Items = positions;
             
                % Loop volume              
                % Max injection volume : loop volume / 2                
                loopVolume = rxvalueLoopVolume(end-3:end);
                fprintf('Loop volume : %s\n', loopVolume)
                app.InjectionvolumeSpinner.Limits = [0, floor(str2double(loopVolume)/2)];
                
                % Tubing volume                
                tubingVolume = rxvalueTubingVolume(end-2:end);
                fprintf('Tubing volume : %s\n', tubingVolume)
                
                % Syringe volume                
                syringeVolume = rxvalueSyringeVolume(end-4:end);
                fprintf('Syringe volume : %s\n', syringeVolume)
                
                % Use Prep mode                
                usePrepMode = rxUsePrepMode(end);
                if strcmp(usePrepMode, '0')
                    disp('Use prep mode : No')
                else
                    disp('Use prep mode : Yes')
                end
                
                % Software Version (Part Number - Revision XL)
                partNumber = sprintf('0%s.%s', rxSoftwarePartNumber(end-5:end-3), rxSoftwarePartNumber(end-2:end));
                  
                if strcmp(rxSoftwareRevisionXL(end-5), '0')
                    hwType = '';
                    minorVer = '';
                elseif strcmp(rxSoftwareRevisionXL(end-5), '1')
                    hwType = 'T';
                    minorVer = strcat('-', rxSoftwareRevisionXL(end-1:end));
                elseif strcmp(rxSoftwareRevisionXL(end-5), '2')
                    hwType = 'P';
                    minorVer = '';
                end
                                    
                app.StatusSoftwareVersionLabel.Text =...
                    strcat(partNumber, hwType, '-', rxSoftwareRevisionXL(end-4), '.', rxSoftwareRevisionXL(end-3:end-2), minorVer);
                
                % Door Sensor
                if strcmp(rxDoorSensor(end), '1')
                    statusDoorSensor = "Closed";                
                else                
                    statusDoorSensor = "Open";                
                end            
                app.DoorStatusLabel.Text = statusDoorSensor;
                
                % Initialize NeedleHeightDropDown items
                tmpMat = [2:0.5:6]';
                tmpCell = cell(size(tmpMat));
                
                for i = 1:size(tmpMat, 1)
                    
                    tmpCell{i, 1} = num2str(tmpMat(i, 1), '%.1f');
                    
                end                        
                app.NeedleHeightDropDown.Items = tmpCell;
                
                % Initialize SyringeSpeedDropDown items
                syringeSpeed = 'Normal';
                tmpCell = {'Low'; 'Normal'; 'High'};
                app.SyringeSpeedDropDown.Items = tmpCell;
                
                if strcmp(rxSyringeSpeed(end), '1')
                    syringeSpeed = 'Low';
                elseif strcmp(rxSyringeSpeed(end), '2')
                    syringeSpeed = 'Normal';
                elseif strcmp(rxSyringeSpeed(end), '3')
                    syringeSpeed = 'High';
                end
                
                app.SyringeSpeedDropDown.Value = syringeSpeed;
                
                close(uiDlg)
            
            catch
                
                close(uiDlg)
                ret = uiconfirm(app.RunUIFigure,...
                    'Check the connection!',...
                    'Error', 'Icon', 'error', 'Options', {'OK'});               

                RunUIFigureCloseRequest(app, [])
                
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'None')
                app.FlushvolumeSpinner.Enable = 'off';
                app.AnalysistimeEditFieldH.Enable = 'off';
                app.AnalysistimeEditFieldM.Enable = 'off';
                app.AnalysistimeEditFieldS.Enable = 'off';
                app.InjectionvolumeSpinner.Enable = 'off';
                app.FillTransportSpinner.Enable = 'off';                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Full Loop')
                app.FlushvolumeSpinner.Enable = 'on';
                app.AnalysistimeEditFieldH.Enable = 'on';
                app.AnalysistimeEditFieldM.Enable = 'on';
                app.AnalysistimeEditFieldS.Enable = 'on';
                app.InjectionvolumeSpinner.Enable = 'off';
                app.FillTransportSpinner.Enable = 'off';                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Partial Loopfill')
                app.FlushvolumeSpinner.Enable = 'on';
                app.AnalysistimeEditFieldH.Enable = 'on';
                app.AnalysistimeEditFieldM.Enable = 'on';
                app.AnalysistimeEditFieldS.Enable = 'on';
                app.InjectionvolumeSpinner.Enable = 'on';
                app.FillTransportSpinner.Enable = 'off';                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'uL Pickup')
                app.FlushvolumeSpinner.Enable = 'off';
                app.AnalysistimeEditFieldH.Enable = 'on';
                app.AnalysistimeEditFieldM.Enable = 'on';
                app.AnalysistimeEditFieldS.Enable = 'on';
                app.InjectionvolumeSpinner.Enable = 'on';
                app.FillTransportSpinner.Enable = 'on';                
            end
            
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'None') && strcmp(app.ButtonGroup_2.SelectedObject.Text, 'None')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if strcmp(app.ButtonGroup_2.SelectedObject.Text, 'None')
                app.WashVolumeSpinner.Enable = 'off';
            else
                app.WashVolumeSpinner.Enable = 'on';
            end            
            
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'None') && strcmp(app.ButtonGroup_2.SelectedObject.Text, 'None')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Close request function: RunUIFigure
        function RunUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % Start / Stop (Initialize)
            pfc = "5100";
            value = "000000"; % Stop Run or Initialize when Ready            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);
            disp(rx)
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            RunUIFigureCloseRequest(app, [])
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%             waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
%             if ~strcmp(waitRx, '61010152000000')
%                 disp('Not ready to start!!')
%                 return
%             end
            
            uiDlg = uiprogressdlg(app.RunUIFigure, 'Title', 'Please Wait',...
                'Message', 'Applying settings', 'Indeterminate', 'on');
            
            % Air segment : 0 (Use air segment : No)            
            pfc = "0192";
            q0 = num2str(app.AirSegmentCheckBox.Value);
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);            
            
            % (0194) Head space pressure : 0 (head space pressure No)
            pfc = "0194";
            q0 = num2str(app.HeadspacePressureCheckBox.Value);
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);            
            
            % (0193) Skip missing sample position
            pfc = "0193";
            q0 = num2str(app.SkipMissingSampleCheckBox.Value);
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % (0130) Sample needle height : 6 0 (6.0 mm with increments of 0.5 mm)
            pfc = "0130";            
            q1 = app.NeedleHeightDropDown.Value(1);
            q0 = app.NeedleHeightDropDown.Value(3);
            value = strcat("0000", q1, q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % (0131) Syringe speed : 2 (Normal)
            pfc = "0131";
            if strcmp(app.SyringeSpeedDropDown.Value, 'Low')
                q0 = '1';
            elseif strcmp(app.SyringeSpeedDropDown.Value, 'Normal')
                q0 = '2';
            elseif strcmp(app.SyringeSpeedDropDown.Value, 'High')
                q0 = '3';
            end
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % (0124) Injection mode : 2 (Full loop injection mode)
            pfc = "0124";
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'None')
                q0 = '0';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Full Loop')
                q0 = '2';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Partial Loopfill')
                q0 = '1';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'uL Pickup')
                q0 = '3';
            end
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'None')
                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Full Loop')
                % (0111) Flush volume : 0030 (30 uL)
                pfc = "0111";
                flushVolume = sprintf('%04.0f', app.FlushvolumeSpinner.Value);
                q3 = flushVolume(1);
                q2 = flushVolume(2);
                q1 = flushVolume(3);
                q0 = flushVolume(4);
                value = strcat("00", q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
                % (0100) Analysis time : 00000 (0 hours 00 minutes 00 seconds)   
                pfc = "0100";
                h = sprintf('%01.0f', app.AnalysistimeEditFieldH.Value);
                m = sprintf('%02.0f', app.AnalysistimeEditFieldM.Value);
                s = sprintf('%02.0f', app.AnalysistimeEditFieldS.Value);
                q4 = h;
                q3 = m(1);
                q2 = m(2);
                q1 = s(1);
                q0 = s(2);
                value = strcat("0", q4, q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Partial Loopfill')
                % (0111) Flush volume : 0030 (30 uL)
                pfc = "0111";
                flushVolume = sprintf('%04.0f', app.FlushvolumeSpinner.Value);
                q3 = flushVolume(1);
                q2 = flushVolume(2);
                q1 = flushVolume(3);
                q0 = flushVolume(4);
                value = strcat("00", q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
                % (0100) Analysis time : 00000 (0 hours 00 minutes 00 seconds)
                pfc = "0100";
                h = sprintf('%01.0f', app.AnalysistimeEditFieldH.Value);
                m = sprintf('%02.0f', app.AnalysistimeEditFieldM.Value);
                s = sprintf('%02.0f', app.AnalysistimeEditFieldS.Value);
                q4 = h;
                q3 = m(1);
                q2 = m(2);
                q1 = s(1);
                q0 = s(2);
                value = strcat("0", q4, q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
                % (0210) Injection Volume (P-SP) : 10 uL                
                pfc = "0210";
                injectionVolume = sprintf('%05.0f', app.InjectionvolumeSpinner.Value);
                q4 = injectionVolume(1);
                q3 = injectionVolume(2);
                q2 = injectionVolume(3);
                q1 = injectionVolume(4);
                q0 = injectionVolume(5);
                value = strcat("0", q4, q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'uL Pickup')
                % (0100) Analysis time : 00000 (0 hours 00 minutes 00 seconds)
                pfc = "0100";
                h = sprintf('%01.0f', app.AnalysistimeEditFieldH.Value);
                m = sprintf('%02.0f', app.AnalysistimeEditFieldM.Value);
                s = sprintf('%02.0f', app.AnalysistimeEditFieldS.Value);
                q4 = h;
                q3 = m(1);
                q2 = m(2);
                q1 = s(1);
                q0 = s(2);
                value = strcat("0", q4, q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
                % (0210) Injection Volume (P-SP) : 10 uL
                pfc = "0210";
                injectionVolume = sprintf('%05.0f', app.InjectionvolumeSpinner.Value);
                q4 = injectionVolume(1);
                q3 = injectionVolume(2);
                q2 = injectionVolume(3);
                q1 = injectionVolume(4);
                q0 = injectionVolume(5);
                value = strcat("0", q4, q3, q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
                % (0505) Fill Transport Position Times (P-SP)
                pfc = "0505";
                q0 = sprintf('%01.0f', app.FillTransportSpinner.Value);                
                q1 = '0';
                q2 = '0'; % Transport liquid: Syringe valve WASH port ('1' : 4th port, optional)
                value = strcat("000", q2, q1, q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                
            end
            
            if strcmp(app.ButtonGroup_2.SelectedObject.Text, 'None')
                % (0500) Wash between : 0 (None)                
                pfc = "0500";
                q0 = '0';
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);            
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Between Samples')
                % (0500) Wash between : 2 (Between vials)
                pfc = "0500";
                q0 = '2';
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);     
                
                % (0501) Wash times (P-SP) : 1 (times the syringe volume) SSV?
                pfc = "0501";
                q0 = num2str(app.WashVolumeSpinner.Value);
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);  
                
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Between Injections')
                % (0500) Wash between : 3 (Between injections)
                pfc = "0500";
                q0 = '3';
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);     
                
                % (0501) Wash times (P-SP) : 1 (times the syringe volume) SSV?
                pfc = "0501";
                q0 = num2str(app.WashVolumeSpinner.Value);
                value = strcat("00000", q0);
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            end            
            
            % (0108) First sample position : 10001
            pfc = "0108";
            fisrtSampleValue = split(app.FirstSampleDropDown.Value);
            direction = fisrtSampleValue{1};
            chamber = fisrtSampleValue{2};
            
            if strcmp(direction, 'Left')
                q4 = '1';
            elseif strcmp(direction, 'Right')
                q4 = '2';
            else
                q4 = '1'; % Left (inistant default)
            end
            
            columnNum = double(chamber(1)) - 65; % 65 : 'A'
            columnStr = sprintf('%02.0f', columnNum);
            q3 = columnStr(1);
            q2 = columnStr(2);
            
            rowNum = str2double(chamber(2:end));
            rowStr = sprintf('%02.0f', rowNum);
            q1 = rowStr(1);
            q0 = rowStr(2);
            value = strcat("0", q4, q3, q2, q1, q0);            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % (0109) Last sample position : 20508
            pfc = "0109";
            LastSampleValue = split(app.LastSampleDropDown.Value);
            direction = LastSampleValue{1};
            chamber = LastSampleValue{2};
            
            if strcmp(direction, 'Left')
                q4 = '1';
            elseif strcmp(direction, 'Right')
                q4 = '2';
            else
                q4 = '1'; % Left (inistant default)
            end
            
            columnNum = double(chamber(1)) - 65; % 65 : 'A'
            columnStr = sprintf('%02.0f', columnNum);
            q3 = columnStr(1);
            q2 = columnStr(2);
            
            rowNum = str2double(chamber(2:end));
            rowStr = sprintf('%02.0f', rowNum);
            q1 = rowStr(1);
            q0 = rowStr(2);
            value = strcat("0", q4, q3, q2, q1, q0);            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            % (0112) Number of injections / Sample : 1 (1~9)
            pfc = "0112";
            q0 = num2str(app.NumberofInjectionsSpinner.Value);
            value = strcat("00000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
            
            close(uiDlg)
            
            % (5100) Start/Stop (C) : 0 0000 1 (Start SparkLink method)
            pfc = "5100";
            q5 = '0';
            q0 = '1';
            value = strcat(q5, "0000", q0);
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);             
            app.StopButton.Enable = 'on';  
            
            % (0155) Error Code (SA)
            rxErr = '000';            
            pfc = '1001';
            value = '000155';
            valueStatus = '000152';
            tStart = tic;
            tEnd = 0;
            timeOut = 10;
            while strcmp(rxErr(end-2:end), '000') || tEnd > timeOut
                rxErr = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value); 
                rxStatus = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, valueStatus); 
                if strcmp(rxStatus, '61010152000000')
                    break;
                end
                tEnd = toc(tStart);
            end
            disp(rxErr)
            disp(rxStatus)            
            
            % (0156) Reset Errors (C)
            if ~strcmp(rxErr(end-2:end), '000')     
                errMsg = sprintf('Error Code : %s', rxErr(end-2:end));
                ret = uiconfirm(app.RunUIFigure,...
                    errMsg,...
                    'Error', 'Icon', 'error', 'Options', {'OK'}); 
                
                pfc = "0156";
                value = "000001";
                rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);                
            end
            
            waitRx = WaitUntilWantedRX(app.MainApp.PortDevice, [], 20);
            disp(waitRx)
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Start / Stop (Initialize)
            pfc = "5100";
            value = "000000"; % Stop Run or Initialize when Ready            
            rx = AliasCommunication(app.MainApp.PortDevice, [], [], [], [], pfc, value);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RunUIFigure = uifigure('Visible', 'off');
            app.RunUIFigure.Name = 'Run';
            app.RunUIFigure.CloseRequestFcn = createCallbackFcn(app, @RunUIFigureCloseRequest, true);
            app.Panel = uipanel(app.RunUIFigure);
            app.AirSegmentCheckBox = uicheckbox(app.Panel);
            app.AirSegmentCheckBox.Text = 'Air Segment';
            app.HeadspacePressureCheckBox = uicheckbox(app.Panel);
            app.HeadspacePressureCheckBox.Text = 'Headspace Pressure';
            app.SkipMissingSampleCheckBox = uicheckbox(app.Panel);
            app.SkipMissingSampleCheckBox.Text = 'Skip Missing Sample';
            app.NeedleHeightDropDownLabel = uilabel(app.Panel);
            app.NeedleHeightDropDownLabel.Text = 'Needle Height';
            app.NeedleHeightDropDown = uidropdown(app.Panel);
            app.NeedleHeightDropDown.Items = {'2.0', 'Option 2', 'Option 3', 'Option 4'};
            app.NeedleHeightDropDown.Value = '2.0';
            app.SyringeSpeedDropDownLabel = uilabel(app.Panel);
            app.SyringeSpeedDropDownLabel.Text = 'Syringe Speed';
            app.SyringeSpeedDropDown = uidropdown(app.Panel);
            app.SyringeSpeedDropDown.Items = {'Normal', 'Option 2', 'Option 3', 'Option 4'};
            app.SyringeSpeedDropDown.Value = 'Normal';
            app.InjectionPanel = uipanel(app.RunUIFigure);
            app.InjectionPanel.Title = 'Injection';
            app.ButtonGroup = uibuttongroup(app.InjectionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NoneButton = uiradiobutton(app.ButtonGroup);
            app.NoneButton.Text = 'None';
            app.FullLoopButton = uiradiobutton(app.ButtonGroup);
            app.FullLoopButton.Text = 'Full Loop';
            app.PartialLoopfillButton = uiradiobutton(app.ButtonGroup);
            app.PartialLoopfillButton.Text = 'Partial Loopfill';
            app.PartialLoopfillButton.Value = true;
            app.uLPickupButton = uiradiobutton(app.ButtonGroup);
            app.uLPickupButton.Text = 'uL Pickup';
            app.FlushvolumeSpinnerLabel = uilabel(app.InjectionPanel);
            app.FlushvolumeSpinnerLabel.Text = 'Flush volume';
            app.FlushvolumeSpinner = uispinner(app.InjectionPanel);
            app.FlushvolumeSpinner.Limits = [0 9999];
            app.FlushvolumeSpinner.ValueDisplayFormat = '%.0f';
            app.FlushvolumeSpinner.Value = 30;
            app.uLLabel = uilabel(app.InjectionPanel);
            app.uLLabel.Text = 'uL';
            app.InjectionvolumeSpinnerLabel = uilabel(app.InjectionPanel);
            app.InjectionvolumeSpinnerLabel.Text = 'Injection volume';
            app.InjectionvolumeSpinner = uispinner(app.InjectionPanel);
            app.InjectionvolumeSpinner.Limits = [0 12];
            app.InjectionvolumeSpinner.ValueDisplayFormat = '%.0f';
            app.InjectionvolumeSpinner.Value = 10;
            app.FillTransportSpinnerLabel = uilabel(app.InjectionPanel);
            app.FillTransportSpinnerLabel.Enable = 'off';
            app.FillTransportSpinnerLabel.Text = 'Fill Transport';
            app.FillTransportSpinner = uispinner(app.InjectionPanel);
            app.FillTransportSpinner.Limits = [1 9];
            app.FillTransportSpinner.ValueDisplayFormat = '%.0f';
            app.FillTransportSpinner.Enable = 'off';
            app.FillTransportSpinner.Value = 1;
            app.xthesyringevolume500uLLabel = uilabel(app.InjectionPanel);
            app.xthesyringevolume500uLLabel.Enable = 'off';
            app.xthesyringevolume500uLLabel.Text = 'x the syringe volume (500 uL)';
            app.uLLabel_2 = uilabel(app.InjectionPanel);
            app.uLLabel_2.Text = 'uL';
            app.AnalysistimeHMSEditFieldLabel = uilabel(app.InjectionPanel);
            app.AnalysistimeHMSEditFieldLabel.Text = 'Analysis time (H, M, S)';
            app.AnalysistimeEditFieldH = uieditfield(app.InjectionPanel, 'numeric');
            app.AnalysistimeEditFieldH.Limits = [0 9];
            app.AnalysistimeEditFieldH.ValueDisplayFormat = '%01.0f';
            app.AnalysistimeEditFieldM = uieditfield(app.InjectionPanel, 'numeric');
            app.AnalysistimeEditFieldM.Limits = [0 99];
            app.AnalysistimeEditFieldM.ValueDisplayFormat = '%02.0f';
            app.AnalysistimeEditFieldM.Value = 10;
            app.AnalysistimeEditFieldS = uieditfield(app.InjectionPanel, 'numeric');
            app.AnalysistimeEditFieldS.Limits = [0 99];
            app.AnalysistimeEditFieldS.ValueDisplayFormat = '%02.0f';
            app.SamplesPanel = uipanel(app.RunUIFigure);
            app.SamplesPanel.Title = 'Samples';
            app.FirstSampleDropDownLabel = uilabel(app.SamplesPanel);
            app.FirstSampleDropDownLabel.Text = 'First Sample';
            app.FirstSampleDropDown = uidropdown(app.SamplesPanel);
            app.FirstSampleDropDown.Items = {'Left A1', 'Option 2', 'Option 3', 'Option 4'};
            app.FirstSampleDropDown.Value = 'Left A1';
            app.LastSampleDropDownLabel = uilabel(app.SamplesPanel);
            app.LastSampleDropDownLabel.Text = 'Last Sample';
            app.LastSampleDropDown = uidropdown(app.SamplesPanel);
            app.LastSampleDropDown.Items = {'Left A3', 'Option 2', 'Option 3', 'Option 4'};
            app.LastSampleDropDown.Value = 'Left A3';
            app.NumberofInjectionsSpinnerLabel = uilabel(app.SamplesPanel);
            app.NumberofInjectionsSpinnerLabel.Text = 'Number of Injections';
            app.NumberofInjectionsSpinner = uispinner(app.SamplesPanel);
            app.NumberofInjectionsSpinner.Limits = [1 9];
            app.NumberofInjectionsSpinner.Value = 1;
            app.WashPanel = uipanel(app.RunUIFigure);
            app.WashPanel.Title = 'Wash';
            app.ButtonGroup_2 = uibuttongroup(app.WashPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.NoneButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.NoneButton_2.Text = 'None';
            app.BetweenSamplesButton = uiradiobutton(app.ButtonGroup_2);
            app.BetweenSamplesButton.Text = 'Between Samples';
            app.BetweenSamplesButton.Value = true;
            app.BetweenInjectionsButton = uiradiobutton(app.ButtonGroup_2);
            app.BetweenInjectionsButton.Text = 'Between Injections';
            app.xthesyringevolume500uLLabel_2 = uilabel(app.WashPanel);
            app.xthesyringevolume500uLLabel_2.Text = 'x the syringe volume (500 uL)';
            app.WashVolumeSpinnerLabel = uilabel(app.WashPanel);
            app.WashVolumeSpinnerLabel.Text = 'Wash Volume';
            app.WashVolumeSpinner = uispinner(app.WashPanel);
            app.WashVolumeSpinner.Limits = [1 9];
            app.WashVolumeSpinner.Value = 1;
            app.Panel_3 = uipanel(app.RunUIFigure);
            app.SoftwareversionLabel = uilabel(app.Panel_3);
            app.SoftwareversionLabel.Text = 'Software version:';
            app.SystembootIDLabel = uilabel(app.Panel_3);
            app.SystembootIDLabel.Text = 'System boot ID:';
            app.PCBversionLabel = uilabel(app.Panel_3);
            app.PCBversionLabel.Text = 'PCB version:';
            app.StatusStatusLabel = uilabel(app.Panel_3);
            app.StatusStatusLabel.Text = 'Idle';
            app.StatusSoftwareVersionLabel = uilabel(app.Panel_3);
            app.StatusSoftwareVersionLabel.Text = '0840.110-1.13';
            app.StatusSystemBootID = uilabel(app.Panel_3);
            app.StatusSystemBootID.Text = '0840.141-2.10';
            app.InformationPCBVersionLabel = uilabel(app.Panel_3);
            app.InformationPCBVersionLabel.Text = '0840.601-11';
            app.DoorLabel = uilabel(app.Panel_3);
            app.DoorLabel.Text = 'Door:';
            app.DoorStatusLabel = uilabel(app.Panel_3);
            app.DoorStatusLabel.Text = 'Closed';
            app.SerialnumberLabel = uilabel(app.Panel_3);
            app.SerialnumberLabel.Text = 'Serial number:';
            app.StatusSerialNumberLabel = uilabel(app.Panel_3);
            app.StatusSerialNumberLabel.Text = '111177';
            app.InitializeButton = uibutton(app.Panel_3, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusLabel = uilabel(app.Panel_3);
            app.StatusLabel.Text = 'Status:';
            app.StartButton = uibutton(app.RunUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RunUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.CloseButton = uibutton(app.RunUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.HelpButton = uibutton(app.RunUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.RunUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Alias_Run(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RunUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RunUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RunUIFigure)
        end
    end
end