classdef WorkingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Tier4Panel      matlab.ui.container.Panel
        CheckBox_4      matlab.ui.control.CheckBox
        ListBox_4       matlab.ui.control.ListBox
        ListBox_4Label  matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        Button_4        matlab.ui.control.Button
        Tier3Panel      matlab.ui.container.Panel
        CheckBox_3      matlab.ui.control.CheckBox
        ListBox_3       matlab.ui.control.ListBox
        ListBox_3Label  matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Button_3        matlab.ui.control.Button
        Tier2Panel      matlab.ui.container.Panel
        CheckBox_2      matlab.ui.control.CheckBox
        ListBox_2       matlab.ui.control.ListBox
        ListBox_2Label  matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        Tier1Panel      matlab.ui.container.Panel
        CheckBox        matlab.ui.control.CheckBox
        ListBox         matlab.ui.control.ListBox
        ListBoxLabel    matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Button          matlab.ui.control.Button
    end

    
    properties (Access = public)
        ListTiers = {'Admin', 'Manager', 'Senior', 'Staff'};
        CurrentTier = '';
        IsLoggedIn = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for i = 1:size(app.ListTiers, 2)
                set(findobj(app.UIFigure, 'Tag', app.ListTiers{i}), 'Visible', 'Off');                
            end

            loginApp = LoginWindow(app);
            waitfor(loginApp.UIFigure);    

            if ~app.IsLoggedIn; return; end

            tierIdx = find(matches(app.ListTiers, app.CurrentTier), true);
            for i = tierIdx:size(app.ListTiers, 2)
                set(findobj(app.UIFigure, 'Tag', app.ListTiers{i}), 'Visible', 'On');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tier1Panel = uipanel(app.UIFigure);
            app.Tier1Panel.Title = 'Tier1';
            app.Button = uibutton(app.Tier1Panel, 'push');
            app.Button.Tag = 'Admin';
            app.Label = uilabel(app.Tier1Panel);
            app.Label.Tag = 'Admin';
            app.ListBoxLabel = uilabel(app.Tier1Panel);
            app.ListBoxLabel.Tag = 'Admin';
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Tier1Panel);
            app.ListBox.Tag = 'Admin';
            app.CheckBox = uicheckbox(app.Tier1Panel);
            app.CheckBox.Tag = 'Admin';
            app.Tier2Panel = uipanel(app.UIFigure);
            app.Tier2Panel.Title = 'Tier2';
            app.Button_2 = uibutton(app.Tier2Panel, 'push');
            app.Button_2.Tag = 'Manager';
            app.Label_2 = uilabel(app.Tier2Panel);
            app.Label_2.Tag = 'Manager';
            app.ListBox_2Label = uilabel(app.Tier2Panel);
            app.ListBox_2Label.Tag = 'Manager';
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.Tier2Panel);
            app.ListBox_2.Tag = 'Manager';
            app.CheckBox_2 = uicheckbox(app.Tier2Panel);
            app.CheckBox_2.Tag = 'Manager';
            app.Tier3Panel = uipanel(app.UIFigure);
            app.Tier3Panel.Title = 'Tier3';
            app.Button_3 = uibutton(app.Tier3Panel, 'push');
            app.Button_3.Tag = 'Senior';
            app.Label_3 = uilabel(app.Tier3Panel);
            app.Label_3.Tag = 'Senior';
            app.ListBox_3Label = uilabel(app.Tier3Panel);
            app.ListBox_3Label.Tag = 'Senior';
            app.ListBox_3Label.Text = 'List Box';
            app.ListBox_3 = uilistbox(app.Tier3Panel);
            app.ListBox_3.Tag = 'Senior';
            app.CheckBox_3 = uicheckbox(app.Tier3Panel);
            app.CheckBox_3.Tag = 'Senior';
            app.Tier4Panel = uipanel(app.UIFigure);
            app.Tier4Panel.Title = 'Tier4';
            app.Button_4 = uibutton(app.Tier4Panel, 'push');
            app.Button_4.Tag = 'Staff';
            app.Label_4 = uilabel(app.Tier4Panel);
            app.Label_4.Tag = 'Staff';
            app.ListBox_4Label = uilabel(app.Tier4Panel);
            app.ListBox_4Label.Tag = 'Staff';
            app.ListBox_4Label.Text = 'List Box';
            app.ListBox_4 = uilistbox(app.Tier4Panel);
            app.ListBox_4.Tag = 'Staff';
            app.CheckBox_4 = uicheckbox(app.Tier4Panel);
            app.CheckBox_4.Tag = 'Staff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end