classdef ResultApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MainGrid              matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        FittingResultLabel    matlab.ui.control.Label
        BottomButtonGrid      matlab.ui.container.GridLayout
        UIButtonClose         matlab.ui.control.Button
        UIButtonExport        matlab.ui.control.Button
        UIButtonReport        matlab.ui.control.Button
        UIButtonFit           matlab.ui.control.Button
        UIButtonFittingSet    matlab.ui.control.Button
        UIOptionButtonGrid    matlab.ui.container.GridLayout
        UIButtonCancel        matlab.ui.control.Button
        UIButtonOK            matlab.ui.control.Button
        UIButtonBaseline      matlab.ui.control.Button
        UIButtonTimingSet     matlab.ui.control.Button
        UIDropdownGrid        matlab.ui.container.GridLayout
        UICheckBoxLegend      matlab.ui.control.CheckBox
        UIDropdownName        matlab.ui.control.DropDown
        AnalyteDropDownLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        Analyte;
        Legend;
    end
    
    properties (Access = private)
        lineExpandRatio = 1e5;
        markAsStart;
        markAsEnd;
        markDisStart;
        markDisEnd;        
        lineAsStart;
        lineAsEnd;
        lineDisStart;
        lineDisEnd;
        resultFilePath;
        lineBaseline;
    end
    
    methods (Access = private)
        
        function properStr = MakeProperFolderName(app, inputStr)
            if ~ischar(inputStr)
                disp('Input type should be char!')
                properStr = inputStr;
                return
            end
            properStr = ...
            inputStr((inputStr~='\')&(inputStr~='/')...
            &(inputStr~=':')&(inputStr~='*')...
            &(inputStr~='?')&(inputStr~='"')...
            &(inputStr~='<')&(inputStr~='>')...
            &(inputStr~='|'));
        end


    end
    
    methods (Access = public)
        
        function [concStr, unit] = FindConcentrationUnit(app, concentration, concentrationUnit)

        % Giga ~ ato    
            unitNames = {'GM', 'MM', 'KM', 'M', 'mM', 'uM', 'nM', 'pM', 'fM', 'aM'};
            unitDigitCriteria = [9 6 3 0 -3 -6 -9 -12 -15 -18];    
            
            if isempty(concentrationUnit)
                logConc = log10(concentration);
                digitConc = round(round(min(logConc(~isinf(logConc))))/3) * 3;
                [~, idx] = min(abs(digitConc - unitDigitCriteria));
            else
                idx = find(matches(unitNames, concentrationUnit));
            end
            unitDigit = unitDigitCriteria(idx);        
            unit = (unitNames{idx});
            concNum = concentration / 10^unitDigit;
            concStr = cell(length(concNum), 1);
            
            for i = 1:length(concNum)
                concStr{i, 1} = sprintf('%0.2f', concNum(i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentPath)
            app.UIButtonOK.Visible = 'off';
            app.UIButtonCancel.Visible = 'off';
            
            % Info file vadlidation check?
            AnalyzeKinetics(app, parentPath)
        end

        % Value changed function: UIDropdownName
        function UIDropdownNameValueChanged(app, event)
            src = app.UIDropdownName;
            analyte = app.Analyte;

            if isempty(analyte)
                return
            end

            cla(app.UIAxes);
            analyteNo = find(strcmp(src.Items, src.Value));
            concentrationUnit = analyte(analyteNo).ConcentrationUnit{1};
            [concentration, concentrationUnit] = FindConcentrationUnit(app, analyte(analyteNo).Concentration, concentrationUnit);
            
            linePlot.Raw = [];
            linePlot.RawString = [];
            linePlot.Fitted = [];
            linePlot.FittedString = [];
            for i = 1:size(analyte(analyteNo).Data, 1)
                hold(app.UIAxes, 'on');
                linePlot.Raw(end+1) = plot(app.UIAxes,...
                    analyte(analyteNo).Data{i, 1}.x,...
                    analyte(analyteNo).Data{i, 1}.y);
                linePlot.RawString{end+1} = strcat(concentration{i}, concentrationUnit);
            end
            
            app.markAsStart = annotation(app.UIFigure, 'arrow'); 
            app.markAsStart.Parent = app.UIAxes;
            app.markAsStart.Position = [analyte(analyteNo).EventTime(1), -1, 0, 1];
            app.markAsStart.Color = [1, 0, 0];
            
            app.markAsEnd = annotation(app.UIFigure, 'arrow'); 
            app.markAsEnd.Parent = app.UIAxes;
            app.markAsEnd.Position = [analyte(analyteNo).EventTime(2), -1, 0, 1];
            app.markAsEnd.Color = [0, 1, 0];
            
            app.markDisStart = annotation(app.UIFigure, 'arrow'); 
            app.markDisStart.Parent = app.UIAxes;
            app.markDisStart.Position = [analyte(analyteNo).EventTime(3), -1, 0, 1];
            app.markDisStart.Color = [0, 0, 1];
            
            app.markDisEnd = annotation(app.UIFigure, 'arrow'); 
            app.markDisEnd.Parent = app.UIAxes;
            app.markDisEnd.Position = [analyte(analyteNo).EventTime(4), -1, 0, 1];
            app.markDisEnd.Color = [0, 1, 1];
            
            for i = 1:size(analyte(analyteNo).FittedR, 2)
                hold(app.UIAxes, 'On');
                linePlot.Fitted(end+1) = plot(app.UIAxes,...
                    analyte(analyteNo).FittedT(:, i),...
                    analyte(analyteNo).FittedR(:, i),...
                    'color', 'black');
                linePlot.FittedString{end+1} = strcat(concentration{i}, concentrationUnit, '(Fitted)');
            end
            
            app.Legend = legend(app.UIAxes,...
                [flip(linePlot.Raw) flip(linePlot.Fitted)],...
                [flip(linePlot.RawString) flip(linePlot.FittedString)]);
            app.Legend.ContextMenu = '';
            app.Legend.ItemHitFcn = @(src, event)LegendItemHitFcn(src, event);
            app.Legend.Orientation = 'horizontal';
            app.Legend.Location = 'northoutside';
            app.Legend.NumColumns = 5;
            app.Legend.FontSize = 9.5;
            UICheckBoxLegendValueChanged(app, [])
            axtoolbar(app.UIAxes, {'pan', 'zoomin', 'zoomout', 'restoreview', 'export'});
            
            % Table display (koff, kon, Rmax, KA, KD)
            currentModel = analyte(analyteNo).FittingVariable.FittingModel;
            kVars = analyte(analyteNo).FittingVariable.(currentModel).Name;
            kResult = cell(size(kVars, 1), 1);
            for i = 1:size(kResult, 1)    
                kResult{i, 1} = zeros(size(analyte(analyteNo).Concentration, 1), 1);
                tmpIdx = contains(analyte(analyteNo).kName, analyte(analyteNo).kName{i});
                kResult{i, 1}(:, 1) = analyte(analyteNo).k(tmpIdx);    
            end
            
            numericKD = kResult{2, 1} ./ kResult{1, 1};
            numericKA = kResult{1, 1} ./ kResult{2, 1};
            numericChi2 = ones(size(analyte(analyteNo).Concentration, 1), 1) * analyte(analyteNo).chi2;
            
            for i = 1:size(kVars, 1)
                variable.(kVars{i, 1}) = cell(size(analyte(analyteNo).Concentration, 1), 1);
                if strcmp(kVars{i, 1}, 'kon') || strcmp(kVars{i, 1}, 'koff') || strcmp(kVars{i, 1}, 'kt')
                    varType = '%0.2e';
                elseif strcmp(kVars{i, 1}, 'Rmax') || strcmp(kVars{i, 1}, 'BI')
                    varType = '%0.2f';
                end
                for ii = 1:size(analyte(analyteNo).Concentration, 1)
                    variable.(kVars{i, 1}){ii, 1} = sprintf(varType, kResult{i, 1}(ii, 1));
                    variable.KD{ii, 1} = sprintf('%0.2e', numericKD(ii, 1));
                    variable.KA{ii, 1} = sprintf('%0.2e', numericKA(ii, 1));
                    variable.chi2{ii, 1} = sprintf('%0.2f', numericChi2(ii, 1));
                end
            end
            
            for i = 1:size(analyte(analyteNo).Concentration, 1)        
                variable.KD{i, 1} = sprintf('%0.2e', numericKD(i, 1));
                variable.KA{i, 1} = sprintf('%0.2e', numericKA(i, 1));
                variable.chi2{i, 1} = sprintf('%0.2f', numericChi2(i, 1));
            end
            
            if isempty(analyte(analyteNo).chi2); analyte(analyteNo).chi2 = NaN; end
            
            if strcmp(currentModel, 'OneToOneStandard')
                app.UITable.Data = table(concentration, variable.KD, variable.kon, variable.koff, variable.Rmax, variable.KA, variable.BI, variable.chi2);
                app.UITable.ColumnName = cell(1, 8);
                app.UITable.ColumnName{1} = strcat('Concentration (', concentrationUnit, ')');
                app.UITable.ColumnName{2} = 'KD (M)';
                app.UITable.ColumnName{3} = 'kon (1/(M*s))';
                app.UITable.ColumnName{4} = 'koff (1/s)';
                app.UITable.ColumnName{5} = 'Rmax (RU)';
                app.UITable.ColumnName{6} = 'KA (1/M)';
                app.UITable.ColumnName{7} = 'BI (RU)';
                app.UITable.ColumnName{8} = 'Chi2 (RU^2)';
            elseif strcmp(currentModel, 'OneToOneMassTransfer')
                app.UITable.Data = table(concentration, variable.KD, variable.kon, variable.koff, variable.kt, variable.Rmax, variable.KA, variable.BI, variable.chi2);
                app.UITable.ColumnName = cell(1, 9);
                app.UITable.ColumnName{1} = strcat('Concentration (', concentrationUnit, ')');
                app.UITable.ColumnName{2} = 'KD (M)';
                app.UITable.ColumnName{3} = 'kon (1/(M*s))';
                app.UITable.ColumnName{4} = 'koff (1/s)';
                app.UITable.ColumnName{5} = 'kt (RU/Ms)';
                app.UITable.ColumnName{6} = 'Rmax (RU)';
                app.UITable.ColumnName{7} = 'KA (1/M)';
                app.UITable.ColumnName{8} = 'BI (RU)';
                app.UITable.ColumnName{9} = 'Chi2 (RU^2)';
            end

            function LegendItemHitFcn(src, event)
                if strcmp(event.SelectionType, 'normal')
                    if event.Peer.LineWidth == 2
                        event.Peer.LineWidth = 0.5;
                    else
                        event.Peer.LineWidth = 2;
                    end
                end
            end
            
        end

        % Value changed function: UICheckBoxLegend
        function UICheckBoxLegendValueChanged(app, event)
            try
                app.Legend.Visible = app.UICheckBoxLegend.Value;
                app.Legend.Location = 'northoutside';
            catch
                disp('Checkbox Error!')
            end
        end

        % Button pushed function: UIButtonTimingSet
        function UIButtonTimingSetPushed(app, event)
            app.UIButtonOK.UserData = app.UIButtonTimingSet.Text;            
            analyte = app.Analyte;

            analyteNo = find(strcmp(app.UIDropdownName.Items, app.UIDropdownName.Value));
            
            % Disable buttons and dropdowns
            set(findobj(app.UIFigure, 'type', 'uibutton'), 'Enable', 'Off')
            set(findobj(app.UIFigure, 'type', 'uidropdown'), 'Enable', 'Off')
            
            % Visualize OK button
            app.UIButtonOK.Visible = 'On'; app.UIButtonOK.Enable = 'On';
            app.UIButtonCancel.Visible = 'On'; app.UIButtonCancel.Enable = 'On';
            
            UIAxes = app.UIAxes;
            xLim = [analyte(analyteNo).XData(1), analyte(analyteNo).XData(end)]; 
            yMag = abs(app.UIAxes.YLim(1) - app.UIAxes.YLim(2));
            yLim = [-yMag yMag] * app.lineExpandRatio;
            
            asStartPos = [analyte(analyteNo).EventTime(1), yLim(1);...
                analyte(analyteNo).EventTime(1), yLim(2)];
            asEndPos = [analyte(analyteNo).EventTime(2), yLim(1);...
                analyte(analyteNo).EventTime(2), yLim(2)];
            disStartPos = [analyte(analyteNo).EventTime(3), yLim(1);...
                analyte(analyteNo).EventTime(3), yLim(2)];
            disEndPos = [analyte(analyteNo).EventTime(4), yLim(1);...
                analyte(analyteNo).EventTime(4), yLim(2)];
            
            app.lineAsStart = drawline('Parent', UIAxes,...
                 'Position', asStartPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Association Start', ...
                 'LabelVisible', 'hover', ...
                 'Color', [1 0 0], ...
                 'LineWidth', 2);
            
            app.lineAsEnd = drawline('Parent', UIAxes,...
                 'Position', asEndPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Association End', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 1 0], ...
                 'LineWidth', 2);
            
            app.lineDisStart = drawline('Parent', UIAxes,...
                 'Position', disStartPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Dissociation Start', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 0 1], ...
                 'LineWidth', 2);
            
            app.lineDisEnd = drawline('Parent', UIAxes,...
                 'Position', disEndPos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Dissociation End', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 1 1], ...
                 'LineWidth', 2);
             
            addlistener(app.lineAsStart, 'ROIMoved', @(lineSrc, event)LineMoveEvent(lineSrc, event, xLim));
            addlistener(app.lineAsEnd, 'ROIMoved', @(lineSrc, event)LineMoveEvent(lineSrc, event, xLim));
            addlistener(app.lineDisStart, 'ROIMoved', @(lineSrc, event)LineMoveEvent(lineSrc, event, xLim));
            addlistener(app.lineDisEnd, 'ROIMoved', @(lineSrc, event)LineMoveEvent(lineSrc, event, xLim));
            
            SetDrawingArea(xLim)
            
            function LineMoveEvent(lineSrc, event, xLim)
            
                srcType = {'Association Start', 'Association End', 'Dissociation Start', 'Dissociation End'};    
            
                if strcmp(lineSrc.Label, srcType{1})
                    limitPos = [xLim(1)-1, app.lineAsEnd.Position(1)];
                    margin = [1 5];
                elseif strcmp(lineSrc.Label, srcType{2})
                    limitPos = [app.lineAsStart.Position(1), app.lineDisStart.Position(1)];
                    margin = [5 1];
                elseif strcmp(lineSrc.Label, srcType{3})
                    limitPos = [app.lineAsEnd.Position(1), app.lineDisEnd.Position(1)];
                    margin = [1 5];
                elseif strcmp(lineSrc.Label, srcType{4})
                    limitPos = [app.lineDisStart.Position(1), xLim(2)+1];
                    margin = [5 1];
                end
            
                if lineSrc.Position(1) <= limitPos(1)
                    lineSrc.Position(1) = limitPos(1) + margin(1);
                    lineSrc.Position(2) = lineSrc.Position(1);
                elseif lineSrc.Position(1) >= limitPos(2)
                    lineSrc.Position(1) = limitPos(2) - margin(2);
                    lineSrc.Position(2) = lineSrc.Position(1);
                end
            
                SetDrawingArea(xLim)
            end
            
            function SetDrawingArea(xLim)
                app.lineAsStart.DrawingArea =...
                    [xLim(1), 0,...
                    app.lineAsEnd.Position(1)-xLim(1)-5, 0];
                app.lineAsEnd.DrawingArea =...
                    [app.lineAsStart.Position(1)+5, 0,...
                    app.lineDisStart.Position(1)-app.lineAsStart.Position(1)-1, 0];
                app.lineDisStart.DrawingArea =...
                    [app.lineAsEnd.Position(1)+1, 0,...
                    app.lineDisEnd.Position(1) - app.lineAsEnd.Position(1) - 5, 0];
                app.lineDisEnd.DrawingArea =...
                    [app.lineDisStart.Position(1)+1, 0,...
                    xLim(2) - app.lineDisStart.Position(1), 0];
            end
        end

        % Button pushed function: UIButtonBaseline
        function UIButtonBaselinePushed(app, event)
            app.UIButtonOK.UserData = app.UIButtonBaseline.Text;
            analyte = app.Analyte;
            analyteNo = find(strcmp(app.UIDropdownName.Items, app.UIDropdownName.Value));
            
            % Disable buttons and dropdowns
            set(findobj(app.UIFigure, 'type', 'uibutton'), 'Enable', 'Off')
            set(findobj(app.UIFigure, 'type', 'uidropdown'), 'Enable', 'Off')
            
            % Visualize OK button
            app.UIButtonOK.Visible = 'On'; app.UIButtonOK.Enable = 'On';
            app.UIButtonCancel.Visible = 'On'; app.UIButtonCancel.Enable = 'On';
            
            UIAxes = app.UIAxes;
            xLim = [analyte(analyteNo).XData(1), analyte(analyteNo).XData(end)]; 
            yMag = abs(app.UIAxes.YLim(1) - app.UIAxes.YLim(2));
            yLim = [-yMag yMag] * app.lineExpandRatio;
            
            baselinePos = [analyte(analyteNo).Baseline, yLim(1);...
                analyte(analyteNo).Baseline, yLim(2)];
            app.lineBaseline = drawline('Parent', UIAxes,...
                 'Position', baselinePos, ...
                 'Deletable', 0, ...
                 'InteractionsAllowed', 'translate', ...
                 'Label', 'Baseline', ...
                 'LabelVisible', 'hover', ...
                 'Color', [0 0 0], ...
                 'LineWidth', 2);
             
            app.lineBaseline.DrawingArea =...
                    [xLim(1), 0,...
                    xLim(2), 0];
        end

        % Button pushed function: UIButtonOK
        function UIButtonOKPushed(app, event)
            set(findobj(app.UIFigure, 'type', 'uibutton'), 'Enable', 'On')
            set(findobj(app.UIFigure, 'type', 'uidropdown'), 'Enable', 'On')
            app.UIButtonOK.Visible = 'Off'; app.UIButtonOK.Enable = 'Off';
            app.UIButtonCancel.Visible = 'Off'; app.UIButtonCancel.Enable = 'Off';
            analyteNo = find(strcmp(app.UIDropdownName.Items, app.UIDropdownName.Value));
            analyte = app.Analyte(analyteNo);
            
            if strcmp(app.UIButtonOK.UserData, app.UIButtonTimingSet.Text)
                app.Analyte(analyteNo).EventTime =...
                round([app.lineAsStart.Position(1),...
                app.lineAsEnd.Position(1), ...
                app.lineDisStart.Position(1), ...
                app.lineDisEnd.Position(1)]);
            
                app.markAsStart.Position(1) = app.lineAsStart.Position(1);
                app.markAsEnd.Position(1) = app.lineAsEnd.Position(1);
                app.markDisStart.Position(1) = app.lineDisStart.Position(1);
                app.markDisEnd.Position(1) = app.lineDisEnd.Position(1);
                
                delete(app.lineAsStart)
                delete(app.lineAsEnd)
                delete(app.lineDisStart)
                delete(app.lineDisEnd)
            elseif strcmp(app.UIButtonOK.UserData, app.UIButtonBaseline.Text)    
                analyte.YData = [];    
                
                if app.lineBaseline.Position(1) > analyte.XData(end)
                    app.lineBaseline.Position(1) = analyte.XData(end);
                    app.lineBaseline.Position(2) = analyte.XData(end);
                elseif app.lineBaseline.Position(1) < analyte.XData(1)
                    app.lineBaseline.Position(1) = analyte.XData(1);
                    app.lineBaseline.Position(2) = analyte.XData(1);
                end
            
                for ii = 1:length(analyte.Data)                
                    analyte.Data{ii}.y = analyte.Data{ii}.y - analyte.Data{ii}.y(round(app.lineBaseline.Position(1)));
                    analyte.RmaxCandidate(ii, 1) = analyte.Data{ii, 1}.y(analyte.Data{ii, 1}.x == analyte.EventTime(3));                
                    analyte.YData = [analyte.YData; analyte.Data{ii, 1}.y];
                end
            
                analyte.Baseline = app.lineBaseline.Position(1);
                delete(app.lineBaseline);
                app.Analyte(analyteNo) = analyte;
                UIDropdownNameValueChanged(app, []);
            end
        end

        % Button pushed function: UIButtonCancel
        function UIButtonCancelPushed(app, event)
            src = app.UIButtonOK;
            set(findobj(app.UIFigure, 'type', 'uibutton'), 'Enable', 'On')
            set(findobj(app.UIFigure, 'type', 'uidropdown'), 'Enable', 'On')
            app.UIButtonOK.Visible = 'Off'; app.UIButtonOK.Enable = 'Off';
            app.UIButtonCancel.Visible = 'Off'; app.UIButtonCancel.Enable = 'Off';
            
            if strcmp(src.UserData, app.UIButtonTimingSet.Text)
                delete(app.lineAsStart)
                delete(app.lineAsEnd)
                delete(app.lineDisStart)
                delete(app.lineDisEnd)
            elseif strcmp(src.UserData, app.UIButtonBaseline.Text)
                delete(app.lineBaseline)
            end
        end

        % Button pushed function: UIButtonFittingSet
        function UIButtonFittingSetPushed(app, event)
            FittingSet(app);            
        end

        % Button pushed function: UIButtonFit
        function UIButtonFitPushed(app, event)
            UIProgressDialog = ...
                uiprogressdlg(app.UIFigure, 'Title', 'Please wait', ...
                'Message', 'Fitting Curve(s)...', ...
                'Indeterminate', 'on');
            pause(0.01)
            
            analyteNo = find(strcmp(app.UIDropdownName.Items, app.UIDropdownName.Value));
            
            [app.Analyte(analyteNo).k,...
                app.Analyte(analyteNo).kName,...
                app.Analyte(analyteNo).chi2,...
                app.Analyte(analyteNo).FittedT,...
                app.Analyte(analyteNo).FittedR]...
                    = ReadyForCurveFitting(...
                        app.Analyte(analyteNo).Concentration,...
                        app.Analyte(analyteNo).EventTime,...            
                        app.Analyte(analyteNo).XData,...
                        app.Analyte(analyteNo).YData,...
                        app.Analyte(analyteNo).FittingVariable);
                    
            UIDropdownNameValueChanged(app, []);
            close(UIProgressDialog)
        end

        % Button pushed function: UIButtonReport
        function UIButtonReportPushed(app, event)
            filter = {'PDF file *.pdf'};
            defPath = app.resultFilePath;    
            analyte = app.Analyte;
            analyteIdx = strcmp(app.UIDropdownName.Items, app.UIDropdownName.Value);
            defName = strcat(analyte(analyteIdx).Name, '_Report');
            [file, path] = uiputfile(filter, 'Save Report File', fullfile(defPath, defName));
        
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            GenerateReport(app, fullfile(path, file));
            app.resultFilePath = path;
        end

        % Button pushed function: UIButtonExport
        function UIButtonExportPushed(app, event)
            selPath = uigetdir(app.resultFilePath, 'Save folder');
            if isequal(selPath, 0); return; end
            
            formatOut = 'yymmddHHMMSS';
            dateStr = datestr(now, formatOut);
            extension = 'txt';
            
            for i = 1:size(app.Analyte, 2)    
                analyteName = MakeProperFolderName(app, app.Analyte(i).Name);
                pathName = fullfile(selPath, dateStr, analyteName);
                mkdir(pathName);
                
                fileName = fullfile(pathName, strcat('Calculated_Constants', '.', extension));
                resultCell = table2cell(app.UITable.Data);    
                resultHeader = app.UITable.ColumnName';
                resultCell = [resultHeader; resultCell];
                writecell(resultCell, fileName, 'Delimiter', 'tab');
                [concStr, unit] = FindConcentrationUnit(app, app.Analyte(i).Concentration, app.Analyte(i).ConcentrationUnit{1});
                
                for ii = 1:size(app.Analyte(i).Data, 1)
                    fileNameRawData = strcat('Raw_', concStr{ii}, unit, '.', extension);
                    writetable(app.Analyte(i).Data{ii}, fullfile(pathName, fileNameRawData), 'Delimiter', 'tab')
                    
                    fileNameFittedData = strcat('Fitted_', concStr{ii}, unit, '.', extension);
                    x = app.Analyte(i).FittedT(:, ii);
                    y = app.Analyte(i).FittedR(:, ii);
                    fittedDataTable = table(x, y);
                    writetable(fittedDataTable, fullfile(pathName, fileNameFittedData), 'Delimiter', 'tab')
                end
            end
            app.resultFilePath = selPath;
            winopen(fullfile(selPath, dateStr))
        end

        % Button pushed function: UIButtonClose
        function UIButtonClosePushed(app, event)
            UIFigureCloseRequest(app, [])
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.MainGrid);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Response (RU)')
            zlabel(app.UIAxes, 'Z')
            app.UIDropdownGrid = uigridlayout(app.MainGrid);
            app.AnalyteDropDownLabel = uilabel(app.UIDropdownGrid);
            app.AnalyteDropDownLabel.Text = 'Analyte:';
            app.UIDropdownName = uidropdown(app.UIDropdownGrid);
            app.UIDropdownName.ValueChangedFcn = createCallbackFcn(app, @UIDropdownNameValueChanged, true);
            app.UICheckBoxLegend = uicheckbox(app.UIDropdownGrid);
            app.UICheckBoxLegend.ValueChangedFcn = createCallbackFcn(app, @UICheckBoxLegendValueChanged, true);
            app.UICheckBoxLegend.Text = 'Legend';
            app.UICheckBoxLegend.Value = true;
            app.UIOptionButtonGrid = uigridlayout(app.MainGrid);
            app.UIButtonTimingSet = uibutton(app.UIOptionButtonGrid, 'push');
            app.UIButtonTimingSet.ButtonPushedFcn = createCallbackFcn(app, @UIButtonTimingSetPushed, true);
            app.UIButtonTimingSet.Text = 'Timing Set';
            app.UIButtonBaseline = uibutton(app.UIOptionButtonGrid, 'push');
            app.UIButtonBaseline.ButtonPushedFcn = createCallbackFcn(app, @UIButtonBaselinePushed, true);
            app.UIButtonBaseline.Text = 'Baseline';
            app.UIButtonOK = uibutton(app.UIOptionButtonGrid, 'push');
            app.UIButtonOK.ButtonPushedFcn = createCallbackFcn(app, @UIButtonOKPushed, true);
            app.UIButtonOK.Text = 'OK';
            app.UIButtonCancel = uibutton(app.UIOptionButtonGrid, 'push');
            app.UIButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @UIButtonCancelPushed, true);
            app.UIButtonCancel.Text = 'Cancel';
            app.BottomButtonGrid = uigridlayout(app.MainGrid);
            app.UIButtonFittingSet = uibutton(app.BottomButtonGrid, 'push');
            app.UIButtonFittingSet.ButtonPushedFcn = createCallbackFcn(app, @UIButtonFittingSetPushed, true);
            app.UIButtonFittingSet.Text = 'Fitting Set';
            app.UIButtonFit = uibutton(app.BottomButtonGrid, 'push');
            app.UIButtonFit.ButtonPushedFcn = createCallbackFcn(app, @UIButtonFitPushed, true);
            app.UIButtonFit.Text = 'Fit';
            app.UIButtonReport = uibutton(app.BottomButtonGrid, 'push');
            app.UIButtonReport.ButtonPushedFcn = createCallbackFcn(app, @UIButtonReportPushed, true);
            app.UIButtonReport.Text = 'Report';
            app.UIButtonExport = uibutton(app.BottomButtonGrid, 'push');
            app.UIButtonExport.ButtonPushedFcn = createCallbackFcn(app, @UIButtonExportPushed, true);
            app.UIButtonExport.Text = 'Export Data';
            app.UIButtonClose = uibutton(app.BottomButtonGrid, 'push');
            app.UIButtonClose.ButtonPushedFcn = createCallbackFcn(app, @UIButtonClosePushed, true);
            app.UIButtonClose.Text = 'Close';
            app.FittingResultLabel = uilabel(app.MainGrid);
            app.FittingResultLabel.Text = 'Fitting Result';
            app.UITable = uitable(app.MainGrid);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end