classdef CorrectDrift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        ClearButton                 matlab.ui.control.Button
        CorrectEndEditField         matlab.ui.control.NumericEditField
        CorrectEndEditFieldLabel    matlab.ui.control.Label
        CorrectStartEditField       matlab.ui.control.NumericEditField
        CorrectStartEditFieldLabel  matlab.ui.control.Label
        DriftEndEditField           matlab.ui.control.NumericEditField
        DriftEndEditFieldLabel      matlab.ui.control.Label
        DriftStartEditField         matlab.ui.control.NumericEditField
        DriftStartEditFieldLabel    matlab.ui.control.Label
        SaveCorrectedButton         matlab.ui.control.Button
        CorrectDriftButton          matlab.ui.control.Button
        SelectDataButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataImport % Imported data
        correctedData % Corrected data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            if isequal(file, 0)
                return;
            end
            dataFullFile = fullfile(path, file);
            tmpData = importdata(dataFullFile);
            
            if isstruct(tmpData)
                if isfield(tmpData, 'data')
                    app.dataImport = tmpData.data;
                else
                    return;
                end
            else
                app.dataImport = tmpData;
            end
            
            
            cla(app.UIAxes);
            plot(app.UIAxes, app.dataImport(:, 1), app.dataImport(:, 2));
        end

        % Button pushed function: CorrectDriftButton
        function CorrectDriftButtonPushed(app, event)
            driftStart = app.DriftStartEditField.Value;
            driftEnd = app.DriftEndEditField.Value;
            
            X = app.dataImport(:, 1);
            Y = app.dataImport(:, 2);
            
            if strcmp(app.MethodDropDown.Value, 'Fitting')            
                tgtX = X(find(X==driftStart):find(X==driftEnd));
                tgtY = Y(find(X==driftStart):find(X==driftEnd));
                
                f = fit(tgtX, tgtY, 'poly1');
                driftSlope = f.p1;                
            elseif strcmp(app.MethodDropDown.Value, 'Node')                
                driftSlope = (Y(X==driftEnd) - Y(X==driftStart)) / (driftEnd - driftStart);                
            end
                
            corrStart = app.CorrectStartEditField.Value;
            corrEnd = app.CorrectEndEditField.Value;
            corrX = X(find(X==corrStart):find(X==corrEnd));
            corrY = Y(find(X==corrStart):find(X==corrEnd));
            
            driftY = driftSlope*corrX + corrY(1);
            correctedX = corrX;
            correctedY = corrY - driftY;
            
            leftX = [];
            leftY = [];
            rightX = [];
            rightY = [];

            if correctedX(1) > X(1)
                correctedY = correctedY + Y(X==correctedX(1)) - correctedY(1);
                leftX = X(1:find(X==correctedX(1))-1);
                leftY = Y(1:find(X==correctedX(1))-1);                
            end
            
            if correctedX(end) < X(end)
                rightX = X(find(X==correctedX(end))+1:X(end));
                rightY = Y(find(X==correctedX(end))+1:X(end));
                rightY = rightY + correctedY(end) - rightY(1);
            end
            
            correctedX = [leftX; correctedX; rightX];
            correctedY = [leftY; correctedY; rightY];
            
            app.correctedData = [correctedX, correctedY];
            
            hold(app.UIAxes, 'On')
            plot(app.UIAxes, correctedX, correctedY)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: SaveCorrectedButton
        function SaveCorrectedButtonPushed(app, event)
            if isempty(app.correctedData)
                return;
            end
            
            [file, path] = uiputfile('DriftCorrected.txt');
            saveFullFile = fullfile(path, file);
            
            writematrix(app.correctedData, saveFullFile, 'Delimiter', '\t');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.CorrectDriftButton = uibutton(app.UIFigure, 'push');
            app.CorrectDriftButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectDriftButtonPushed, true);
            app.CorrectDriftButton.Text = 'Correct Drift';
            app.SaveCorrectedButton = uibutton(app.UIFigure, 'push');
            app.SaveCorrectedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCorrectedButtonPushed, true);
            app.SaveCorrectedButton.Text = 'Save Corrected';
            app.DriftStartEditFieldLabel = uilabel(app.UIFigure);
            app.DriftStartEditFieldLabel.Text = 'Drift Start';
            app.DriftStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.DriftEndEditFieldLabel = uilabel(app.UIFigure);
            app.DriftEndEditFieldLabel.Text = 'Drift End';
            app.DriftEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorrectStartEditFieldLabel = uilabel(app.UIFigure);
            app.CorrectStartEditFieldLabel.Text = 'Correct Start';
            app.CorrectStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorrectEndEditFieldLabel = uilabel(app.UIFigure);
            app.CorrectEndEditFieldLabel.Text = 'Correct End';
            app.CorrectEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Fitting', 'Node'};
            app.MethodDropDown.Value = 'Fitting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorrectDrift

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end